classdef fileloader_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SelectChannelsLabel  matlab.ui.control.Label
        btn_close            matlab.ui.control.Button
        lstbx_out            matlab.ui.control.ListBox
        lstbx_in             matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        calling_app% Description
    end
    
    properties (Access = public)
        F % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.calling_app = caller;
            file_path =  app.calling_app.settings.file_path;
            [~,~,file_extension] = fileparts(file_path);
            if strcmp(file_extension,'.mat')
                sdk = adi.mat_file_sdk;
            elseif strcmp(file_extension,'.h5')
                sdk = adi.h5_file_sdk;
            else
                sdk = adi.sdk;
            end

            file_h = sdk.openFile(file_path);
            in = adi.file_read_options;
            app.F = adi.file(file_path,file_h, sdk, in);
            
            channel_names = cell(app.F.n_channels,1);
            for i = 1: app.F.n_channels

                channel_names{i} = [num2str(i) ' ' app.F.channel_specs(1, i).name];
            end

            channel_names(2:length(channel_names)+1) = channel_names;
            channel_names{1} = 'Available Channels';
            app.lstbx_in.Items = channel_names;
        end

        % Value changed function: lstbx_in
        function lstbx_inValueChanged(app, event)
            if ~strcmp(app.lstbx_in.Value, 'Available Channels')    
                
                tmp = app.lstbx_out.Items;
                if find(strcmp(app.lstbx_out.Items, app.lstbx_in.Value))
                    tmp(strcmp(app.lstbx_out.Items, app.lstbx_in.Value)) = [];
                else
                    tmp{end+1} = app.lstbx_in.Value;
                end
                app.lstbx_out.Items = tmp;
                app.lstbx_in.Value = app.lstbx_in.Items{1};
            end
            
        end

        % Value changed function: lstbx_out
        function lstbx_outValueChanged(app, event)
            if ~strcmp(app.lstbx_out.Value, 'Selected Channels')    
                tmp = app.lstbx_out.Items;
                tmp(strcmp(app.lstbx_out.Items, app.lstbx_out.Value)) = [];
                app.lstbx_out.Items = tmp;
                app.lstbx_out.Value = app.lstbx_out.Items{1};
            end
            
        end

        % Button pushed function: btn_close
        function btn_closeButtonPushed(app, event)
            chs = app.lstbx_out.Items(2:end);
            sel_channels = nan(1,length(chs));
            for i = 1 : length(chs)
                sel_channels(i) = find(strcmp(app.lstbx_in.Items(2:end),chs{i}));
            end
            data = struct([]);
            
            for i = 1: app.F.n_records
                data(i).n_ticks = app.F.records(1,i).n_ticks;
                data(i).comments = app.F.records(1,i).comments;
                data(i).tick_dt = app.F.records(1,i).tick_dt;
                data(i).tick_fs = app.F.records(1,i).tick_fs;
                data(i).duration = app.F.records(1,i).duration;
                data(i).rec_start = app.F.records(1,i).record_start;
                data(i).data_start = app.F.records(1,i).data_start;
                data(i).data_start_str = app.F.records(1,i).data_start_str;
                
                for j = 1 : length(sel_channels)
                    data(i).channel(j).name = app.F.channel_names(sel_channels(j));
                    data(i).channel(j).unit = app.F.channel_specs(1,sel_channels(j)).units(1,i);
                    data(i).channel(j).n_samples = app.F.channel_specs(1,sel_channels(j)).n_samples(1,i);
                    data(i).channel(j).dt = app.F.channel_specs(1,sel_channels(j)).dt(1,i);
                    data(i).channel(j).fs = app.F.channel_specs(1,sel_channels(j)).fs(1,i);
                    data(i).channel(j).data_starts = app.F.channel_specs(1,sel_channels(j)).data_starts(1,i);
                    data(i).channel(j).rec_starts = app.F.channel_specs(1,sel_channels(j)).record_starts(1,i);
                    data(i).channel(j).ds = app.F.channel_specs(1,sel_channels(j)).downsample_amount(1,i);
                    chan_tmp = app.F.getChannelByName(data(i).channel(j).name,'partial_match',false); 
                    data(i).channel(j).data = chan_tmp.getData(i);
            
                end
            
            end
            
            output = struct([]);
            
            
            for i = 1:length(data(1).channel)
                output(i).name = data(1).channel(i).name;
                output(i).unit = data(1).channel(i).unit;
                output(i).tic_multipl = data(1).tick_fs / data(1).channel(i).fs;
                output(i).data = [];
                output(i).derived = false;
            %     tmp = 0;
                for j = 1 : length(data)
                    output(i).data = [output(i).data;data(j).channel(i).data];
                end
                output(i).ts = [data(1).channel(i).dt,length(output(i).data)*data(1).channel(i).dt]; 
            end
            tmp = zeros(length(data(1).channel),1);
            for i = 1 : length(output)
                tmp(i) = data(1).channel(i).dt;
            end
            min_dt = min(tmp);
            comments = struct([]);
            int_start_tic = 0;
            for i = 1: length(data)
                if ~isempty(data(i).comments)
                    for j = 1 : length(data(i).comments)
                        comments(end+1).id = data(i).comments(1,j).id;
                        comments(end).string = data(i).comments(1,j).str;
                        comments(end).tic_pos = data(i).comments(1,j).tick_position + int_start_tic;
                        comments(end).ts = comments(end).tic_pos *min_dt;
                    end
                end
                int_start_tic = int_start_tic + data(i).n_ticks;
            end
            import_data(app.calling_app,output,comments)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.lstbx_in = uilistbox(app.UIFigure);
            app.lstbx_in.Items = {'Available Channels'};
            app.lstbx_in.ValueChangedFcn = createCallbackFcn(app, @lstbx_inValueChanged, true);
            app.lstbx_in.Value = 'Available Channels';
            app.lstbx_out = uilistbox(app.UIFigure);
            app.lstbx_out.Items = {'Selected Channels'};
            app.lstbx_out.ValueChangedFcn = createCallbackFcn(app, @lstbx_outValueChanged, true);
            app.lstbx_out.Value = 'Selected Channels';
            app.btn_close = uibutton(app.UIFigure, 'push');
            app.btn_close.ButtonPushedFcn = createCallbackFcn(app, @btn_closeButtonPushed, true);
            app.btn_close.Text = 'Confirm & close';
            app.SelectChannelsLabel = uilabel(app.UIFigure);
            app.SelectChannelsLabel.Text = 'Select Channels';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fileloader_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end