classdef hr_fix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        chbx_bp_overlay          matlab.ui.control.CheckBox
        chbx_bp                  matlab.ui.control.CheckBox
        chbx_inverse_ecg         matlab.ui.control.CheckBox
        edt_threshold            matlab.ui.control.NumericEditField
        thresholdEditFieldLabel  matlab.ui.control.Label
        btn_run                  matlab.ui.control.Button
        btn_finish               matlab.ui.control.Button
        edt_window               matlab.ui.control.NumericEditField
        FramesizeLabel           matlab.ui.control.Label
        btn_next                 matlab.ui.control.Button
        btn_save                 matlab.ui.control.Button
        btn_prev                 matlab.ui.control.Button
        sldr_pos                 matlab.ui.control.Slider
        ax_ov_ecg                matlab.ui.control.UIAxes
        ax_edit                  matlab.ui.control.UIAxes
        ContextMenu              matlab.ui.container.ContextMenu
        Menu                     matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
    end

    
    properties (Access = private)
        callingApp % main app
    end
    
    properties (Access = public)
        hb_res = struct('idx', [], 's',[]) % Description
        settings = struct('ecg', [],...
                          'ts',nan,...
                          'ecg_lim', [nan, nan],...
                          'hb_idx', [],...
                          'line',[],...
                          'listener', [],...
                          'dcm_obj', [],...
                          'ptr', [],...
                          'line_1',[], ...
                          'line_2', [])% Description
        ptr% Description
        ecg
        bp
        
    end
    
    methods (Access = private)
        
        function update_axes(app,src,evnt)
            if app.chbx_inverse_ecg.Value
                inv = -1;
            else
                inv = 1;
            end

            app.settings.line_1.XData = [app.ecg(app.ptr.start_idx,2) app.ecg(app.ptr.start_idx,2)];
            app.settings.line_2.XData = [app.ecg(app.ptr.stop_idx,2) app.ecg(app.ptr.stop_idx,2)];
            hold(app.ptr.ax_2, 'off')

            plot(app.ptr.ax_2, app.ecg(app.ptr.start_idx:app.ptr.stop_idx,2), app.ecg(app.ptr.start_idx:app.ptr.stop_idx,1)*inv)
            hold(app.ptr.ax_2, 'on')
            plot(app.ptr.ax_2, app.ecg(app.ptr.dspl_idx,2), app.ecg(app.ptr.dspl_idx,1)*inv, '*r')
            hold(app.ptr.ax_2, 'off')
            xlim(app.ptr.ax_2, [app.ecg(app.ptr.start_idx,2) app.ecg(app.ptr.stop_idx,2)])
            set(app.sldr_pos, 'Value',double(app.ptr.start_idx))
            %app.sldr_pos.Value = app.ptr.start_idx;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.callingApp = caller;
            idx = app.callingApp.settings.channel_idx.ecg;
            ecg_r = app.callingApp.data(idx).data;
            ecg_use = ecg_r(app.callingApp.settings.interval(1,1)/app.callingApp.data(idx).ts(1):app.callingApp.settings.interval(1,2)/app.callingApp.data(idx).ts(1),:);
            app.ecg(:,1) = lowpass(ecg_use(:,1),40,1/app.callingApp.data(idx).ts(1));
            app.ecg(:,2) = (0:size(ecg_use,1)-1)*app.callingApp.data(idx).ts(1);
            app.settings.ts = app.callingApp.data(idx).ts(1);
            if ~isnan(caller.settings.channel_idx.bldp)
                [tmp_bp,ts,~, ~] = current_signal(caller,caller.settings.channel_idx.bldp);
                tmp_bp(:,2) = (ts(1):ts(1):ts(2))';
                app.bp = interp1(tmp_bp(:,2),tmp_bp(:,1),app.ecg(:,2));
            else
                app.bp = nan;
                app.chbx_bp.Enable = "off";
            end
            plot(app.ax_ov_ecg, downsample(app.ecg(:,2),10),downsample(app.ecg(:,1),10))
            title(app.ax_ov_ecg,'ECG overview')
            xlabel(app.ax_ov_ecg,'')
            xlim (app.ax_ov_ecg, [app.ecg(1,2) app.ecg(end,2)])
            %% add bp processing
                       
            %%
            app.ptr = pointer(app);
            app.settings.line_1 = line(app.ax_ov_ecg, [app.ecg(app.ptr.start_idx, 2) app.ecg(app.ptr.start_idx, 2)], ylim(app.ax_ov_ecg), 'Color','r');
            app.settings.line_2 = line(app.ax_ov_ecg, [app.ecg(app.ptr.stop_idx, 2) app.ecg(app.ptr.stop_idx, 2)], ylim(app.ax_ov_ecg), 'Color','r');
            app.settings.listener = addlistener(app.ptr, 'new_frame', @(src, evnt)update_axes(app,src,evnt));
            datacursormode (app.UIFigure, 'on');
            app.settings.dcm_obj = datacursormode(app.UIFigure);
                        
            if  ~isempty(app.callingApp.hb_res)
                app.hb_res = app.callingApp.hb_res;
            end
            if (size(app.ecg,1)-app.ptr.dur) == 1
                app.sldr_pos.Enable = "off";
            else
                app.sldr_pos.Limits = [1, size(app.ecg,1)-app.ptr.dur];
                app.sldr_pos.Value = 1;
            end
            app.ptr.get_frame(1,app)
%             app.sldr_pos.Value = app.ecg(1,2);
%             app.sldr_pos.Limits = [app.ecg(1,2) app.ecg(size(app.ecg,1)-app.ptr.dur,2)];
    
            
        end

        % Value changing function: sldr_pos
        function sldr_posValueChanging(app, event)
%             idx = find(app.ecg(:,2) >= event.Value,1);
%             app.ptr.get_frame(idx,app)
            
        end

        % Value changed function: edt_window
        function edt_windowValueChanged(app, event)
            app.ptr.dur = app.edt_window.Value * 1/(mean(diff(app.ecg(:,2))));
            app.sldr_pos.Limits = [1 size(app.ecg,1)-app.ptr.dur];
            app.ptr.get_frame(app.ptr.start_idx,app)
        end

        % Button pushed function: btn_prev
        function btn_prevButtonPushed(app, event)
            app.ptr.prev_frame(app)
        end

        % Button pushed function: btn_next
        function btn_nextButtonPushed(app, event)
            app.ptr.next_frame(app)
        end

        % Button pushed function: btn_run
        function btn_runButtonPushed(app, event)
            
            app.hb_res = hb_det_2(app.ecg, app.edt_threshold.Value,app.chbx_inverse_ecg.Value, app.callingApp.bp_res , app.chbx_bp.Value);
            app.ptr.get_frame(app.ptr.start_idx,app)
        end

        % Button pushed function: btn_save
        function btn_saveButtonPushed(app, event)
            info_struct = getCursorInfo(app.settings.dcm_obj);
            remove = [];

            if app.chbx_inverse_ecg.Value
                inv = -1;
            else
                inv = 1;
            end

            add = [];
            for i =1: size(info_struct,2)
                if info_struct(i).Target.Marker == 'none'
                    add(end+1) = int64(info_struct(i).DataIndex)-1+app.ptr.start_idx;
                else
                    remove(end+1) = app.ptr.dspl_idx(info_struct(i).DataIndex);
                end
            end
            
            idx = [];
            for i = 1 : length(remove)
                idx(i) = find(app.hb_res.idx == remove(i));
            end
            app.hb_res.idx(idx) = [];
            app.hb_res.t_events(idx) =[];
            peakint = [round(0.02/app.settings.ts), round(0.01/app.settings.ts)];
            idx = [];
            ts = [];
            for i = 1 : length(add)
                tmp = add(i)-peakint(2); %tmp = add(i)-20;
                [~,tmp_i] = max(app.ecg(tmp:tmp+peakint(1),1)*inv);
                idx(i) = tmp-1+tmp_i;
                ts(i) = app.ecg(idx(i),2);
            end       
            app.hb_res.idx = sort(unique([app.hb_res.idx; idx']));
            app.hb_res.t_events = sort(unique([app.hb_res.t_events;ts']));
            app.ptr.get_frame(app.ptr.start_idx,app)
        end

        % Button pushed function: btn_finish
        function btn_finishButtonPushed(app, event)
            app.hb_res.dt_instantaneous = diff(app.hb_res.t_events);
            app.hb_res.rate_instantaneous = app.hb_res.dt_instantaneous.^(-1) * 60;
            app.hb_res.rate_mean = mean(app.hb_res.rate_instantaneous);
            app.hb_res.dtDifferences = diff(app.hb_res.dt_instantaneous);
            app.hb_res.ts = [app.ecg(1,2),mean(diff(app.ecg(:,2))), app.ecg(end,2)];
            import_hb_datafunc(app.callingApp,app.hb_res)
            delete(app)
        end

        % Value changed function: sldr_pos
        function sldr_posValueChanged(app, event)
%             idx = find(app.sldr_pos.Value >=app.ecg(:,2),1);
            
            app.ptr.get_frame(app.sldr_pos.Value,app)
        end

        % Value changed function: chbx_inverse_ecg
        function chbx_inverse_ecgValueChanged(app, event)
            value = app.chbx_inverse_ecg.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_edit = uiaxes(app.UIFigure);
            app.ax_ov_ecg = uiaxes(app.UIFigure);
            app.sldr_pos = uislider(app.UIFigure);
            app.sldr_pos.MajorTicks = [];
            app.sldr_pos.ValueChangedFcn = createCallbackFcn(app, @sldr_posValueChanged, true);
            app.sldr_pos.ValueChangingFcn = createCallbackFcn(app, @sldr_posValueChanging, true);
            app.sldr_pos.MinorTicks = [];
            app.btn_prev = uibutton(app.UIFigure, 'push');
            app.btn_prev.ButtonPushedFcn = createCallbackFcn(app, @btn_prevButtonPushed, true);
            app.btn_prev.Text = 'prev.  frame';
            app.btn_save = uibutton(app.UIFigure, 'push');
            app.btn_save.ButtonPushedFcn = createCallbackFcn(app, @btn_saveButtonPushed, true);
            app.btn_save.Text = 'apply changes';
            app.btn_next = uibutton(app.UIFigure, 'push');
            app.btn_next.ButtonPushedFcn = createCallbackFcn(app, @btn_nextButtonPushed, true);
            app.btn_next.Text = 'next frame';
            app.FramesizeLabel = uilabel(app.UIFigure);
            app.FramesizeLabel.Text = 'Framesize';
            app.edt_window = uieditfield(app.UIFigure, 'numeric');
            app.edt_window.ValueChangedFcn = createCallbackFcn(app, @edt_windowValueChanged, true);
            app.edt_window.Value = 60;
            app.btn_finish = uibutton(app.UIFigure, 'push');
            app.btn_finish.ButtonPushedFcn = createCallbackFcn(app, @btn_finishButtonPushed, true);
            app.btn_finish.Text = 'save and return';
            app.btn_run = uibutton(app.UIFigure, 'push');
            app.btn_run.ButtonPushedFcn = createCallbackFcn(app, @btn_runButtonPushed, true);
            app.btn_run.Text = 'run hartbeat detection';
            app.thresholdEditFieldLabel = uilabel(app.UIFigure);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.edt_threshold = uieditfield(app.UIFigure, 'numeric');
            app.edt_threshold.Value = 3;
            app.chbx_inverse_ecg = uicheckbox(app.UIFigure);
            app.chbx_inverse_ecg.ValueChangedFcn = createCallbackFcn(app, @chbx_inverse_ecgValueChanged, true);
            app.chbx_inverse_ecg.Text = 'inverse ECG';
            app.chbx_bp = uicheckbox(app.UIFigure);
            app.chbx_bp.Text = 'BP aided ';
            app.chbx_bp_overlay = uicheckbox(app.UIFigure);
            app.chbx_bp_overlay.Text = 'BP overlay ';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hr_fix(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end