classdef resp_fix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        btn_resp_dect               matlab.ui.control.Button
        edt_threshold               matlab.ui.control.NumericEditField
        thresholdLabel              matlab.ui.control.Label
        edt_window                  matlab.ui.control.NumericEditField
        WindowlengthEditFieldLabel  matlab.ui.control.Label
        btn_next                    matlab.ui.control.Button
        btn_save                    matlab.ui.control.Button
        btn_prev                    matlab.ui.control.Button
        sldr_pos                    matlab.ui.control.Slider
        btn_end                     matlab.ui.control.Button
        ax_ov_resp                  matlab.ui.control.UIAxes
        ax_edit                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingApp % main app 
    end
    
    properties (Access = public)
        settings = struct('ecg', [],...
                          'ecg_lim', [nan, nan],...
                          'hb_idx', [],...
                          'listener',[],...
                          'line',[],...
                          'dcm_obj', [],...
                          'ptr', [],...
                          'line_1',[], ...
                          'line_2', [], ...
                          'start_idx',[],...
                          'peak_idx',[])% Description
        resp_res = struct('idx', [], 's',[]) % Description% Description
        data
        flt_data
        ptr
    end
    
    methods (Access = private)
        
        function update_axes(app,src,~)
            app.settings.line_1.XData = [app.data(app.ptr.start_idx,2) app.data(app.ptr.start_idx,2)];
            app.settings.line_2.XData = [app.data(app.ptr.stop_idx,2) app.data(app.ptr.stop_idx,2)];
            hold(app.ptr.ax_2, 'off')
            plot(app.ptr.ax_2, app.data(app.ptr.start_idx:app.ptr.stop_idx,2), app.data(app.ptr.start_idx:app.ptr.stop_idx,1))
            hold(app.ptr.ax_2, 'on')
            plot(app.ptr.ax_2, app.data(src.dspl_strt_idx,2), app.data(src.dspl_strt_idx,1), '*k')
            plot(app.ptr.ax_2, app.data(src.dspl_peak_idx,2), app.data(src.dspl_peak_idx,1),'*m')
            hold(app.ptr.ax_2, 'off')
            xlim(app.ptr.ax_2, [app.data(app.ptr.start_idx,2) app.data(app.ptr.stop_idx,2)])
            app.sldr_pos.Value = app.data(app.ptr.start_idx,2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.callingApp = caller;
            [app.data,ts,~, ~] = current_signal(caller, caller.settings.channel_idx.resp);
            app.data = lowpass(app.data,3,1/ts(1));
            [b,a] = butter(1,[0.2 1]/50,'bandpass');
            app.flt_data = filtfilt(b,a,app.data(:,1));
            app.data(:,2) = (ts(1):ts(1):ts(2))';
            app.flt_data(:,2) = (ts(1):ts(1):ts(2))';
            if isempty (app.callingApp.resp_res)
                app.resp_res = resp_det2(app,app.flt_data, .25);
            else
                app.resp_res = caller.resp_res;
            end
            plot(app.ax_ov_resp, downsample(app.data(:,2),50),downsample(app.data(:,1),50)) 
            title(app.ax_ov_resp,'respiration overview')
            xlabel(app.ax_ov_resp, '')
            xlim(app.ax_ov_resp,[app.data(1,2) app.data(end,2)])
            app.ptr = pointer_resp(app);
            app.settings.line_1 = line(app.ax_ov_resp, [app.data(app.ptr.start_idx, 2) app.data(app.ptr.start_idx, 2)], ylim(app.ax_ov_resp), 'Color','r');
            app.settings.line_2 = line(app.ax_ov_resp, [app.data(app.ptr.stop_idx, 2) app.data(app.ptr.stop_idx, 2)], ylim(app.ax_ov_resp), 'Color','r');
            app.settings.listener = addlistener(app.ptr, 'new_frame', @(src, evnt)update_axes(app,src,evnt));
            datacursormode (app.UIFigure, 'on');
            app.settings.dcm_obj = datacursormode(app.UIFigure);
            app.sldr_pos.Value = app.data(1,2);
            app.sldr_pos.Limits = [app.data(1,2) app.data(size(app.data,1)-app.ptr.dur,2)];
            app.settings.start_idx = unique([app.resp_res.idx(:,1);app.resp_res.idx(:,3)]);
            app.settings.peak_idx = app.resp_res.idx(:,2);
            app.ptr.get_frame(1,app)    
        end

        % Button pushed function: btn_end
        function btn_endPushed(app, event)
            all_idx = [app.settings.start_idx,ones(size(app.settings.start_idx)); ...
                        app.settings.peak_idx, zeros(size(app.settings.peak_idx))];
            [~, sort_idx]= sort(all_idx(:,1));
            all_idx = all_idx(sort_idx,:);
            
            if all_idx(1,2) == 0
                all_idx(1,:) = [];
                app.settings.peak_idx(1) = [];
            end

            if all_idx(end,2) == 0
                all_idx(end,:) = [];
                app.settings.peak_idx(end) = [];
            end
            
            err_idx = find(diff(all_idx(:,2)) == 0);
            end_flag = true;
            fix_flag = false;
            if ~isempty(err_idx)
                time_str =[];
                for i = 1: length(err_idx)
                    if isempty(time_str)
                        time_str = [num2str(app.data(all_idx(err_idx(i),1),2)) ', ' num2str(app.data(all_idx(err_idx(i)+1,1),2))];
                    else
                        time_str = [time_str, ', ' num2str(app.data(all_idx(err_idx(i),1),2)) ', ' num2str(app.data(all_idx(err_idx(i)+1,1),2))];
                    end
                end
                
                answer = questdlg(['Errors found at ' time_str ' seconds'], ...
	                    'Errors found', ...
	                    'return & resolve manually','end & resolve automatically','end & resolve automatically');
                if strcmp  (answer, 'return & resolve manually')
                    end_flag = false;
                    fix_flag = false;
                else
                    end_flag = true;
                    fix_flag = true;
                end
            end

            if fix_flag
                for i = length(err_idx):-1:1
                    if all_idx(err_idx,2) % two neigboring start idx
                        [~,rem_idx] = max(app.data(all_idx([err_idx(i),err_idx(i)+1]),1));
                        all_idx(err_idx(i)+rem_idx-1,:) = []; 
                    else                    %two neigboring peak dix 
                        [~,rem_idx] = min(app.data(all_idx([err_idx(i),err_idx(i)+1]),1));
                        all_idx(err_idx(i)+rem_idx-1,:) = [];
                    end
                end
                if ~all_idx(1,2)
                    all_idx(1,:) = [];
                end
                if ~all_idx(end,2)
                    all_idx(end,:) = [];
                end
                app.settings.start_idx = all_idx(all_idx(:,2) == 1,1);
                app.settings.peak_idx = all_idx(all_idx(:,2) == 0,1);
            end

            
            if end_flag
                resp_idx =  [app.settings.start_idx(1:end-1),app.settings.peak_idx,app.settings.start_idx(2:end)];
                results.idx = resp_idx;
                results.ts = app.data(resp_idx(:,2),2);
                results.dt_inst = diff(resp_idx(:,2)*mean(diff(app.data(:,2))));
                results.rate_inst = 60./results.dt_inst;
                results.dt_mean = mean(results.dt_inst);
                results.rate_mean = mean(results.rate_inst);
                import_resp_data(app.callingApp,results)
                delete(app)
            end
        end

        % Button pushed function: btn_prev
        function btn_prevButtonPushed(app, event)
            app.ptr.prev_frame(app)
        end

        % Button pushed function: btn_save
        function btn_saveButtonPushed(app, event)
            info_struct = getCursorInfo(app.settings.dcm_obj);
            remove_strt = [];
            remove_pk = [];
            add = [];
            for i =1: size(info_struct,2)
                if info_struct(i).Target.Marker == 'none'
                    add(end+1) = int64(info_struct(i).DataIndex)-1+app.ptr.start_idx;
                else
                    if isequal(info_struct(i).Target.Color, [0,0,0])
                        remove_strt(end+1) = app.ptr.dspl_strt_idx(info_struct(i).DataIndex);
                    else
                        remove_pk(end+1) = app.ptr.dspl_peak_idx(info_struct(i).DataIndex);
                    end

                end
            end
            
            idx = [];
            for i = 1 : length(remove_strt)
                idx(i) = find(app.settings.start_idx == remove_strt(i));
            end
            app.settings.start_idx(idx) = [];

            for i = 1 : length(remove_pk)
                idx(i) = find(app.settings.peak_idx == remove_pk(i));
            end
            app.settings.peak_idx(idx) = [];

%             idx = [];
%             ts = [];
            for i = 1 : length(add)
                if app.data(add(i),1) > mean(app.data(add(i)-25:add(i)+25,1))
                    [~,tmp] = max(app.data(add(i)-25:add(i)+25));
                    app.settings.peak_idx(end+1) = tmp+add(i)-26;
                else
                    [~,tmp] = min(app.data(add(i)-25:add(i)+25));
                    app.settings.start_idx(end+1) = tmp+add(i)-26;
                end
            end       
            app.settings.peak_idx = sort(app.settings.peak_idx);
            app.settings.start_idx = sort(app.settings.start_idx);
            app.ptr.get_frame(app.ptr.start_idx,app)
        end

        % Button pushed function: btn_next
        function btn_nextButtonPushed(app, event)
            app.ptr.next_frame(app)
        end

        % Value changed function: edt_window
        function edt_windowValueChanged(app, event)
            app.ptr.dur = app.edt_window.Value*1/(mean(diff(app.data(:,2))));
            app.ptr.get_frame(app.ptr.start_idx,app)
            
        end

        % Button pushed function: btn_resp_dect
        function btn_resp_dectButtonPushed(app, event)
            app.resp_res = resp_det2(app, app.data, app.edt_threshold.Value);
            app.settings.start_idx = unique([app.resp_res.idx(:,1);app.resp_res.idx(:,3)]);
            app.settings.peak_idx = app.resp_res.idx(:,2);
            app.ptr.get_frame(app.ptr.start_idx,app)
        end

        % Value changing function: sldr_pos
        function sldr_posValueChanging(app, event)
            idx = find(app.data(:,2)>= event.Value ,1);
            app.ptr.get_frame(idx-floor(app.ptr.dur/2),app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_edit = uiaxes(app.UIFigure);
            app.ax_ov_resp = uiaxes(app.UIFigure);
            app.btn_end = uibutton(app.UIFigure, 'push');
            app.btn_end.ButtonPushedFcn = createCallbackFcn(app, @btn_endPushed, true);
            app.btn_end.Text = 'save and return';
            app.sldr_pos = uislider(app.UIFigure);
            app.sldr_pos.MajorTicks = [];
            app.sldr_pos.ValueChangingFcn = createCallbackFcn(app, @sldr_posValueChanging, true);
            app.sldr_pos.MinorTicks = [];
            app.btn_prev = uibutton(app.UIFigure, 'push');
            app.btn_prev.ButtonPushedFcn = createCallbackFcn(app, @btn_prevButtonPushed, true);
            app.btn_prev.Text = 'prev.  frame';
            app.btn_save = uibutton(app.UIFigure, 'push');
            app.btn_save.ButtonPushedFcn = createCallbackFcn(app, @btn_saveButtonPushed, true);
            app.btn_save.Text = 'apply changes';
            app.btn_next = uibutton(app.UIFigure, 'push');
            app.btn_next.ButtonPushedFcn = createCallbackFcn(app, @btn_nextButtonPushed, true);
            app.btn_next.Text = 'next frame';
            app.WindowlengthEditFieldLabel = uilabel(app.UIFigure);
            app.WindowlengthEditFieldLabel.Text = 'Window length';
            app.edt_window = uieditfield(app.UIFigure, 'numeric');
            app.edt_window.ValueChangedFcn = createCallbackFcn(app, @edt_windowValueChanged, true);
            app.edt_window.Value = 60;
            app.thresholdLabel = uilabel(app.UIFigure);
            app.thresholdLabel.Text = 'threshold';
            app.edt_threshold = uieditfield(app.UIFigure, 'numeric');
            app.edt_threshold.Value = 0.25;
            app.btn_resp_dect = uibutton(app.UIFigure, 'push');
            app.btn_resp_dect.ButtonPushedFcn = createCallbackFcn(app, @btn_resp_dectButtonPushed, true);
            app.btn_resp_dect.Text = 'rerun resp detection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resp_fix(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end