classdef template_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        btn_save                       matlab.ui.control.Button
        edt_name                       matlab.ui.control.EditField
        NewtemplatenameEditFieldLabel  matlab.ui.control.Label
        lstbx_names                    matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        caller % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.caller = caller;
        end

        % Button pushed function: btn_save
        function btn_savePushed(app, event)
            selpath = uigetdir;
            int_templates = app.lstbx_names.Items;
            int_templates(1) = [];
            save([selpath '\templ_name.mat'],'int_templates')
            import_templates(app.caller,int_templates)
            delete(app)
        end

        % Value changed function: edt_name
        function edt_nameValueChanged(app, event)
            tmp = app.lstbx_names.Items;
            if sum(contains(tmp,['§$>' app.edt_name.Value '<$§']))
                warndlg('enter an unique interval name')
            else
                tmp{end+1} = ['§$>' app.edt_name.Value '<$§'];
            end
            app.lstbx_names.Items = tmp;
            app.edt_name.Value = '';
            
        end

        % Value changed function: lstbx_names
        function lstbx_namesValueChanged(app, event)
            idx = find(strcmp(app.lstbx_names.Items,app.lstbx_names.Value));
            if idx ~= 1
                tmp = app.lstbx_names.Items;
                tmp(idx) = [];
                app.lstbx_names.Items = tmp;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.lstbx_names = uilistbox(app.UIFigure);
            app.lstbx_names.Items = {'intervall name templates'};
            app.lstbx_names.ValueChangedFcn = createCallbackFcn(app, @lstbx_namesValueChanged, true);
            app.lstbx_names.Value = 'intervall name templates';
            app.NewtemplatenameEditFieldLabel = uilabel(app.UIFigure);
            app.NewtemplatenameEditFieldLabel.Text = 'New template name';
            app.edt_name = uieditfield(app.UIFigure, 'text');
            app.edt_name.ValueChangedFcn = createCallbackFcn(app, @edt_nameValueChanged, true);
            app.btn_save = uibutton(app.UIFigure, 'push');
            app.btn_save.ButtonPushedFcn = createCallbackFcn(app, @btn_savePushed, true);
            app.btn_save.Text = 'Save templates and return';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = template_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end