classdef MNG_toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        btn_save_sesh                   matlab.ui.control.Button
        lbl_output                      matlab.ui.control.Label
        btn_output_directory            matlab.ui.control.Button
        btn_resp                        matlab.ui.control.Button
        btn_hb                          matlab.ui.control.Button
        btn_sel_int                     matlab.ui.control.Button
        lbl_working                     matlab.ui.control.Label
        Label3                          matlab.ui.control.Label
        lbl_interval                    matlab.ui.control.Label
        lbl_file                        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        tbl_com                         matlab.ui.control.Table
        btn_clc_end                     matlab.ui.control.Button
        btn_home                        matlab.ui.control.Button
        btn_clc_start                   matlab.ui.control.Button
        edt_stop                        matlab.ui.control.NumericEditField
        StopEditFieldLabel              matlab.ui.control.Label
        edt_start                       matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        popup_stop                      matlab.ui.control.DropDown
        StopDropDownLabel               matlab.ui.control.Label
        popup_start                     matlab.ui.control.DropDown
        StartDropDownLabel              matlab.ui.control.Label
        btn_next                        matlab.ui.control.Button
        btn_prev                        matlab.ui.control.Button
        btn_load                        matlab.ui.control.Button
        ax_overview_4                   matlab.ui.control.UIAxes
        ax_overview_3                   matlab.ui.control.UIAxes
        ax_overview_2                   matlab.ui.control.UIAxes
        ax_overview_1                   matlab.ui.control.UIAxes
        BurstTab                        matlab.ui.container.Tab
        chkbx_normalize                 matlab.ui.control.CheckBox
        popup_baseline_int              matlab.ui.control.DropDown
        baselineintervalLabel           matlab.ui.control.Label
        pnl_burst_shape                 matlab.ui.container.Panel
        sbtn_resp                       matlab.ui.control.StateButton
        sbtn_hb                         matlab.ui.control.StateButton
        edt_burst_delay                 matlab.ui.control.NumericEditField
        averagedelayEditFieldLabel      matlab.ui.control.Label
        pnl_burst_thresh                matlab.ui.container.Panel
        edt_outlier_threshold           matlab.ui.control.NumericEditField
        OutlierTHEditFieldLabel         matlab.ui.control.Label
        edt_window                      matlab.ui.control.NumericEditField
        WindowssEditFieldLabel          matlab.ui.control.Label
        edt_threshold_abs               matlab.ui.control.NumericEditField
        thresholdabsEditFieldLabel      matlab.ui.control.Label
        edt_threshold                   matlab.ui.control.NumericEditField
        K_STDEditFieldLabel             matlab.ui.control.Label
        chkbx_manual                    matlab.ui.control.CheckBox
        chkbx_th_on_silent              matlab.ui.control.CheckBox
        sbtn_thresh                     matlab.ui.control.StateButton
        sbtn_shape                      matlab.ui.control.StateButton
        lbl_19                          matlab.ui.control.Label
        burstlatencyLabel               matlab.ui.control.Label
        MSNALabel                       matlab.ui.control.Label
        lbl_18                          matlab.ui.control.Label
        chkbx_add_int                   matlab.ui.control.CheckBox
        chkbx_rem_int                   matlab.ui.control.CheckBox
        btn_save_reset_bursts           matlab.ui.control.Button
        lbl_1                           matlab.ui.control.Label
        lbl_2                           matlab.ui.control.Label
        lbl_3                           matlab.ui.control.Label
        lbl_4                           matlab.ui.control.Label
        lbl_5                           matlab.ui.control.Label
        lbl_6                           matlab.ui.control.Label
        lbl_7                           matlab.ui.control.Label
        lbl8                            matlab.ui.control.Label
        lbl_9                           matlab.ui.control.Label
        lbl_10                          matlab.ui.control.Label
        lbl_11                          matlab.ui.control.Label
        lbl_12                          matlab.ui.control.Label
        lbl_incidence                   matlab.ui.control.Label
        lbl_threshold                   matlab.ui.control.Label
        lbl_burst_rate                  matlab.ui.control.Label
        lbl_num_burst                   matlab.ui.control.Label
        lbl_13                          matlab.ui.control.Label
        lbl_14                          matlab.ui.control.Label
        popup_int                       matlab.ui.control.DropDown
        showintervalsDropDownLabel      matlab.ui.control.Label
        chkbx_add_burst                 matlab.ui.control.CheckBox
        chkbx_rem_burst                 matlab.ui.control.CheckBox
        btn_burst_analysis              matlab.ui.control.Button
        chkbx_detrent_rms               matlab.ui.control.CheckBox
        ax_burst_latency_his            matlab.ui.control.UIAxes
        ax_msna_raw                     matlab.ui.control.UIAxes
        ax_burst_interval_his           matlab.ui.control.UIAxes
        ax_burst_duration_his           matlab.ui.control.UIAxes
        ax_burst_amplitude_his          matlab.ui.control.UIAxes
        ax_burst_integral_his           matlab.ui.control.UIAxes
        ax_burst_res                    matlab.ui.control.UIAxes
        ax_msna_int                     matlab.ui.control.UIAxes
        SpikeTab                        matlab.ui.container.Tab
        savetrainsButton                matlab.ui.control.Button
        sbtn_lag                        matlab.ui.control.StateButton
        pnl_lag                         matlab.ui.container.Panel
        sbtn_skew_kurt                  matlab.ui.control.StateButton
        sbtn_spklag                     matlab.ui.control.StateButton
        popup_cluster_lag               matlab.ui.control.DropDown
        sldr_spikelag                   matlab.ui.control.Slider
        edt_lag_movmean                 matlab.ui.control.NumericEditField
        smpltoaverageEditFieldLabel     matlab.ui.control.Label
        edt_num_beat                    matlab.ui.control.NumericEditField
        heartbeatsEditFieldLabel        matlab.ui.control.Label
        ax_lag                          matlab.ui.control.UIAxes
        ax_hist                         matlab.ui.control.UIAxes
        sbtn_rate                       matlab.ui.control.StateButton
        sbtn_phase                      matlab.ui.control.StateButton
        pnl_rate                        matlab.ui.container.Panel
        edt_step                        matlab.ui.control.NumericEditField
        stepEditFieldLabel              matlab.ui.control.Label
        edt_max                         matlab.ui.control.NumericEditField
        maxEditField_4Label             matlab.ui.control.Label
        lstbx_ints                      matlab.ui.control.ListBox
        intervalsListBoxLabel           matlab.ui.control.Label
        ax_isi                          matlab.ui.control.UIAxes
        ax_rate                         matlab.ui.control.UIAxes
        setspikedetectionparameterButton  matlab.ui.control.Button
        deselectselectspikesButton      matlab.ui.control.Button
        lbl_spike_ttl3                  matlab.ui.control.Label
        lbl_spike_ttl2                  matlab.ui.control.Label
        lbl_spike_ttl1                  matlab.ui.control.Label
        popup_int_spike                 matlab.ui.control.DropDown
        IntervallLabel_8                matlab.ui.control.Label
        btn_save_spike_res              matlab.ui.control.Button
        Panel_5                         matlab.ui.container.Panel
        edt_dip2                        matlab.ui.control.NumericEditField
        Dip2EditField_3Label            matlab.ui.control.Label
        edt_peak                        matlab.ui.control.NumericEditField
        PeakEditField_2Label            matlab.ui.control.Label
        edt_dip1                        matlab.ui.control.NumericEditField
        Dip1EditField_3Label            matlab.ui.control.Label
        chkbx_spike_sorting             matlab.ui.control.CheckBox
        popup_cluster                   matlab.ui.control.DropDown
        showclusterDropDownLabel        matlab.ui.control.Label
        btn_spike_analysis              matlab.ui.control.Button
        chkbx_spike_clustering          matlab.ui.control.CheckBox
        pnl_phase                       matlab.ui.container.Panel
        lbl_last_phase_circ             matlab.ui.control.Label
        lbl_last_phase_lin              matlab.ui.control.Label
        lbl_all_phase_circ              matlab.ui.control.Label
        lbl_all_phase_lin               matlab.ui.control.Label
        lbl_1st_phase_circ              matlab.ui.control.Label
        lbl_1st_phase_lin               matlab.ui.control.Label
        LastspikephaseLabel             matlab.ui.control.Label
        LastspikeshapeLabel             matlab.ui.control.Label
        AvergagespikephaseLabel         matlab.ui.control.Label
        AveragespikeshapeLabel          matlab.ui.control.Label
        FirstspikephaseLabel            matlab.ui.control.Label
        FirstspikeshapeLabel_2          matlab.ui.control.Label
        ax_all_hist                     matlab.ui.control.UIAxes
        ax_last_hist                    matlab.ui.control.UIAxes
        ax_1st_hist                     matlab.ui.control.UIAxes
        ax_last_phase                   matlab.ui.control.UIAxes
        ax_last_shape                   matlab.ui.control.UIAxes
        ax_all_phase                    matlab.ui.control.UIAxes
        ax_all_shape                    matlab.ui.control.UIAxes
        ax_1st_phase                    matlab.ui.control.UIAxes
        ax_1st_shape                    matlab.ui.control.UIAxes
        ax_spike_msna                   matlab.ui.control.UIAxes
        ax_cluster_shapes               matlab.ui.control.UIAxes
        ax_cluster_timestamps           matlab.ui.control.UIAxes
        SpectralanalysisTab             matlab.ui.container.Tab
        HzLabel_2                       matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        PowerspectrumusingARparametricpmcovfunctionWHzLabel  matlab.ui.control.Label
        PowerspectrumusingARparametricpcovfunctionWHzLabel  matlab.ui.control.Label
        PowerspectrumusingARparametricpburgfunctionWHzLabel  matlab.ui.control.Label
        PowerspectrumusingARparametricpyulearfunctionWHzLabel  matlab.ui.control.Label
        PowerspectrumusingNOTparametricpmtmfunctionWHzLabel  matlab.ui.control.Label
        PowerspectrumusingNOTparametricFFTfunctionWHzLabel  matlab.ui.control.Label
        PowerspectrumusingNOTparametricperiodogramfunctionWHzLabel  matlab.ui.control.Label
        PowerspectrumusingNOTparametricpwelchfunctionWHzLabel  matlab.ui.control.Label
        lbl_ds1                         matlab.ui.control.Label
        lbl_ds2                         matlab.ui.control.Label
        lbl_ds2_o1                      matlab.ui.control.Label
        lbl_ds2_o3                      matlab.ui.control.Label
        lbl_ds2_o2                      matlab.ui.control.Label
        lbl_ds1_o3                      matlab.ui.control.Label
        lbl_ds1_o1                      matlab.ui.control.Label
        lbl_ds1_o2                      matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        edt_amp                         matlab.ui.control.NumericEditField
        ampEditFieldLabel               matlab.ui.control.Label
        edt_max_frq                     matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        edt_min_frq                     matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        lbl_16                          matlab.ui.control.Label
        popup_int_spect                 matlab.ui.control.DropDown
        IntervallLabel                  matlab.ui.control.Label
        chkbx_windowing                 matlab.ui.control.CheckBox
        btn_save_spect_result           matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        chkbx_show_o3                   matlab.ui.control.CheckBox
        chkbx_show_o2                   matlab.ui.control.CheckBox
        chkbx_show_o1                   matlab.ui.control.CheckBox
        edt_o3                          matlab.ui.control.NumericEditField
        O3EditFieldLabel                matlab.ui.control.Label
        edt_o2                          matlab.ui.control.NumericEditField
        O2EditFieldLabel                matlab.ui.control.Label
        edt_o1                          matlab.ui.control.NumericEditField
        O1EditFieldLabel                matlab.ui.control.Label
        lbl_15                          matlab.ui.control.Label
        edt_ds2                         matlab.ui.control.NumericEditField
        DS2EditFieldLabel               matlab.ui.control.Label
        edt_ds1                         matlab.ui.control.NumericEditField
        DS1EditFieldLabel               matlab.ui.control.Label
        lbl_17                          matlab.ui.control.Label
        btn_spectral_analysis           matlab.ui.control.Button
        popup_signal_spect              matlab.ui.control.DropDown
        SignalforfrequencyanalysisDropDownLabel  matlab.ui.control.Label
        ax_spect_4_2                    matlab.ui.control.UIAxes
        ax_spect_3_2                    matlab.ui.control.UIAxes
        ax_spect_2_2                    matlab.ui.control.UIAxes
        ax_spect_1_2                    matlab.ui.control.UIAxes
        ax_spect_2_1                    matlab.ui.control.UIAxes
        ax_spect_4_1                    matlab.ui.control.UIAxes
        ax_spect_3_1                    matlab.ui.control.UIAxes
        ax_spect_1_1                    matlab.ui.control.UIAxes
        ax_signal                       matlab.ui.control.UIAxes
        LagdetectionTab                 matlab.ui.container.Tab
        extractsignalsButton            matlab.ui.control.Button
        chkbx_lag_wavelet               matlab.ui.control.CheckBox
        popup_lag_bgsig                 matlab.ui.control.DropDown
        IntervallLabel_10               matlab.ui.control.Label
        addtolistButton                 matlab.ui.control.Button
        lstbx_save                      matlab.ui.control.ListBox
        chkbx_lock                      matlab.ui.control.CheckBox
        chkbx_lag_ma_sig2               matlab.ui.control.CheckBox
        edt_lag_ma_sig2                 matlab.ui.control.NumericEditField
        movingaverageEditFieldLabel_2   matlab.ui.control.Label
        chkbx_lag_ma_sig1               matlab.ui.control.CheckBox
        edt_lag_ma_sig1                 matlab.ui.control.NumericEditField
        movingaverageEditFieldLabel     matlab.ui.control.Label
        popup_lag_int                   matlab.ui.control.DropDown
        IntervallLabel_9                matlab.ui.control.Label
        btn_lag_save                    matlab.ui.control.Button
        lbl_lag_lag                     matlab.ui.control.Label
        lbl_lag_xcorr                   matlab.ui.control.Label
        btn_lag_calc                    matlab.ui.control.Button
        sldr_lag_start                  matlab.ui.control.Slider
        sldr_lag_end                    matlab.ui.control.Slider
        popup_lag_sig2                  matlab.ui.control.DropDown
        Signal2Label                    matlab.ui.control.Label
        popup_lag_sig1                  matlab.ui.control.DropDown
        Signal1Label                    matlab.ui.control.Label
        ax_lag_overlay                  matlab.ui.control.UIAxes
        ax_lag_sig2                     matlab.ui.control.UIAxes
        ax_lag_xcorr                    matlab.ui.control.UIAxes
        ax_lag_sig1                     matlab.ui.control.UIAxes
        WaveletTab                      matlab.ui.container.Tab
        timesLabel_6                    matlab.ui.control.Label
        timesLabel_5                    matlab.ui.control.Label
        timesLabel_4                    matlab.ui.control.Label
        lbl_wavelet_xwt3_ttl            matlab.ui.control.Label
        lbl_wavelet_xwt2_ttl            matlab.ui.control.Label
        lbl_wavelet_xwt1_ttl            matlab.ui.control.Label
        timesLabel_3                    matlab.ui.control.Label
        lbl_wavelet_sig2_ttl            matlab.ui.control.Label
        timesLabel_2                    matlab.ui.control.Label
        lbl_wavelet_sig1_ttl            matlab.ui.control.Label
        timesLabel                      matlab.ui.control.Label
        lbl_signal2                     matlab.ui.control.Label
        lbl_wave1                       matlab.ui.control.Label
        lbl_signal1                     matlab.ui.control.Label
        popup_int_wavelet               matlab.ui.control.DropDown
        IntervallLabel_2                matlab.ui.control.Label
        TimeLagssPanel                  matlab.ui.container.Panel
        edt_lag3                        matlab.ui.control.NumericEditField
        Lag3EditField_2Label            matlab.ui.control.Label
        edt_lag2                        matlab.ui.control.NumericEditField
        Lag2EditField_2Label            matlab.ui.control.Label
        edt_lag1                        matlab.ui.control.NumericEditField
        Lag1EditFieldLabel              matlab.ui.control.Label
        SignalsforCrossWaveletAnalysisPanel  matlab.ui.container.Panel
        popup_signal2                   matlab.ui.control.DropDown
        Signal2DropDownLabel            matlab.ui.control.Label
        popup_signal1                   matlab.ui.control.DropDown
        Signal1DropDownLabel            matlab.ui.control.Label
        btn_save_wavelet_results        matlab.ui.control.Button
        PeriodlimitssPanel              matlab.ui.container.Panel
        edt_max_period                  matlab.ui.control.NumericEditField
        maxEditField_2Label             matlab.ui.control.Label
        edt_min_period                  matlab.ui.control.NumericEditField
        minEditField_2Label             matlab.ui.control.Label
        btn_wavelet                     matlab.ui.control.Button
        chkbx_detrend                   matlab.ui.control.CheckBox
        ax_wavelet_sig1                 matlab.ui.control.UIAxes
        ax_xwt3                         matlab.ui.control.UIAxes
        ax_wavelet_sig2                 matlab.ui.control.UIAxes
        ax_xwt2                         matlab.ui.control.UIAxes
        ax_xwt1                         matlab.ui.control.UIAxes
        ax_wave_signals                 matlab.ui.control.UIAxes
        CorrelationTab                  matlab.ui.container.Tab
        lbl_corre_3_2_1                 matlab.ui.control.Label
        lbl_corre_3_1_2                 matlab.ui.control.Label
        lbl_corre_2_1_2                 matlab.ui.control.Label
        lbl_corre_2_2_1                 matlab.ui.control.Label
        lbl_corre_1_2_1                 matlab.ui.control.Label
        lbl_corre_1_1_2                 matlab.ui.control.Label
        lbl_signals_corre1              matlab.ui.control.Label
        lbl_signals_corre2              matlab.ui.control.Label
        popup_int_corre                 matlab.ui.control.DropDown
        IntervallLabel_4                matlab.ui.control.Label
        lbl_xcorre3                     matlab.ui.control.Label
        lbl_xcorre2                     matlab.ui.control.Label
        lbl_xcorre1                     matlab.ui.control.Label
        timesLabel_9                    matlab.ui.control.Label
        lbl_signal2_corre               matlab.ui.control.Label
        lbl_signal1_corre               matlab.ui.control.Label
        lbl_signals_corre               matlab.ui.control.Label
        SignalsforlinearcrosscorrelationPanel  matlab.ui.container.Panel
        lbl_fs_sig2                     matlab.ui.control.Label
        lbl_fs_sig1                     matlab.ui.control.Label
        popup_coorelation_signal2       matlab.ui.control.DropDown
        Signal2DropDown_2Label          matlab.ui.control.Label
        popup_coorelation_signal1       matlab.ui.control.DropDown
        Signal1DropDown_2Label          matlab.ui.control.Label
        MovingAveragePanel              matlab.ui.container.Panel
        edt_movmean                     matlab.ui.control.NumericEditField
        timesLabel_11                   matlab.ui.control.Label
        chkbx_movmean                   matlab.ui.control.CheckBox
        SamplingfrequencyforcorrelationPanel  matlab.ui.container.Panel
        edt_downsampling                matlab.ui.control.NumericEditField
        FsLabel                         matlab.ui.control.Label
        TimeLagssPanel_2                matlab.ui.container.Panel
        edt_corre_lag3                  matlab.ui.control.NumericEditField
        Lag3EditFieldLabel              matlab.ui.control.Label
        edt_corre_lag2                  matlab.ui.control.NumericEditField
        Lag2EditFieldLabel              matlab.ui.control.Label
        edt_corre_lag1                  matlab.ui.control.NumericEditField
        Lag1EditField_4Label            matlab.ui.control.Label
        btn_save_corre_results          matlab.ui.control.Button
        btn_correlation                 matlab.ui.control.Button
        chkbx_rem_outliers              matlab.ui.control.CheckBox
        ax_corre_3_2_1                  matlab.ui.control.UIAxes
        ax_corre_3_1_2                  matlab.ui.control.UIAxes
        ax_corre_3_2_2                  matlab.ui.control.UIAxes
        ax_corre_3_1_1                  matlab.ui.control.UIAxes
        ax_corre_2_2_1                  matlab.ui.control.UIAxes
        ax_corre_2_1_2                  matlab.ui.control.UIAxes
        ax_corre_2_2_2                  matlab.ui.control.UIAxes
        ax_corre_2_1_1                  matlab.ui.control.UIAxes
        ax_corre_1_2_1                  matlab.ui.control.UIAxes
        ax_corre_1_1_2                  matlab.ui.control.UIAxes
        ax_corre_1_2_2                  matlab.ui.control.UIAxes
        ax_corre_1_1_1                  matlab.ui.control.UIAxes
        ax_signal2_corre                matlab.ui.control.UIAxes
        ax_signal1_corre                matlab.ui.control.UIAxes
        ax_signals_corre                matlab.ui.control.UIAxes
        StepwiseregressionTab           matlab.ui.container.Tab
        popup_int_stepwise              matlab.ui.control.DropDown
        IntervallLabel_5                matlab.ui.control.Label
        SignaltobepredictedPanel        matlab.ui.container.Panel
        popup_signal_to_predict         matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        btn_save_reg_results            matlab.ui.control.Button
        RegressorsPanel                 matlab.ui.container.Panel
        tbl_regressor                   matlab.ui.control.Table
        btn_remove_regressor            matlab.ui.control.Button
        btn_add_regressor               matlab.ui.control.Button
        edt_lagtime                     matlab.ui.control.NumericEditField
        LagtimesEditFieldLabel          matlab.ui.control.Label
        popup_regressor                 matlab.ui.control.DropDown
        RegressorDropDownLabel          matlab.ui.control.Label
        MovingAveragePanel_2            matlab.ui.container.Panel
        chkbx_reg_mov_average           matlab.ui.control.CheckBox
        edt_reg_mov                     matlab.ui.control.NumericEditField
        timesEditField_2Label           matlab.ui.control.Label
        DownSamplingPanel_2             matlab.ui.container.Panel
        edt_reg_downsampling            matlab.ui.control.NumericEditField
        DSEditField_2Label              matlab.ui.control.Label
        btn_regression                  matlab.ui.control.Button
        ax_regression2                  matlab.ui.control.UIAxes
        ax_regression1                  matlab.ui.control.UIAxes
        AnnotateTab                     matlab.ui.container.Tab
        TimelimitssPanel                matlab.ui.container.Panel
        edt_max_time                    matlab.ui.control.NumericEditField
        maxEditField_3Label_2           matlab.ui.control.Label
        edt_min_time                    matlab.ui.control.NumericEditField
        minEditField_3Label_2           matlab.ui.control.Label
        timesLabel_10                   matlab.ui.control.Label
        lbl_annotate_3_1                matlab.ui.control.Label
        lbl_annotate_4_1                matlab.ui.control.Label
        lbl_annotate_2_1                matlab.ui.control.Label
        lbl_annotate_1_1                matlab.ui.control.Label
        popup_int_annotate              matlab.ui.control.DropDown
        IntervallLabel_6                matlab.ui.control.Label
        btn_save_annotate_results       matlab.ui.control.Button
        FrequencyscalelimitsHzPanel     matlab.ui.container.Panel
        edt_max_freq                    matlab.ui.control.NumericEditField
        maxEditField_3Label             matlab.ui.control.Label
        edt_min_freq                    matlab.ui.control.NumericEditField
        minEditField_3Label             matlab.ui.control.Label
        ax_annotate_4_2                 matlab.ui.control.UIAxes
        ax_annotate_3_2                 matlab.ui.control.UIAxes
        ax_annotate_2_2                 matlab.ui.control.UIAxes
        ax_annotate_1_2                 matlab.ui.control.UIAxes
        ax_annotate_3_1                 matlab.ui.control.UIAxes
        ax_annotate_2_1                 matlab.ui.control.UIAxes
        ax_annotate_1_1                 matlab.ui.control.UIAxes
        ax_annotate_4_1                 matlab.ui.control.UIAxes
        TransductionanalysisTab         matlab.ui.container.Tab
        lbl_trans_2_3                   matlab.ui.control.Label
        lbl_trans_2_2                   matlab.ui.control.Label
        lbl_trans_2_1                   matlab.ui.control.Label
        lbl_trans_shape                 matlab.ui.control.Label
        lbl_trans_1_3                   matlab.ui.control.Label
        lbl_trans_1_2                   matlab.ui.control.Label
        lbl_trans_1_1                   matlab.ui.control.Label
        popup_int_transduction          matlab.ui.control.DropDown
        IntervallLabel_7                matlab.ui.control.Label
        btn_save_trans_results          matlab.ui.control.Button
        btn_transduction                matlab.ui.control.Button
        ax_spike_shape                  matlab.ui.control.UIAxes
        ax_trans_2_3                    matlab.ui.control.UIAxes
        ax_trans_1_3                    matlab.ui.control.UIAxes
        ax_trans_2_2                    matlab.ui.control.UIAxes
        ax_trans_1_2                    matlab.ui.control.UIAxes
        ax_trans_1_1                    matlab.ui.control.UIAxes
        ax_trans_2_1                    matlab.ui.control.UIAxes
        ReportTab                       matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        edt_filename                    matlab.ui.control.EditField
        filenameEditFieldLabel          matlab.ui.control.Label
        lstbx_signals                   matlab.ui.control.ListBox
        AvailablesignalsLabel           matlab.ui.control.Label
        popup_posthoc                   matlab.ui.control.DropDown
        PosthoctestDropDownLabel        matlab.ui.control.Label
        popup_test                      matlab.ui.control.DropDown
        StatisticaltestDropDownLabel    matlab.ui.control.Label
        lstbx_intervals                 matlab.ui.control.ListBox
        AvailableintervalsListBoxLabel  matlab.ui.control.Label
        btn_add                         matlab.ui.control.Button
        btn_remove                      matlab.ui.control.Button
        SelectstatisticgroupsLabel      matlab.ui.control.Label
        tbl_groups                      matlab.ui.control.Table
        btn_select_templ_dir            matlab.ui.control.Button
        lstbx_rec_combine               matlab.ui.control.ListBox
        recordingstobecombinedLabel     matlab.ui.control.Label
        btn_combine                     matlab.ui.control.Button
        btn_select_folder               matlab.ui.control.Button
        lstbx_report                    matlab.ui.control.ListBox
        recordingsintervalstobereportedLabel  matlab.ui.control.Label
        btn_create_report               matlab.ui.control.Button
        AcknowledgementsTab             matlab.ui.container.Tab
        Image7                          matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        Label_5                         matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        data % Description
        comments
        settings = struct('interval', [nan, nan; nan, nan],...
                          'channel_sheet', [nan, nan],...
                          'click_select', [false, false],...
                          'file_path','',...
                          'done', struct('hb', false,...
                                         'intervals', false,...
                                         'burst', false, ...
                                         'resp',false),...
                          'burst_mod_mode', [0,nan],...
                          'burst_rem_int',[], ...
                          'channel_idx', struct('msna',nan, 'ecg', nan, 'bldp', nan, 'resp', nan, 'co2', nan ,'res_flow',nan),...
                          'output_dir', 'none', ...
                          'figs',[],...
                          'rep_dir','none',...
                          'brst_files',[],...
                          'int_templ',[],...
                          'collect_path','none',...
                          'tick_freq', nan...
                          )
        lis_burst_ax1   % listener
        lis_burst_ax2   % for linking
        lis_burst_ax3   % burst axes
        
        lis_spikes_ax1   % listener for linking 
        lis_spikes_ax2   % spike axis
        
        lis_spec1   % listener
        lis_spec2   % for
        lis_spec3   % linking
        lis_spec4   % spectral 
        lis_spec5   % axis
        lis_spec6   %
        lis_spec7   %
        lis_spec8   %

        lis_anno1   % listener
        lis_anno2   % for linking
        lis_anno3   % annotate  
        lis_anno4   % axis


        %lis_int
        hb_res
        burst_ints
        burst_res
        spike_res
        spec_res
        entropy_res
        stepwise_res
        resp_res
        bp_res
        corre_res
        stat_group
        comb_stat
        wc_app
        saved_session = [];
        lag
    end
    
    properties (Access = private)
        hb_app % Description
        int_sel_app
        resp_app
        stat_group_app
        fileldr_app
        spike_selection_app
        det_param_app
    end


    methods (Access = private)
        
        function update_start_axis(app, lim_change)
            %Updates the 4 channeldisplays on the start tap, applies inervall borders including adaptive downsampling of the signal, and switches between display of channels 
            
            if lim_change
                reset_results(app,false,true)
            end

            if app.settings.channel_sheet(1) == 1
                app.btn_prev.Text = ' ';
                app.btn_prev.Enable = "off";
            else 
                app.btn_prev.Text = [ '<< Ch ' num2str((app.settings.channel_sheet(1)-2)*4+1) '-' num2str((app.settings.channel_sheet(1)-2)*4+4) ];
                app.btn_prev.Enable = "on";
            end

            if app.settings.channel_sheet(1) == app.settings.channel_sheet(2)
                app.btn_next.Text = ' ';
                app.btn_next.Enable = "off";
            else 
                app.btn_next.Text = [ 'CH ' num2str((app.settings.channel_sheet(1))*4+1) '-' num2str((app.settings.channel_sheet(1))*4+4) '>>' ];
                app.btn_next.Enable = "on";
            end

            ch_idx = (app.settings.channel_sheet(1)-1)+1 : (app.settings.channel_sheet(1)-1)+4;

            if diff(app.settings.interval(1,:)) <= 300
                downsample_th = 25;
            else 
                downsample_th = 50;
            end 
            if ~isempty(app.comments)
                tmp_ts = vertcat(app.comments.ts);
                tmp_idx1 = find(tmp_ts >= app.settings.interval(1,1),1);
                tmp_idx2 = find(tmp_ts <= app.settings.interval(1,2),1,'last');
                if isempty(tmp_idx1) || isempty(tmp_idx2)
                    com_idx(1) = nan;
                    com_idx(2) = nan;
                else
                    com_idx(1) = tmp_idx1;
                    com_idx(2) = tmp_idx2;
                end
            
            else
                com_idx(1) = nan;
                com_idx(2) = nan;
            end
                
            for i = 1 : 4
                switch i
                    case 1
                        ax = app.ax_overview_1;
                    case 2
                        ax = app.ax_overview_2;
                    case 3
                        ax = app.ax_overview_3;
                    case 4
                        ax = app.ax_overview_4;
                end
                tmp = (app.settings.channel_sheet(1)-1)*4+i;
                if app.settings.num_basic_channel >= tmp

                    if app.data(ch_idx(i)).tic_multipl < downsample_th && ch_idx(i) ~= app.settings.channel_idx.msna % if app.data(ch_idx(1)).tic_multipl < downsample_th 
                        ds_fct = floor(downsample_th / app.data(ch_idx(i)).tic_multipl) ;
                    else 
                        ds_fct = 1;
                    end
                    
                    tmp_data(:,1) = app.data(tmp).data;
                    tmp_data(:,2) = app.data(tmp).ts(1) : app.data(tmp).ts(1) : app.data(tmp).ts(2);
                    lim_idx  = [ceil(app.settings.interval(1,1)/(app.data(tmp).tic_multipl/app.settings.tick_freq)), ...
                               floor(app.settings.interval(1,2)/(app.data(tmp).tic_multipl/app.settings.tick_freq))];
                    tmp_data(lim_idx(2) : end,:) = [];
                    tmp_data(1 : lim_idx(1), :) = [];

                    ax.Visible = 'on';
                    cla(ax)
                    plot(ax, downsample(tmp_data(:,2),ds_fct), downsample(tmp_data(:,1),ds_fct),'HitTest','off' ) 
                    tmp_yl = quantile(tmp_data(:,1),[0.00001 .99999]); %%
                    tmp_yl = [tmp_yl(1)-abs(diff(tmp_yl/7)), tmp_yl(2)+abs(diff(tmp_yl/7))];
                    if tmp_yl(1) == tmp_yl(2)
                        tmp_yl(1) = tmp_yl(1)-0.1;
                        tmp_yl(2) = tmp_yl(2)+0.1;
                    end
                    title(ax,app.data(tmp).name{1,1})
                    ylabel(ax, app.data(tmp).unit{1,1})
                    xlim(ax, app.settings.interval(1,:))
                    
                    if tmp == length(app.data) || (i == 4 &&- app.settings.channel_sheet(1) ~= app.settings.channel_sheet(2))
                        xlabel(ax, 's')
                    else 
                        xlabel(ax, '')
                    end
                    % add comment lines
                    if ~isnan(com_idx(1))
                        ltrs = 'ABCDEFGHIJKLMNOPQRSTUVWXYZabcdefghijklmnopqrstuvwxyz';
                        com_str = {[]};
%                         tmp_yl = ylim(ax);
                        cntr = 1;
                        
                        for j = com_idx(1) : com_idx(2)
                            if cntr <= 52
                                suf = '';
                            else
                                suf = num2str(ceil(cntr/52)-1);
                            end
                            line(ax, [app.comments(j).ts, app.comments(j).ts], tmp_yl, 'Color', 'r','HitTest','off')
                            com_str{end+1,1} = [ltrs(cntr-(52*(ceil(cntr/52)-1))) suf ': ' app.comments(j).string];
                            text(ax,app.comments(j).ts+0.5, tmp_yl(1) +abs(diff(tmp_yl)/10), [ltrs(cntr-(52*(ceil(cntr/52)-1))) suf], "Color",'r')
                            cntr = cntr+1;
                            
                        end
                        app.tbl_com.Data = com_str;
                        ylim(ax,tmp_yl)
                    end

                    clear tmp tmp_data
                else
                    % remove unused axes
                    plot(ax,nan)
                    title(ax, ' ')
                    ylabel(ax,' ')
                    xlabel(ax,' ')
                    ax.Visible = 'off';
                end
            end
            app.lbl_interval.Text = [ 'Interval: ' num2str(app.settings.interval(1,1)) ' << >> ' num2str(app.settings.interval(1,2)) ];
            
            app.edt_start.Value = app.settings.interval(1,1); app.edt_stop.Value = app.settings.interval(1,2);  
        end    


        function adapt_lms(app,event)
            xl = event.AffectedObject.XLim;
            switch event.AffectedObject.Tag
                case app.ax_msna_raw.Tag
                    if ~isequal(app.ax_msna_int.XLim, xl)
                        app.ax_msna_int.XLim = xl;
                    end
                case app.ax_msna_int.Tag
                    if ~isequal(app.ax_burst_res.XLim, xl)
                        app.ax_burst_res.XLim = xl;
                    end
                case app.ax_burst_res.Tag
                    if ~isequal(app.ax_msna_raw.XLim, xl)
                        app.ax_msna_raw.XLim = xl;
                    end
            end
        end
        
        function adapt_lms_spk(app, xl)
            xlim(app.ax_cluster_timestamps, xl)
            ylim(app.ax_cluster_timestamps, [.5 length(unique(app.spike_res.cluster))+.5])
            yyaxis(app.ax_spike_msna, 'left') 
            ylim(app.ax_spike_msna, [-20 20])
            yyaxis(app.ax_spike_msna, 'right') 
            if ~isfield(app.spike_res, 'rate_ylim') || isempty(app.spike_res.rate_ylim)
                app.spike_res.rate_ylim = [0,1];
            end
            ylim(app.ax_spike_msna, app.spike_res.rate_ylim)
            xlim(app.ax_spike_msna, xl)
            
        end

        function adapt_lms_anno(app,evnt)
            xl = evnt.AffectedObject.XLim;
            switch evnt.AffectedObject.Tag
                case app.ax_annotate_1_1.Tag
                    if ~isequal(app.ax_annotate_2_1.XLim, xl)
                        app.ax_annotate_2_1.XLim = xl;
                    end
                case app.ax_annotate_2_1.Tag
                    if ~isequal(app.ax_annotate_3_1.XLim, xl)
                        app.ax_annotate_3_1.XLim = xl;
                    end
                case app.ax_annotate_3_1.Tag
                    if ~isequal(app.ax_annotate_4_1.XLim, xl)
                        app.ax_annotate_4_1.XLim = xl;
                    end
                case app.ax_annotate_4_1.Tag
                    if ~isequal(app.ax_annotate_1_1.XLim, xl)
                        app.ax_annotate_1_1.XLim = xl;
                    end
            end
        end
        
        
        function clear_burst_res(app)
            cla(app.ax_burst_amplitude_his)
            cla(app.ax_burst_duration_his)
            cla(app.ax_burst_integral_his)
            cla(app.ax_burst_interval_his)
            cla(app.ax_msna_raw)
            cla(app.ax_msna_int)
            cla(app.ax_burst_res)
            app.burst_res = [];
            app.btn_burst_analysis.BackgroundColor = [.96,.96,.96];
            app.settings.done.burst = false;
        end
        
        function sel_burst(app)
            ts_burst = app.burst_res.ts(1) : app.burst_res.ts(2) : app.burst_res.ts(3);
            for i = 1: length(app.burst_ints)
                app.burst_res.use_burst(:,i+2) = zeros(size(app.burst_res.use_burst,1),1);
                for j = 1: size(app.burst_ints(i).borders)
                    ts_idx = find((ts_burst >= app.burst_ints(i).borders(j,1)) & (ts_burst <= app.burst_ints(i).borders(j,2)));
                    tmp_idx = find((app.burst_res.burst_loc(:,1) >= ts_idx(1)) & (app.burst_res.burst_loc(:,2) <= ts_idx(end)));
                    app.burst_res.use_burst(tmp_idx,i+2) = 1;
                end
            end
        end


        function burst_results_mode(app,caller) 
            % switches the modification mode between 'no action' (0), 'remove burst' (1), 'add burst' (2) , 'remove
            % interval' (3) and 're-add interval' (4) and makes sure only
            % one modus is active
            switch caller
                case 1 % remove burst checkbox
                    if app.settings.burst_mod_mode(1) == 1 % if chbx was checked go back to normal
                        app.chkbx_rem_burst.Value = false;
                        app.settings.burst_mod_mode(1) = 0;
                    else
                        app.settings.burst_mod_mode(1) = 1; % go to burst remove mode
                        app.chkbx_add_burst.Value = false;
                        app.chkbx_rem_int.Value = false;
                        app.chkbx_add_int.Value = false;    
                        app.settings.burst_mod_mode(2) = nan;
                    end

                case 2 % add burst checkbox
                    if app.settings.burst_mod_mode(1) == 2 % if chbx was checked go back to normal
                        app.chkbx_add_burst.Value = false;
                        app.settings.burst_mod_mode(1) = 0;
                    else
                        app.settings.burst_mod_mode(1) = 2; % go to burst remove mode
                        app.chkbx_rem_burst.Value = false; 
                        app.chkbx_rem_int.Value = false;
                        app.chkbx_add_int.Value = false; 
                        app.settings.burst_mod_mode(2) = nan;
                    end

                case 3 % remove interval checkbox
                    if app.settings.burst_mod_mode(1) == 3 % if chbx was checked go back to normal
                        app.chkbx_add_burst.Value = false;
                        app.settings.burst_mod_mode(1) = 0;
                        app.settings.burst_mod_mode(2) = nan;
                    else
                        app.settings.burst_mod_mode(1) = 3; % go to interval remove mode
                        app.chkbx_rem_burst.Value = false;
                        app.chkbx_add_burst.Value = false;
                        app.chkbx_add_int.Value = false;                    
                    end

                case 4 % re-add interval checkbox
                    if app.settings.burst_mod_mode(1) == 4 % if chbx was checked go back to normal
                        app.chkbx_add_burst.Value = false;
                        app.settings.burst_mod_mode(1) = 0;
                        app.settings.burst_mod_mode(2) = nan;
                    else
                        app.settings.burst_mod_mode(1) = 4; % go to burst remove mode
                        app.chkbx_rem_burst.Value = false;
                        app.chkbx_rem_int.Value = false;
                        app.chkbx_rem_int.Value = false;                    
                    end
            end
        end
        
            function modify_burst_results(app,xpos,type)
            %removes/adds bursts/intervalls depending on the mode
            update = false;
            switch app.settings.burst_mod_mode(1)
                case {1,2}
                    if type
                        xidx = find((app.burst_res.ts(1):app.burst_res.ts(2):app.burst_res.ts(3)) >= xpos,1);
                        idx_1= find(app.burst_res.burst_loc(:,1) <= xidx, 1, 'last' );
                        idx_2 = find(app.burst_res.burst_loc(:,2) >= xidx,1);
                        if idx_1 == idx_2
                            switch app.settings.burst_mod_mode(1)
                                case 1
                                    if app.burst_res.use_burst(idx_1,1)
                                        app.burst_res.use_burst(idx_1,1) = 0;
                                        update = true;
                                    else
                                        warndlg('burst already deselected')
                                    end
                                case 2
                                    if app.burst_res.use_burst(idx_1,1)
                                        warndlg('burst already active')
                                    else
                                        app.burst_res.use_burst(idx_1,1) = 1;
                                        update = true;
                                    end

                            end
                        else
                            warndlg('no burst found')
                        end
                    end

                case {3,4}
                    switch app.settings.burst_mod_mode(1)
                        case 3
                            if isnan(app.settings.burst_mod_mode(2))
                                app.settings.burst_mod_mode(2) = xpos;
                            else
                                idx_1 = find(app.settings.burst_rem_int(:) >= app.settings.burst_mod_mode(2),1);
                                idx_2 = find(app.settings.burst_rem_int(:) <= xpos, 1,'last');
                                
                                if idx_1 == idx_2
                                    warndlg('overlap with existing interval')
                                else
                                    app.settings.burst_rem_int(end+1,:) = sort([app.settings.burst_mod_mode(2), xpos]);
                                    app.settings.burst_mod_mode(2) = nan;
                                    update = true;
                                end
                            end
                        case 4
                            idx_1= find(app.settings.burst_rem_int(:,1) <= xpos, 1, 'last' );
                            idx_2 = find(app.settings.burst_rem_int(:,2) >= xpos,1);
                            if idx_1 == idx_2
                                app.settings.burst_rem_int(idx_1, :) = [];
                            end 
                            update = true;
                        
                    end
                    if update
                        app.burst_res.use_burst(:,2) = 1;
                        ts_msna = app.burst_res.ts(1):app.burst_res.ts(2):app.burst_res.ts(3);
                        if ~isempty(app.hb_res)
                            app.hb_res.use_beats(:,1) = 1;
                            ts_hb = app.hb_res.ts(1):app.hb_res.ts(2):app.hb_res.ts(3);
                        end
                        
                        for i = 1: size(app.settings.burst_rem_int,1)
                            bord_idx = [find(ts_msna >= app.settings.burst_rem_int(i,1),1),...
                                        find(ts_msna <= app.settings.burst_rem_int(i,2),1,'last')]; 
                            rem_idx =[ find(app.burst_res.burst_loc(:,2) < bord_idx(1),1,'last'), find(app.burst_res.burst_loc(:,1) > bord_idx(2),1)];
                            rem_idx = [rem_idx(1)+1,rem_idx(2)-1];
                            if rem_idx(1) <= rem_idx(2)
                                app.burst_res.use_burst(rem_idx(1):rem_idx(2),2) = 0;
                            end
                            if ~isempty(app.hb_res)
                                bord_idx = [find(ts_hb >= app.settings.burst_rem_int(i,1),1),...
                                            find(ts_hb <= app.settings.burst_rem_int(i,2),1,'last')]; 
                                rem_idx =[find(app.hb_res.idx < bord_idx(1),1,'last'), find(app.hb_res.idx > bord_idx(2),1)];
                                rem_idx = [rem_idx(1)+1,rem_idx(2)-1];
                                if rem_idx(1) <= rem_idx(2)
                                    app.hb_res.use_beats(rem_idx(1):rem_idx(2),1) = 0;
                                end
                            end
                        end
                    end
            end
            if update
                derived_burst_signals(app)
                update_burst_axis(app, true)
            end
        end
        
        
    end
    
    methods (Access = public)
        
        function working(app,status)
            if strcmp(status, 'on') 
                app.lbl_working.BackgroundColor = 'r';
                app.lbl_working.Text = 'WORKING';
                pause(.05)
            end
            if strcmp(status, 'off')
                app.lbl_working.BackgroundColor = 'none';
                app.lbl_working.Text = '';
                pause(.05)
            end
        end
        
        function import_hb_datafunc(app,hb_res)
            app.hb_res = hb_res;
            app.settings.done.hb = true;
            derived_cardiac_signals(app)
            app.btn_hb.BackgroundColor = 'g';
            if isempty(app.burst_ints)
                app.burst_ints = struct();
                app.burst_ints(1).name = 'autofull';
                app.burst_ints(1).type = 1;
                app.burst_ints(1).parent = 'none';
                app.burst_ints(1).borders = [1,app.settings.interval(1,2)-app.settings.interval(1,1)-1];
            end
            sel_beats(app)
        end
        
        function import_ints(app,ints)
            app.burst_ints = ints;
            app.settings.done.intervals = true;
            app.btn_sel_int.BackgroundColor = 'g';
            update_intervall_popup(app)
            if ~isempty(app.hb_res)
                sel_beats(app)
            end
            if ~isempty(app.spike_res)
                app.spike_res = sel_spikes(app.spike_res,app.data(app.settings.channel_idx.msna).ts,app.burst_ints);
            end
            if app.settings.done.burst
                sel_burst(app)
            end
        end
        
        function import_resp_data(app, resp_res)
            app.resp_res = resp_res;
            app.settings.done.resp = true;
            derived_resp_signals(app)
            app.btn_resp.BackgroundColor = 'g';
        end
        
        function import_stats_groups(app, stat_group, no_stats)
            if no_stats
                app.stat_group = [];
            else
                app.stat_group = stat_group;
            end
           
        end
        
        function import_data(app,data,comments, channel_idx, saved_session )
            app.saved_session = saved_session;
            app.data = data;
            app.comments = comments;
            app.settings.num_basic_channel = length(app.data);
            app.lbl_file.Text = app.settings.file_path;
            tmp = 0;
            app.settings.channel_idx = channel_idx;
%             app.settings.channel_idx.bldp = nan;
            for i = 1:length(app.data)
                tmp = max([tmp app.data(i).ts(2)]);
                
                if app.data(i).tic_multipl == 1
                    app.settings.tick_freq = 1/app.data(i).ts(1);
                end
            
%                 switch app.data(i).name{1,1}
%                     case {'MSNA RAW','MNG', 'msna raw','mng'}
%                         app.settings.channel_idx.msna = i;
%                     case {'ECG','ecg'}
%                         app.settings.channel_idx.ecg = i;
%                     case {'RESP BELT','resp belt'}
%                         app.settings.channel_idx.resp = i;
%                     case { 'BP', 'bp', 'Finger Pressure', 'finger pressure'}
%                         app.settings.channel_idx.bldp = i;
%                 end
% 
            end
            app.settings.interval = [1, floor(tmp)-1; 1, floor(tmp)-1];
            app.lbl_interval.Text = [ 'Interval: ' num2str(app.settings.interval(1,1)) ' << >> ' num2str(app.settings.interval(1,2)) ];
            app.settings.channel_sheet = [1 ceil(length(app.data)/4)];
            app.edt_start.Limits = app.settings.interval(2,:); app.edt_stop.Limits = app.settings.interval(2,:);
            update_start_axis(app,false)

            app.settings.click_select = [false, false];
            app.btn_clc_end.Enable ="on"; app.btn_clc_start.Enable ="on";
            app.btn_home.Enable ="on";
            app.edt_stop.Enable ="on"; app.edt_start.Enable ="on";
            app.popup_start.Enable ="on"; app.popup_stop.Enable ="on";
            app.btn_next.Enable ="on"; app.btn_prev.Enable ="on";
            
            if ~isempty(app.comments)
                tmp_ts = vertcat(app.comments.ts);
                popup_items{1,1} = 'select comment';
                for i = 1 : length(app.comments)
                    popup_items{1,i+1} = [ num2str(app.comments(i).id) ' ' app.comments(i).string '(' num2str(app.comments(i).ts) ')'];
                end
                app.popup_start.Enable = 'on';
                app.popup_stop.Enable = 'on';
            else
                popup_items = {'no comments'};
                app.popup_start.Enable = 'off';
                app.popup_stop.Enable = 'off';
            end
            app.popup_start.Items = popup_items;
            app.popup_stop.Items = popup_items;

            app.btn_hb.BackgroundColor = [.96, .96, .96];
            app.settings.done.hb = false;
            app.settings.done.intervals = false;
            app.settings.done.resp = false;
            app.btn_sel_int.Enable = 'on';
           
            
            app.btn_hb.BackgroundColor = [.96,.96,.96];
            app.btn_sel_int.BackgroundColor = [.96,.96,.96];
            app.hb_res =[];
            app.burst_ints = [];
            clear_burst_res(app)
            if isnan(app.settings.channel_idx.ecg)
                app.btn_hb.Enable = 'off';
            else
                app.btn_hb.Enable = 'on';
            end
            if isnan(app.settings.channel_idx.resp)
                app.btn_resp.Enable = 'off';
            else
                app.btn_resp.Enable = 'on';
            end
            
            app.data(end+1).data = sqrt(movmean(bandpass(app.data(app.settings.channel_idx.msna).data,[500 5000],1e4) .^2,2001)); %%app.data(end+1).data = sqrt(movmean(app.data(app.settings.channel_idx.msna).data .^2,2001));
            app.data(end).name = 'RMS MSNA';
            app.data(end).unit = 'µV';
            app.data(end).derived = false;%true; %% changed app.data(end).derived = false;
            app.data(end).tic_multipl = app.data(app.settings.channel_idx.msna).tic_multipl;
            app.data(end).ts = app.data(app.settings.channel_idx.msna).ts;
            
            
%             working(app,'off')
        end
        
        function import_spike_selection(app,spike_res)
            working(app,'on')

            %% test
            rem_idx = find (~spike_res.use_spikes(:,1));
            spike_res.spikes(:,rem_idx) = [];
            spike_res.spike_idx(rem_idx) = [];
            spike_res.spike_ts(rem_idx) = [];
            spike_res.cluster(rem_idx) = [];
            spike_res.extremes(rem_idx,:) = [];
            spike_res.use_spikes(rem_idx,:) = [];
            spike_res.det_res.spk_pos(rem_idx) = [];
            spike_res.det_res.spikes(rem_idx,:) = [];
            spike_res.det_res.index(rem_idx) = [];

            %%
%             if spike_res.newspikes
                %% spike
            app.spike_res = [];
            app.spike_res.det_res.spikes = spike_res.det_res.spikes;
            app.spike_res.det_res.index = spike_res.spike_idx;%app.spike_res.det_res.index = spike_res.det_res.index;
            app.spike_res.det_res.threshold = spike_res.det_res.threshold;
            app.spike_res.det_res.spk_pos = spike_res.det_res.spk_pos;
            app.spike_res.use_spikes = spike_res.use_spikes;
                
%                 switch spike_res.det_res.panel
%                     case 'spike'
            app.spike_res = spike_analysis(app,true);
            str_cell = {[]};
            for i = 1: max(app.spike_res.cluster)
                str_cell{i} = num2str(i);
            end
            app.popup_cluster.Items = str_cell;
            app.popup_cluster.Value = app.popup_cluster.Items{1};
            app.lstbx_ints.Items = app.popup_int_spike.Items;
            update_spike_axis(app,true) 
            update_cluster_popup(app)
            app.popup_int_spike.Enable = 'on';
%                     case 'transduction'
%                         app.spike_res = transduction_analysis(app);
%                         update_cluster_popup(app)
%                         update_transduction_axis(app)
%                 end

%             else
%                 app.spike_res.use_spikes = spike_res.use_spikes;
%             end

            
            working(app,'off')
        end

        function import_det_params(app,par)
            app.settings.par = update_parameters(app.settings.par, par,'detect');
        end
        
        function import_wc_cluster(app, cluster_res)
%             rem_idx = find (cluster_res.cluster_class == 0);
%             cluster_res.spikes(rem_idx,:) = [];
%             cluster_res.cluster_class(rem_idx,:) = [];
%             cluster_res.forced(rem_idx) = [];
%             cluster_res.inspk(rem_idx,:) = [];
%             cluster_res.ipermut(rem_idx) = [];
%             cluster_res.rejected(rem_idx) = [];
            app.settings.tempclus = cluster_res;
%             app.spike_res.det_res.spk_pos(rem_idx) = [];
%             app.spike_res.det_res.spikes(rem_idx,:) = [];
%             app.spike_res.det_res.index(rem_idx) = [];
        end

        function import_bursts(app, burst_res)
            app.burst_res = burst_res;
            sel_burst(app)
            derived_burst_signals(app)
            update_burst_axis(app, false)%%%%%
            app.popup_int.Enable = 'on';
            app.settings.done.burst = true;
            app.btn_burst_analysis.BackgroundColor = 'g';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            datacursormode (app.UIFigure, 'off');
            app.settings.threshold = .11;
            app.settings.window = .18;
            disableDefaultInteractivity(app.ax_overview_1)
            app.ax_overview_1.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.ax_overview_2)
            app.ax_overview_2.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.ax_overview_3)
            app.ax_overview_3.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.ax_overview_4)
            app.ax_overview_4.Toolbar.Visible = 'off';
            app.lis_burst_ax1 = addlistener(app.ax_burst_res ,'XLim', 'PostSet', @(~,evnt)adapt_lms(app,evnt));
            app.lis_burst_ax2 = addlistener(app.ax_msna_int ,'XLim', 'PostSet', @(~,evnt)adapt_lms(app,evnt));
            app.lis_burst_ax3 = addlistener(app.ax_msna_raw ,'XLim', 'PostSet', @(~,evnt)adapt_lms(app,evnt));
            app.lis_spikes_ax1 = addlistener(app.ax_cluster_timestamps ,'XLim', 'PostSet', @(~,evnt)adapt_lms_spk(app,evnt.AffectedObject.XLim));
            app.lis_spikes_ax2 = addlistener(app.ax_spike_msna ,'XLim', 'PostSet', @(~,evnt)adapt_lms_spk(app,evnt.AffectedObject.XLim));
            app.lis_spec1 = addlistener(app.ax_spect_1_1 ,'XLim', 'PostSet', @(~,evnt)adapt_lms_spec(app,evnt));
            app.lis_spec2 = addlistener(app.ax_spect_2_1 ,'XLim', 'PostSet', @(~,evnt)adapt_lms_spec(app,evnt));
            app.lis_spec3 = addlistener(app.ax_spect_3_1 ,'XLim', 'PostSet', @(~,evnt)adapt_lms_spec(app,evnt));
            app.lis_spec4 = addlistener(app.ax_spect_4_1 ,'XLim', 'PostSet', @(~,evnt)adapt_lms_spec(app,evnt));
            app.lis_spec5 = addlistener(app.ax_spect_1_2 ,'XLim', 'PostSet', @(~,evnt)adapt_lms_spec(app,evnt));
            app.lis_spec6 = addlistener(app.ax_spect_2_2 ,'XLim', 'PostSet', @(~,evnt)adapt_lms_spec(app,evnt));
            app.lis_spec7 = addlistener(app.ax_spect_3_2 ,'XLim', 'PostSet', @(~,evnt)adapt_lms_spec(app,evnt));
            app.lis_spec8 = addlistener(app.ax_spect_4_2 ,'XLim', 'PostSet', @(~,evnt)adapt_lms_spec(app,evnt));
            app.lis_anno1 = addlistener(app.ax_annotate_1_1 ,'XLim', 'PostSet', @(~,evnt)adapt_lms_anno(app,evnt));
            app.lis_anno2 = addlistener(app.ax_annotate_2_1 ,'XLim', 'PostSet', @(~,evnt)adapt_lms_anno(app,evnt));
            app.lis_anno3 = addlistener(app.ax_annotate_3_1 ,'XLim', 'PostSet', @(~,evnt)adapt_lms_anno(app,evnt));
            app.lis_anno4 = addlistener(app.ax_annotate_4_1 ,'XLim', 'PostSet', @(~,evnt)adapt_lms_anno(app,evnt));
            app.popup_int.Items = {'full'};
            app.lbl_output.Text = 'none';
            app.pnl_rate.Visible = 'off';
            app.pnl_phase.Visible = 'on';
            app.sbtn_phase.Value = true;
            app.sbtn_rate.Value = false;
            app.settings.par = [];
            app.lag = struct('bgsig',[],'lims',[],'int',[],'sig1',[],'mm1',[],'sig2',[],'mm2',[],'lag',[],'displ_str',[]);
            app.lag(1) = [];
            app.lstbx_save.Items = {};
            app.lstbx_save.Value = {};

        end

        % Button pushed function: btn_load
        function btn_loadButtonPushed(app, event)
            working(app,'on') 
            reset_results(app,true,false)
            tmp = adi.uiGetChartFile();
            if tmp ~= 0
                app.settings.file_path = tmp;
                app.fileldr_app = fileloader_app(app);
                waitfor(app.fileldr_app)
            end
            
             
            if ~isempty(app.saved_session)
                %% data for debug
%                 if exist("C:\work\OneDrive - Scuola Superiore Sant'Anna\SSSA\matlab\res\bla\control",'file')
%                     bla = load ("C:\work\OneDrive - Scuola Superiore Sant'Anna\SSSA\matlab\res\bla\control");
%                 else
%                     bla = load ("D:\OneDrive - Scuola Superiore Sant'Anna\SSSA\matlab\res\bla\control");
%                 end
                %%
                app.lbl_file.Text = app.saved_session.settings.datafile;
                app.settings.file_path = app.saved_session.settings.datafile;
                
                app.settings.interval = app.saved_session.settings.interval;
                app.edt_start.Value = app.settings.interval(1,1);
                app.edt_stop.Value = app.settings.interval(1,2);
                if exist(app.saved_session.settings.res_folder,'dir')
                    app.settings.output_dir = app.saved_session.settings.res_folder;
                else
                    tmp = uigetdir(pwd,'please select output directory');
                    if tmp ~= 0
                        app.settings.output_dir = tmp;
                        app.lbl_output.Text =  app.settings.output_dir;
                    end
                end
                app.lbl_output.Text = app.settings.output_dir;
                app.settings.int_templ = app.saved_session.settings.int_templ;
                

                update_start_axis(app, false)
                %% restoring hb_res
                if isfield(app.saved_session,'hb')

                    app.lbl_working.Text = 'restorig HB';
                    drawnow
                    app.hb_res.idx = app.saved_session.hb.idx;
                    app.hb_res.t_events = app.saved_session.hb.ts;
                    app.hb_res.dt_instantaneous = diff(app.hb_res.t_events);
                    app.hb_res.rate_instantaneous = app.hb_res.dt_instantaneous.^(-1) * 60;
                    app.hb_res.rate_mean = mean(app.hb_res.rate_instantaneous);
                    app.hb_res.dtDifferences = diff(app.hb_res.dt_instantaneous);
                    idx = app.settings.channel_idx.ecg;
                    ecg_r = app.data(idx).data;
                    ecg_use = ecg_r(app.settings.interval(1,1)/app.data(idx).ts(1):app.settings.interval(1,2)/app.data(idx).ts(1),:);
                    ecg(:,1) = lowpass(ecg_use(:,1),40,1/app.data(idx).ts(1));
                    ecg(:,2) = (0:size(ecg_use,1)-1)*app.data(idx).ts(1);
                    app.hb_res.ts = [ecg(1,2),mean(diff(ecg(:,2))), ecg(end,2)];
                    derived_cardiac_signals(app)
                    app.btn_hb.BackgroundColor = 'g';
                    if isempty(app.burst_ints)
                        app.burst_ints = struct();
                        app.burst_ints(1).name = 'autofull';
                        app.burst_ints(1).type = 1;
                        app.burst_ints(1).parent = 'none';
                        app.burst_ints(1).borders = [1,app.settings.interval(1,2)-app.settings.interval(1,1)-1];
                    end
                    sel_beats(app)
                    app.settings.done.hb = true;
                end
                %%
                %% restoring resp_res
                if isfield(app.saved_session,'resp')
                    app.lbl_working.Text = 'restorig respiration';
                    drawnow
                    app.resp_res.idx = app.saved_session.resp.idx;
                    app.resp_res.ts = app.saved_session.resp.ts;
                    [dat,ts,~, ~] = current_signal(app, app.settings.channel_idx.resp);
                    dat(:,2) = (ts(1):ts(1):ts(2))';
                    app.resp_res.dt_inst = diff(app.resp_res.idx(:,2)*mean(diff(dat(:,2))));
                    app.resp_res.rate_inst = 60./app.resp_res.dt_inst;
                    app.resp_res.dt_mean = mean(app.resp_res.dt_inst);
                    app.resp_res.rate_mean = mean(app.resp_res.rate_inst);
                    derived_resp_signals(app)
                    app.btn_resp.BackgroundColor = 'g';
                    app.settings.done.resp = true;
                end
                %%
                %% restoring sub ints
                if isfield(app.saved_session,'intervals')
                    app.lbl_working.Text = 'restorig subintervals';
                    drawnow
                    app.burst_ints = app.saved_session.intervals.idx;
                    app.settings.done.intervals = true;
                    app.btn_sel_int.BackgroundColor = 'g';
                    update_intervall_popup(app)
                    if ~isempty(app.hb_res)
                        sel_beats(app)
                    end    
                    update_intervall_popup(app)
                end
                %%
                %% restoring burst
                if isfield(app.saved_session, 'burst')
                    app.lbl_working.Text = 'restoring burst res';
                    drawnow
                    app.chkbx_detrent_rms.Value = app.saved_session.burst.detrend;

                    switch app.saved_session.burst.type
                        case 1
                            app.sbtn_shape.Value = true;
                            app.sbtn_thresh.Value = false;
                            sel = app.data(app.settings.channel_idx.msna).data(app.settings.interval(1,1)/app.data(app.settings.channel_idx.msna).ts(1):app.settings.interval(1,2)/app.data(app.settings.channel_idx.msna).ts(1)); 
                            sel(1) = []; 
                            Ts_msna = app.data(app.settings.channel_idx.msna).ts(1);
                            
                            sel=bandpass(sel,[500 5000],1e4);%sel=bandpass(sel,[300 5000],1e4);
                            tmp = sqrt(movmean(sel.^2,2001));
                            rmsa1 = tmp';
                            rmsa2=rmsa1;
                
                            if app.chkbx_detrent_rms.Value
                                x=detrend(rmsa2,2);
                            else
                                x=rmsa2;
                            end
                            t_msna = (1:length(x))*Ts_msna;
                            
                            ridx = zeros(size(app.hb_res.t_events));
                            lo = 1;
                            hi = numel(t_msna);
                            for n = 1:numel(app.hb_res.t_events)
                                b = app.hb_res.t_events(n);
                                l = lo;
                                h = hi;
                                while l < h
                                    m = floor((l + h) / 2);
                                    if t_msna(m) < b
                                        l = m + 1;
                                    else
                                        h = m;
                                    end
                                end
                                ridx(n) = l;
                            end                    
        
                            fs = 1/app.data(app.settings.channel_idx.msna).ts(1);
                            [b,a] = butter(2,[0.05,4]/(fs/2),"bandpass");
                            brsts= nan(length(ridx),7);
                            tmp_data = [x,zeros(1,3*fs)];
                            x_filt = filtfilt(b,a,tmp_data);
                            diff_x_filt = diff(x_filt).*circshift(diff(x_filt),1);
                            for i = 1:length(ridx)
                                [~,tmp_pk] = max(x_filt(ridx(i)+app.saved_session.burst.window(1):ridx(i)+app.saved_session.burst.window(2)));
                                if (tmp_pk ~= 1) & (tmp_pk ~= (abs(diff(app.saved_session.burst.window))+1))
                                    tmp_idx = find(diff_x_filt(ridx(i)+app.saved_session.burst.window(1):ridx(i)+app.saved_session.burst.window(2))<0);
                                    strt = find(tmp_idx<tmp_pk,1,'last');
                                    stp =  find(tmp_idx>tmp_pk,1,'first');
                                    strt= tmp_idx(strt);
                                    if isempty(strt)
                                        strt =1;
                                    end
                                    stp = tmp_idx(stp);
                                    if isempty(stp)
                                        stp = app.saved_session.burst.window(2)-app.saved_session.burst.window(1)+1;
                                    end
                                    brsts(i,1:3) = [strt-1+ridx(i)+app.saved_session.burst.window(1), tmp_pk-1+ridx(i)+app.saved_session.burst.window(1), stp-1+ridx(i)+app.saved_session.burst.window(1)];
                                    brsts(i,4) = max(tmp_data(brsts(i,2))-tmp_data(brsts(i,1)),tmp_data(brsts(i,2))-tmp_data(brsts(i,3)));
                                    brsts(i,5) = mean(diff(tmp_data(brsts(i,1):brsts(i,2))));
                                    brsts(i,6) = mean(diff(tmp_data(brsts(i,2):brsts(i,3))));
                                    brsts(i,7) = (brsts(i,2)-ridx(i)+1)/fs;
                            
                                end
                                
                            end
                            
                            brsts(isnan(brsts(:,3)),:) = [];
                            
                            brsts((brsts(:,1)>= length(x)),:) = [];
                            
                            brsts((brsts(:,2)>= length(x)),:) = [];
                            
                            brsts((brsts(:,3)>= length(x)),:) = [];
                
                            idx = find(diff(brsts(:,2)) == 0);
                
                            for i = 1: length(idx)
                                brsts(idx(i),3) = brsts(idx(i)+1,3);
                                [~, idx_mn] =  min ([abs(brsts(idx(i),7)-app.saved_session.burst.delay),abs(brsts(idx(i)+1,7)-app.saved_session.burst.delay)]);
                
                                if idx_mn == 2
                                    brsts(idx(i),7) = brsts(idx(i)+1,7);
                                end
                                brsts(idx(i),4) = max(tmp_data(brsts(idx(i),2))-tmp_data(brsts(idx(i),1)),tmp_data(brsts(idx(i),2))-tmp_data(brsts(idx(i),3)));
                                brsts(idx(i),5) = mean(diff(tmp_data(brsts(idx(i),1):brsts(idx(i),2))));
                                brsts(idx(i),6) = mean(diff(tmp_data(brsts(idx(i),2):brsts(idx(i),3))));
                                brsts(idx(i)+1,:) = nan;
                                
                            end
                            brsts(isnan(brsts(:,1)),:) = [];
        
                            app.burst_res.window = app.saved_session.burst.window;
                            app.burst_res.x = x;
                            
                            app.burst_res.ts = [app.data(app.settings.channel_idx.msna).ts(1), app.data(app.settings.channel_idx.msna).ts(1),length(app.burst_res.x)*app.data(app.settings.channel_idx.msna).ts(1)];
                            app.burst_res.burst_loc = [brsts(:,1), brsts(:,3)];
                            tmp = app.burst_res.ts(1):app.burst_res.ts(2):app.burst_res.ts(3);
                            app.burst_res.t_burst = tmp (brsts(:,2));
                %             burst_res.dt_burst = diff(burst_res.t_burst);
                            app.burst_res.use_burst = app.saved_session.burst.use_burst;
                            n_brsts = size(app.burst_res.t_burst,2);
                            app.burst_res.burst_int = nan(1,n_brsts);
                            app.burst_res.burst_amp = nan(1,n_brsts);
                            for i = 1 : n_brsts
                                tmp_d = [t_msna(brsts(i,1):brsts(i,3))',app.burst_res.x(brsts(i,1):brsts(i,3))'*1000];
                                tmp_d(:,2) = tmp_d(:,2) - min (tmp_d(:,2));
                            
                                app.burst_res.burst_amp(i)  = max(tmp_d(:,2)); %%% ampl
                                app.burst_res.burst_int(i) = trapz(tmp_d(:,1),tmp_d(:,2)); %% int
                            end
                            app.burst_res.burst_dur = (brsts(:,3)-brsts(:,1))*app.burst_res.ts(1);
                            app.burst_res.burst_latency = brsts(:,7);
                            app.burst_res.delay = app.saved_session.burst.delay;
                            
                        case 2
                            app.sbtn_shape.Value = false;
                            app.sbtn_thresh.Value = true;
                            app.edt_outlier_threshold.Value = app.saved_session.burst.outlier;
                            app.edt_window.Value = app.saved_session.burst.window;
                            app.edt_threshold.Value = app.saved_session.burst.thresh;
                            app.chkbx_th_on_silent.Value = app.saved_session.burst.onsilent;
                            if ~isnan(app.saved_session.burst.man)
                                app.edt_threshold_abs.Value = app.saved_session.burst.man;
                                app.chkbx_manual.Value = true;
                            else
                                app.chkbx_manual.Value = false;
                            end
                            app.burst_res = burst_analysis(app);
                            app.burst_res.use_burst = app.saved_session.burst.use_burst;
                    end

                    derived_burst_signals(app)
                    update_signal_popup(app)
                    update_burst_axis(app,false)
                    app.popup_int.Enable = 'on';
                    app.settings.done.burst = true;
                    if isfield(app.saved_session.burst, "delay")
                        app.burst_res.type = 1;
                    else
                        app.burst_res.type = 2;
                    end

                end
                %
                %% restoring spikes
                if isfield(app.saved_session, 'spike')
                    app.spike_res.spike_idx = app.saved_session.spike.spike_idx;
                    app.spike_res.spike_ts = app.saved_session.spike.spike_ts;
                    app.spike_res.cluster = app.saved_session.spike.cluster;
                    app.spike_res.extremes = app.saved_session.spike.extremes;
                    app.spike_res.use_spikes = app.saved_session.spike.use_spikes;
                    app.spike_res.analysis = app.saved_session.spike.analysis;
                    app.spike_res.det_res.spk_pos = app.saved_session.spike.spike_pos;
                    app.spike_res.det_res.index = app.saved_session.spike.index;
                    app.spike_res.det_res.spikes = app.saved_session.spike.spikes;
                    app.spike_res.det_res.threshold = app.saved_session.spike.threshold;
                    app.chkbx_spike_sorting.Value = app.spike_res.analysis.sorting;
                    app.chkbx_spike_clustering.Value = app.spike_res.analysis.waveclus;
                    app.edt_dip1.Value = app.spike_res.analysis.dips(1);
                    app.edt_peak.Value = app.spike_res.analysis.dips(2);
                    app.edt_dip2.Value = app.spike_res.analysis.dips(3);
                   
                    app.spike_res.rate_ylim  = [];
                    app.settings.par = app.saved_session.spike.par;
                    [dat,ts,~,~] = current_signal(app, app.settings.channel_idx.msna);
                    dat = dat'; 
                    sr = 1/ts(1);
                    peak_idxs=uint32(app.spike_res.spike_ts*sr/1000);
                    spikeMatrix=zeros(20+20+1,numel(peak_idxs));
                    for i=1:numel(peak_idxs)
                    idx=peak_idxs(i);
                    spike=dat(idx-20:idx+20);
                    spikeMatrix(:,i)=spike;
                    end
                    numSpikes=size(spikeMatrix,2);
                    clear i idx spike
                    app.spike_res.spikes = spikeMatrix;
    
                    app.spike_res.spike =  app.saved_session.spike.spike;
                    
                    str_cell = {[]};
                    for i = 1: max(app.spike_res.cluster)
                        str_cell{i} = num2str(i);
                    end
                    app.popup_cluster.Items = str_cell;
                    app.popup_cluster.Value = app.popup_cluster.Items{1};
                    str_cell(2:end+1) = str_cell;
                    str_cell{1} = 'all spikes';
                    app.popup_cluster_lag.Items = str_cell;
                    app.popup_cluster_lag.Value = app.popup_cluster_lag.Items{1};
                    app.lstbx_ints.Items = app.popup_int_spike.Items;
                    update_spike_axis(app,true) 
                    update_cluster_popup(app)
                    app.popup_int_spike.Enable = 'on';
                    if isnan(app.settings.channel_idx.ecg)
                        app.sbtn_phase.Value = false;
                        app.sbtn_rate.Value = true;
                        app.pnl_rate.Visible = 'on';
                        app.pnl_phase.Visible = 'off';
                    end
                    app.spike_res = sel_spikes(app.spike_res,app.data(app.settings.channel_idx.msna).ts,app.burst_ints);
                    int_idx = find(strcmp(app.popup_int_spike.Value,app.popup_int_spike.Items));
                    if ~isempty(app.hb_res)
                        app.spike_res.spike_lag.spks = get_spike_lag(app.spike_res.spike_ts, app.spike_res.cluster, app.spike_res.use_spikes, app.hb_res.t_events,app.hb_res.use_beats,int_idx);
                        app.sbtn_lag.Enable = 'on';
                    else
                        app.spike_res.spike_lag = [];
                        app.sbtn_lag.Enable = 'off';
                    end
                end
                app.saved_session = [];
                auto_derived(app)
                update_signal_popup(app)
            end
            working(app,'off')

        end

        % Value changed function: popup_start
        function popup_startValueChanged(app, event)
            working(app,'on')
            com_idx = find(strcmp(app.popup_start.Value, app.popup_start.Items))-1;

            if  app.comments(com_idx).ts < app.settings.interval(1,2)
                app.settings.interval(1,1) = app.comments(com_idx).ts;
                update_start_axis(app,true)
            else
                warndlg('select event before the end of the interval')
            end
            app.popup_start.Value = app.popup_start.Items{1,1};
            working(app,'off')
        end

        % Value changed function: popup_stop
        function popup_stopValueChanged(app, event)
            working(app,'on')
            com_idx = find(strcmp(app.popup_stop.Value, app.popup_stop.Items))-1;

            if  app.comments(com_idx).ts > app.settings.interval(1,1)
                app.settings.interval(1,2) = app.comments(com_idx).ts;
                update_start_axis(app,true)
            else
                warndlg('select event after the start of the interval')
            end
            app.popup_stop.Value = app.popup_start.Items{1,1};
            working(app,'off')
        end

        % Button down function: ax_overview_1
        function ax_overview_1ButtonDown(app, event)
            working(app,'on')
            if app.settings.click_select (1)
                app.settings.interval(1,1) = event.IntersectionPoint(1);
                update_start_axis(app,true)
            end
            if app.settings.click_select (2)
                app.settings.interval(1,2) = event.IntersectionPoint(1);
                update_start_axis(app,true)
            end
            working(app,'off')
        end

        % Value changed function: edt_start
        function edt_startValueChanged(app, event)
            working(app,'on')
            if app.edt_start.Value < app.edt_stop.Value
                app.settings.interval(1,1) = app.edt_start.Value;
                update_start_axis(app,true)
            else
                app.edt_start.Value = app.settings.interval(1,1);
            end
            working(app,'off')
        end

        % Value changed function: edt_stop
        function edt_stopValueChanged(app, event)
            working(app,'on')
            if app.edt_start.Value < app.edt_stop.Value
                app.settings.interval(1,2) = app.edt_stop.Value;
                update_start_axis(app,true)
            else
                app.edt_stop.Value = app.settings.interval(1,2);
            end
            working(app,'off')
        end

        % Button pushed function: btn_prev
        function btn_prevButtonPushed(app, event)
            working(app,'on')
            app.settings.channel_sheet(1) = app.settings.channel_sheet(1) - 1 ;
            update_start_axis(app,false)
            working(app,'off')
        end

        % Button pushed function: btn_next
        function btn_nextButtonPushed(app, event)
            working(app,'on')
            app.settings.channel_sheet(1) = app.settings.channel_sheet(1) + 1 ;
            update_start_axis(app,false)
            working(app,'off')
        end

        % Button pushed function: btn_clc_start
        function btn_clc_startButtonPushed(app, event)
            if app.settings.click_select(1)
                app.btn_clc_start.BackgroundColor = [.96, .96, .96 ];
                app.settings.click_select(1) = false;
            else
                app.btn_clc_start.BackgroundColor = 'g';
                app.settings.click_select(1) = true;
                app.settings.click_select(2) = false;
                app.btn_clc_end.BackgroundColor = [.96, .96, .96 ];
            end
        end

        % Button pushed function: btn_home
        function btn_homeButtonPushed(app, event)
            working(app,'on')
            app.settings.interval(1,:) = app.settings.interval(2,:);
            update_start_axis(app,true)
            working(app,'off')
        end

        % Button pushed function: btn_clc_end
        function btn_clc_endButtonPushed(app, event)
            if app.settings.click_select(2)
                app.btn_clc_end.BackgroundColor = [.96, .96, .96 ];
                app.settings.click_select(2) = false;
            else
                app.btn_clc_end.BackgroundColor = 'g';
                app.settings.click_select(2) = true;
                app.settings.click_select(1) = false;
                app.btn_clc_start.BackgroundColor = [.96, .96, .96 ];
            end
        end

        % Button down function: ax_overview_2
        function ax_overview_2ButtonDown(app, event)
            working(app,'on')
            if app.settings.click_select (1)
                app.settings.interval(1,1) = event.IntersectionPoint(1);
                update_start_axis(app,true)
            end
            if app.settings.click_select (2)
                app.settings.interval(1,2) = event.IntersectionPoint(1);
                update_start_axis(app,true)
            end
            working(app,'off')
        end

        % Button down function: ax_overview_3
        function ax_overview_3ButtonDown(app, event)
            working(app,'on')
            if app.settings.click_select (1)
                app.settings.interval(1,1) = event.IntersectionPoint(1);
                update_start_axis(app,true)
            end
            if app.settings.click_select (2)
                app.settings.interval(1,2) = event.IntersectionPoint(1);
                update_start_axis(app,true)
            end
            working(app,'off')
        end

        % Button down function: ax_overview_4
        function ax_overview_4ButtonDown(app, event)
            working(app,'on')
            if app.settings.click_select (1)
                app.settings.interval(1,1) = event.IntersectionPoint(1);
                update_start_axis(app,true)
            end
            if app.settings.click_select (2)
                app.settings.interval(1,2) = event.IntersectionPoint(1);
                update_start_axis(app,true)
            end
            working(app,'off')
        end

        % Button pushed function: btn_burst_analysis
        function btn_burst_analysisPushed(app, event)
            working(app,'on')

            if app.sbtn_shape.Value
                burst_app(app)
                
            else
                app.burst_res = burst_analysis(app);
                
                app.settings.done.burst = true;
                app.btn_burst_analysis.BackgroundColor = 'g';

                sel_burst(app)
                derived_burst_signals(app)
                update_burst_axis(app,false)
                app.popup_int.Enable = 'on';

            end
%             if isempty(app.hb_res)
%                 warndlg('please detect heartbeats first')
%                 working(app)
%                 return
%             else
%                 app.burst_res = burst_analysis(app);
%                 derived_burst_signals(app)              
% %             end
%             
%             app.settings.done.burst = true;
%             app.btn_burst_analysis.BackgroundColor = 'g';
% %             if app.settings.done.hb
%                 sel_burst(app)
% %             end
%             update_burst_axis(app,false)
%             app.popup_int.Enable = 'on';
            working(app,'off')
        end

        % Button pushed function: btn_hb
        function btn_hbButtonPushed(app, event)
            auto_bp(app)
            if isfield(app.bp_res,'fail')
                app.settings.channel_idx.bldp = nan;
                app.bp_res = [];
            end
            app.hb_app = hr_fix2(app);
%             app.hb_app = hr_fix(app);
            
        end

        % Button pushed function: btn_sel_int
        function btn_sel_intButtonPushed(app, event)
            if strcmp(app.settings.output_dir, 'none')
                warndlg('please select output folder first')
            else
                app.int_sel_app = int_selection(app);
            end
            
        end

        % Button down function: ax_msna_int
        function ax_msna_intButtonDown(app, event)
            if app.settings.burst_mod_mode(1)
                working(app,'on')
                modify_burst_results(app,event.IntersectionPoint(1),1)
                working(app,'off')
            end
        end

        % Button down function: ax_burst_res
        function ax_burst_resButtonDown(app, event)
            if app.settings.burst_mod_mode(1)
                working(app,'on')
                modify_burst_results(app,event.IntersectionPoint(1),1)
                working(app,'off')
            end
        end

        % Button pushed function: btn_save_reset_bursts
        function btn_save_reset_burstsButtonPushed(app, event)
            if strcmp(app.settings.output_dir , 'none')
                warndlg('Please select output directory first!')
            else
                if isempty(app.burst_res)
                    warndlg('please run burst analysis first')
                else
                    working(app,'on')
                    app.stat_group_app = stat_selection(app);
                    waitfor(app.stat_group_app)
                    burst_report(app)
                    working(app,'off')
                end
            end
        end

        % Value changed function: popup_int
        function popup_intValueChanged(app, event)
            working(app,'on')
            update_burst_axis(app,false)
            working(app,'off')
        end

        % Value changed function: chkbx_rem_burst
        function chkbx_rem_burstValueChanged(app, event)
            burst_results_mode(app,1)
        end

        % Value changed function: chkbx_rem_int
        function chkbx_rem_intValueChanged(app, event)
            burst_results_mode(app,3) 
        end

        % Value changed function: chkbx_add_burst
        function chkbx_add_burstValueChanged(app, event)
            burst_results_mode(app,2) 
        end

        % Value changed function: chkbx_add_int
        function chkbx_add_intValueChanged(app, event)
            burst_results_mode(app,4) 
        end

        % Button down function: ax_msna_raw
        function ax_msna_rawButtonDown(app, event)
            if app.settings.burst_mod_mode(1)
                working(app,'on')
                modify_burst_results(app,event.IntersectionPoint(1),0)
                working(app,'off')
            end
        end

        % Button pushed function: btn_spike_analysis
        function btn_spike_analysisButtonPushed(app, event)
            
            if ~app.settings.done.hb & ~isnan(app.settings.channel_idx.ecg)
                warndlg('Please run heartbeat detection first')
            else 
                working(app,'on')

                app.spike_res = spike_analysis(app);
                int_idx = find(strcmp(app.popup_int_spike.Value,app.popup_int_spike.Items));
                if ~isempty(app.hb_res)
                    app.spike_res.spike_lag.spks = get_spike_lag(app.spike_res.spike_ts, app.spike_res.cluster, app.spike_res.use_spikes, app.hb_res.t_events,app.hb_res.use_beats,int_idx);
                    app.sbtn_lag.Enable = 'on';
                else
                    app.spike_res.spike_lag = [];
                    app.sbtn_lag.Enable = 'off';
                end
                str_cell = {[]};
                for i = 1: max(app.spike_res.cluster)
                    str_cell{i} = num2str(i);
                end
                app.popup_cluster.Items = str_cell;
                app.popup_cluster.Value = app.popup_cluster.Items{1};
                str_cell(2:end+1) = str_cell;
                str_cell{1} = 'all spikes';
                app.popup_cluster_lag.Items = str_cell;
                app.popup_cluster_lag.Value = app.popup_cluster_lag.Items{1};
                app.lstbx_ints.Items = app.popup_int_spike.Items;
                update_spike_axis(app,true) 
                update_cluster_popup(app)
                if ~isempty(app.spike_res.spike_lag)
                    update_spikelag_display(app, 'new')
                end
                app.popup_int_spike.Enable = 'on';
                if isnan(app.settings.channel_idx.ecg)
                    app.sbtn_phase.Value = false;
                    app.sbtn_rate.Value = true;
                    app.pnl_rate.Visible = 'on';
                    app.pnl_phase.Visible = 'off';
                end
                working(app,'off')
            end


        end

        % Value changed function: popup_cluster
        function popup_clusterValueChanged(app, event)
            working(app,'on')
            update_spike_axis(app,false)
            working(app,'off')
        end

        % Button down function: BurstTab
        function BurstTabButtonDown(app, event)
            working(app,'on')
            if isempty(app.burst_res)
                app.popup_int.Enable = 'off';
            else
                app.popup_int.Enable = 'on';
            end
            
            auto_derived(app)
%             sel = app.data(app.settings.channel_idx.msna).data(app.settings.interval(1,1)/app.data(app.settings.channel_idx.msna).ts(1)+1:app.settings.interval(1,2)/app.data(app.settings.channel_idx.msna).ts(1)); 
%             tmp = sqrt(movmean(sel.^2,2001));
%             if app.chkbx_detrent_rms.Value
%                 x=detrend(tmp',2);
%             else
%                 x=tmp';
%             end
%             app.settings.basmed = median(x);
%             app.settings.basStd=std(x);
%             app.edt_threshold_abs.Limits = [-inf, inf];%[app.settings.basmed, inf];
%             app.edt_threshold_abs.Value =  app.settings.basmed+ app.edt_threshold.Value*app.settings.basStd;
            set_burst_anal(app)

            working(app,'off')
        end

        % Button down function: SpikeTab
        function SpikeTabButtonDown(app, event)
            working(app,'on')
            auto_derived(app)
            if  isempty(app.spike_res)
                app.popup_int_spike.Enable = 'off';
            else
                app.popup_int_spike.Enable = 'on';
            end
            
            if isempty(app.settings.par)
                app.settings.par = set_parameters(1/app.data(app.settings.channel_idx.msna).ts(1));
                app.settings.par.sr = 1/app.data(app.settings.channel_idx.msna).ts(1);
            end
            working(app,'off')
        end

        % Button down function: WaveletTab
        function WaveletTabButtonDown(app, event)
            working(app,'on')
            auto_derived(app)
            update_wavelet_axis(app)
            working(app,'off')
        end

        % Button down function: StepwiseregressionTab
        function StepwiseregressionTabButtonDown(app, event)
            working(app,'on')
            auto_derived(app)
            working(app,'off')
        end

        % Button pushed function: btn_spectral_analysis
        function btn_spectral_analysisButtonPushed(app, event)
            working(app,'on')
            [app.spec_res,update] = spectral_analysis(app);
            if update
                update_spectral_axis(app,false)
            end
            working(app,'off') 
        end

        % Button down function: SpectralanalysisTab
        function SpectralanalysisTabButtonDown(app, event)
            working(app,'on')
            auto_derived(app)
            working(app,'off')
        end

        % Value changed function: popup_signal_spect
        function popup_signal_spectValueChanged(app, event)
            app.spec_res = [];
            app.btn_spectral_analysis.BackgroundColor = [.96, .96, .96];
            update_spectral_axis(app,true) 
        end

        % Value changed function: popup_int_spect
        function popup_int_spectValueChanged(app, event)
            working(app,'on')
            update_spectral_axis(app,true)
            working(app,'off')
        end

        % Button pushed function: btn_save_spect_result
        function btn_save_spect_resultButtonPushed(app, event)
            if strcmp(app.settings.output_dir , 'none')
                warndlg('Please select output directory first!')
            else
                if isempty(app.spec_res)
                    warndlg('pleas run spectral analysis first')
                else
                    working(app,'on')
                    save_spec_res(app)
                    working(app,'off')
                end
            end
        end

        % Button pushed function: btn_wavelet
        function btn_waveletButtonPushed(app, event)
            working(app,'on')
            wavelet_analysis(app)
            working(app,'off')
        end

        % Value changed function: popup_signal1
        function popup_signal1ValueChanged(app, event)
            working(app,'on')
            update_wavelet_axis(app)
            working(app,'off')
        end

        % Value changed function: popup_signal2
        function popup_signal2ValueChanged(app, event)
            working(app,'on')
            update_wavelet_axis(app)
            working(app,'off')
        end

        % Value changed function: popup_int_wavelet
        function popup_int_waveletValueChanged(app, event)
            working(app,'on')
            update_wavelet_axis(app)
            working(app,'off')
        end

        % Button pushed function: btn_save_wavelet_results
        function btn_save_wavelet_resultsButtonPushed(app, event)
            if strcmp(app.settings.output_dir , 'none')
                warndlg('Please select output directory first!')
            else
                working(app,'on')
                wavelet_report(app)
                working(app,'off')
            end
        end

        % Value changed function: edt_min_frq
        function edt_min_frqValueChanged(app, event)
            working(app, 'on')
            xlim (app.ax_spect_1_1, [app.edt_min_frq.Value, app.edt_max_frq.Value])
            xlim (app.ax_spect_2_1, [app.edt_min_frq.Value, app.edt_max_frq.Value])
            xlim (app.ax_spect_3_1, [app.edt_min_frq.Value, app.edt_max_frq.Value])
            xlim (app.ax_spect_4_1, [app.edt_min_frq.Value, app.edt_max_frq.Value]) 
            xlim (app.ax_spect_1_2, [app.edt_min_frq.Value, app.edt_max_frq.Value])
            xlim (app.ax_spect_2_2, [app.edt_min_frq.Value, app.edt_max_frq.Value])
            xlim (app.ax_spect_3_2, [app.edt_min_frq.Value, app.edt_max_frq.Value])
            xlim (app.ax_spect_4_2, [app.edt_min_frq.Value, app.edt_max_frq.Value]) 
%             app.entropy_res = [];
%             cla(app.ax_entropy2)
%             cla(app.ax_entropy3)
%             cla(app.ax_entropy4)
%             app.ax_entropy3.Visible = 'off';
%             app.ax_entropy4.Visible = 'off';
%             app.lbl_entropy3.Visible = 'off';
%             app.lbl_entropy4.Visible = 'off';
%             app.lbl_entropy_unit3.Visible = 'off';
%             app.lbl_entropy_unit2.Visible = 'off';
            working(app, 'off')
        end

        % Value changed function: edt_amp
        function edt_ampValueChanged(app, event)
            ylim (app.ax_spect_1_2, [0 app.edt_amp.Value])
            ylim (app.ax_spect_2_2, [0 app.edt_amp.Value])
            ylim (app.ax_spect_3_2, [0 app.edt_amp.Value])
            ylim (app.ax_spect_4_2, [0 app.edt_amp.Value])           
        end

        % Value changed function: edt_max_frq
        function edt_max_frqValueChanged(app, event)
            working(app, 'on')
            xlim (app.ax_spect_1_1, [app.edt_min_frq.Value, app.edt_max_frq.Value])
            xlim (app.ax_spect_2_1, [app.edt_min_frq.Value, app.edt_max_frq.Value])
            xlim (app.ax_spect_3_1, [app.edt_min_frq.Value, app.edt_max_frq.Value])
            xlim (app.ax_spect_4_1, [app.edt_min_frq.Value, app.edt_max_frq.Value]) 
            xlim (app.ax_spect_1_2, [app.edt_min_frq.Value, app.edt_max_frq.Value])
            xlim (app.ax_spect_2_2, [app.edt_min_frq.Value, app.edt_max_frq.Value])
            xlim (app.ax_spect_3_2, [app.edt_min_frq.Value, app.edt_max_frq.Value])
            xlim (app.ax_spect_4_2, [app.edt_min_frq.Value, app.edt_max_frq.Value]) 
%             app.entropy_res = [];
%             cla(app.ax_entropy2)
%             cla(app.ax_entropy3)
%             cla(app.ax_entropy4)
%             app.ax_entropy3.Visible = 'off';
%             app.ax_entropy4.Visible = 'off';
%             app.lbl_entropy3.Visible = 'off';
%             app.lbl_entropy4.Visible = 'off';
%             app.lbl_entropy_unit3.Visible = 'off';
%             app.lbl_entropy_unit2.Visible = 'off';
            working(app, 'off') 
        end

        % Button pushed function: btn_add_regressor
        function btn_add_regressorButtonPushed(app, event)
            edit_regressor_table(app,'add')
        end

        % Button pushed function: btn_remove_regressor
        function btn_remove_regressorButtonPushed(app, event)
            edit_regressor_table(app,'rem')
        end

        % Button pushed function: btn_regression
        function btn_regressionButtonPushed(app, event)
            working(app,'on')
            app.stepwise_res = stepwise_regression(app);
            update_regression_axis(app)
            working(app,'off')
        end

        % Value changed function: popup_int_stepwise
        function popup_int_stepwiseValueChanged(app, event)
            working(app,'on')
            update_regression_axis(app)
            working(app,'off')
        end

        % Button pushed function: btn_save_reg_results
        function btn_save_reg_resultsButtonPushed(app, event)
            if strcmp(app.settings.output_dir , 'none')
                warndlg('Please select output directory first!')
            else
                working(app,'on')
                save_regression_res(app)
                working(app,'off')
            end
        end

        % Button down function: AnnotateTab
        function AnnotateTabButtonDown(app, event)
            working(app,'on')
            auto_derived(app)
%             calc_annotate(app)
            update_annotate_axis(app)
            working(app,'off')
        end

        % Button down function: CorrelationTab
        function CorrelationTabButtonDown(app, event)
            working(app,'on')
            auto_derived(app)
            ch1_idx = find(strcmp(app.popup_coorelation_signal1.Value, app.popup_coorelation_signal1.Items));
            ts_1 = app.data(ch1_idx).ts(1);
            app.lbl_fs_sig1.Text = sprintf('FS: %d Hz', round(1/ts_1));
            if 1/ts_1 > 1
                app.lbl_fs_sig1.Text = sprintf('FS: %.0f Hz', 1/ts_1);
            else
                app.lbl_fs_sig1.Text = sprintf('FS: %.3f Hz', 1/ts_1);
            end


            ch2_idx = find(strcmp(app.popup_coorelation_signal2.Value, app.popup_coorelation_signal2.Items));
            ts_2 = app.data(ch2_idx).ts(1);
            if 1/ts_2 > 1
                app.lbl_fs_sig2.Text = sprintf('FS: %.0f Hz', 1/ts_2);
            else
                app.lbl_fs_sig2.Text = sprintf('FS: %.3f Hz', 1/ts_2);
            end
            working(app,'off')
        end

        % Button down function: TransductionanalysisTab
        function TransductionanalysisTabButtonDown(app, event)
            working(app,'on')
            auto_derived(app)
            if isempty(app.settings.par)
                app.settings.par = set_parameters();
                app.settings.par.sr = 1/mean(app.data(app.settings.channel_idx.msna).ts(1));
            end
            working(app,'off')
        end

        % Button pushed function: btn_resp
        function btn_respButtonPushed(app, event)
            app.resp_app = resp_fix(app);
        end

        % Value changed function: popup_int_annotate
        function popup_int_annotateValueChanged(app, event)
            working(app,'on')
            update_annotate_axis(app)
            working(app,'off')
        end

        % Button pushed function: btn_save_annotate_results
        function btn_save_annotate_resultsButtonPushed(app, event)
            if strcmp(app.settings.output_dir , 'none')
                warndlg('Please select output directory first!')
            else
                working(app,'on')
                save_annotate_results(app)
                working(app,'off')
            end
        end

        % Value changed function: edt_min_freq
        function edt_min_freqValueChanged(app, event)
            xl = app.ax_annotate_1_2.XLim;
            xl(1) = app.edt_min_freq.Value;
            app.ax_annotate_1_2.XLim = xl;
            app.ax_annotate_2_2.XLim = xl;
            app.ax_annotate_3_2.XLim = xl;
            app.ax_annotate_4_2.XLim = xl;
        end

        % Value changed function: edt_max_freq
        function edt_max_freqValueChanged(app, event)
            xl = app.ax_annotate_1_2.XLim;
            xl(2) = app.edt_max_freq.Value;
            app.ax_annotate_1_2.XLim = xl;
            app.ax_annotate_2_2.XLim = xl;
            app.ax_annotate_3_2.XLim = xl;
            app.ax_annotate_4_2.XLim = xl;
        end

        % Value changed function: edt_min_time
        function edt_min_timeValueChanged(app, event)
            xl = app.ax_annotate_1_1.XLim;
            xl(1) = app.edt_min_time.Value;
            app.ax_annotate_1_1.XLim = xl;            
        end

        % Value changed function: edt_max_time
        function edt_max_timeValueChanged(app, event)
            xl = app.ax_annotate_1_1.XLim;
            xl(2) = app.edt_max_time.Value;
            app.ax_annotate_1_1.XLim = xl;    
            
        end

        % Button pushed function: btn_correlation
        function btn_correlationButtonPushed(app, event)
            working(app,'on')
            app.corre_res = correlation_analysis(app);
            update_correlation_axis(app)
            working(app,'off')
        end

        % Button pushed function: btn_save_corre_results
        function btn_save_corre_resultsButtonPushed(app, event)
            if strcmp(app.settings.output_dir , 'none')
                warndlg('Please select output directory first!')
            else
                if isempty(app.corre_res)
                    warndlg('please run correlation analysis first')
                else
                    working(app,'on')
                    corre_report(app)
                    working(app,'off')
                end
            end
        end

        % Value changed function: popup_int_corre
        function popup_int_correValueChanged(app, event)
            working(app,'on')
            update_correlation_axis(app)            
            working(app,'off')
        end

        % Button pushed function: btn_transduction
        function btn_transductionButtonPushed(app, event)
           working(app,'on')
            if isempty(app.spike_res)
                warndlg('please run spike detection first ')
                return
            end

            if ~isnan(app.settings.channel_idx.msna) & ~isnan(app.settings.channel_idx.ecg) & ~isnan(app.settings.channel_idx.bldp)
                app.spike_res = transduction_analysis(app);
                update_cluster_popup(app)
                update_transduction_axis(app)
            else
                warndlg('MNG, ECG AND blood pressure signal needed')
            end
            working(app,'off')
        end

        % Value changed function: popup_int_transduction
        function popup_int_transductionValueChanged(app, event)
            working(app,'on')
            update_transduction_axis(app)
            working(app,'off')
        end

        % Button pushed function: btn_save_trans_results
        function btn_save_trans_resultsButtonPushed(app, event)
            if strcmp(app.settings.output_dir , 'none')
                warndlg('Please select output directory first!')
            else
                if isempty(app.spike_res)
                    warndlg('please run transduction analysis first')
                else
                    working(app,'on')
                    save_trans_results(app)
                    working(app,'off')
                end
            end
        end

        % Value changed function: chkbx_spike_sorting
        function chkbx_spike_sortingValueChanged(app, event)
%             app.chkbx_sorting_trans.Value = app.chkbx_spike_sorting.Value;
            if app.chkbx_spike_sorting.Value
                app.chkbx_spike_clustering.Value = false;
%                 app.chkbx_trans_clustering.Value = false;
            end
            
        end

        % Value changed function: edt_dip1
        function edt_dip1ValueChanged(app, event)
%             app.edt_trans_dip1.Value = app.edt_dip1.Value;
            
        end

        % Value changed function: edt_peak
        function edt_peakValueChanged(app, event)
%             app.edt_trans_peak.Value = app.edt_peak.Value;           
        end

        % Value changed function: edt_dip2
        function edt_dip2ValueChanged(app, event)
%             app.edt_trans_dip2.Value = app.edt_dip2.Value;
            
        end

        % Value changed function: popup_int_spike
        function popup_int_spikeValueChanged(app, event)
            working(app,'on')
            update_spike_axis(app,true)
            int_idx = find(strcmp(app.popup_int_spike.Value,app.popup_int_spike.Items));
%             app.spike_res.spike_lag.spks = get_spike_lag(app.spike_res.spike_ts, app.spike_res.cluster, app.spike_res.use_spikes, app.hb_res.t_events,app.hb_res.use_beats, int_idx);
            if ~isempty(app.hb_res)
                app.spike_res.spike_lag.spks = get_spike_lag(app.spike_res.spike_ts, app.spike_res.cluster, app.spike_res.use_spikes, app.hb_res.t_events,app.hb_res.use_beats,int_idx);
                app.sbtn_lag.Enable = 'on';
                update_spikelag_display(app, 'new')
            else
                app.spike_res.spike_lag = [];
                app.sbtn_lag.Enable = 'off';
            end
            

            working(app,'off')
        end

        % Button pushed function: btn_save_spike_res
        function btn_save_spike_resButtonPushed(app, event)
            if strcmp(app.settings.output_dir , 'none')
                warndlg('Please select output directory first!')
            else
                if isempty(app.spike_res)
                    warndlg('please run spike analysis first')
                else
                    working(app,'on')
                    save_spike_results(app)
                    working(app,'off')
                end
            end
        end

        % Button pushed function: btn_create_report
        function btn_create_reportPushed(app, event)
            working(app,'on')
            create_report(app)
            working(app,'off')
        end

        % Button pushed function: btn_select_folder
        function btn_select_folderButtonPushed(app, event)
            working(app,'on')
            [app.settings.figs, app.settings.rep_dir] = find_outputfiles(app);
            working(app,'off')
        end

        % Button pushed function: btn_select_templ_dir
        function btn_select_templ_dirButtonPushed(app, event)
            working(app,'on')
            [app.settings.brst_files, app.settings.int_templ, app.settings.collect_path] = find_xls(app);
            working(app,'off')
        end

        % Button pushed function: btn_output_directory
        function btn_output_directoryButtonPushed(app, event)
            if isempty(app.data)
                warndlg('please load data first')
            end
            tmp = uigetdir(pwd,'please select output directory');
            if tmp ~= 0
                app.settings.output_dir = tmp;
                app.lbl_output.Text =  app.settings.output_dir;
            end
        end

        % Button pushed function: btn_combine
        function btn_combineButtonPushed(app, event)
            if ~isempty(app.edt_filename.Value)
                working(app,'on')
                calc_combine_stats(app)
                working(app,'off')
            else
                warndlg('please enter filename')
            end
        end

        % Value changed function: lstbx_intervals
        function lstbx_intervalsValueChanged(app, event)
            stat_test = {{''},{'two sample t test','Wilcoxon rank sum test'}, {'anova','anocova'}}; % {{''},{'two sample t test','Wilcoxon rank sum test'}, {'anova','anocova'}};
            posthoc = {{''},{'none', 'tukey-kramer', 'hsd', 'dunn-sidak', 'bonferroni', 'scheffe', 'dunnett','lsd'}};
                       
            switch length(app.lstbx_intervals.Value)
                case 0
                    app.popup_test.Items = stat_test{1};
                    app.popup_test.Value = app.popup_test.Items{1};
                    app.popup_test.Enable = 'off';
                    app.popup_posthoc.Items = posthoc{1};
                    app.popup_posthoc.Value = app.popup_posthoc.Items{1};
                    app.popup_posthoc.Enable = 'off';
                case 1
                    app.popup_test.Items = stat_test{1};
                    app.popup_test.Value = app.popup_test.Items{1};
                    app.popup_test.Enable = 'off';
                    app.popup_posthoc.Items = posthoc{1};
                    app.popup_posthoc.Value = app.popup_posthoc.Items{1};
                    app.popup_posthoc.Enable = 'off';

                case 2
                    app.popup_test.Items = stat_test{2};
                    app.popup_test.Value = app.popup_test.Items{1};
                    app.popup_test.Enable = 'on';
                    app.popup_posthoc.Items = posthoc{1};
                    app.popup_posthoc.Value = app.popup_posthoc.Items{1};
                    app.popup_posthoc.Enable = 'off';
                otherwise
                    app.popup_test.Items = stat_test{3};
                    app.popup_test.Value = app.popup_test.Items{1};
                    app.popup_test.Enable = 'on';
                    app.popup_posthoc.Items = posthoc{2};
                    app.popup_posthoc.Value = app.popup_posthoc.Items{1};
                    app.popup_posthoc.Enable = 'on';
            end
        end

        % Button pushed function: btn_add
        function btn_addButtonPushed(app, event)
            if isempty(app.comb_stat)
                tmp_strc = struct('intervals',[],...
                                  'signals',[],...
                                  'test',[],...
                                  'posthoc',[],...
                                  'sig_str',[],'test_str',[],'int_str',[]);
                tmp_strc(1).intervals = app.lstbx_intervals.Value;
                tmp_strc(1).signals = app.lstbx_signals.Value;
                tmp_strc(1).test = app.popup_test.Value;
                tmp_strc(1).posthoc = app.popup_posthoc.Value;
            else
                tmp_strc = app.comb_stat;
                tmp_strc(end+1).intervals = app.lstbx_intervals.Value;
                tmp_strc(end).signals = app.lstbx_signals.Value;
                tmp_strc(end).test = app.popup_test.Value;
                tmp_strc(end).posthoc = app.popup_posthoc.Value;
            end
            tmp = {[]};
            for i = 1 : length(tmp_strc(end).intervals)
                tmp{1,i} = tmp_strc(end).intervals{i};
                tmp_idx = strfind(tmp{1,i}, '<$§');
                if ~isempty(tmp_idx)
                    tmp{1,i}(tmp_idx:end) = [];
                end
                tmp_idx = strfind(tmp{1,i},'§$>');
                if ~isempty(tmp_idx)
                    tmp{1,i}(1:3) = [];
                end
                tmp_idx = strfind(tmp{1,i}, '§$');
                if ~isempty(tmp_idx)
                    tmp{1,i}(tmp_idx:tmp_idx+3) = [];
                end
                tmp_idx = strfind(tmp{1,i}, '$§');
                if ~isempty(tmp_idx)
                    tmp{1,i}(tmp_idx-2:tmp_idx+1) = [];
                end
                tmp{2,i} = ',';
            end
            tmp = strjoin(reshape(tmp,[1,size(tmp,2)*2]));
            tmp_strc(end).int_str = tmp(1:end-1);
            tmp_strc(end).test_str =  [tmp_strc(end).test, ' ' tmp_strc(end).posthoc];
            
            tmp = {[]};
            for i = 1: length(tmp_strc(end).signals)
                tmp{1,i} = tmp_strc(end).signals{i};
                tmp_idx = find(tmp{1,i} == '[');
                if ~isempty(tmp{1,i})
                    tmp{1,i}(tmp_idx-1:end) = [];
                end
                tmp{2,i} = ',';
            end
            tmp = strjoin(reshape(tmp,[1,size(tmp,2)*2]));
            tmp_strc(end).sig_str = tmp(1:end-1);
            
            tbl_cell = {[]};
            for i = 1:length(tmp_strc)
                tbl_cell{i,1} = tmp_strc(i).int_str;
                tbl_cell{i,2} = tmp_strc(i).test_str;
                tbl_cell{i,3} = tmp_strc(i).sig_str;
            end
            app.tbl_groups.Data = tbl_cell;
            app.comb_stat = tmp_strc;
        end

        % Button pushed function: btn_remove
        function btn_removeButtonPushed(app, event)
            tmp_strc = app.comb_stat;
            
            idx = app.tbl_groups.Selection;
            tmp_strc(idx) = [];
            if length(tmp_strc) ==0
                tbl_cell = [];
            else
                for i = 1:length(tmp_strc)
                    tbl_cell{i,1} = tmp_strc(i).int_str;
                    tbl_cell{i,2} = tmp_strc(i).test_str;
                    tbl_cell{i,3} = tmp_strc(i).sig_str;
                end
            end
            app.tbl_groups.Data = tbl_cell;
            app.comb_stat = tmp_strc;
        end

        % Value changed function: chkbx_spike_clustering
        function chkbx_spike_clusteringValueChanged(app, event)
%             app.chkbx_trans_clustering.Value = app.chkbx_spike_clustering.Value;
            if app.chkbx_spike_clustering.Value
                app.chkbx_spike_sorting.Value = false;
%                 app.chkbx_sorting_trans.Value = false;
            end
            
        end

        % Value changed function: chkbx_show_o1
        function chkbx_show_o1ValueChanged(app, event)
            working(app,'on')
            update_spectral_axis(app,false)
            working(app,'on')
            
        end

        % Value changed function: chkbx_show_o2
        function chkbx_show_o2ValueChanged(app, event)
            working(app,'on')
            update_spectral_axis(app,false)
            working(app,'on')
            
        end

        % Value changed function: chkbx_show_o3
        function chkbx_show_o3ValueChanged(app, event)
            working(app,'on')
            update_spectral_axis(app,false)
            working(app,'on')
            
        end

        % Button pushed function: deselectselectspikesButton
        function deselectselectspikesButtonPushed(app, event)
            if ~isempty(app.spike_res)
                app.spike_res.det_res.panel = 'spike';
                app.spike_selection_app = spike_selection(app);
            else
                warndlg('no spikes detected yet')
            end

        end

        % Button pushed function: setspikedetectionparameterButton
        function setspikedetectionparameterButtonPushed(app, event)
            app.det_param_app = det_params(app);
        end

        % Value changed function: sbtn_phase
        function sbtn_phaseValueChanged(app, event)
            if app.sbtn_phase.Value
                app.sbtn_rate.Value = false;
                app.sbtn_lag.Value = false;
                app.pnl_rate.Visible = false;
                app.pnl_lag.Visible = false;
                app.pnl_phase.Visible = true;
            else
                app.sbtn_phase.Value = true;
                app.sbtn_lag.Value = true;
            end
            
        end

        % Value changed function: sbtn_rate
        function sbtn_rateValueChanged(app, event)
            if app.sbtn_rate.Value
                app.sbtn_phase.Value = false;
                app.sbtn_lag.Value = false;
                app.pnl_phase.Visible = false;
                app.pnl_lag.Visible = false;
                app.pnl_rate.Visible = true;
            else
                app.sbtn_rate.Value = true;
                app.sbtn_lag.Value = true;
            end

        end

        % Value changed function: lstbx_ints
        function lstbx_intsValueChanged(app, event)
            cols = distinguishable_colors(max(app.spike_res.cluster),[1 1 1; 1 0 0; 0 0 1; 0.80 0.95 0.90]);
            mrate = nan(length(app.lstbx_ints.Value),max(app.spike_res.cluster));
            
            for i = 1 :length(app.lstbx_ints.Value)
                tmp_idx = find(strcmp(app.lstbx_ints.Value{i},app.lstbx_ints.Items));
                mrate(i,:) = app.spike_res.spike(tmp_idx).m_fr;
            end
            cla(app.ax_rate)
            hold (app.ax_rate,'on')
            for i= 1:size(mrate,2)
                plot (app.ax_rate,mrate(:,i), 'LineStyle','--', 'Color',cols(i,:),'Marker','diamond','MarkerSize',10,'MarkerFaceColor',cols(i,:))
            end
            hold(app.ax_rate,'off')
            app.ax_rate.XTick = 1:size(mrate,1);
            app.ax_rate.XTickLabel = app.lstbx_ints.Value;
            app.ax_rate.XTickLabelRotation = 30;

        end

        % Button pushed function: btn_save_sesh
        function btn_save_seshButtonPushed(app, event)
            working(app,'on')
            save_session(app)
            working(app,'off')
        end

        % Button down function: LagdetectionTab
        function LagdetectionTabButtonDown(app, event)
            if length(app.ax_lag_sig1.Children) <3
                update_lag_axis(app, 'init')
            end

        end

        % Value changing function: sldr_lag_start
        function sldr_lag_startValueChanging(app, event)
            %update_lag_axis(app, 'slider')
            
        end

        % Value changing function: sldr_lag_end
        function sldr_lag_endValueChanging(app, event)
            %update_lag_axis(app, 'slider')
            
        end

        % Value changed function: popup_lag_int
        function popup_lag_intValueChanged(app, event)
            update_lag_axis(app, 'sig')
            
        end

        % Value changed function: popup_lag_sig1
        function popup_lag_sig1ValueChanged(app, event)
            update_lag_axis(app, 'sig')
            
        end

        % Value changed function: popup_lag_sig2
        function popup_lag_sig2ValueChanged(app, event)
            update_lag_axis(app, 'sig')
            
        end

        % Button pushed function: btn_lag_calc
        function btn_lag_calcButtonPushed(app, event)
            update_lag_axis(app, 'calc')
        end

        % Button pushed function: btn_lag_save
        function btn_lag_saveButtonPushed(app, event)
            save_lag_fig(app)
        end

        % Value changed function: sldr_lag_start
        function sldr_lag_startValueChanged(app, event)
            update_lag_axis(app, 'slider')
            
        end

        % Value changed function: sldr_lag_end
        function sldr_lag_endValueChanged(app, event)
            update_lag_axis(app, 'slider')
            
        end

        % Value changed function: chkbx_lag_ma_sig1
        function chkbx_lag_ma_sig1ValueChanged(app, event)
            update_lag_axis(app, 'sig')
            
        end

        % Value changed function: chkbx_lag_ma_sig2
        function chkbx_lag_ma_sig2ValueChanged(app, event)
            update_lag_axis(app, 'sig')
            
        end

        % Value changed function: sbtn_lag
        function sbtn_lagValueChanged(app, event)
            if app.sbtn_lag.Value
                app.sbtn_rate.Value = false;
                app.sbtn_phase.Value = false;
                app.pnl_rate.Visible = false;
                app.pnl_phase.Visible = false;
                app.pnl_lag.Visible = true;
                
                update_spikelag_display(app, 'new')
            else
                app.sbtn_phase.Value = true;
                app.sbtn_rate.Value = true;
            end

        end

        % Value changed function: sldr_spikelag
        function sldr_spikelagValueChanged(app, event)
            if app.sbtn_lag.Value
                update_spikelag_display(app, 'pointer')
            end
            
        end

        % Value changed function: popup_cluster_lag
        function popup_cluster_lagValueChanged(app, event)
            update_spikelag_display(app, 'new')
            
        end

        % Value changed function: edt_num_beat
        function edt_num_beatValueChanged(app, event)
            update_spikelag_display(app, 'new')
            
        end

        % Value changed function: edt_lag_movmean
        function edt_lag_movmeanValueChanged(app, event)
            update_spikelag_display(app, 'new')
            
        end

        % Value changed function: sbtn_spklag
        function sbtn_spklagValueChanged(app, event)
            value = app.sbtn_spklag.Value;
            
        end

        % Value changed function: sbtn_skew_kurt
        function sbtn_skew_kurtValueChanged(app, event)
            value = app.sbtn_skew_kurt.Value;
            
        end

        % Value changed function: edt_lag_ma_sig1
        function edt_lag_ma_sig1ValueChanged(app, event)
            
            if app.edt_lag_ma_sig1.Value >0
                app.chkbx_lag_ma_sig1.Value = true;
            end    
            update_lag_axis(app, 'sig')
            
        end

        % Value changed function: edt_lag_ma_sig2
        function edt_lag_ma_sig2ValueChanged(app, event)
            
            if app.edt_lag_ma_sig1.Value >0
                app.chkbx_lag_ma_sig2.Value = true;
            end
            update_lag_axis(app, 'sig')
            
        end

        % Button pushed function: addtolistButton
        function addtolistButtonPushed(app, event)
            app.lag(end+1).bgsig = find(strcmp(app.popup_lag_bgsig.Value, app.popup_lag_bgsig.Items));
            app.lag(end).lims = [app.sldr_lag_start.Value,app.sldr_lag_end.Value];
            app.lag(end).int = find(strcmp(app.popup_lag_int.Value, app.popup_lag_int.Items));
            app.lag(end).sig1 = find(strcmp(app.popup_lag_sig1.Value, app.popup_lag_sig1.Items));
            if app.chkbx_lag_ma_sig1.Value
                app.lag(end).mm1 = app.edt_lag_ma_sig1.Value;
            else
                app.lag(end).mm1 = 0;
            end
            app.lag(end).sig2 = find(strcmp(app.popup_lag_sig2.Value, app.popup_lag_sig2.Items));
            if app.chkbx_lag_ma_sig2.Value
                app.lag(end).mm2 = app.edt_lag_ma_sig2.Value;
            else
                app.lag(end).mm2 = 0;
            end
            app.lag(end).lag = app.settings.lag;
            app.lag(end).displ_str = [ app.popup_lag_int.Value ' - ' app.popup_lag_sig1.Value ' - ' app.popup_lag_sig2.Value ];
            tmp = {};
            for i = 1 : length(app.lag)
                tmp{end+1} = app.lag(i).displ_str;
            end

             app.lstbx_save.Items = tmp;
        end

        % Value changed function: lstbx_save
        function lstbx_saveValueChanged(app, event)
            idx = find(strcmp(app.lstbx_save.Value, app.lstbx_save.Items));
            app.lag(idx) = [];
            tmp = {};
            for i = 1 : length(app.lag)
                tmp{end+1} = app.lag(i).displ_str;
            end

             app.lstbx_save.Items = tmp;
             app.lstbx_save.Value = {};
        end

        % Value changed function: chkbx_lock
        function chkbx_lockValueChanged(app, event)
            value = app.chkbx_lock.Value;
            
        end

        % Value changed function: popup_lag_bgsig
        function popup_lag_bgsigValueChanged(app, event)
            update_lag_axis(app, 'sig')
            
        end

        % Button down function: ax_lag_xcorr
        function ax_lag_xcorrButtonDown(app, event)
            update_lag_axis(app, 'select',event.IntersectionPoint(1))
        end

        % Button pushed function: savetrainsButton
        function savetrainsButtonPushed(app, event)
            spiketrains = struct();
            strt_idx = app.settings.interval(1,1)/app.data(app.settings.channel_idx.msna).ts(1);
            strt_ts = app.settings.interval(1,1);
            clus = unique(app.spike_res.cluster);
    
            tmp = find(clus  == 0);
            if ~isempty(tmp)
                clus(tmp)=[];
            end

            for i = 1 : size(app.spike_res.use_spikes,2)
                if i ==1
                    spiketrains(i).name = 'Full';
                    for j = 1 : length(clus)
                        spiketrains(i).ts{j} = app.spike_res.spike_ts(app.spike_res.cluster == clus(j));
                        spiketrains(i).ts_global{j} =spiketrains(i).ts{j} +strt_ts;
                        spiketrains(i).idx{j} = app.spike_res.spike_idx(app.spike_res.cluster == clus(j));
                        spiketrains(i).idx_global{j} = spiketrains(i).idx{j} +strt_idx;
                    end

                    spiketrains(i).sub_int_start = 1/app.settings.tick_freq;
                    [data,~,~,~]=current_signal(app,app.settings.channel_idx.msna);
                    spiketrains(i).sub_int_end = length(data)/app.settings.tick_freq;
                    spiketrains(i).sub_int_start_global = spiketrains(i).sub_int_start +strt_ts;
                    spiketrains(i).sub_int_end_global = spiketrains(i).sub_int_end +strt_ts;
                    spiketrains(i).fs = app.settings.tick_freq;
                else
                    spiketrains(i).name = app.burst_ints(i-1).name;
                    for j = 1 : length(clus)
                        spiketrains(i).ts{j} = app.spike_res.spike_ts(app.spike_res.cluster' == clus(j) & app.spike_res.use_spikes(:,i) );
                        spiketrains(i).ts_global{j} = spiketrains(i).ts{j} +strt_ts;
                        spiketrains(i).idx{j} = app.spike_res.spike_idx(app.spike_res.cluster' == clus(j) & app.spike_res.use_spikes(:,i) );
                        spiketrains(i).idx_global{j} = spiketrains(i).idx{j} +strt_idx;
                    end

                    spiketrains(i).sub_int_start = app.burst_ints(i-1).borders(1,1);
                    spiketrains(i).sub_int_start_global = spiketrains(i).sub_int_start +strt_ts;
                    spiketrains(i).sub_int_end = app.burst_ints(i-1).borders(1,2);
                    spiketrains(i).sub_int_end_global = spiketrains(i).sub_int_end +strt_ts;
                    spiketrains(i).fs = app.settings.tick_freq;
                    spiketrains(i).sub_int_start_global = spiketrains(i).sub_int_start +strt_ts;
                    spiketrains(i).sub_int_end_global = spiketrains(i).sub_int_end +strt_ts;
                end
            end
            idx_d = find(app.settings.file_path == '.',1,'last');
            idx_bs = find(app.settings.file_path == '\',1,'last');
            save([app.settings.output_dir '\spike_trains_' app.settings.file_path(idx_bs+1:idx_d-1)], "spiketrains")
        end

        % Value changed function: sbtn_shape
        function sbtn_shapeValueChanged(app, event)
            app.sbtn_shape.Value = true;
            app.sbtn_thresh.Value = false;
            app.pnl_burst_shape.Visible = "on";
            app.pnl_burst_shape.Enable = "on";
            app.pnl_burst_thresh.Visible = "off";
            app.pnl_burst_thresh.Enable = "off";            
        end

        % Value changed function: sbtn_thresh
        function sbtn_threshValueChanged(app, event)
            app.sbtn_thresh.Value = true;
            app.sbtn_shape.Value = false;
            app.pnl_burst_shape.Visible = "off";
            app.pnl_burst_shape.Enable = "off";
            app.pnl_burst_thresh.Visible = "on";
            app.pnl_burst_thresh.Enable = "on";  
            
        end

        % Value changed function: sbtn_hb
        function sbtn_hbValueChanged(app, event)
            app.sbtn_resp.Value = false;
            app.sbtn_hb.Value = true;
            
        end

        % Value changed function: sbtn_resp
        function sbtn_respValueChanged(app, event)
            app.sbtn_resp.Value = true;
            app.sbtn_hb.Value = false;
            
        end

        % Button pushed function: extractsignalsButton
        function extractsignalsButtonPushed(app, event)
            
            [sig_idxs,~] = listdlg('PromptString','Please select signals to extract ',    'SelectionMode','multiple','ListString',app.popup_lag_sig1.Items);
            data_struc = struct();
            for i = 1:length(sig_idxs)
                data_struc(i).name = app.data(sig_idxs(i)).name;
                data_struc(i).unit = app.data(sig_idxs(i)).unit;
                data_struc(i).tic_multpl = app.data(sig_idxs(i)).tic_multipl;
                data_struc(i).data = app.data(sig_idxs(i)).data;
                data_struc(i).derived = app.data(sig_idxs(i)).derived;
                data_struc(i).ts = app.data(sig_idxs(i)).ts;
            end
            ints = app.burst_ints;
            dt_idx = find (app.settings.file_path == '.',1,'last');
            save([app.settings.file_path(1:dt_idx-1) '_' num2str(app.settings.interval(1,1)) '-' num2str(app.settings.interval(1,2)) '_data'],"ints","data_struc",'-v7.3') 
        end

        % Value changed function: popup_coorelation_signal1
        function popup_coorelation_signal1ValueChanged(app, event)
            ch1_idx = find(strcmp(app.popup_coorelation_signal1.Value, app.popup_coorelation_signal1.Items));
            ts_1 = app.data(ch1_idx).ts(1);
            if 1/ts_1 > 1
                app.lbl_fs_sig1.Text = sprintf('FS: %.0d Hz', 1/ts_1);
            else
                app.lbl_fs_sig1.Text = sprintf('FS: %.3f Hz', 1/ts_1);
            end
        end

        % Value changed function: popup_coorelation_signal2
        function popup_coorelation_signal2ValueChanged(app, event)
            ch2_idx = find(strcmp(app.popup_coorelation_signal2.Value, app.popup_coorelation_signal2.Items));
            ts_2 = app.data(ch2_idx).ts(1);
            if 1/ts_2 > 1
                app.lbl_fs_sig2.Text = sprintf('FS: %.0f Hz', 1/ts_2);
            else
                app.lbl_fs_sig2.Text = sprintf('FS: %.3f Hz', 1/ts_2);
            end
        end

        % Value changed function: popup_baseline_int
        function popup_baseline_intValueChanged(app, event)
            if ~isempty(app.burst_res) 
                derived_burst_signals(app)
                update_burst_axis(app, true)
            end
            
        end

        % Value changed function: chkbx_normalize
        function chkbx_normalizeValueChanged(app, event)
            if ~isempty(app.burst_res) 
                derived_burst_signals(app)
                update_burst_axis(app, true)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.ax_overview_1 = uiaxes(app.StartTab);
            title(app.ax_overview_1, 'Title')
            xlabel(app.ax_overview_1, 'X')
            ylabel(app.ax_overview_1, 'Y')
            zlabel(app.ax_overview_1, 'Z')
            app.ax_overview_1.ButtonDownFcn = createCallbackFcn(app, @ax_overview_1ButtonDown, true);
            app.ax_overview_2 = uiaxes(app.StartTab);
            title(app.ax_overview_2, 'Title')
            xlabel(app.ax_overview_2, 'X')
            ylabel(app.ax_overview_2, 'Y')
            zlabel(app.ax_overview_2, 'Z')
            app.ax_overview_2.ButtonDownFcn = createCallbackFcn(app, @ax_overview_2ButtonDown, true);
            app.ax_overview_3 = uiaxes(app.StartTab);
            title(app.ax_overview_3, 'Title')
            xlabel(app.ax_overview_3, 'X')
            ylabel(app.ax_overview_3, 'Y')
            zlabel(app.ax_overview_3, 'Z')
            app.ax_overview_3.ButtonDownFcn = createCallbackFcn(app, @ax_overview_3ButtonDown, true);
            app.ax_overview_4 = uiaxes(app.StartTab);
            title(app.ax_overview_4, 'Title')
            xlabel(app.ax_overview_4, 'X')
            ylabel(app.ax_overview_4, 'Y')
            zlabel(app.ax_overview_4, 'Z')
            app.ax_overview_4.ButtonDownFcn = createCallbackFcn(app, @ax_overview_4ButtonDown, true);
            app.btn_load = uibutton(app.StartTab, 'push');
            app.btn_load.ButtonPushedFcn = createCallbackFcn(app, @btn_loadButtonPushed, true);
            app.btn_load.Text = 'Load';
            app.btn_prev = uibutton(app.StartTab, 'push');
            app.btn_prev.ButtonPushedFcn = createCallbackFcn(app, @btn_prevButtonPushed, true);
            app.btn_prev.Enable = 'off';
            app.btn_prev.Text = 'Button3';
            app.btn_next = uibutton(app.StartTab, 'push');
            app.btn_next.ButtonPushedFcn = createCallbackFcn(app, @btn_nextButtonPushed, true);
            app.btn_next.Enable = 'off';
            app.btn_next.Text = 'Ch 5-8 >>';
            app.StartDropDownLabel = uilabel(app.StartTab);
            app.StartDropDownLabel.Text = 'Start';
            app.popup_start = uidropdown(app.StartTab);
            app.popup_start.ValueChangedFcn = createCallbackFcn(app, @popup_startValueChanged, true);
            app.popup_start.Enable = 'off';
            app.StopDropDownLabel = uilabel(app.StartTab);
            app.StopDropDownLabel.Text = 'Stop';
            app.popup_stop = uidropdown(app.StartTab);
            app.popup_stop.ValueChangedFcn = createCallbackFcn(app, @popup_stopValueChanged, true);
            app.popup_stop.Enable = 'off';
            app.StartEditFieldLabel = uilabel(app.StartTab);
            app.StartEditFieldLabel.Text = 'Start';
            app.edt_start = uieditfield(app.StartTab, 'numeric');
            app.edt_start.ValueChangedFcn = createCallbackFcn(app, @edt_startValueChanged, true);
            app.edt_start.Enable = 'off';
            app.StopEditFieldLabel = uilabel(app.StartTab);
            app.StopEditFieldLabel.Text = 'Stop';
            app.edt_stop = uieditfield(app.StartTab, 'numeric');
            app.edt_stop.ValueChangedFcn = createCallbackFcn(app, @edt_stopValueChanged, true);
            app.edt_stop.Enable = 'off';
            app.btn_clc_start = uibutton(app.StartTab, 'push');
            app.btn_clc_start.ButtonPushedFcn = createCallbackFcn(app, @btn_clc_startButtonPushed, true);
            app.btn_clc_start.Enable = 'off';
            app.btn_clc_start.Text = 'click start';
            app.btn_home = uibutton(app.StartTab, 'push');
            app.btn_home.ButtonPushedFcn = createCallbackFcn(app, @btn_homeButtonPushed, true);
            app.btn_home.Enable = 'off';
            app.btn_home.Text = 'full rec';
            app.btn_clc_end = uibutton(app.StartTab, 'push');
            app.btn_clc_end.ButtonPushedFcn = createCallbackFcn(app, @btn_clc_endButtonPushed, true);
            app.btn_clc_end.Enable = 'off';
            app.btn_clc_end.Text = 'click end';
            app.tbl_com = uitable(app.StartTab);
            app.tbl_com.ColumnName = {'Comments'};
            app.tbl_com.RowName = {};
            app.BurstTab = uitab(app.TabGroup);
            app.BurstTab.Title = 'Burst';
            app.BurstTab.ButtonDownFcn = createCallbackFcn(app, @BurstTabButtonDown, true);
            app.ax_msna_int = uiaxes(app.BurstTab);
            app.ax_msna_int.ButtonDownFcn = createCallbackFcn(app, @ax_msna_intButtonDown, true);
            app.ax_msna_int.Tag = 'ax_msna_int';
            app.ax_burst_res = uiaxes(app.BurstTab);
            app.ax_burst_res.ButtonDownFcn = createCallbackFcn(app, @ax_burst_resButtonDown, true);
            app.ax_burst_res.Tag = 'ax_burst_res';
            app.ax_burst_integral_his = uiaxes(app.BurstTab);
            app.ax_burst_amplitude_his = uiaxes(app.BurstTab);
            app.ax_burst_duration_his = uiaxes(app.BurstTab);
            app.ax_burst_interval_his = uiaxes(app.BurstTab);
            app.ax_msna_raw = uiaxes(app.BurstTab);
            app.ax_msna_raw.ButtonDownFcn = createCallbackFcn(app, @ax_msna_rawButtonDown, true);
            app.ax_msna_raw.Tag = 'ax_msna_raw';
            app.ax_burst_latency_his = uiaxes(app.BurstTab);
            app.chkbx_detrent_rms = uicheckbox(app.BurstTab);
            app.chkbx_detrent_rms.Text = 'detrend RMS';
            app.btn_burst_analysis = uibutton(app.BurstTab, 'push');
            app.btn_burst_analysis.ButtonPushedFcn = createCallbackFcn(app, @btn_burst_analysisPushed, true);
            app.btn_burst_analysis.Text = 'Burst analysis';
            app.chkbx_rem_burst = uicheckbox(app.BurstTab);
            app.chkbx_rem_burst.ValueChangedFcn = createCallbackFcn(app, @chkbx_rem_burstValueChanged, true);
            app.chkbx_rem_burst.Text = 'remove burst';
            app.chkbx_add_burst = uicheckbox(app.BurstTab);
            app.chkbx_add_burst.ValueChangedFcn = createCallbackFcn(app, @chkbx_add_burstValueChanged, true);
            app.chkbx_add_burst.Text = 'add burst';
            app.showintervalsDropDownLabel = uilabel(app.BurstTab);
            app.showintervalsDropDownLabel.Text = 'show intervals ';
            app.popup_int = uidropdown(app.BurstTab);
            app.popup_int.ValueChangedFcn = createCallbackFcn(app, @popup_intValueChanged, true);
            app.lbl_14 = uilabel(app.BurstTab);
            app.lbl_14.Text = 'MSNA';
            app.lbl_13 = uilabel(app.BurstTab);
            app.lbl_13.Text = 'MSNA RMS and R peaks detected on ECG';
            app.lbl_num_burst = uilabel(app.BurstTab);
            app.lbl_num_burst.Text = 'MSNA';
            app.lbl_burst_rate = uilabel(app.BurstTab);
            app.lbl_burst_rate.Text = 'MSNA';
            app.lbl_threshold = uilabel(app.BurstTab);
            app.lbl_threshold.Text = 'MSNA';
            app.lbl_incidence = uilabel(app.BurstTab);
            app.lbl_incidence.Text = 'MSNA';
            app.lbl_12 = uilabel(app.BurstTab);
            app.lbl_12.Text = '[t]';
            app.lbl_11 = uilabel(app.BurstTab);
            app.lbl_11.Text = '[µV]';
            app.lbl_10 = uilabel(app.BurstTab);
            app.lbl_10.Text = '[µV*ms]';
            app.lbl_9 = uilabel(app.BurstTab);
            app.lbl_9.Text = '[µV]';
            app.lbl8 = uilabel(app.BurstTab);
            app.lbl8.Text = 'burst integral';
            app.lbl_7 = uilabel(app.BurstTab);
            app.lbl_7.Text = 'burst duration';
            app.lbl_6 = uilabel(app.BurstTab);
            app.lbl_6.Text = '[µV]';
            app.lbl_5 = uilabel(app.BurstTab);
            app.lbl_5.Text = '[µV*ms]';
            app.lbl_4 = uilabel(app.BurstTab);
            app.lbl_4.Text = 'inter burst interval';
            app.lbl_3 = uilabel(app.BurstTab);
            app.lbl_3.Text = 'burst amplitude';
            app.lbl_2 = uilabel(app.BurstTab);
            app.lbl_2.Text = '[s]';
            app.lbl_1 = uilabel(app.BurstTab);
            app.lbl_1.Text = '[s]';
            app.btn_save_reset_bursts = uibutton(app.BurstTab, 'push');
            app.btn_save_reset_bursts.ButtonPushedFcn = createCallbackFcn(app, @btn_save_reset_burstsButtonPushed, true);
            app.btn_save_reset_bursts.Text = 'Save results/statistics';
            app.chkbx_rem_int = uicheckbox(app.BurstTab);
            app.chkbx_rem_int.ValueChangedFcn = createCallbackFcn(app, @chkbx_rem_intValueChanged, true);
            app.chkbx_rem_int.Text = 'remove intervall';
            app.chkbx_add_int = uicheckbox(app.BurstTab);
            app.chkbx_add_int.ValueChangedFcn = createCallbackFcn(app, @chkbx_add_intValueChanged, true);
            app.chkbx_add_int.Text = 're-add intervall';
            app.lbl_18 = uilabel(app.BurstTab);
            app.lbl_18.Text = 'Burst integral';
            app.MSNALabel = uilabel(app.BurstTab);
            app.MSNALabel.Text = 'MSNA';
            app.burstlatencyLabel = uilabel(app.BurstTab);
            app.burstlatencyLabel.Text = 'burst latency';
            app.lbl_19 = uilabel(app.BurstTab);
            app.lbl_19.Text = '[s]';
            app.sbtn_shape = uibutton(app.BurstTab, 'state');
            app.sbtn_shape.ValueChangedFcn = createCallbackFcn(app, @sbtn_shapeValueChanged, true);
            app.sbtn_shape.Text = 'Shape';
            app.sbtn_shape.Value = true;
            app.sbtn_thresh = uibutton(app.BurstTab, 'state');
            app.sbtn_thresh.ValueChangedFcn = createCallbackFcn(app, @sbtn_threshValueChanged, true);
            app.sbtn_thresh.Text = 'Threshold';
            app.pnl_burst_thresh = uipanel(app.BurstTab);
            app.pnl_burst_thresh.Enable = 'off';
            app.pnl_burst_thresh.Visible = 'off';
            app.chkbx_th_on_silent = uicheckbox(app.pnl_burst_thresh);
            app.chkbx_th_on_silent.Text = 'Th on silent';
            app.chkbx_manual = uicheckbox(app.pnl_burst_thresh);
            app.chkbx_manual.Text = 'Manual';
            app.K_STDEditFieldLabel = uilabel(app.pnl_burst_thresh);
            app.K_STDEditFieldLabel.Text = 'K_STD';
            app.edt_threshold = uieditfield(app.pnl_burst_thresh, 'numeric');
            app.edt_threshold.Limits = [0 Inf];
            app.edt_threshold.Value = 0.11;
            app.thresholdabsEditFieldLabel = uilabel(app.pnl_burst_thresh);
            app.thresholdabsEditFieldLabel.Text = 'threshold (abs)';
            app.edt_threshold_abs = uieditfield(app.pnl_burst_thresh, 'numeric');
            app.edt_threshold_abs.Value = 0.11;
            app.WindowssEditFieldLabel = uilabel(app.pnl_burst_thresh);
            app.WindowssEditFieldLabel.Text = 'Windows [s]';
            app.edt_window = uieditfield(app.pnl_burst_thresh, 'numeric');
            app.edt_window.Limits = [0 Inf];
            app.edt_window.Value = 0.18;
            app.OutlierTHEditFieldLabel = uilabel(app.pnl_burst_thresh);
            app.OutlierTHEditFieldLabel.Text = 'Outlier TH';
            app.edt_outlier_threshold = uieditfield(app.pnl_burst_thresh, 'numeric');
            app.edt_outlier_threshold.Limits = [0 Inf];
            app.edt_outlier_threshold.Value = 100;
            app.pnl_burst_shape = uipanel(app.BurstTab);
            app.averagedelayEditFieldLabel = uilabel(app.pnl_burst_shape);
            app.averagedelayEditFieldLabel.Text = 'average delay';
            app.edt_burst_delay = uieditfield(app.pnl_burst_shape, 'numeric');
            app.edt_burst_delay.Value = 1.2;
            app.sbtn_hb = uibutton(app.pnl_burst_shape, 'state');
            app.sbtn_hb.ValueChangedFcn = createCallbackFcn(app, @sbtn_hbValueChanged, true);
            app.sbtn_hb.Text = 'Heatbeat';
            app.sbtn_hb.Value = true;
            app.sbtn_resp = uibutton(app.pnl_burst_shape, 'state');
            app.sbtn_resp.ValueChangedFcn = createCallbackFcn(app, @sbtn_respValueChanged, true);
            app.sbtn_resp.Enable = 'off';
            app.sbtn_resp.Visible = 'off';
            app.sbtn_resp.Text = 'Respiration';
            app.baselineintervalLabel = uilabel(app.BurstTab);
            app.baselineintervalLabel.Text = 'norm. interval';
            app.popup_baseline_int = uidropdown(app.BurstTab);
            app.popup_baseline_int.ValueChangedFcn = createCallbackFcn(app, @popup_baseline_intValueChanged, true);
            app.chkbx_normalize = uicheckbox(app.BurstTab);
            app.chkbx_normalize.ValueChangedFcn = createCallbackFcn(app, @chkbx_normalizeValueChanged, true);
            app.chkbx_normalize.Text = 'normalize';
            app.SpikeTab = uitab(app.TabGroup);
            app.SpikeTab.Title = 'Spike';
            app.SpikeTab.ButtonDownFcn = createCallbackFcn(app, @SpikeTabButtonDown, true);
            app.ax_cluster_timestamps = uiaxes(app.SpikeTab);
            title(app.ax_cluster_timestamps, 'Spike timestamps all units')
            zlabel(app.ax_cluster_timestamps, 'Z')
            app.ax_cluster_shapes = uiaxes(app.SpikeTab);
            title(app.ax_cluster_shapes, 'Title')
            xlabel(app.ax_cluster_shapes, 'X')
            zlabel(app.ax_cluster_shapes, 'Z')
            app.ax_spike_msna = uiaxes(app.SpikeTab);
            xlabel(app.ax_spike_msna, 'time [s]')
            zlabel(app.ax_spike_msna, 'Z')
            app.pnl_phase = uipanel(app.SpikeTab);
            app.ax_1st_shape = uiaxes(app.pnl_phase);
            app.ax_1st_phase = uiaxes(app.pnl_phase);
            app.ax_all_shape = uiaxes(app.pnl_phase);
            app.ax_all_phase = uiaxes(app.pnl_phase);
            app.ax_last_shape = uiaxes(app.pnl_phase);
            app.ax_last_phase = uiaxes(app.pnl_phase);
            app.ax_1st_hist = uiaxes(app.pnl_phase);
            xlabel(app.ax_1st_hist, 'X')
            ylabel(app.ax_1st_hist, 'Y')
            zlabel(app.ax_1st_hist, 'Z')
            app.ax_last_hist = uiaxes(app.pnl_phase);
            xlabel(app.ax_last_hist, 'X')
            ylabel(app.ax_last_hist, 'Y')
            zlabel(app.ax_last_hist, 'Z')
            app.ax_all_hist = uiaxes(app.pnl_phase);
            xlabel(app.ax_all_hist, 'X')
            ylabel(app.ax_all_hist, 'Y')
            zlabel(app.ax_all_hist, 'Z')
            app.FirstspikeshapeLabel_2 = uilabel(app.pnl_phase);
            app.FirstspikeshapeLabel_2.Text = 'First spike shape';
            app.FirstspikephaseLabel = uilabel(app.pnl_phase);
            app.FirstspikephaseLabel.Text = 'First spike phase';
            app.AveragespikeshapeLabel = uilabel(app.pnl_phase);
            app.AveragespikeshapeLabel.Text = 'Average spike shape';
            app.AvergagespikephaseLabel = uilabel(app.pnl_phase);
            app.AvergagespikephaseLabel.Text = 'Avergage spike phase';
            app.LastspikeshapeLabel = uilabel(app.pnl_phase);
            app.LastspikeshapeLabel.Text = 'Last spike shape';
            app.LastspikephaseLabel = uilabel(app.pnl_phase);
            app.LastspikephaseLabel.Text = 'Last spike phase';
            app.lbl_1st_phase_lin = uilabel(app.pnl_phase);
            app.lbl_1st_phase_lin.Text = 'linear = ';
            app.lbl_1st_phase_circ = uilabel(app.pnl_phase);
            app.lbl_1st_phase_circ.Text = 'circular = ';
            app.lbl_all_phase_lin = uilabel(app.pnl_phase);
            app.lbl_all_phase_lin.Text = 'linear = ';
            app.lbl_all_phase_circ = uilabel(app.pnl_phase);
            app.lbl_all_phase_circ.Text = 'circular = ';
            app.lbl_last_phase_lin = uilabel(app.pnl_phase);
            app.lbl_last_phase_lin.Text = 'linear = ';
            app.lbl_last_phase_circ = uilabel(app.pnl_phase);
            app.lbl_last_phase_circ.Text = 'circular = ';
            app.chkbx_spike_clustering = uicheckbox(app.SpikeTab);
            app.chkbx_spike_clustering.ValueChangedFcn = createCallbackFcn(app, @chkbx_spike_clusteringValueChanged, true);
            app.chkbx_spike_clustering.Text = 'Wave Clus';
            app.btn_spike_analysis = uibutton(app.SpikeTab, 'push');
            app.btn_spike_analysis.ButtonPushedFcn = createCallbackFcn(app, @btn_spike_analysisButtonPushed, true);
            app.btn_spike_analysis.Text = 'spike analysis';
            app.showclusterDropDownLabel = uilabel(app.SpikeTab);
            app.showclusterDropDownLabel.Text = 'show cluster';
            app.popup_cluster = uidropdown(app.SpikeTab);
            app.popup_cluster.ValueChangedFcn = createCallbackFcn(app, @popup_clusterValueChanged, true);
            app.Panel_5 = uipanel(app.SpikeTab);
            app.chkbx_spike_sorting = uicheckbox(app.Panel_5);
            app.chkbx_spike_sorting.ValueChangedFcn = createCallbackFcn(app, @chkbx_spike_sortingValueChanged, true);
            app.chkbx_spike_sorting.Text = 'Spike Clustering';
            app.Dip1EditField_3Label = uilabel(app.Panel_5);
            app.Dip1EditField_3Label.Text = 'Dip1';
            app.edt_dip1 = uieditfield(app.Panel_5, 'numeric');
            app.edt_dip1.Limits = [1 Inf];
            app.edt_dip1.ValueChangedFcn = createCallbackFcn(app, @edt_dip1ValueChanged, true);
            app.edt_dip1.Value = 1;
            app.PeakEditField_2Label = uilabel(app.Panel_5);
            app.PeakEditField_2Label.Text = 'Peak';
            app.edt_peak = uieditfield(app.Panel_5, 'numeric');
            app.edt_peak.Limits = [1 Inf];
            app.edt_peak.ValueChangedFcn = createCallbackFcn(app, @edt_peakValueChanged, true);
            app.edt_peak.Value = 1;
            app.Dip2EditField_3Label = uilabel(app.Panel_5);
            app.Dip2EditField_3Label.Text = 'Dip2';
            app.edt_dip2 = uieditfield(app.Panel_5, 'numeric');
            app.edt_dip2.ValueChangedFcn = createCallbackFcn(app, @edt_dip2ValueChanged, true);
            app.edt_dip2.Value = 1;
            app.btn_save_spike_res = uibutton(app.SpikeTab, 'push');
            app.btn_save_spike_res.ButtonPushedFcn = createCallbackFcn(app, @btn_save_spike_resButtonPushed, true);
            app.btn_save_spike_res.Text = 'save results';
            app.IntervallLabel_8 = uilabel(app.SpikeTab);
            app.IntervallLabel_8.Text = 'Interval';
            app.popup_int_spike = uidropdown(app.SpikeTab);
            app.popup_int_spike.Items = {'full'};
            app.popup_int_spike.ValueChangedFcn = createCallbackFcn(app, @popup_int_spikeValueChanged, true);
            app.popup_int_spike.Value = 'full';
            app.lbl_spike_ttl1 = uilabel(app.SpikeTab);
            app.lbl_spike_ttl1.Text = 'MSNA';
            app.lbl_spike_ttl2 = uilabel(app.SpikeTab);
            app.lbl_spike_ttl2.Text = 'Timestamps unit';
            app.lbl_spike_ttl3 = uilabel(app.SpikeTab);
            app.lbl_spike_ttl3.Text = 'Firing freqiency unit:';
            app.deselectselectspikesButton = uibutton(app.SpikeTab, 'push');
            app.deselectselectspikesButton.ButtonPushedFcn = createCallbackFcn(app, @deselectselectspikesButtonPushed, true);
            app.deselectselectspikesButton.Text = 'deselect/select spikes';
            app.setspikedetectionparameterButton = uibutton(app.SpikeTab, 'push');
            app.setspikedetectionparameterButton.ButtonPushedFcn = createCallbackFcn(app, @setspikedetectionparameterButtonPushed, true);
            app.setspikedetectionparameterButton.Text = 'set spike detection parameter';
            app.pnl_rate = uipanel(app.SpikeTab);
            app.ax_rate = uiaxes(app.pnl_rate);
            ylabel(app.ax_rate, '[Hz]')
            zlabel(app.ax_rate, 'Z')
            app.ax_isi = uiaxes(app.pnl_rate);
            title(app.ax_isi, 'Title')
            xlabel(app.ax_isi, 'X')
            ylabel(app.ax_isi, 'Y')
            zlabel(app.ax_isi, 'Z')
            app.intervalsListBoxLabel = uilabel(app.pnl_rate);
            app.intervalsListBoxLabel.Text = 'intervals';
            app.lstbx_ints = uilistbox(app.pnl_rate);
            app.lstbx_ints.Multiselect = 'on';
            app.lstbx_ints.ValueChangedFcn = createCallbackFcn(app, @lstbx_intsValueChanged, true);
            app.lstbx_ints.Value = {'Item 1'};
            app.maxEditField_4Label = uilabel(app.pnl_rate);
            app.maxEditField_4Label.Text = 'max';
            app.edt_max = uieditfield(app.pnl_rate, 'numeric');
            app.edt_max.Value = 100;
            app.stepEditFieldLabel = uilabel(app.pnl_rate);
            app.stepEditFieldLabel.Text = 'step';
            app.edt_step = uieditfield(app.pnl_rate, 'numeric');
            app.edt_step.Value = 1;
            app.sbtn_phase = uibutton(app.SpikeTab, 'state');
            app.sbtn_phase.ValueChangedFcn = createCallbackFcn(app, @sbtn_phaseValueChanged, true);
            app.sbtn_phase.Text = 'Phase';
            app.sbtn_phase.Value = true;
            app.sbtn_rate = uibutton(app.SpikeTab, 'state');
            app.sbtn_rate.ValueChangedFcn = createCallbackFcn(app, @sbtn_rateValueChanged, true);
            app.sbtn_rate.Text = 'Rate';
            app.pnl_lag = uipanel(app.SpikeTab);
            app.pnl_lag.Visible = 'off';
            app.ax_hist = uiaxes(app.pnl_lag);
            title(app.ax_hist, 'Title')
            app.ax_lag = uiaxes(app.pnl_lag);
            title(app.ax_lag, 'Title')
            app.heartbeatsEditFieldLabel = uilabel(app.pnl_lag);
            app.heartbeatsEditFieldLabel.Text = '# heartbeats';
            app.edt_num_beat = uieditfield(app.pnl_lag, 'numeric');
            app.edt_num_beat.ValueChangedFcn = createCallbackFcn(app, @edt_num_beatValueChanged, true);
            app.edt_num_beat.Value = 10;
            app.smpltoaverageEditFieldLabel = uilabel(app.pnl_lag);
            app.smpltoaverageEditFieldLabel.Text = 'smpl to average ';
            app.edt_lag_movmean = uieditfield(app.pnl_lag, 'numeric');
            app.edt_lag_movmean.ValueChangedFcn = createCallbackFcn(app, @edt_lag_movmeanValueChanged, true);
            app.edt_lag_movmean.Value = 20;
            app.sldr_spikelag = uislider(app.pnl_lag);
            app.sldr_spikelag.ValueChangedFcn = createCallbackFcn(app, @sldr_spikelagValueChanged, true);
            app.popup_cluster_lag = uidropdown(app.pnl_lag);
            app.popup_cluster_lag.ValueChangedFcn = createCallbackFcn(app, @popup_cluster_lagValueChanged, true);
            app.sbtn_spklag = uibutton(app.pnl_lag, 'state');
            app.sbtn_spklag.ValueChangedFcn = createCallbackFcn(app, @sbtn_spklagValueChanged, true);
            app.sbtn_spklag.Text = 'lag';
            app.sbtn_spklag.Value = true;
            app.sbtn_skew_kurt = uibutton(app.pnl_lag, 'state');
            app.sbtn_skew_kurt.ValueChangedFcn = createCallbackFcn(app, @sbtn_skew_kurtValueChanged, true);
            app.sbtn_skew_kurt.Text = 'skew/kurt';
            app.sbtn_lag = uibutton(app.SpikeTab, 'state');
            app.sbtn_lag.ValueChangedFcn = createCallbackFcn(app, @sbtn_lagValueChanged, true);
            app.sbtn_lag.Text = 'Lag';
            app.savetrainsButton = uibutton(app.SpikeTab, 'push');
            app.savetrainsButton.ButtonPushedFcn = createCallbackFcn(app, @savetrainsButtonPushed, true);
            app.savetrainsButton.Text = 'save trains';
            app.SpectralanalysisTab = uitab(app.TabGroup);
            app.SpectralanalysisTab.Title = 'Spectral analysis';
            app.SpectralanalysisTab.ButtonDownFcn = createCallbackFcn(app, @SpectralanalysisTabButtonDown, true);
            app.ax_signal = uiaxes(app.SpectralanalysisTab);
            title(app.ax_signal, 'Title')
            xlabel(app.ax_signal, 'X')
            ylabel(app.ax_signal, 'Y')
            zlabel(app.ax_signal, 'Z')
            app.ax_signal.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ax_spect_1_1 = uiaxes(app.SpectralanalysisTab);
            app.ax_spect_1_1.Tag = 'ax_spec_1_1';
            app.ax_spect_3_1 = uiaxes(app.SpectralanalysisTab);
            app.ax_spect_3_1.Tag = 'ax_spec_3_1';
            app.ax_spect_4_1 = uiaxes(app.SpectralanalysisTab);
            app.ax_spect_4_1.Tag = 'ax_spec_4_1';
            app.ax_spect_2_1 = uiaxes(app.SpectralanalysisTab);
            app.ax_spect_2_1.Tag = 'ax_spec_2_1';
            app.ax_spect_1_2 = uiaxes(app.SpectralanalysisTab);
            app.ax_spect_1_2.Tag = 'ax_spec_1_2';
            app.ax_spect_2_2 = uiaxes(app.SpectralanalysisTab);
            app.ax_spect_2_2.Tag = 'ax_spec_2_2';
            app.ax_spect_3_2 = uiaxes(app.SpectralanalysisTab);
            app.ax_spect_3_2.Tag = 'ax_spec_3_2';
            app.ax_spect_4_2 = uiaxes(app.SpectralanalysisTab);
            app.ax_spect_4_2.Tag = 'ax_spec_4_2';
            app.SignalforfrequencyanalysisDropDownLabel = uilabel(app.SpectralanalysisTab);
            app.SignalforfrequencyanalysisDropDownLabel.Text = 'Signal for frequency analysis';
            app.popup_signal_spect = uidropdown(app.SpectralanalysisTab);
            app.popup_signal_spect.Items = {'full'};
            app.popup_signal_spect.ValueChangedFcn = createCallbackFcn(app, @popup_signal_spectValueChanged, true);
            app.popup_signal_spect.Value = 'full';
            app.btn_spectral_analysis = uibutton(app.SpectralanalysisTab, 'push');
            app.btn_spectral_analysis.ButtonPushedFcn = createCallbackFcn(app, @btn_spectral_analysisButtonPushed, true);
            app.btn_spectral_analysis.Text = 'Spectral Analysis';
            app.Panel_2 = uipanel(app.SpectralanalysisTab);
            app.lbl_17 = uilabel(app.Panel_2);
            app.lbl_17.Text = 'Downsampling factors';
            app.DS1EditFieldLabel = uilabel(app.Panel_2);
            app.DS1EditFieldLabel.Text = 'DS1';
            app.edt_ds1 = uieditfield(app.Panel_2, 'numeric');
            app.edt_ds1.Limits = [1 Inf];
            app.edt_ds1.Value = 50;
            app.DS2EditFieldLabel = uilabel(app.Panel_2);
            app.DS2EditFieldLabel.Text = 'DS2';
            app.edt_ds2 = uieditfield(app.Panel_2, 'numeric');
            app.edt_ds2.Limits = [1 Inf];
            app.edt_ds2.Value = 2000;
            app.lbl_15 = uilabel(app.Panel_2);
            app.lbl_15.Text = 'Auto Regressive Orders';
            app.O1EditFieldLabel = uilabel(app.Panel_2);
            app.O1EditFieldLabel.Text = 'O1';
            app.edt_o1 = uieditfield(app.Panel_2, 'numeric');
            app.edt_o1.Value = 5;
            app.O2EditFieldLabel = uilabel(app.Panel_2);
            app.O2EditFieldLabel.Text = 'O2';
            app.edt_o2 = uieditfield(app.Panel_2, 'numeric');
            app.edt_o2.Value = 20;
            app.O3EditFieldLabel = uilabel(app.Panel_2);
            app.O3EditFieldLabel.Text = 'O3';
            app.edt_o3 = uieditfield(app.Panel_2, 'numeric');
            app.edt_o3.Value = 80;
            app.chkbx_show_o1 = uicheckbox(app.Panel_2);
            app.chkbx_show_o1.ValueChangedFcn = createCallbackFcn(app, @chkbx_show_o1ValueChanged, true);
            app.chkbx_show_o1.Text = 'O1';
            app.chkbx_show_o1.Value = true;
            app.chkbx_show_o2 = uicheckbox(app.Panel_2);
            app.chkbx_show_o2.ValueChangedFcn = createCallbackFcn(app, @chkbx_show_o2ValueChanged, true);
            app.chkbx_show_o2.Text = 'O2';
            app.chkbx_show_o2.Value = true;
            app.chkbx_show_o3 = uicheckbox(app.Panel_2);
            app.chkbx_show_o3.ValueChangedFcn = createCallbackFcn(app, @chkbx_show_o3ValueChanged, true);
            app.chkbx_show_o3.Text = 'O3';
            app.chkbx_show_o3.Value = true;
            app.btn_save_spect_result = uibutton(app.SpectralanalysisTab, 'push');
            app.btn_save_spect_result.ButtonPushedFcn = createCallbackFcn(app, @btn_save_spect_resultButtonPushed, true);
            app.btn_save_spect_result.Text = 'save results';
            app.chkbx_windowing = uicheckbox(app.SpectralanalysisTab);
            app.chkbx_windowing.Text = 'hamming window';
            app.Panel_3 = uipanel(app.SpectralanalysisTab);
            app.IntervallLabel = uilabel(app.Panel_3);
            app.IntervallLabel.Text = 'Intervall';
            app.popup_int_spect = uidropdown(app.Panel_3);
            app.popup_int_spect.Items = {'full'};
            app.popup_int_spect.ValueChangedFcn = createCallbackFcn(app, @popup_int_spectValueChanged, true);
            app.popup_int_spect.Value = 'full';
            app.lbl_16 = uilabel(app.Panel_3);
            app.lbl_16.Text = 'Frequency scale limits [Hz]';
            app.minEditFieldLabel = uilabel(app.Panel_3);
            app.minEditFieldLabel.Text = 'min';
            app.edt_min_frq = uieditfield(app.Panel_3, 'numeric');
            app.edt_min_frq.ValueChangedFcn = createCallbackFcn(app, @edt_min_frqValueChanged, true);
            app.edt_min_frq.Value = 0.2;
            app.maxEditFieldLabel = uilabel(app.Panel_3);
            app.maxEditFieldLabel.Text = 'max';
            app.edt_max_frq = uieditfield(app.Panel_3, 'numeric');
            app.edt_max_frq.ValueChangedFcn = createCallbackFcn(app, @edt_max_frqValueChanged, true);
            app.edt_max_frq.Value = 2;
            app.ampEditFieldLabel = uilabel(app.Panel_3);
            app.ampEditFieldLabel.Text = 'amp';
            app.edt_amp = uieditfield(app.Panel_3, 'numeric');
            app.edt_amp.ValueChangedFcn = createCallbackFcn(app, @edt_ampValueChanged, true);
            app.edt_amp.Value = 0.1;
            app.lbl_ds1_o2 = uilabel(app.SpectralanalysisTab);
            app.lbl_ds1_o2.Text = 'Label23';
            app.lbl_ds1_o1 = uilabel(app.SpectralanalysisTab);
            app.lbl_ds1_o1.Text = 'Label23';
            app.lbl_ds1_o3 = uilabel(app.SpectralanalysisTab);
            app.lbl_ds1_o3.Text = 'Label23';
            app.lbl_ds2_o2 = uilabel(app.SpectralanalysisTab);
            app.lbl_ds2_o2.Text = 'Label23';
            app.lbl_ds2_o3 = uilabel(app.SpectralanalysisTab);
            app.lbl_ds2_o3.Text = 'Label23';
            app.lbl_ds2_o1 = uilabel(app.SpectralanalysisTab);
            app.lbl_ds2_o1.Text = 'Label23';
            app.lbl_ds2 = uilabel(app.SpectralanalysisTab);
            app.lbl_ds2.Text = 'Label23';
            app.lbl_ds1 = uilabel(app.SpectralanalysisTab);
            app.lbl_ds1.Text = 'Label23';
            app.PowerspectrumusingNOTparametricpwelchfunctionWHzLabel = uilabel(app.SpectralanalysisTab);
            app.PowerspectrumusingNOTparametricpwelchfunctionWHzLabel.Text = 'Power spectrum using NOT parametric pwelch function (W/Hz)';
            app.PowerspectrumusingNOTparametricperiodogramfunctionWHzLabel = uilabel(app.SpectralanalysisTab);
            app.PowerspectrumusingNOTparametricperiodogramfunctionWHzLabel.Text = 'Power spectrum using NOT parametric periodogram function (W/Hz)';
            app.PowerspectrumusingNOTparametricFFTfunctionWHzLabel = uilabel(app.SpectralanalysisTab);
            app.PowerspectrumusingNOTparametricFFTfunctionWHzLabel.Text = 'Power spectrum using NOT parametric FFT function (W/Hz)';
            app.PowerspectrumusingNOTparametricpmtmfunctionWHzLabel = uilabel(app.SpectralanalysisTab);
            app.PowerspectrumusingNOTparametricpmtmfunctionWHzLabel.Text = 'Power spectrumusing NOT parametric pmtm function (W/Hz)';
            app.PowerspectrumusingARparametricpyulearfunctionWHzLabel = uilabel(app.SpectralanalysisTab);
            app.PowerspectrumusingARparametricpyulearfunctionWHzLabel.Text = 'Power spectrum using AR parametric pyulear function (W/Hz)';
            app.PowerspectrumusingARparametricpburgfunctionWHzLabel = uilabel(app.SpectralanalysisTab);
            app.PowerspectrumusingARparametricpburgfunctionWHzLabel.Text = 'Power spectrum using AR parametric pburg function (W/Hz)';
            app.PowerspectrumusingARparametricpcovfunctionWHzLabel = uilabel(app.SpectralanalysisTab);
            app.PowerspectrumusingARparametricpcovfunctionWHzLabel.Text = 'Power spectrum using AR parametric pcov function (W/Hz)';
            app.PowerspectrumusingARparametricpmcovfunctionWHzLabel = uilabel(app.SpectralanalysisTab);
            app.PowerspectrumusingARparametricpmcovfunctionWHzLabel.Text = 'Power spectrum using AR parametric pmcov function (W/Hz)';
            app.HzLabel = uilabel(app.SpectralanalysisTab);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.SpectralanalysisTab);
            app.HzLabel_2.Text = 'Hz';
            app.LagdetectionTab = uitab(app.TabGroup);
            app.LagdetectionTab.Title = 'Lag detection ';
            app.LagdetectionTab.ButtonDownFcn = createCallbackFcn(app, @LagdetectionTabButtonDown, true);
            app.ax_lag_sig1 = uiaxes(app.LagdetectionTab);
            app.ax_lag_sig1.YTick = [];
            app.ax_lag_xcorr = uiaxes(app.LagdetectionTab);
            app.ax_lag_xcorr.YTick = [];
            app.ax_lag_xcorr.ButtonDownFcn = createCallbackFcn(app, @ax_lag_xcorrButtonDown, true);
            app.ax_lag_sig2 = uiaxes(app.LagdetectionTab);
            app.ax_lag_sig2.YTick = [];
            app.ax_lag_overlay = uiaxes(app.LagdetectionTab);
            zlabel(app.ax_lag_overlay, 'Z')
            app.ax_lag_overlay.YTick = [];
            app.Signal1Label = uilabel(app.LagdetectionTab);
            app.Signal1Label.Text = 'Signal 1';
            app.popup_lag_sig1 = uidropdown(app.LagdetectionTab);
            app.popup_lag_sig1.ValueChangedFcn = createCallbackFcn(app, @popup_lag_sig1ValueChanged, true);
            app.Signal2Label = uilabel(app.LagdetectionTab);
            app.Signal2Label.Text = 'Signal 2';
            app.popup_lag_sig2 = uidropdown(app.LagdetectionTab);
            app.popup_lag_sig2.ValueChangedFcn = createCallbackFcn(app, @popup_lag_sig2ValueChanged, true);
            app.sldr_lag_end = uislider(app.LagdetectionTab);
            app.sldr_lag_end.MajorTicks = [];
            app.sldr_lag_end.ValueChangedFcn = createCallbackFcn(app, @sldr_lag_endValueChanged, true);
            app.sldr_lag_end.ValueChangingFcn = createCallbackFcn(app, @sldr_lag_endValueChanging, true);
            app.sldr_lag_end.MinorTicks = [];
            app.sldr_lag_start = uislider(app.LagdetectionTab);
            app.sldr_lag_start.MajorTicks = [];
            app.sldr_lag_start.ValueChangedFcn = createCallbackFcn(app, @sldr_lag_startValueChanged, true);
            app.sldr_lag_start.ValueChangingFcn = createCallbackFcn(app, @sldr_lag_startValueChanging, true);
            app.sldr_lag_start.MinorTicks = [];
            app.btn_lag_calc = uibutton(app.LagdetectionTab, 'push');
            app.btn_lag_calc.ButtonPushedFcn = createCallbackFcn(app, @btn_lag_calcButtonPushed, true);
            app.btn_lag_calc.Text = 'calculate X-corr';
            app.lbl_lag_xcorr = uilabel(app.LagdetectionTab);
            app.lbl_lag_xcorr.Text = 'X-Corr:';
            app.lbl_lag_lag = uilabel(app.LagdetectionTab);
            app.lbl_lag_lag.Text = 'Lag: ';
            app.btn_lag_save = uibutton(app.LagdetectionTab, 'push');
            app.btn_lag_save.ButtonPushedFcn = createCallbackFcn(app, @btn_lag_saveButtonPushed, true);
            app.btn_lag_save.Text = 'save results';
            app.IntervallLabel_9 = uilabel(app.LagdetectionTab);
            app.IntervallLabel_9.Text = 'Intervall';
            app.popup_lag_int = uidropdown(app.LagdetectionTab);
            app.popup_lag_int.ValueChangedFcn = createCallbackFcn(app, @popup_lag_intValueChanged, true);
            app.movingaverageEditFieldLabel = uilabel(app.LagdetectionTab);
            app.movingaverageEditFieldLabel.Text = 'moving average';
            app.edt_lag_ma_sig1 = uieditfield(app.LagdetectionTab, 'numeric');
            app.edt_lag_ma_sig1.ValueChangedFcn = createCallbackFcn(app, @edt_lag_ma_sig1ValueChanged, true);
            app.edt_lag_ma_sig1.Value = 1;
            app.chkbx_lag_ma_sig1 = uicheckbox(app.LagdetectionTab);
            app.chkbx_lag_ma_sig1.ValueChangedFcn = createCallbackFcn(app, @chkbx_lag_ma_sig1ValueChanged, true);
            app.chkbx_lag_ma_sig1.Text = '';
            app.movingaverageEditFieldLabel_2 = uilabel(app.LagdetectionTab);
            app.movingaverageEditFieldLabel_2.Text = 'moving average';
            app.edt_lag_ma_sig2 = uieditfield(app.LagdetectionTab, 'numeric');
            app.edt_lag_ma_sig2.ValueChangedFcn = createCallbackFcn(app, @edt_lag_ma_sig2ValueChanged, true);
            app.edt_lag_ma_sig2.Value = 1;
            app.chkbx_lag_ma_sig2 = uicheckbox(app.LagdetectionTab);
            app.chkbx_lag_ma_sig2.ValueChangedFcn = createCallbackFcn(app, @chkbx_lag_ma_sig2ValueChanged, true);
            app.chkbx_lag_ma_sig2.Text = '';
            app.chkbx_lock = uicheckbox(app.LagdetectionTab);
            app.chkbx_lock.ValueChangedFcn = createCallbackFcn(app, @chkbx_lockValueChanged, true);
            app.chkbx_lock.Text = 'lock to peaks';
            app.lstbx_save = uilistbox(app.LagdetectionTab);
            app.lstbx_save.Items = {''};
            app.lstbx_save.ValueChangedFcn = createCallbackFcn(app, @lstbx_saveValueChanged, true);
            app.lstbx_save.Value = {};
            app.addtolistButton = uibutton(app.LagdetectionTab, 'push');
            app.addtolistButton.ButtonPushedFcn = createCallbackFcn(app, @addtolistButtonPushed, true);
            app.addtolistButton.Text = 'add to list';
            app.IntervallLabel_10 = uilabel(app.LagdetectionTab);
            app.IntervallLabel_10.Text = 'Background sig';
            app.popup_lag_bgsig = uidropdown(app.LagdetectionTab);
            app.popup_lag_bgsig.ValueChangedFcn = createCallbackFcn(app, @popup_lag_bgsigValueChanged, true);
            app.chkbx_lag_wavelet = uicheckbox(app.LagdetectionTab);
            app.chkbx_lag_wavelet.Text = 'Wavelet approach';
            app.extractsignalsButton = uibutton(app.LagdetectionTab, 'push');
            app.extractsignalsButton.ButtonPushedFcn = createCallbackFcn(app, @extractsignalsButtonPushed, true);
            app.extractsignalsButton.Text = 'extract signals';
            app.WaveletTab = uitab(app.TabGroup);
            app.WaveletTab.Title = 'Wavelet';
            app.WaveletTab.ButtonDownFcn = createCallbackFcn(app, @WaveletTabButtonDown, true);
            app.ax_wave_signals = uiaxes(app.WaveletTab);
            app.ax_xwt1 = uiaxes(app.WaveletTab);
            zlabel(app.ax_xwt1, 'Z')
            app.ax_xwt2 = uiaxes(app.WaveletTab);
            app.ax_wavelet_sig2 = uiaxes(app.WaveletTab);
            app.ax_xwt3 = uiaxes(app.WaveletTab);
            app.ax_wavelet_sig1 = uiaxes(app.WaveletTab);
            app.chkbx_detrend = uicheckbox(app.WaveletTab);
            app.chkbx_detrend.Text = 'Detrend';
            app.btn_wavelet = uibutton(app.WaveletTab, 'push');
            app.btn_wavelet.ButtonPushedFcn = createCallbackFcn(app, @btn_waveletButtonPushed, true);
            app.btn_wavelet.Text = 'Wavelet';
            app.PeriodlimitssPanel = uipanel(app.WaveletTab);
            app.PeriodlimitssPanel.Title = 'Period limits [s]';
            app.minEditField_2Label = uilabel(app.PeriodlimitssPanel);
            app.minEditField_2Label.Text = 'min';
            app.edt_min_period = uieditfield(app.PeriodlimitssPanel, 'numeric');
            app.edt_min_period.Value = 0.1;
            app.maxEditField_2Label = uilabel(app.PeriodlimitssPanel);
            app.maxEditField_2Label.Text = 'max';
            app.edt_max_period = uieditfield(app.PeriodlimitssPanel, 'numeric');
            app.edt_max_period.Value = 256;
            app.btn_save_wavelet_results = uibutton(app.WaveletTab, 'push');
            app.btn_save_wavelet_results.ButtonPushedFcn = createCallbackFcn(app, @btn_save_wavelet_resultsButtonPushed, true);
            app.btn_save_wavelet_results.Text = 'save results';
            app.SignalsforCrossWaveletAnalysisPanel = uipanel(app.WaveletTab);
            app.SignalsforCrossWaveletAnalysisPanel.Title = 'Signals for Cross Wavelet Analysis';
            app.Signal1DropDownLabel = uilabel(app.SignalsforCrossWaveletAnalysisPanel);
            app.Signal1DropDownLabel.Text = 'Signal 1';
            app.popup_signal1 = uidropdown(app.SignalsforCrossWaveletAnalysisPanel);
            app.popup_signal1.ValueChangedFcn = createCallbackFcn(app, @popup_signal1ValueChanged, true);
            app.Signal2DropDownLabel = uilabel(app.SignalsforCrossWaveletAnalysisPanel);
            app.Signal2DropDownLabel.Text = 'Signal 2';
            app.popup_signal2 = uidropdown(app.SignalsforCrossWaveletAnalysisPanel);
            app.popup_signal2.ValueChangedFcn = createCallbackFcn(app, @popup_signal2ValueChanged, true);
            app.TimeLagssPanel = uipanel(app.WaveletTab);
            app.TimeLagssPanel.Title = 'Time Lags [s]';
            app.Lag1EditFieldLabel = uilabel(app.TimeLagssPanel);
            app.Lag1EditFieldLabel.Text = 'Lag 1';
            app.edt_lag1 = uieditfield(app.TimeLagssPanel, 'numeric');
            app.edt_lag1.Value = 1;
            app.Lag2EditField_2Label = uilabel(app.TimeLagssPanel);
            app.Lag2EditField_2Label.Text = 'Lag 2';
            app.edt_lag2 = uieditfield(app.TimeLagssPanel, 'numeric');
            app.edt_lag2.Value = 5;
            app.Lag3EditField_2Label = uilabel(app.TimeLagssPanel);
            app.Lag3EditField_2Label.Text = 'Lag 3';
            app.edt_lag3 = uieditfield(app.TimeLagssPanel, 'numeric');
            app.edt_lag3.Value = 9;
            app.IntervallLabel_2 = uilabel(app.WaveletTab);
            app.IntervallLabel_2.Text = 'Intervall';
            app.popup_int_wavelet = uidropdown(app.WaveletTab);
            app.popup_int_wavelet.Items = {'full'};
            app.popup_int_wavelet.ValueChangedFcn = createCallbackFcn(app, @popup_int_waveletValueChanged, true);
            app.popup_int_wavelet.Value = 'full';
            app.lbl_signal1 = uilabel(app.WaveletTab);
            app.lbl_signal1.Text = 'Label4';
            app.lbl_wave1 = uilabel(app.WaveletTab);
            app.lbl_wave1.Text = '+';
            app.lbl_signal2 = uilabel(app.WaveletTab);
            app.lbl_signal2.Text = 'Label4';
            app.timesLabel = uilabel(app.WaveletTab);
            app.timesLabel.Text = 'time [s]';
            app.lbl_wavelet_sig1_ttl = uilabel(app.WaveletTab);
            app.lbl_wavelet_sig1_ttl.Text = 'Label6';
            app.timesLabel_2 = uilabel(app.WaveletTab);
            app.timesLabel_2.Text = 'time [s]';
            app.lbl_wavelet_sig2_ttl = uilabel(app.WaveletTab);
            app.lbl_wavelet_sig2_ttl.Text = 'Label8';
            app.timesLabel_3 = uilabel(app.WaveletTab);
            app.timesLabel_3.Text = 'time [s]';
            app.lbl_wavelet_xwt1_ttl = uilabel(app.WaveletTab);
            app.lbl_wavelet_xwt1_ttl.Text = 'Label6';
            app.lbl_wavelet_xwt2_ttl = uilabel(app.WaveletTab);
            app.lbl_wavelet_xwt2_ttl.Text = 'Label6';
            app.lbl_wavelet_xwt3_ttl = uilabel(app.WaveletTab);
            app.lbl_wavelet_xwt3_ttl.Text = 'Label6';
            app.timesLabel_4 = uilabel(app.WaveletTab);
            app.timesLabel_4.Text = 'time [s]';
            app.timesLabel_5 = uilabel(app.WaveletTab);
            app.timesLabel_5.Text = 'time [s]';
            app.timesLabel_6 = uilabel(app.WaveletTab);
            app.timesLabel_6.Text = 'time [s]';
            app.CorrelationTab = uitab(app.TabGroup);
            app.CorrelationTab.Title = 'Correlation';
            app.CorrelationTab.ButtonDownFcn = createCallbackFcn(app, @CorrelationTabButtonDown, true);
            app.ax_signals_corre = uiaxes(app.CorrelationTab);
            app.ax_signal1_corre = uiaxes(app.CorrelationTab);
            app.ax_signal2_corre = uiaxes(app.CorrelationTab);
            app.ax_corre_1_1_1 = uiaxes(app.CorrelationTab);
            app.ax_corre_1_1_1.XTick = [];
            app.ax_corre_1_2_2 = uiaxes(app.CorrelationTab);
            app.ax_corre_1_2_2.YTick = [];
            app.ax_corre_1_1_2 = uiaxes(app.CorrelationTab);
            app.ax_corre_1_1_2.XTick = [];
            app.ax_corre_1_1_2.YTick = [];
            app.ax_corre_1_2_1 = uiaxes(app.CorrelationTab);
            app.ax_corre_2_1_1 = uiaxes(app.CorrelationTab);
            app.ax_corre_2_1_1.XTick = [];
            app.ax_corre_2_2_2 = uiaxes(app.CorrelationTab);
            app.ax_corre_2_2_2.YTick = [];
            app.ax_corre_2_1_2 = uiaxes(app.CorrelationTab);
            app.ax_corre_2_1_2.XTick = [];
            app.ax_corre_2_1_2.YTick = [];
            app.ax_corre_2_2_1 = uiaxes(app.CorrelationTab);
            app.ax_corre_3_1_1 = uiaxes(app.CorrelationTab);
            app.ax_corre_3_1_1.XTick = [];
            app.ax_corre_3_2_2 = uiaxes(app.CorrelationTab);
            app.ax_corre_3_2_2.YTick = [];
            app.ax_corre_3_1_2 = uiaxes(app.CorrelationTab);
            app.ax_corre_3_1_2.XTick = [];
            app.ax_corre_3_1_2.YTick = [];
            app.ax_corre_3_2_1 = uiaxes(app.CorrelationTab);
            app.chkbx_rem_outliers = uicheckbox(app.CorrelationTab);
            app.chkbx_rem_outliers.Visible = 'off';
            app.chkbx_rem_outliers.Text = 'remove outliers';
            app.btn_correlation = uibutton(app.CorrelationTab, 'push');
            app.btn_correlation.ButtonPushedFcn = createCallbackFcn(app, @btn_correlationButtonPushed, true);
            app.btn_correlation.Text = 'Correlation';
            app.btn_save_corre_results = uibutton(app.CorrelationTab, 'push');
            app.btn_save_corre_results.ButtonPushedFcn = createCallbackFcn(app, @btn_save_corre_resultsButtonPushed, true);
            app.btn_save_corre_results.Text = 'save results';
            app.TimeLagssPanel_2 = uipanel(app.CorrelationTab);
            app.TimeLagssPanel_2.Title = 'Time Lags [s]';
            app.Lag1EditField_4Label = uilabel(app.TimeLagssPanel_2);
            app.Lag1EditField_4Label.Text = 'Lag 1';
            app.edt_corre_lag1 = uieditfield(app.TimeLagssPanel_2, 'numeric');
            app.Lag2EditFieldLabel = uilabel(app.TimeLagssPanel_2);
            app.Lag2EditFieldLabel.Text = 'Lag 2';
            app.edt_corre_lag2 = uieditfield(app.TimeLagssPanel_2, 'numeric');
            app.edt_corre_lag2.Value = 5;
            app.Lag3EditFieldLabel = uilabel(app.TimeLagssPanel_2);
            app.Lag3EditFieldLabel.Text = 'Lag 3';
            app.edt_corre_lag3 = uieditfield(app.TimeLagssPanel_2, 'numeric');
            app.edt_corre_lag3.Value = 9;
            app.SamplingfrequencyforcorrelationPanel = uipanel(app.CorrelationTab);
            app.SamplingfrequencyforcorrelationPanel.Title = 'Sampling frequency for correlation';
            app.FsLabel = uilabel(app.SamplingfrequencyforcorrelationPanel);
            app.FsLabel.Text = 'Fs [Hz]';
            app.edt_downsampling = uieditfield(app.SamplingfrequencyforcorrelationPanel, 'numeric');
            app.edt_downsampling.Value = 100;
            app.MovingAveragePanel = uipanel(app.CorrelationTab);
            app.MovingAveragePanel.Title = 'Moving Average';
            app.chkbx_movmean = uicheckbox(app.MovingAveragePanel);
            app.chkbx_movmean.Text = '';
            app.timesLabel_11 = uilabel(app.MovingAveragePanel);
            app.timesLabel_11.Text = 'time [s]';
            app.edt_movmean = uieditfield(app.MovingAveragePanel, 'numeric');
            app.edt_movmean.Value = 3;
            app.SignalsforlinearcrosscorrelationPanel = uipanel(app.CorrelationTab);
            app.SignalsforlinearcrosscorrelationPanel.Title = 'Signals for linear cross correlation';
            app.Signal1DropDown_2Label = uilabel(app.SignalsforlinearcrosscorrelationPanel);
            app.Signal1DropDown_2Label.Text = 'Signal 1';
            app.popup_coorelation_signal1 = uidropdown(app.SignalsforlinearcrosscorrelationPanel);
            app.popup_coorelation_signal1.ValueChangedFcn = createCallbackFcn(app, @popup_coorelation_signal1ValueChanged, true);
            app.Signal2DropDown_2Label = uilabel(app.SignalsforlinearcrosscorrelationPanel);
            app.Signal2DropDown_2Label.Text = 'Signal 2';
            app.popup_coorelation_signal2 = uidropdown(app.SignalsforlinearcrosscorrelationPanel);
            app.popup_coorelation_signal2.ValueChangedFcn = createCallbackFcn(app, @popup_coorelation_signal2ValueChanged, true);
            app.lbl_fs_sig1 = uilabel(app.SignalsforlinearcrosscorrelationPanel);
            app.lbl_fs_sig1.Text = 'Label25';
            app.lbl_fs_sig2 = uilabel(app.SignalsforlinearcrosscorrelationPanel);
            app.lbl_fs_sig2.Text = 'Label25';
            app.lbl_signals_corre = uilabel(app.CorrelationTab);
            app.lbl_signals_corre.Text = 'Label9';
            app.lbl_signal1_corre = uilabel(app.CorrelationTab);
            app.lbl_signal1_corre.Text = 'Label11';
            app.lbl_signal2_corre = uilabel(app.CorrelationTab);
            app.lbl_signal2_corre.Text = 'Label13';
            app.timesLabel_9 = uilabel(app.CorrelationTab);
            app.timesLabel_9.Text = 'time [s]';
            app.lbl_xcorre1 = uilabel(app.CorrelationTab);
            app.lbl_xcorre1.Text = 'Lag 1';
            app.lbl_xcorre2 = uilabel(app.CorrelationTab);
            app.lbl_xcorre2.Text = 'Lag 2';
            app.lbl_xcorre3 = uilabel(app.CorrelationTab);
            app.lbl_xcorre3.Text = 'Lag 3';
            app.IntervallLabel_4 = uilabel(app.CorrelationTab);
            app.IntervallLabel_4.Text = 'Intervall';
            app.popup_int_corre = uidropdown(app.CorrelationTab);
            app.popup_int_corre.Items = {'full'};
            app.popup_int_corre.ValueChangedFcn = createCallbackFcn(app, @popup_int_correValueChanged, true);
            app.popup_int_corre.Value = 'full';
            app.lbl_signals_corre2 = uilabel(app.CorrelationTab);
            app.lbl_signals_corre2.Text = 'Label9';
            app.lbl_signals_corre1 = uilabel(app.CorrelationTab);
            app.lbl_signals_corre1.Text = 'Label9';
            app.lbl_corre_1_1_2 = uilabel(app.CorrelationTab);
            app.lbl_corre_1_1_2.Text = '';
            app.lbl_corre_1_2_1 = uilabel(app.CorrelationTab);
            app.lbl_corre_1_2_1.Text = '';
            app.lbl_corre_2_2_1 = uilabel(app.CorrelationTab);
            app.lbl_corre_2_2_1.Text = '';
            app.lbl_corre_2_1_2 = uilabel(app.CorrelationTab);
            app.lbl_corre_2_1_2.Text = '';
            app.lbl_corre_3_1_2 = uilabel(app.CorrelationTab);
            app.lbl_corre_3_1_2.Text = '';
            app.lbl_corre_3_2_1 = uilabel(app.CorrelationTab);
            app.lbl_corre_3_2_1.Text = '';
            app.StepwiseregressionTab = uitab(app.TabGroup);
            app.StepwiseregressionTab.Title = 'Stepwise regression';
            app.StepwiseregressionTab.ButtonDownFcn = createCallbackFcn(app, @StepwiseregressionTabButtonDown, true);
            app.ax_regression1 = uiaxes(app.StepwiseregressionTab);
            app.ax_regression2 = uiaxes(app.StepwiseregressionTab);
            app.btn_regression = uibutton(app.StepwiseregressionTab, 'push');
            app.btn_regression.ButtonPushedFcn = createCallbackFcn(app, @btn_regressionButtonPushed, true);
            app.btn_regression.Text = 'Stepwise Regression';
            app.DownSamplingPanel_2 = uipanel(app.StepwiseregressionTab);
            app.DownSamplingPanel_2.Title = 'DownSampling';
            app.DSEditField_2Label = uilabel(app.DownSamplingPanel_2);
            app.DSEditField_2Label.Text = 'DS';
            app.edt_reg_downsampling = uieditfield(app.DownSamplingPanel_2, 'numeric');
            app.edt_reg_downsampling.Value = 50;
            app.MovingAveragePanel_2 = uipanel(app.StepwiseregressionTab);
            app.MovingAveragePanel_2.Title = 'Moving Average';
            app.timesEditField_2Label = uilabel(app.MovingAveragePanel_2);
            app.timesEditField_2Label.Text = 'time [s]';
            app.edt_reg_mov = uieditfield(app.MovingAveragePanel_2, 'numeric');
            app.edt_reg_mov.Value = 3;
            app.chkbx_reg_mov_average = uicheckbox(app.MovingAveragePanel_2);
            app.chkbx_reg_mov_average.Text = '';
            app.RegressorsPanel = uipanel(app.StepwiseregressionTab);
            app.RegressorsPanel.Title = 'Regressors';
            app.RegressorDropDownLabel = uilabel(app.RegressorsPanel);
            app.RegressorDropDownLabel.Text = 'Regressor';
            app.popup_regressor = uidropdown(app.RegressorsPanel);
            app.LagtimesEditFieldLabel = uilabel(app.RegressorsPanel);
            app.LagtimesEditFieldLabel.Text = 'Lag time  [s]';
            app.edt_lagtime = uieditfield(app.RegressorsPanel, 'numeric');
            app.edt_lagtime.Limits = [0 Inf];
            app.btn_add_regressor = uibutton(app.RegressorsPanel, 'push');
            app.btn_add_regressor.ButtonPushedFcn = createCallbackFcn(app, @btn_add_regressorButtonPushed, true);
            app.btn_add_regressor.Text = 'add';
            app.btn_remove_regressor = uibutton(app.RegressorsPanel, 'push');
            app.btn_remove_regressor.ButtonPushedFcn = createCallbackFcn(app, @btn_remove_regressorButtonPushed, true);
            app.btn_remove_regressor.Text = 'rem. sel';
            app.tbl_regressor = uitable(app.RegressorsPanel);
            app.tbl_regressor.ColumnName = {'Regressor'; 'Lag time [s]'};
            app.tbl_regressor.RowName = {};
            app.tbl_regressor.SelectionType = 'row';
            app.btn_save_reg_results = uibutton(app.StepwiseregressionTab, 'push');
            app.btn_save_reg_results.ButtonPushedFcn = createCallbackFcn(app, @btn_save_reg_resultsButtonPushed, true);
            app.btn_save_reg_results.Text = 'save results';
            app.SignaltobepredictedPanel = uipanel(app.StepwiseregressionTab);
            app.SignaltobepredictedPanel.Title = 'Signal to be predicted';
            app.DropDownLabel = uilabel(app.SignaltobepredictedPanel);
            app.DropDownLabel.Text = '';
            app.popup_signal_to_predict = uidropdown(app.SignaltobepredictedPanel);
            app.IntervallLabel_5 = uilabel(app.StepwiseregressionTab);
            app.IntervallLabel_5.Text = 'Intervall';
            app.popup_int_stepwise = uidropdown(app.StepwiseregressionTab);
            app.popup_int_stepwise.Items = {'full'};
            app.popup_int_stepwise.ValueChangedFcn = createCallbackFcn(app, @popup_int_stepwiseValueChanged, true);
            app.popup_int_stepwise.Value = 'full';
            app.AnnotateTab = uitab(app.TabGroup);
            app.AnnotateTab.Title = 'Annotate';
            app.AnnotateTab.ButtonDownFcn = createCallbackFcn(app, @AnnotateTabButtonDown, true);
            app.ax_annotate_4_1 = uiaxes(app.AnnotateTab);
            app.ax_annotate_4_1.Tag = 'ax_annotate_4_1';
            app.ax_annotate_1_1 = uiaxes(app.AnnotateTab);
            app.ax_annotate_1_1.Tag = 'ax_annotate_1_1';
            app.ax_annotate_2_1 = uiaxes(app.AnnotateTab);
            app.ax_annotate_2_1.Tag = 'ax_annotate_2_1';
            app.ax_annotate_3_1 = uiaxes(app.AnnotateTab);
            app.ax_annotate_3_1.Tag = 'ax_annotate_3_1';
            app.ax_annotate_1_2 = uiaxes(app.AnnotateTab);
            app.ax_annotate_2_2 = uiaxes(app.AnnotateTab);
            app.ax_annotate_3_2 = uiaxes(app.AnnotateTab);
            app.ax_annotate_4_2 = uiaxes(app.AnnotateTab);
            app.FrequencyscalelimitsHzPanel = uipanel(app.AnnotateTab);
            app.FrequencyscalelimitsHzPanel.Title = 'Frequency scale limits [Hz]';
            app.minEditField_3Label = uilabel(app.FrequencyscalelimitsHzPanel);
            app.minEditField_3Label.Text = 'min';
            app.edt_min_freq = uieditfield(app.FrequencyscalelimitsHzPanel, 'numeric');
            app.edt_min_freq.ValueChangedFcn = createCallbackFcn(app, @edt_min_freqValueChanged, true);
            app.edt_min_freq.Value = 0.05;
            app.maxEditField_3Label = uilabel(app.FrequencyscalelimitsHzPanel);
            app.maxEditField_3Label.Text = 'max';
            app.edt_max_freq = uieditfield(app.FrequencyscalelimitsHzPanel, 'numeric');
            app.edt_max_freq.ValueChangedFcn = createCallbackFcn(app, @edt_max_freqValueChanged, true);
            app.edt_max_freq.Value = 1.9;
            app.btn_save_annotate_results = uibutton(app.AnnotateTab, 'push');
            app.btn_save_annotate_results.ButtonPushedFcn = createCallbackFcn(app, @btn_save_annotate_resultsButtonPushed, true);
            app.btn_save_annotate_results.Text = 'save Results';
            app.IntervallLabel_6 = uilabel(app.AnnotateTab);
            app.IntervallLabel_6.Text = 'Intervall';
            app.popup_int_annotate = uidropdown(app.AnnotateTab);
            app.popup_int_annotate.Items = {'full'};
            app.popup_int_annotate.ValueChangedFcn = createCallbackFcn(app, @popup_int_annotateValueChanged, true);
            app.popup_int_annotate.Value = 'full';
            app.lbl_annotate_1_1 = uilabel(app.AnnotateTab);
            app.lbl_annotate_1_1.Text = 'Label19';
            app.lbl_annotate_2_1 = uilabel(app.AnnotateTab);
            app.lbl_annotate_2_1.Text = 'Label20';
            app.lbl_annotate_4_1 = uilabel(app.AnnotateTab);
            app.lbl_annotate_4_1.Text = 'Label21';
            app.lbl_annotate_3_1 = uilabel(app.AnnotateTab);
            app.lbl_annotate_3_1.Text = 'Label22';
            app.timesLabel_10 = uilabel(app.AnnotateTab);
            app.timesLabel_10.Text = 'time [s]';
            app.TimelimitssPanel = uipanel(app.AnnotateTab);
            app.TimelimitssPanel.Title = 'Time limits [s]';
            app.minEditField_3Label_2 = uilabel(app.TimelimitssPanel);
            app.minEditField_3Label_2.Text = 'min';
            app.edt_min_time = uieditfield(app.TimelimitssPanel, 'numeric');
            app.edt_min_time.ValueChangedFcn = createCallbackFcn(app, @edt_min_timeValueChanged, true);
            app.edt_min_time.Value = 0.05;
            app.maxEditField_3Label_2 = uilabel(app.TimelimitssPanel);
            app.maxEditField_3Label_2.Text = 'max';
            app.edt_max_time = uieditfield(app.TimelimitssPanel, 'numeric');
            app.edt_max_time.ValueChangedFcn = createCallbackFcn(app, @edt_max_timeValueChanged, true);
            app.edt_max_time.Value = 0.25;
            app.TransductionanalysisTab = uitab(app.TabGroup);
            app.TransductionanalysisTab.Title = 'Transduction analysis';
            app.TransductionanalysisTab.ButtonDownFcn = createCallbackFcn(app, @TransductionanalysisTabButtonDown, true);
            app.ax_trans_2_1 = uiaxes(app.TransductionanalysisTab);
            app.ax_trans_1_1 = uiaxes(app.TransductionanalysisTab);
            app.ax_trans_1_2 = uiaxes(app.TransductionanalysisTab);
            app.ax_trans_2_2 = uiaxes(app.TransductionanalysisTab);
            app.ax_trans_1_3 = uiaxes(app.TransductionanalysisTab);
            app.ax_trans_2_3 = uiaxes(app.TransductionanalysisTab);
            app.ax_spike_shape = uiaxes(app.TransductionanalysisTab);
            app.btn_transduction = uibutton(app.TransductionanalysisTab, 'push');
            app.btn_transduction.ButtonPushedFcn = createCallbackFcn(app, @btn_transductionButtonPushed, true);
            app.btn_transduction.Text = 'Transduction analysis';
            app.btn_save_trans_results = uibutton(app.TransductionanalysisTab, 'push');
            app.btn_save_trans_results.ButtonPushedFcn = createCallbackFcn(app, @btn_save_trans_resultsButtonPushed, true);
            app.btn_save_trans_results.Text = 'save results';
            app.IntervallLabel_7 = uilabel(app.TransductionanalysisTab);
            app.IntervallLabel_7.Text = 'Intervall';
            app.popup_int_transduction = uidropdown(app.TransductionanalysisTab);
            app.popup_int_transduction.Items = {'full'};
            app.popup_int_transduction.ValueChangedFcn = createCallbackFcn(app, @popup_int_transductionValueChanged, true);
            app.popup_int_transduction.Value = 'full';
            app.lbl_trans_1_1 = uilabel(app.TransductionanalysisTab);
            app.lbl_trans_1_1.Text = 'Pearson Firing Rate - Blood Pressure';
            app.lbl_trans_1_2 = uilabel(app.TransductionanalysisTab);
            app.lbl_trans_1_2.Text = 'Pearson First Spike Latency - Blood pressure';
            app.lbl_trans_1_3 = uilabel(app.TransductionanalysisTab);
            app.lbl_trans_1_3.Text = 'Pearson First Spike PHASE - BP';
            app.lbl_trans_shape = uilabel(app.TransductionanalysisTab);
            app.lbl_trans_shape.Text = 'Spikes Sorted Shapes';
            app.lbl_trans_2_1 = uilabel(app.TransductionanalysisTab);
            app.lbl_trans_2_1.Text = 'Pearson Firing Rate - R-R Intervall';
            app.lbl_trans_2_2 = uilabel(app.TransductionanalysisTab);
            app.lbl_trans_2_2.Text = 'Pearson First Spike Latency - R-R Interval';
            app.lbl_trans_2_3 = uilabel(app.TransductionanalysisTab);
            app.lbl_trans_2_3.Text = 'Pearson First Spike PHASE - RR';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.btn_create_report = uibutton(app.ReportTab, 'push');
            app.btn_create_report.ButtonPushedFcn = createCallbackFcn(app, @btn_create_reportPushed, true);
            app.btn_create_report.Text = 'Create report';
            app.recordingsintervalstobereportedLabel = uilabel(app.ReportTab);
            app.recordingsintervalstobereportedLabel.Text = 'Recordings / intervals to be reported';
            app.lstbx_report = uilistbox(app.ReportTab);
            app.lstbx_report.Items = {'none'};
            app.lstbx_report.Multiselect = 'on';
            app.lstbx_report.Value = {'none'};
            app.btn_select_folder = uibutton(app.ReportTab, 'push');
            app.btn_select_folder.ButtonPushedFcn = createCallbackFcn(app, @btn_select_folderButtonPushed, true);
            app.btn_select_folder.Text = 'select folder';
            app.Panel_4 = uipanel(app.ReportTab);
            app.btn_combine = uibutton(app.Panel_4, 'push');
            app.btn_combine.ButtonPushedFcn = createCallbackFcn(app, @btn_combineButtonPushed, true);
            app.btn_combine.Text = 'Combine datafiles';

            app.recordingstobecombinedLabel = uilabel(app.Panel_4);
            app.recordingstobecombinedLabel.Text = 'Recordings to be combined';
            app.lstbx_rec_combine = uilistbox(app.Panel_4);
            app.lstbx_rec_combine.Items = {'none'};
            app.lstbx_rec_combine.Multiselect = 'on';
            app.lstbx_rec_combine.Value = {'none'};
            app.btn_select_templ_dir = uibutton(app.Panel_4, 'push');
            app.btn_select_templ_dir.ButtonPushedFcn = createCallbackFcn(app, @btn_select_templ_dirButtonPushed, true);
            app.btn_select_templ_dir.Text = 'select master folder / interval template ';
            app.tbl_groups = uitable(app.Panel_4);
            app.tbl_groups.ColumnName = {'Intervals'; 'Test'; 'Signal'};
            app.tbl_groups.RowName = {};
            app.tbl_groups.SelectionType = 'row';
            app.SelectstatisticgroupsLabel = uilabel(app.Panel_4);
            app.SelectstatisticgroupsLabel.Text = 'Select statistic groups';
            app.btn_remove = uibutton(app.Panel_4, 'push');
            app.btn_remove.ButtonPushedFcn = createCallbackFcn(app, @btn_removeButtonPushed, true);
            app.btn_remove.Text = 'remove selected';
            app.btn_add = uibutton(app.Panel_4, 'push');
            app.btn_add.ButtonPushedFcn = createCallbackFcn(app, @btn_addButtonPushed, true);
            app.btn_add.Text = 'add group';
            app.AvailableintervalsListBoxLabel = uilabel(app.Panel_4);
            app.AvailableintervalsListBoxLabel.Text = 'Available  intervals';
            app.lstbx_intervals = uilistbox(app.Panel_4);
            app.lstbx_intervals.Items = {'none'};
            app.lstbx_intervals.Multiselect = 'on';
            app.lstbx_intervals.ValueChangedFcn = createCallbackFcn(app, @lstbx_intervalsValueChanged, true);
            app.lstbx_intervals.Value = {'none'};
            app.StatisticaltestDropDownLabel = uilabel(app.Panel_4);
            app.StatisticaltestDropDownLabel.Text = 'Statistical test';
            app.popup_test = uidropdown(app.Panel_4);
            app.PosthoctestDropDownLabel = uilabel(app.Panel_4);
            app.PosthoctestDropDownLabel.Text = 'Post hoc test';
            app.popup_posthoc = uidropdown(app.Panel_4);
            app.AvailablesignalsLabel = uilabel(app.Panel_4);
            app.AvailablesignalsLabel.Text = 'Available signals';
            app.lstbx_signals = uilistbox(app.Panel_4);
            app.lstbx_signals.Items = {'none'};
            app.lstbx_signals.Multiselect = 'on';
            app.lstbx_signals.Value = {'none'};
            app.filenameEditFieldLabel = uilabel(app.Panel_4);
            app.filenameEditFieldLabel.Text = 'filename';
            app.edt_filename = uieditfield(app.Panel_4, 'text');
            app.AcknowledgementsTab = uitab(app.TabGroup);
            app.AcknowledgementsTab.Title = 'Acknowledgements';
            app.TextArea = uitextarea(app.AcknowledgementsTab);
            app.TextArea.Value = {'This project is supported by the Tuscany Region through the Tuscany Network for BioElectronic Approaches in Medicine: AI-based predictive algorithms for fine-tuning of electroceutical treatments in neurological, cardiovascular and endocrinological diseases (TUNE-BEAM), cod. H14I20000300002'};
            app.TextArea_2 = uitextarea(app.AcknowledgementsTab);
            app.TextArea_2.Value = {'Project funded under the National Recovery and Resilience Plan (NRRP), Mission 4 Component 2 Investment 1.3 - Call for tender No. 341 of 15/03/2022 of Italian Ministry of University and Research funded by the European Union – NextGenerationEU; Project code PE0000006, Concession Decree No. 1553 of 11/10/2022 adopted by the Italian Ministry of University and Research, CUP D93C22000930002, “A multiscale integrated approach to the study of the nervous system in health and disease” (MNESYS)'};
            app.TextArea_3 = uitextarea(app.AcknowledgementsTab);
            app.TextArea_3.Value = {'This project is supported by PNRR THE project, Toscany Health Ecosystem, cod. 00000017, Spoke 8 Biotechnologies and Imaging in Neuroscience'};
            app.TextArea_4 = uitextarea(app.AcknowledgementsTab);
            app.TextArea_4.Value = {'This project is supported by the BRIEF (Biorobotics Research and Innovation Engineering Facilities) project (WP3 – Task 3.4), PNRR M4C2, '; 'ID IR0000036, funded by the European Union - NextGenerationEU'};
            app.Label = uilabel(app.AcknowledgementsTab);
            app.Label.Text = '';
            app.Image = uiimage(app.AcknowledgementsTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pics', 'tuen Beam.png');
            app.Label_4 = uilabel(app.AcknowledgementsTab);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.AcknowledgementsTab);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.AcknowledgementsTab);
            app.Label_2.Text = '';
            app.Image2 = uiimage(app.AcknowledgementsTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'pics', 'mnesys.png');
            app.Image3 = uiimage(app.AcknowledgementsTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'pics', 'tuscany heaalth ecosystem.png');
            app.Image4 = uiimage(app.AcknowledgementsTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'pics', 'biorobotics research et innovation engineering facilities.png');
            app.Label_5 = uilabel(app.AcknowledgementsTab);
            app.Label_5.Text = '';
            app.Image5 = uiimage(app.AcknowledgementsTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'pics', 'IT Finanziato dall Unione europea_POS.jpg');
            app.Image6 = uiimage(app.AcknowledgementsTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'pics', 'LOGO_UniversitaaRicerca Nolineare_TRACCIATO_s copia.jpg');
            app.Image7 = uiimage(app.AcknowledgementsTab);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'pics', 'italia-domani-tracciato copia.jpg');
            app.lbl_file = uilabel(app.UIFigure);
            app.lbl_interval = uilabel(app.UIFigure);
            app.lbl_interval.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.lbl_working = uilabel(app.UIFigure);
            app.lbl_working.Text = '';
            app.btn_sel_int = uibutton(app.UIFigure, 'push');
            app.btn_sel_int.ButtonPushedFcn = createCallbackFcn(app, @btn_sel_intButtonPushed, true);
            app.btn_sel_int.Enable = 'off';
            app.btn_sel_int.Text = 'select subintervals';
            app.btn_hb = uibutton(app.UIFigure, 'push');
            app.btn_hb.ButtonPushedFcn = createCallbackFcn(app, @btn_hbButtonPushed, true);
            app.btn_hb.Enable = 'off';
            app.btn_hb.Text = 'edit heartbeats';
            app.btn_resp = uibutton(app.UIFigure, 'push');
            app.btn_resp.ButtonPushedFcn = createCallbackFcn(app, @btn_respButtonPushed, true);
            app.btn_resp.Enable = 'off';
            app.btn_resp.Text = 'edit respiration';
            app.btn_output_directory = uibutton(app.UIFigure, 'push');
            app.btn_output_directory.ButtonPushedFcn = createCallbackFcn(app, @btn_output_directoryButtonPushed, true);
            app.btn_output_directory.Text = 'set output directory';
            app.lbl_output = uilabel(app.UIFigure);
            app.lbl_output.Text = 'Label24';
            app.btn_save_sesh = uibutton(app.UIFigure, 'push');
            app.btn_save_sesh.ButtonPushedFcn = createCallbackFcn(app, @btn_save_seshButtonPushed, true);
            app.btn_save_sesh.Text = 'save session';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MNG_toolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end