classdef burst_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BasedonGEFosteratal2021PMID34705589Label  matlab.ui.control.Label
        edt_abs_th                   matlab.ui.control.NumericEditField
        absthresholdEditFieldLabel   matlab.ui.control.Label
        RegressionlineYDXELabel      matlab.ui.control.Label
        edt_outlier                  matlab.ui.control.NumericEditField
        outliernstdEditFieldLabel    matlab.ui.control.Label
        lbl_work                     matlab.ui.control.Label
        sldr_yp                      matlab.ui.control.Slider
        dyLabel                      matlab.ui.control.Label
        sldr_xp                      matlab.ui.control.Slider
        slopeLabel                   matlab.ui.control.Label
        confirmcloseButton           matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        edt_n_std                    matlab.ui.control.NumericEditField
        nstdCLabel                   matlab.ui.control.Label
        edt_sec_width                matlab.ui.control.NumericEditField
        sectionwidthBLabel           matlab.ui.control.Label
        extentwindowAEditField       matlab.ui.control.NumericEditField
        extentwindowAEditFieldLabel  matlab.ui.control.Label
        ax_slope                     matlab.ui.control.UIAxes
        ax_amp                       matlab.ui.control.UIAxes
        ax_shapes                    matlab.ui.control.UIAxes
        ax_thresh                    matlab.ui.control.UIAxes
        ax_summs                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        caller
        data
        burst_res
        settings
    end
    
    methods (Access = public)
        
        function brsts = get_bursts(app)

            brsts= nan(length(app.data.evnt_idx),7);
            tmp_data = [app.data.x,zeros(1,3*app.data.fs)];
            for i = 1:length(app.data.evnt_idx)
                tmp_int = app.data.x_filt(app.data.evnt_idx(i)+app.settings.window(1):app.data.evnt_idx(i)+app.settings.window(2));
                [~,tmp_pk] = max(tmp_int);
                if tmp_pk == 1
                    tmp_idx  = find(diff(tmp_int) >0, 1 ,'first');
                    tmp_int(1:tmp_idx) = tmp_int(tmp_idx);
                    [~,tmp_pk] = max(tmp_int);
                else
                    if tmp_pk ==length(tmp_int)
                        tmp_idx  = find(diff(tmp_int) <0, 1 ,'last');
                        tmp_int(tmp_idx:end) = tmp_int(tmp_idx);
                        [~,tmp_pk] = max(tmp_int);
                    end

                end


%                 h= figure;
%                 bla = app.data.x_filt(app.data.evnt_idx(i)+app.settings.window(1):app.data.evnt_idx(i)+app.settings.window(2));
%                 plot (bla)
%                 hold on 
%                 plot (tmp_pk,bla(tmp_pk),'*r');
%                 plot(tmp_int)
%                 blubb = (tmp_pk ~= 1) & (tmp_pk ~= (abs(diff(app.settings.window))+1));
%                 close (h)
                switch app.settings.type
                    case 1
                        if (tmp_pk ~= 1) & (tmp_pk ~= (abs(diff(app.settings.window))+1))
                            tmp_idx = find(app.data.diff_x_filt(app.data.evnt_idx(i)+app.settings.window(1):app.data.evnt_idx(i)+app.settings.window(2))<0);
                            strt = find(tmp_idx<tmp_pk,1,'last');
                            stp =  find(tmp_idx>tmp_pk,1,'first');
                            strt= tmp_idx(strt);
                            if isempty(strt)
                                strt =1;
                            end
                            stp = tmp_idx(stp);
                            if isempty(stp)
                                stp = app.settings.window(2)-app.settings.window(1)+1;
                            end
                            brsts(i,1:3) = [strt-1+app.data.evnt_idx(i)+app.settings.window(1), tmp_pk-1+app.data.evnt_idx(i)+app.settings.window(1), stp-1+app.data.evnt_idx(i)+app.settings.window(1)];
                            brsts(i,4) = max(tmp_data(brsts(i,2))-tmp_data(brsts(i,1)),tmp_data(brsts(i,2))-tmp_data(brsts(i,3)));
                            brsts(i,5) = mean(diff(tmp_data(brsts(i,1):brsts(i,2))));
                            brsts(i,6) = mean(diff(tmp_data(brsts(i,2):brsts(i,3))));
                            brsts(i,7) = (brsts(i,2)-app.data.evnt_idx(i)+1)/app.data.fs;
                        end
                    case 2

                end 
                
            end
            
            brsts(isnan(brsts(:,3)),:) = [];
            
            brsts((brsts(:,1)>= length(app.data.x)),:) = [];
            
            brsts((brsts(:,2)>= length(app.data.x)),:) = [];
            
            brsts((brsts(:,3)>= length(app.data.x)),:) = [];

            idx = find(diff(brsts(:,2)) == 0);

            for i = 1: length(idx)
                brsts(idx(i),3) = brsts(idx(i)+1,3);
                [~, idx_mn] =  min ([abs(brsts(idx(i),7)-app.settings.delay),abs(brsts(idx(i)+1,7)-app.settings.delay)]);

                if idx_mn == 2
                    brsts(idx(i),7) = brsts(idx(i)+1,7);
                end
                brsts(idx(i),4) = max(tmp_data(brsts(idx(i),2))-tmp_data(brsts(idx(i),1)),tmp_data(brsts(idx(i),2))-tmp_data(brsts(idx(i),3)));
                brsts(idx(i),5) = mean(diff(tmp_data(brsts(idx(i),1):brsts(idx(i),2))));
                brsts(idx(i),6) = mean(diff(tmp_data(brsts(idx(i),2):brsts(idx(i),3))));
                brsts(idx(i)+1,:) = nan;
                
            end
            brsts(isnan(brsts(:,1)),:) = [];
            app.burst_res = brsts;
            % brsts(:,1)= start;
            % brsts(:,2)= peak; 
            % brsts(:,3)= end; 
            % brsts(:,4)= amplitude; 
            % brsts(:,5)= mean slope rise; 
            % brsts(:,6)= mean slope fall;
            % brsts(:,7)= burst delay;
        end

        function brsts = get_bursts2(app)

            brsts= nan(length(app.data.evnt_idx),7);
            tmp_data = [app.data.x,zeros(1,3*app.data.fs)];
            for i = 1:length(app.data.evnt_idx)
                tmp_int = app.data.x_filt(app.data.evnt_idx(i)+app.settings.window(1):app.data.evnt_idx(i)+app.settings.window(2));
%                 tmp_int = detrend(tmp_int);
                tmp_int_d = diff(tmp_int);
                if tmp_int_d(1) <0
                    tmp_idx  = find(diff(tmp_int) >0, 1 ,'first');
                    tmp_int(1:tmp_idx-1) = tmp_int(tmp_idx-1)+0.0000001;                    
                end

                if tmp_int_d(end) >0
                    tmp_idx  = find(diff(tmp_int) <0, 1 ,'last');
                    tmp_int(tmp_idx+2:end) = tmp_int(tmp_idx+1)+0.0000001;
                end
                tmp_idx = find(diff(tmp_int).*circshift(diff(tmp_int),-1) <0);
                tmp_idx = tmp_idx+1;
                try
                    if tmp_idx(end) ==length(tmp_int)
                        tmp_idx (end) = [];
                    end
                end
                tmp_n =tmp_idx(tmp_int(tmp_idx)<0);
                tmp_p =tmp_idx(tmp_int(tmp_idx)>=0);
                if ~isempty(tmp_p)
                    l = length(tmp_int);
                    center = (l + 1) / 2;
                
                    % --- Determine flanking minima for each peak ---
                    leftMin = nan(size(tmp_p));
                    rightMin = nan(size(tmp_p));
                    hasTwoMinima = false(size(tmp_p));
                
                    for j = 1:length(tmp_p)
                        if ~isempty(tmp_n(tmp_n < tmp_p(j))) & ~isempty(tmp_n(tmp_n > tmp_p(j)))
                            hasTwoMinima(j) = true;
                        end
                    end
                
                    % --- Peak amplitudes ---
                    peakVals = tmp_int(tmp_p);
                
                    % --- Scoring logic ---
                    % Prefer higher peaks, central ones, and those flanked by two minima
                    flankBonus = hasTwoMinima * 0.02 * max(peakVals); % small bonus
                    distFromCenter = abs(tmp_p - center) / l;
                    centralWeight = 1 - distFromCenter; % closer to center → higher weight
                
                    score = peakVals .* (0.75 + 0.25 * centralWeight) + flankBonus;
                
                    % --- Select best-scoring peak ---
                    [~, bestIdx] = max(score);
                
                    peak_idx = tmp_p(bestIdx);
                    peak_val = tmp_int(peak_idx);
                
                    % --- Flanking minima of selected peak ---
                    if ~isempty(tmp_n(tmp_n < peak_idx)) 
                        tmp_mins = tmp_n(tmp_n < peak_idx);
                        [~, tmp_idx] = min(tmp_int(tmp_mins));
                        start_idx = tmp_mins(tmp_idx);
                    else
                        [~, start_idx] = min(tmp_int(1:peak_idx));
                    end

                    if ~isempty(tmp_n(tmp_n > peak_idx))
                        tmp_mins = tmp_n(tmp_n > peak_idx);
                        [~, tmp_idx] = min(tmp_int(tmp_mins));
                        stop_idx = tmp_mins(tmp_idx);
                    else
                        [~, stop_idx] = min(tmp_int(peak_idx:end));
                        stop_idx = stop_idx+peak_idx-1; 
                    end
    
                    h= figure;
                    bla = app.data.x_filt(app.data.evnt_idx(i)+app.settings.window(1):app.data.evnt_idx(i)+app.settings.window(2));
                    plot (bla)
                    hold on 
                    plot (tmp_int)
                    plot(peak_idx,tmp_int(peak_idx),'*m')
                    plot(start_idx,tmp_int(start_idx),'*g')
                    plot(stop_idx,tmp_int(stop_idx),'*r')
                    close (h)

                    
                    brsts(i,1:3) = [int32(start_idx), int32(peak_idx), int32(stop_idx)]+int32(app.data.evnt_idx(i))+app.settings.window(1)-1;
                    brsts(i,4) = max(tmp_data(brsts(i,2))-tmp_data(brsts(i,1)),tmp_data(brsts(i,2))-tmp_data(brsts(i,3)));
                    brsts(i,5) = mean(diff(tmp_data(brsts(i,1):brsts(i,2))));
                    brsts(i,6) = mean(diff(tmp_data(brsts(i,2):brsts(i,3))));
                    brsts(i,7) = (brsts(i,2)-app.data.evnt_idx(i)+1)/app.data.fs;
                end
            end

            
            brsts(isnan(brsts(:,3)),:) = [];
            
            brsts((brsts(:,1)>= length(app.data.x)),:) = [];
            
            brsts((brsts(:,2)>= length(app.data.x)),:) = [];
            
            brsts((brsts(:,3)>= length(app.data.x)),:) = [];

            idx = find(diff(brsts(:,2)) == 0);

            for i = 1: length(idx)
                brsts(idx(i),3) = brsts(idx(i)+1,3);
                [~, idx_mn] =  min ([abs(brsts(idx(i),7)-app.settings.delay),abs(brsts(idx(i)+1,7)-app.settings.delay)]);

                if idx_mn == 2
                    brsts(idx(i),7) = brsts(idx(i)+1,7);
                end
                brsts(idx(i),4) = max(tmp_data(brsts(idx(i),2))-tmp_data(brsts(idx(i),1)),tmp_data(brsts(idx(i),2))-tmp_data(brsts(idx(i),3)));
                brsts(idx(i),5) = mean(diff(tmp_data(brsts(idx(i),1):brsts(idx(i),2))));
                brsts(idx(i),6) = mean(diff(tmp_data(brsts(idx(i),2):brsts(idx(i),3))));
                brsts(idx(i)+1,:) = nan;
                
            end
            brsts(isnan(brsts(:,1)),:) = [];
            app.burst_res = brsts;
            % brsts(:,1)= start;
            % brsts(:,2)= peak; 
            % brsts(:,3)= end; 
            % brsts(:,4)= amplitude; 
            % brsts(:,5)= mean slope rise; 
            % brsts(:,6)= mean slope fall;
            % brsts(:,7)= burst delay;
        end
        
        function brsts = get_bursts3(app)

            brsts= nan(length(app.data.evnt_idx),7);
            data = [app.data.x,zeros(1,4*app.data.fs)];
            data_good = [true(1,length(app.data.x)),false(1,4*app.data.fs)];
            
            data_filt = [app.data.x_filt,zeros(1,4*app.data.fs)];
            delay = app.settings.delay*app.data.fs;
            win = app.settings.window;
            hwlen = win(2)-delay;
            for i = 1:length(app.data.evnt_idx)
                tmp_data = data(app.data.evnt_idx(i):app.data.evnt_idx(i)+4*app.data.fs);
                tmp_data_good = data_good(app.data.evnt_idx(i):app.data.evnt_idx(i)+4*app.data.fs);
                if sum(tmp_data_good(win(1):win(2))) == length(tmp_data_good(win(1):win(2)))

                    tmp_data_filt = data_filt(app.data.evnt_idx(i):app.data.evnt_idx(i)+4*app.data.fs);
                    tmp_data_filt_diff = diff(tmp_data_filt); 
                    tmp_idx = find(tmp_data_filt_diff.*circshift(tmp_data_filt_diff,-1) <0);
                    tmp_idx = tmp_idx+1;
                     
                    if tmp_idx(1) == 1
                        tmp_idx(1) = [];
                    end
                    if tmp_idx(end) == (4*app.data.fs+1)
                        tmp_idx(end) = [];
                    end
                    
                    tmp_mx = tmp_idx(tmp_data_filt_diff(tmp_idx)<0);
                    tmp_mn = tmp_idx(tmp_data_filt_diff(tmp_idx)>0);
                    tmp_mx = tmp_mx(tmp_mx>=win(1) & tmp_mx<=win(2));
                    [~, tmp_idx] = sort(abs(tmp_mx-delay));
                    tmp_mx = tmp_mx(tmp_idx);
                                       
                    if ~isempty(tmp_mx)
                        if length(tmp_mx) >1
                            rnk1 = (ones(size(tmp_mx)).*double(hwlen) -abs(tmp_mx-delay))/double(hwlen);
                            rnk2 = (tmp_data(tmp_mx)-mean(tmp_data))./(max(tmp_data)-mean(tmp_data));
                            [~, tmp_idx] = max(rnk1+rnk2);
                            tmp_mx= tmp_mx(tmp_idx);
                        end
                        
                         go_on = true;
                        try
                            tmp_idx = find(tmp_mn < tmp_mx,1,'last');
                            if isempty(tmp_idx)         %new
                                tmp_idx = 1;            %new
                                tmp_mn= [1,tmp_mn];     %new
                            end                         %new
                            tmp_idx(2) = find(tmp_mn > tmp_mx,1,'first');
                        catch
                            go_on = false;
                        end
                        if go_on
                            tmp_mn_sel = tmp_mn(tmp_idx);
                            tmp_check = abs(tmp_data_filt(tmp_mn_sel(1))-tmp_data_filt(tmp_mn_sel(2)))/(tmp_data_filt(tmp_mx)-(min(tmp_data_filt(tmp_mn_sel))));
                            
                            if tmp_check >=0.9
                                [~,flank] = max(tmp_data_filt(tmp_mn_sel));
                                switch flank
                                    case 1 
                                        dir = -1;                                
                                    case 2
                                        dir = 1;
                                end
                                try
                                    if abs(tmp_mn(tmp_idx(flank))-tmp_mn(tmp_idx(flank)+dir)) < app.data.fs*0.75
                                        if tmp_data_filt(tmp_mn(tmp_idx(flank))) > tmp_data_filt(tmp_mn(tmp_idx(flank)+dir))
                                            switch flank
                                                case 1
                                                    pk2 = max(tmp_data_filt(tmp_mn(tmp_idx(flank)+dir):tmp_mn_sel(2))) -min(tmp_data_filt(tmp_mn(tmp_idx(flank)+dir):tmp_mn_sel(2)));
                                                    pk2 = pk2/(tmp_data_filt(tmp_mx)-min(tmp_data_filt(tmp_mn(tmp_idx(flank)+dir):tmp_mn_sel(2))));
                                                case 2
                                                    pk2 = max(tmp_data_filt(tmp_mn_sel(1):tmp_mn(tmp_idx(flank)+dir))) -min(tmp_data_filt(tmp_mn_sel(1):tmp_mn(tmp_idx(flank)+dir)));
                                                    pk2 = pk2/(tmp_data_filt(tmp_mx)-min(tmp_data_filt(tmp_mn_sel(1):tmp_mn(tmp_idx(flank)+dir))));
                                            end
                                            if pk2 <=1.75
                                                tmp_idx(flank) = tmp_idx(flank)+dir;
                                                tmp_mn_sel = tmp_mn(tmp_idx);
                                            end
                                        end
                                    end
                                end
        
                            end
    
    %                     h = figure;
    %                     plot (tmp_data)
    %                     hold on
    %                     plot (tmp_data_filt)
    %                     line([delay delay], [-0.5 2.5],'Color','r')
    %                     line([app.settings.window(1), app.settings.window(1)], [-0.5 2.5],'Color','c')
    %                     line([app.settings.window(2), app.settings.window(2)], [-0.5 2.5],'Color','c')
    %                     plot (tmp_mx,tmp_data_filt(tmp_mx),'r*' )
    %                     plot (tmp_mx,tmp_data(tmp_mx),'r*' )
    %                     plot (tmp_mn_sel,tmp_data_filt(tmp_mn_sel),'g*' )
    %                     plot (tmp_mn_sel,tmp_data(tmp_mn_sel),'g*' )
    %                     
    %                     title([num2str(tmp_check)])
    % %                     if tmp_check<0.9
    %                         close (h)
    % %                     end
                            brsts(i,1:3) = [int32(tmp_mn_sel(1)), int32(tmp_mx), int32(tmp_mn_sel(2))]+int32(app.data.evnt_idx(i))-1;
                            brsts(i,4) = max(data(brsts(i,2))-data(brsts(i,1)),data(brsts(i,2))-data(brsts(i,3)));
                            brsts(i,5) = mean(diff(data(brsts(i,1):brsts(i,2))));
                            brsts(i,6) = mean(diff(data(brsts(i,2):brsts(i,3))));
                            brsts(i,7) = (brsts(i,2)-app.data.evnt_idx(i)+1)/app.data.fs;
                        else
                            brsts(i,1:3) = [nan, nan, nan];
                            brsts(i,4) = nan;
                            brsts(i,5) = nan;
                            brsts(i,6) = nan;
                            brsts(i,7) = nan;
                        end
                    end
                else
                    brsts(i,1:3) = [nan, nan, nan];
                    brsts(i,4) = nan;
                    brsts(i,5) = nan;
                    brsts(i,6) = nan;
                    brsts(i,7) = nan;
                end
            end


            brsts(isnan(brsts(:,3)),:) = [];
            
            brsts((brsts(:,1)>= length(app.data.x)),:) = [];
            
            brsts((brsts(:,2)>= length(app.data.x)),:) = [];
            
            brsts((brsts(:,3)>= length(app.data.x)),:) = [];

            idx = find(diff(brsts(:,2)) == 0);
            skip = [];
            for i = 1: length(idx)
                if ~sum(ismember(i, skip))
                    tmp_idx = idx(i);
                    tmp_idx(2) = idx(i)+1;
                    go = true;
                    incr= 1;
                    while go
                        try
                            if idx(i+incr) == tmp_idx(end)
                                tmp_idx(end+1) = tmp_idx(end)+1;
                                skip(end+1) = i+incr;
                                incr = incr+1;
                            else
                                go = false;
                            end
                        catch
                            go = false;
                        end
                    end               
    
                    tmp_del = brsts(tmp_idx,7);
                    [~ ,to_keep] = min(abs(tmp_del-app.settings.delay));
    
                    tmp_idx(to_keep) = [];
                    brsts(tmp_idx,:) = nan(length(tmp_idx),7);
                end
                
            end
            brsts(isnan(brsts(:,1)),:) = [];
            ovrlp = false(size(brsts,1),1);
            for i = 1 :size(brsts,1)-1
                if brsts(i,3)>brsts(i+1,1)
                    ovrlp(i) = true;
                end
            end
            ovrlp = flip(find(ovrlp));
            for i = 1:length(ovrlp)
                [~,tmp] = sort(abs(app.data.evnt_idx-brsts(ovrlp(i),2)+brsts(ovrlp(i),7)*app.data.fs));
                hr_idx = app.data.evnt_idx(tmp(1));
                [~,tmp] = sort(abs(app.data.evnt_idx-brsts(ovrlp(i)+1,2)+brsts(ovrlp(i)+1,7)*app.data.fs));
                hr_idx(2) = app.data.evnt_idx(tmp(1));
                brsts(ovrlp(i),1) = int32(brsts(ovrlp(i),1));
                brsts(ovrlp(i),3) = int32(brsts(ovrlp(i)+1,3));
                [~,tmp] = max(data_filt(brsts(ovrlp(i),1):brsts(ovrlp(i),3)));
                brsts(ovrlp(i),2) = int32(brsts(ovrlp(i),1)+tmp-1);
                brsts(ovrlp(i),4) = max(data(brsts(ovrlp(i),2))-data(brsts(ovrlp(i),1)),data(brsts(ovrlp(i),2))-data(brsts(ovrlp(i),3)));
                brsts(ovrlp(i),5) = mean(diff(data(brsts(ovrlp(i),1):brsts(ovrlp(i),2))));
                brsts(ovrlp(i),6) = mean(diff(data(brsts(ovrlp(i),2):brsts(ovrlp(i),3))));
                tmp = [(brsts(ovrlp(i),2)-hr_idx(1)+1), (brsts(ovrlp(i),2)-hr_idx(2)+1)];
                [~,idx] = min(abs(tmp-delay));
                brsts(ovrlp(i),7) = tmp(idx)/app.data.fs;
                brsts(ovrlp(i)+1,:) = [];
            end

            app.burst_res = brsts;
            % brsts(:,1)= start;
            % brsts(:,2)= peak; 
            % brsts(:,3)= end; 
            % brsts(:,4)= amplitude; 
            % brsts(:,5)= mean slope rise; 
            % brsts(:,6)= mean slope fall;
            % brsts(:,7)= burst delay;
        end

        function select_bursts(app,set,abst)
            
            xp = app.burst_res(:,4); % Independent variable
            yp = app.burst_res(:,7); % Dependent variable
            app.settings.yl = [floor(min(yp*10))/10 ,ceil(max(yp*10))/10];
            app.settings.xl = [0 max(xp)*1.15];

            
            if set
                coefficients = [app.sldr_xp.Value, app.sldr_yp.Value];
            else
                % Fit a linear model
                coefficients = polyfit(xp, yp, 1); % 1st-degree polynomial (linear regression)
                
                
                if abs(coefficients(1)) > 0.7
                    app.sldr_xp.Limits = [abs(coefficients(1))*(-1.1), abs(coefficients(1))*(1.1)];
                else
                    app.sldr_xp.Limits  = [-.7, .7];
                end
                app.sldr_xp.Value  = coefficients(1);

                app.sldr_yp.Limits = app.settings.yl;
                app.sldr_yp.Value = coefficients(2);
                
            end
            
            % Generate fitted values
            x_fit = linspace(min(xp), max(xp), 100); % Generate more points for smooth plotting
            y_fit = polyval(coefficients, x_fit);
            
            % Compute distances from the regression line
            y_pred = polyval(coefficients, xp);
            distances = abs(yp - y_pred);
            
            dist = app.edt_sec_width.Value; % Define distance threshold %% <=== change that
            selected_points = (distances <= dist);
            app.burst_res(:,9)= selected_points;
            selected_x = xp(selected_points);
            selected_y = yp(selected_points);
            
            % Compute selection area borders
            y_upper = y_fit + dist;
            y_lower = y_fit - dist;
            
            tmp = xp(~selected_points);
            mn = mean (tmp);
            st =std(tmp);
            % Plot original data and fitted line
            if abst
                thrsh = app.edt_abs_th.Value;
                app.edt_n_std.Value  = (thrsh-mn)/st;
            else
                thrsh = mn+app.edt_n_std.Value*st;
                app.edt_abs_th.Value = thrsh;
            end

            app.settings.thres = thrsh;
            app.settings.x_fit = x_fit;
            app.settings.y_fit = y_fit;
            app.settings.dist = dist;
            
            app.burst_res(:,8)= app.burst_res(:,4) >= thrsh;
            
            st = std([app.burst_res(:,5);-app.burst_res(:,6)]);
            mn = mean([app.burst_res(:,5);-app.burst_res(:,6)]);
            brsts_idx = find (app.burst_res(:,8) == 1);
            rem_idx_up = find(app.burst_res(brsts_idx,5)> mn+app.edt_outlier.Value*st); %sort out too high rising slopes => artefact
            rem_idx_un = find(-app.burst_res(brsts_idx,6)> mn+app.edt_outlier.Value*st); % sort out too high falling slopess => artefact
            rem_idx_lp = find(app.burst_res(brsts_idx,5)< mn-3*st);
            rem_idx_ln = find(-app.burst_res(brsts_idx,6)< mn-3*st); 
            
            rem_idx = unique([rem_idx_up;rem_idx_un;rem_idx_lp;rem_idx_ln]);
            app.burst_res(brsts_idx(rem_idx),8) = 0;
            
            mn = mean(app.burst_res(:,7));
            st =std(app.burst_res(:,7));
            brsts_idx = find (app.burst_res(:,8) == 1);
            rem_idx_p = find(app.burst_res(brsts_idx,7)> mn+3*st);
            rem_idx_n = find(app.burst_res(brsts_idx,7)< mn-3*st);
            
            rem_idx = unique([rem_idx_p;rem_idx_n]);
            app.burst_res(brsts_idx(rem_idx),8) = 0;
            tmp_idx = find(diff(app.burst_res(:,2)) == 0);
            app.burst_res(tmp_idx,8) = 0;
            app.burst_res(tmp_idx+1,8) = 0;

        end
    end
    
    methods (Access = private)
        
        function update_axes(app)
            xp = app.burst_res(:,4); % Independent variable
            yp = app.burst_res(:,7); % Dependent variable

            in_section = logical(app.burst_res(:,9));
            over_thres = logical(app.burst_res(:,8));
            tmp_x = xp(in_section & over_thres);
            tmp_y = yp(in_section & over_thres);
            app.settings.s1.XData = tmp_x;
    
            app.settings.s1.YData = tmp_y;
            tmp_x = xp(in_section & ~over_thres);
            tmp_y = yp(in_section & ~over_thres);
            app.settings.s2.XData = tmp_x;
            app.settings.s2.YData = tmp_y;
            tmp_x = xp(~in_section & ~over_thres);
            tmp_y = yp(~in_section & ~over_thres);
            app.settings.s3.XData = tmp_x;
            app.settings.s3.YData = tmp_y;
            tmp_x = xp(~in_section & over_thres);
            tmp_y = yp(~in_section & over_thres);
            app.settings.s4.XData = tmp_x;
            app.settings.s4.YData = tmp_y;
            y_upper = app.settings.y_fit + app.settings.dist;
            y_lower = app.settings.y_fit - app.settings.dist;
            app.settings.p1.XData = app.settings.x_fit;
            app.settings.p1.YData = app.settings.y_fit;
            app.settings.p2.XData = app.settings.x_fit;
            app.settings.p2.YData = y_upper;
            app.settings.p3.XData = app.settings.x_fit;
            app.settings.p3.YData = y_lower;
            app.settings.l.XData = [app.settings.thres app.settings.thres];
            app.settings.l.YData = ylim(app.ax_thresh);
%             app.edt_abs_th.Value = app.settings.thres;
            ylim (app.ax_thresh, app.settings.yl)
            xlim (app.ax_thresh, app.settings.xl)
            
%             scatter(app.ax_thresh,tmp_x,tmp_y,'MarkerEdgeColor','r','MarkerFaceColor','g','LineWidth',1.5)
%             hold (app.ax_thresh,'on');
%             tmp_x = xp(in_section & ~over_thres);
%             tmp_y = yp(in_section & ~over_thres);
%             
%             scatter(app.ax_thresh,tmp_x,tmp_y,'MarkerEdgeColor','k','MarkerFaceColor','g','LineWidth',1.5)
%             
%             tmp_x = xp(~in_section & ~over_thres);
%             tmp_y = yp(~in_section & ~over_thres);
%             
%             scatter(app.ax_thresh,tmp_x,tmp_y,'MarkerEdgeColor','k','MarkerFaceColor','b','LineWidth',1.5)
% 
%             tmp_x = xp(~in_section & over_thres);
%             tmp_y = yp(~in_section & over_thres);
%             
%             scatter(app.ax_thresh,tmp_x,tmp_y,'MarkerEdgeColor','r','MarkerFaceColor','b','LineWidth',1.5)
%            
% 
%             y_upper = app.settings.y_fit + app.settings.dist;
%             y_lower = app.settings.y_fit - app.settings.dist;
% 
% 
%             plot(app.ax_thresh,app.settings.x_fit, app.settings.y_fit,'Color',[.7,.7,.7],'LineStyle', '-.', 'LineWidth', 2); % Plot regression line
%             plot(app.ax_thresh,app.settings.x_fit, y_upper,'Color',[.7,.7,.7],'LineStyle',  '--', 'LineWidth', 2); % Upper boundary
%             plot(app.ax_thresh,app.settings.x_fit, y_lower,'Color',[.7,.7,.7],'LineStyle',  '--', 'LineWidth', 2); % Lower boundary
%             line (app.ax_thresh,[app.settings.thres app.settings.thres],ylim(app.ax_thresh), "Color",'m')
%             hold (app.ax_thresh,'off');
            
%             xlabel(app.ax_thresh,'X');
%             ylabel(app.ax_thresh,'Y');
            grid (app.ax_thresh,'on');
%             legend('Data Points', 'Fitted Line', 'Selected Points', 'Selection Borders');


            plot(app.ax_slope,app.burst_res(logical(app.burst_res(:,8)),5),app.burst_res(logical(app.burst_res(:,8)),6),'ro')
            hold (app.ax_slope,'on')
            plot(app.ax_slope,app.burst_res(~logical(app.burst_res(:,8)),5),app.burst_res(~logical(app.burst_res(:,8)),6),'ko')
            xlabel (app.ax_slope,'rise slope')
            ylabel (app.ax_slope,'fall slope')
            hold (app.ax_slope,'off')
           
            tmp1 = diff([app.data.x(int64(app.burst_res(:,1))); app.data.x(int64(app.burst_res(:,2)))],1,1);
            tmp2 = abs(diff([app.data.x(int64(app.burst_res(:,2))); app.data.x(int64(app.burst_res(:,3)))],1,1));
            plot(app.ax_amp,tmp1(logical(app.burst_res(:,8))),tmp2(logical(app.burst_res(:,8))),'ro')
            hold (app.ax_amp,'on')
            plot(app.ax_amp,tmp1(~logical(app.burst_res(:,8))),tmp2(~logical(app.burst_res(:,8))),'ko')
            xlabel (app.ax_amp,'rise amplitude')
            ylabel (app.ax_amp,'fall amplitude')

            dur = max(max(app.burst_res(:,2)-app.burst_res(:,1)),max(app.burst_res(:,3)-app.burst_res(:,2)));
            tmp = nan(2*dur+1,size(app.burst_res,1));
            for i = 1 :size(app.burst_res,1)
                dix = diff(app.burst_res(i,1:3));
                tmp(dur-dix(1)+1:dur+dix(2)+1,i) = app.data.x (app.burst_res(i,2)-dix(1):app.burst_res(i,2)+dix(2));

            end
            plot (app.ax_shapes,(-dur:dur)/app.data.fs,tmp(:,~over_thres),'Color',[.5,.5,.5])
            hold (app.ax_shapes,'on') 
            plot (app.ax_shapes,(-dur:dur)/app.data.fs,tmp(:,over_thres),'r')
            hold (app.ax_shapes,'off') 

        end

        function working(app,on)
            if on
                app.lbl_work.Text = 'Working';
                app.lbl_work.BackgroundColor = 'r';
            else
                app.lbl_work.Text = '';
                app.lbl_work.BackgroundColor = 'none';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            working(app, true)
            drawnow 
            app.caller = caller;
            if ~app.caller.sbtn_hb.Value & app.caller.sbtn_resp.Value
                app.settings.type = 2;
            else
                app.settings.type = 1;
            end


            sel = app.caller.data(app.caller.settings.channel_idx.msna).data(app.caller.settings.interval(1,1)/app.caller.data(app.caller.settings.channel_idx.msna).ts(1):app.caller.settings.interval(1,2)/app.caller.data(app.caller.settings.channel_idx.msna).ts(1)); 
            sel(1) = []; 
            Ts_msna = app.caller.data(app.caller.settings.channel_idx.msna).ts(1);
            
            sel=bandpass(sel,[500 5000],1e4);%sel=bandpass(sel,[300 5000],1e4);
            tmp = sqrt(movmean(sel.^2,2001));
            rmsa1 = tmp';
            rmsa2=rmsa1;

            if app.caller.chkbx_detrent_rms.Value
                x=detrend(rmsa2,2);
            else
                x=rmsa2;
            end
            t_msna = (1:length(x))*Ts_msna;
            app.data.fs = 1/app.caller.data(app.caller.settings.channel_idx.msna).ts(1);
            switch app.settings.type
                case 1
                    app.data.evnt_ts = app.caller.hb_res.t_events;
                    app.settings.dur = 3*app.data.fs-1; % 4s +- for resp
                    tmp_data = [x,zeros(1,3*app.data.fs)];
                case 2
                    app.data.evnt_ts = app.caller.resp_res.ts;
                    app.settings.dur = 4*app.data.fs-1;
                    tmp_data = [x,zeros(1,4*app.data.fs)];
                    tmp = find(app.data.evnt_ts < 4);
                    app.data.evnt_ts(tmp) = [];
            end


            app.data.evnt_idx =zeros(size(app.data.evnt_ts));
            %app.data.ridx = zeros(size(app.caller.hb_res.t_events));
            lo = 1;
            hi = numel(t_msna);
            for n = 1:numel(app.data.evnt_ts)%(app.caller.hb_res.t_events)
                b = app.data.evnt_ts(n);%app.caller.hb_res.t_events(n);
                l = lo;
                h = hi;
                while l < h
                    m = floor((l + h) / 2);
                    if t_msna(m) < b
                        l = m + 1;
                    else
                        h = m;
                    end
                end
                app.data.evnt_idx(n) = l;%app.data.ridx(n) = l;
            end 
            
            
            switch app.settings.type
                case 1
                    summ = zeros(app.settings.dur+1,1);
                    for i = 1:length(app.data.evnt_idx)%(app.caller.hb_res.t_events)
                        summ= summ+tmp_data(app.data.evnt_idx(i):app.data.evnt_idx(i)+app.settings.dur)';%(app.data.ridx(i):app.data.ridx(i)+dur)';
                    end
                case 2
                    summ = zeros(2*app.settings.dur+1,1);
                    for i = 1:length(app.data.evnt_idx)%(app.caller.hb_res.t_events)
                        summ= summ+tmp_data(app.data.evnt_idx(i)-app.settings.dur:app.data.evnt_idx(i)+app.settings.dur)';
                    end
            end
            
            summ = summ ./length(app.data.evnt_idx);%(app.caller.hb_res.t_events);

            [b,a] = butter(4,5/(app.data.fs/2),"low");
            sumf = filtfilt(b,a,summ);
     
            sumts = (1:length(summ))/app.data.fs;
            if app.settings.type == 2
                sumts = sumts-sumts(app.settings.dur+1);
            end
            
%             [~,mxi] = max(movmean(sumf,app.data.fs/20));
            
            [~,tmp_lcs] = findpeaks(movmean(sumf,app.data.fs/20),'MinPeakHeight',max(movmean(sumf,app.data.fs/20)/10));
            [~,tmp_idx] = sort(sumf(tmp_lcs),"ascend");
            avrg_idx = find(sumts >= caller.edt_burst_delay.Value,1,'first');
            [~,del_idx] = min(abs(tmp_lcs(tmp_idx)-avrg_idx));
            mxi = tmp_lcs(tmp_idx(del_idx)); 
            
            switch app.settings.type
                case 1
                    [~,tmp_lcs] = findpeaks(sumf(1:mxi+10),'MinPeakHeight',max(sumf(1:mxi+10))/10);
                    if tmp_lcs(end) >= mxi-100 & tmp_lcs(end) <= mxi+100
                        tmp_lcs(end) = [];
                    end
                    if isempty(tmp_lcs)
                        strt = 1;
                    else
                        strt = tmp_lcs(end);
                    end
                    
                    [~,tmp_lcs] = findpeaks(sumf(mxi-10:end),'MinPeakHeight',max(sumf(mxi-10:end))/10);
                    if tmp_lcs(1) <= 111
                        tmp_lcs(1) = [];
                    end
                    if isempty(tmp_lcs)
                        stp = length(sumf);
                    else
                        stp = tmp_lcs(1)-9+mxi;
                    end
                    
                    [~,tmp_lcs] = findpeaks(-sumf(strt:mxi)-mean(-sumf(strt:mxi)),'MinPeakHeight',max(-sumf(strt:mxi)-mean(-sumf(strt:mxi)))/10, 'SortStr','descend');
                    lcs(1) = tmp_lcs(1)+strt-1;

                    [~,tmp_lcs] = findpeaks(-sumf(mxi:stp)-mean(-sumf(mxi:stp)),'MinPeakHeight',max(-sumf(mxi:stp)-mean(-sumf(mxi:stp)))/10, 'SortStr','descend');
                    lcs(2) = tmp_lcs(1)+mxi-1;


%                 case 2
%                     [~,tmp_lcs] = findpeaks(-sumf(1:mxi)-mean(-sumf),'MinPeakHeight',max(-sumf-mean(-sumf))/10);
%                     [~,idx] = sort(abs(tmp_lcs-mxi),"ascend");
%                     idx(2:end) = [];
%                     lcs =tmp_lcs(idx);
%                     [~,tmp_lcs] = findpeaks(-sumf(mxi:end)-mean(-sumf),'MinPeakHeight',max(-sumf-mean(-sumf))/10);
%                     [~,idx] = sort(abs(tmp_lcs),"ascend");
%                     idx(2:end) = [];
%                     lcs(2)= tmp_lcs(idx)+mxi-1;
%                     idx = [1,2];
            end


            plot(app.ax_summs, sumts,sumf, HitTest="off")
            hold (app.ax_summs,'on') 
            plot (app.ax_summs, sumts(mxi),sumf(mxi),'*g',HitTest='off')
            plot (app.ax_summs, sumts(lcs),sumf(lcs),'*r',HitTest='off')
            yl= ylim (app.ax_summs);
            app.settings.lines(1) = line(app.ax_summs, [sumts(lcs(1)) sumts(lcs(1))], yl, 'Color','r',HitTest='off');
            app.settings.lines(2) = line(app.ax_summs, [sumts(lcs(2)) sumts(lcs(2))], yl, 'Color','r',HitTest='off');

            app.settings.delay = sumts(mxi);
            switch app.settings.type
                case 1
                    app.settings.window = sort(lcs,'ascend');
                    app.settings.window_org = app.settings.window; % adapt for resp
%                 case 2
%                     app.settings.window = sort(lcs-app.settings.dur,'ascend');
%                     app.settings.window_org = app.settings.window;
            end
            
            app.data.x = x;
            switch app.settings.type
                case 1
                    [b,a] = butter(2,[0.05,4]/(app.data.fs/2),"bandpass"); 
                case 2
                    [b,a] = ellip(2,0.1,40,[0.05 4]*2/app.data.fs);
            end

            app.data.x_filt = filtfilt(b,a,x); %%filtfilt(b,a,tmp_data);
            app.data.diff_x_filt = diff(app.data.x_filt).*circshift(diff(app.data.x_filt),1);
            
%             get_bursts(app);
            get_bursts3(app);
            
            select_bursts(app, false,false);

            app.settings.s1 = scatter(app.ax_thresh,nan,nan,'MarkerEdgeColor','r','MarkerFaceColor','g','LineWidth',1.5);
            hold (app.ax_thresh,'on');
            app.settings.s2 = scatter(app.ax_thresh,nan,nan,'MarkerEdgeColor','k','MarkerFaceColor','g','LineWidth',1.5);

            app.settings.s3 = scatter(app.ax_thresh,nan,nan,'MarkerEdgeColor','k','MarkerFaceColor','b','LineWidth',1.5);
            app.settings.s4 = scatter(app.ax_thresh,nan,nan,'MarkerEdgeColor','r','MarkerFaceColor','b','LineWidth',1.5);

            app.settings.p1 = plot(app.ax_thresh,nan, nan,'Color',[.7,.7,.7],'LineStyle', '-.', 'LineWidth', 3); % Plot regression line
            app.settings.p2 = plot(app.ax_thresh,nan, nan,'Color',[.7,.7,.7],'LineStyle',  '--', 'LineWidth', 3); % Upper boundary
            app.settings.p3 = plot(app.ax_thresh,nan, nan,'Color',[.7,.7,.7],'LineStyle',  '--', 'LineWidth', 3); % Lower boundary
            app.settings.l = line (app.ax_thresh,[nan nan],ylim(app.ax_thresh), "Color",'m', 'LineWidth', 3);
            hold (app.ax_thresh,'off');

            update_axes(app)
            working(app, false)
        end

        % Value changed function: extentwindowAEditField
        function extentwindowAEditFieldValueChanged(app, event)
            tmp = int32(app.extentwindowAEditField.Value*app.data.fs);
            app.settings.window = [app.settings.window_org(1)-tmp, app.settings.window_org(2)+tmp];
            
            tmp = (1:app.data.fs*3)/app.data.fs;
            app.settings.lines(1).XData = [tmp(app.settings.window(1)), tmp(app.settings.window(1))];
            app.settings.lines(2).XData = [tmp(app.settings.window(2)), tmp(app.settings.window(2))];
%             get_bursts(app);
            get_bursts3(app);

            select_bursts(app, false,false);

            update_axes(app)
        end

        % Value changed function: edt_sec_width
        function edt_sec_widthValueChanged(app, event)
            select_bursts(app,true,false);

            update_axes(app)
            
        end

        % Value changed function: edt_n_std
        function edt_n_stdValueChanged(app, event)
            select_bursts(app,true,false);

            update_axes(app)
            
        end

        % Button pushed function: confirmcloseButton
        function confirmcloseButtonPushed(app, event)
            t_msna = (1:length(app.data.x))/app.data.fs;
            burst_res.delay = app.settings.delay ;
            burst_res.window = app.settings.window;
            burst_res.x = app.data.x;
            burst_res.ts = [1/app.data.fs, 1/app.data.fs,length(burst_res.x)/app.data.fs];
            burst_res.burst_loc = [app.burst_res(:,1), app.burst_res(:,3)];
            tmp = burst_res.ts(1):burst_res.ts(2):burst_res.ts(3);
            burst_res.t_burst = tmp (app.burst_res(:,2));
%             burst_res.dt_burst = diff(burst_res.t_burst);
            burst_res.use_burst = logical(app.burst_res(:,8));
            burst_res.use_burst(:,2) =true(size(burst_res.use_burst(:,1))) ;
            n_brsts = size(app.burst_res,1);
            burst_res.burst_int = nan(1,n_brsts);
            burst_res.burst_amp = nan(1,n_brsts);
            for i = 1 : n_brsts
                tmp_d = [t_msna(app.burst_res(i,1):app.burst_res(i,3))',app.data.x(app.burst_res(i,1):app.burst_res(i,3))'*1000];
                tmp_d(:,2) = tmp_d(:,2) - min (tmp_d(:,2));
            
                burst_res.burst_amp(i)  = max(tmp_d(:,2)); %%% ampl
                burst_res.burst_int(i) = trapz(tmp_d(:,1),tmp_d(:,2)); %% int
            end
            burst_res.burst_dur = (app.burst_res(:,3)-app.burst_res(:,1))/app.data.fs;
            burst_res.burst_latency = app.burst_res(:,7);
            burst_res.type = 1;
            burst_res.delay = app.settings.delay;
            burst_res.window = app.settings.window;
            burst_res.window_org = app.settings.window_org;
            import_bursts(app.caller,burst_res, false)
            delete(app)
        end

        % Value changed function: sldr_xp
        function sldr_xpValueChanged(app, event)
            select_bursts(app, true,false);

            update_axes(app)
            
        end

        % Value changing function: sldr_xp
        function sldr_xpValueChanging(app, event)
            
%             app.settings.y_fit = polyval([app.sldr_xp.Value ,app.sldr_yp.Value], app.settings.x_fit);
%             y_upper = app.settings.y_fit + app.settings.dist;
%             y_lower = app.settings.y_fit - app.settings.dist;
%             app.settings.p1.XData = app.settings.x_fit;
%             app.settings.p1.YData = app.settings.y_fit;
%             app.settings.p2.XData = app.settings.x_fit;
%             app.settings.p2.YData = y_upper;
%             app.settings.p3.XData = app.settings.x_fit;
%             app.settings.p3.YData = y_lower;
%             drawnow
        end

        % Value changed function: sldr_yp
        function sldr_ypValueChanged(app, event)
            select_bursts(app, true, false);

            update_axes(app)
            
        end

        % Value changing function: sldr_yp
        function sldr_ypValueChanging(app, event)

%             app.settings.y_fit = polyval([app.sldr_xp.Value ,app.sldr_yp.Value], app.settings.x_fit);
%             y_upper = app.settings.y_fit + app.settings.dist;
%             y_lower = app.settings.y_fit - app.settings.dist;
%             app.settings.p1.XData = app.settings.x_fit;
%             app.settings.p1.YData = app.settings.y_fit;
%             app.settings.p2.XData = app.settings.x_fit;
%             app.settings.p2.YData = y_upper;
%             app.settings.p3.XData = app.settings.x_fit;
%             app.settings.p3.YData = y_lower;
%             drawnow
            
        end

        % Value changed function: edt_outlier
        function edt_outlierValueChanged(app, event)
            select_bursts(app, true, false);

            update_axes(app)
            
            
        end

        % Button down function: ax_summs
        function ax_summsButtonDown(app, event)
            working(app,true)

            ts = app.ax_summs.Children(5).XData;
            idx = find(ts >=event.IntersectionPoint(1),1,"first"); 
            dt = app.ax_summs.Children(5).YData;
            dur = app.data.fs*0.05;
            
            tmp = movmean(dt,app.data.fs/20);
            tmp = tmp(idx-dur:idx+dur);
            [~,tmp_idx] = max(tmp);
            mxi = tmp_idx+idx-dur+1;

%             [~,lcs] = findpeaks(-dt-mean(-dt),'MinPeakHeight',max(-dt-mean(-dt))/10);%findpeaks(-sumf,'MinPeakHeight',min(sumf)/10,"NPeaks",2);
%             [~,idx] = sort(abs(lcs-mxi),"ascend");
%             idx(3:end) = [];

            switch app.settings.type
                case 1
                    [~,tmp_lcs] = findpeaks(dt(1:mxi+10),'MinPeakHeight',max(dt(1:mxi+10))/10);
                    if tmp_lcs(end) >= mxi-100 & tmp_lcs(end) <= mxi+100
                        tmp_lcs(end) = [];
                    end
                    if isempty(tmp_lcs)
                        strt = 1;
                    else
                        strt = tmp_lcs(end);
                    end
                    
                    [~,tmp_lcs] = findpeaks(dt(mxi-10:end),'MinPeakHeight',max(dt(mxi-10:end))/10);
                    if tmp_lcs(1) <= 111
                        tmp_lcs(1) = [];
                    end
                    if isempty(tmp_lcs)
                        stp = length(dt);
                    else
                        stp = tmp_lcs(1)-9+mxi;
                    end
                    
                    [~,tmp_lcs] = findpeaks(-dt(strt:mxi)-mean(-dt(strt:mxi)),'MinPeakHeight',max(-dt(strt:mxi)-mean(-dt(strt:mxi)))/10, 'SortStr','descend');
                    if isempty(tmp_lcs)
                        [~,tmp_mxi] = max(-dt(strt:mxi)-mean(-dt(strt:mxi)));
                        lcs(1) = tmp_mxi+strt-1;
                    else
                        lcs(1) = tmp_lcs(1)+strt-1;
                    end

                    [~,tmp_lcs] = findpeaks(-dt(mxi:stp)-mean(-dt(mxi:stp)),'MinPeakHeight',max(-dt(mxi:stp)-mean(-dt(mxi:stp)))/10, 'SortStr','descend');
                    if isempty(tmp_lcs)
                        [~,tmp_mxi] = max(-dt(mxi:stp)-mean(-dt(mxi:stp)));
                        lcs(2) = tmp_mxi+mxi-1;
                    else
                        lcs(2) = tmp_lcs(1)+mxi-1;
                    end

%                 case 2
%                     [~,tmp_lcs] = findpeaks(-sumf(1:mxi)-mean(-sumf),'MinPeakHeight',max(-sumf-mean(-sumf))/10);
%                     [~,idx] = sort(abs(tmp_lcs-mxi),"ascend");
%                     idx(2:end) = [];
%                     lcs =tmp_lcs(idx);
%                     [~,tmp_lcs] = findpeaks(-sumf(mxi:end)-mean(-sumf),'MinPeakHeight',max(-sumf-mean(-sumf))/10);
%                     [~,idx] = sort(abs(tmp_lcs),"ascend");
%                     idx(2:end) = [];
%                     lcs(2)= tmp_lcs(idx)+mxi-1;
%                     idx = [1,2];
            end
            
            app.ax_summs.Children(5).XData = ts;
            app.ax_summs.Children(5).YData = dt;
            app.ax_summs.Children(4).XData = ts(mxi);
            app.ax_summs.Children(4).YData = dt(mxi);
            app.ax_summs.Children(3).XData = ts(lcs);
            app.ax_summs.Children(3).YData = dt(lcs);

            yl= ylim (app.ax_summs);
            app.settings.lines(1).XData = [ts(lcs(1)) ts(lcs(1))];
            app.settings.lines(1).YData = yl;
            app.settings.lines(2).XData = [ts(lcs(2)) ts(lcs(2))];
            app.settings.lines(2).YData = yl;

            app.settings.delay = ts(mxi);
            
            app.settings.window = sort(lcs,'ascend');
            app.settings.window_org = app.settings.window; % adapt for resp
            
            get_bursts3(app);
            
            select_bursts(app, false,false);
           
            update_axes(app)
            working(app, false)

        end

        % Value changed function: edt_abs_th
        function edt_abs_thValueChanged(app, event)
            working(app, true)
            select_bursts(app, false,true);
           
            update_axes(app)
            working(app, false)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_summs = uiaxes(app.UIFigure);
            xlabel(app.ax_summs, '[s]')
            ylabel(app.ax_summs, '[mV]')
            zlabel(app.ax_summs, 'Z')
            app.ax_summs.ButtonDownFcn = createCallbackFcn(app, @ax_summsButtonDown, true);
            app.ax_thresh = uiaxes(app.UIFigure);
            xlabel(app.ax_thresh, {'Amplitude'; ''})
            ylabel(app.ax_thresh, 'Delay')
            zlabel(app.ax_thresh, 'Z')
            app.ax_shapes = uiaxes(app.UIFigure);
            xlabel(app.ax_shapes, '[s]')
            ylabel(app.ax_shapes, '[mV]')
            zlabel(app.ax_shapes, 'Z')
            app.ax_amp = uiaxes(app.UIFigure);
            xlabel(app.ax_amp, 'X')
            ylabel(app.ax_amp, 'Y')
            zlabel(app.ax_amp, 'Z')
            app.ax_slope = uiaxes(app.UIFigure);
            xlabel(app.ax_slope, 'X')
            ylabel(app.ax_slope, 'Y')
            zlabel(app.ax_slope, 'Z')
            app.extentwindowAEditFieldLabel = uilabel(app.UIFigure);
            app.extentwindowAEditFieldLabel.Text = 'extent window (A)';
            app.extentwindowAEditField = uieditfield(app.UIFigure, 'numeric');
            app.extentwindowAEditField.ValueChangedFcn = createCallbackFcn(app, @extentwindowAEditFieldValueChanged, true);
            app.sectionwidthBLabel = uilabel(app.UIFigure);
            app.sectionwidthBLabel.Text = 'section  width (B)';
            app.edt_sec_width = uieditfield(app.UIFigure, 'numeric');
            app.edt_sec_width.Limits = [0 Inf];
            app.edt_sec_width.ValueChangedFcn = createCallbackFcn(app, @edt_sec_widthValueChanged, true);
            app.edt_sec_width.Value = 0.1;
            app.nstdCLabel = uilabel(app.UIFigure);
            app.nstdCLabel.Text = 'n* std (C)';
            app.edt_n_std = uieditfield(app.UIFigure, 'numeric');
            app.edt_n_std.ValueChangedFcn = createCallbackFcn(app, @edt_n_stdValueChanged, true);
            app.edt_n_std.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pics', 'scheme.png');
            app.confirmcloseButton = uibutton(app.UIFigure, 'push');
            app.confirmcloseButton.ButtonPushedFcn = createCallbackFcn(app, @confirmcloseButtonPushed, true);
            app.confirmcloseButton.Text = 'confirm / close';
            app.slopeLabel = uilabel(app.UIFigure);
            app.slopeLabel.Text = 'D';
            app.sldr_xp = uislider(app.UIFigure);
            app.sldr_xp.ValueChangedFcn = createCallbackFcn(app, @sldr_xpValueChanged, true);
            app.sldr_xp.ValueChangingFcn = createCallbackFcn(app, @sldr_xpValueChanging, true);
            app.dyLabel = uilabel(app.UIFigure);
            app.dyLabel.Text = 'E';
            app.sldr_yp = uislider(app.UIFigure);
            app.sldr_yp.ValueChangedFcn = createCallbackFcn(app, @sldr_ypValueChanged, true);
            app.sldr_yp.ValueChangingFcn = createCallbackFcn(app, @sldr_ypValueChanging, true);
            app.lbl_work = uilabel(app.UIFigure);
            app.lbl_work.Text = '';
            app.outliernstdEditFieldLabel = uilabel(app.UIFigure);
            app.outliernstdEditFieldLabel.Text = 'outlier n*std';
            app.edt_outlier = uieditfield(app.UIFigure, 'numeric');
            app.edt_outlier.ValueChangedFcn = createCallbackFcn(app, @edt_outlierValueChanged, true);
            app.edt_outlier.Value = 9;
            app.RegressionlineYDXELabel = uilabel(app.UIFigure);
            app.RegressionlineYDXELabel.Text = 'Regression line Y = D*X +E';
            app.absthresholdEditFieldLabel = uilabel(app.UIFigure);
            app.absthresholdEditFieldLabel.Text = 'abs. threshold';
            app.edt_abs_th = uieditfield(app.UIFigure, 'numeric');
            app.edt_abs_th.ValueChangedFcn = createCallbackFcn(app, @edt_abs_thValueChanged, true);
            app.BasedonGEFosteratal2021PMID34705589Label = uilabel(app.UIFigure);
            app.BasedonGEFosteratal2021PMID34705589Label.Text = 'Based on G.E. Foster at al. 2021, PMID: 34705589 ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = burst_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end