classdef cluster_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        lbl_disp_clu                  matlab.ui.control.Label
        sbtn_accept_clu_d             matlab.ui.control.StateButton
        sbtn_reject_clu_d             matlab.ui.control.StateButton
        sbtn_accept_clu_c             matlab.ui.control.StateButton
        sbtn_reject_clu_c             matlab.ui.control.StateButton
        sbtn_accept_clu_b             matlab.ui.control.StateButton
        sbtn_reject_clu_b             matlab.ui.control.StateButton
        sbtn_accept_clu_a             matlab.ui.control.StateButton
        sbtn_reject_clu_a             matlab.ui.control.StateButton
        sbtn_force                    matlab.ui.control.StateButton
        Wave_Clus303Label             matlab.ui.control.Label
        BasedonLabel                  matlab.ui.control.Label
        SetparametersButton           matlab.ui.control.Button
        Button2                       matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        sbtn_fix_clus_d               matlab.ui.control.StateButton
        sbtn_fix_clus_c               matlab.ui.control.StateButton
        sbtn_fix_clus_b               matlab.ui.control.StateButton
        sbtn_fix_clus_a               matlab.ui.control.StateButton
        lbl_clu_d_isi                 matlab.ui.control.Label
        lbl_clu_c_isi                 matlab.ui.control.Label
        lbl_clu_b_isi                 matlab.ui.control.Label
        lbl_clu_a_isi                 matlab.ui.control.Label
        lbl_clus0_isi                 matlab.ui.control.Label
        lbl_clu_d                     matlab.ui.control.Label
        lbl_clu_c                     matlab.ui.control.Label
        lbl_clu0                      matlab.ui.control.Label
        lbl_clu_b                     matlab.ui.control.Label
        lbl_clu_a                     matlab.ui.control.Label
        edt_step_clu_d                matlab.ui.control.NumericEditField
        stepEditField_5Label          matlab.ui.control.Label
        edt_max_clu_d                 matlab.ui.control.NumericEditField
        maxEditField_5Label           matlab.ui.control.Label
        edt_step_clu_c                matlab.ui.control.NumericEditField
        stepEditField_4Label          matlab.ui.control.Label
        edt_max_clu_c                 matlab.ui.control.NumericEditField
        maxEditField_4Label           matlab.ui.control.Label
        edt_step_clu_b                matlab.ui.control.NumericEditField
        stepEditField_3Label          matlab.ui.control.Label
        edt_max_clu_b                 matlab.ui.control.NumericEditField
        maxEditField_3Label           matlab.ui.control.Label
        edt_step_clu_a                matlab.ui.control.NumericEditField
        stepEditField_2Label          matlab.ui.control.Label
        edt_max_clu_a                 matlab.ui.control.NumericEditField
        maxEditField_2Label           matlab.ui.control.Label
        btn_man_clus_d                matlab.ui.control.Button
        btn_man_clus_c                matlab.ui.control.Button
        btn_man_clus_b                matlab.ui.control.Button
        btn_man_clus_a                matlab.ui.control.Button
        dd_shape                      matlab.ui.control.DropDown
        SpikeDropDownLabel            matlab.ui.control.Label
        edt_step_clu_0                matlab.ui.control.NumericEditField
        stepEditFieldLabel            matlab.ui.control.Label
        edt_max_clu_0                 matlab.ui.control.NumericEditField
        maxEditFieldLabel             matlab.ui.control.Label
        btn_merge                     matlab.ui.control.Button
        btn_clus0_man                 matlab.ui.control.Button
        btn_fix_all                   matlab.ui.control.Button
        btn_manual                    matlab.ui.control.Button
        btn_projection                matlab.ui.control.Button
        edt_min_clus                  matlab.ui.control.NumericEditField
        minclustersizeEditFieldLabel  matlab.ui.control.Label
        btn_temp                      matlab.ui.control.Button
        btn_next                      matlab.ui.control.Button
        btn_prev                      matlab.ui.control.Button
        btn_confirm                   matlab.ui.control.Button
        btn_undo                      matlab.ui.control.Button
        dd_plot                       matlab.ui.control.DropDown
        plotDropDownLabel             matlab.ui.control.Label
        ax_clus_b                     matlab.ui.control.UIAxes
        ax_clus_d_isi                 matlab.ui.control.UIAxes
        ax_clus_d                     matlab.ui.control.UIAxes
        ax_clus_c_isi                 matlab.ui.control.UIAxes
        ax_clus_a_isi                 matlab.ui.control.UIAxes
        ax_clus_a                     matlab.ui.control.UIAxes
        ax_clus_b_isi                 matlab.ui.control.UIAxes
        ax_clus_c                     matlab.ui.control.UIAxes
        ax_clu0_isi                   matlab.ui.control.UIAxes
        ax_clu0                       matlab.ui.control.UIAxes
        ax_clusters                   matlab.ui.control.UIAxes
        ax_temp                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        calling_app % Description
        user_data
        settings
        data_in
        autosort_nfo
    end
    
    methods (Access = public)
        
        function cluster_funct(app, par_in)
            app.settings.cluster_display = 1:4;
            
            app.settings.par = set_parameters(par_in.sr);
            app.settings.par = update_parameters(app.settings.par, par_in,'clus');

            tmp = '0abcd';
            for i =1:length(tmp)
                eval(['app.edt_max_clu_' tmp(i) '.Value = app.settings.par.nbins;']);
                eval(['app.edt_step_clu_' tmp(i) '.Value = app.settings.par.bin_step;']);
            end
            for i =2:length(tmp)
                eval(['app.sbtn_fix_clus_' tmp(i) '.Value = false;'])
            end
            for i = 1 : 33
                app.settings.cluster(i).nbins = app.settings.par.nbins;
                app.settings.cluster(i).bin_step = app.settings.par.bin_step;
                app.settings.cluster(i).fix = false;
%                 app.settings.cluster(i).reject = false;
                
            end
            app.settings.cluster_0.nbins = app.settings.par.nbins;
            app.settings.cluster_0.bin_step  = app.settings.par.bin_step;
            [cluster_res] = cluster_spikes(app.data_in.index, app.data_in.spikes, app.data_in.par,app.data_in.threshold);
            
            if isfield(cluster_res, 'ipermut')
                ipermut = cluster_res.ipermut;
            else
            	ipermut = [];
            end

            if isfield(cluster_res, 'forced')
                forced = cluster_res.forced;
            else
            	forced = false(size(spikes,1), 1);
            end
  
            % cluster_class(:,1);
            index = cluster_res.cluster_class(1:end,2);
            classes = cluster_res.cluster_class(1:end,1);
            
            if isfield(cluster_res, 'Temp')
                Temp = cluster_res.Temp;
            else
            	Temp = ones(max(classes));
            end

            clu = cluster_res.clu;
            tree = cluster_res.tree;
            
            if isfield(cluster_res, 'rejected')
                rejected = cluster_res.rejected;
            else
            	rejected = false(1,size(cluster_res.cluster_class,1));
            end

            app.settings.setclus = 1;
            if isempty(ipermut)
                app. settings.par.permut = 'n';
            end

            if app.settings.par.permut == 'y' && ~isempty(clu)
                if isempty(ipermut) %load from old result without ipermut or par, but par.permut=='y'
                   naux =  size(clu,2)-2;
	               ipermut = 1:naux;
                end
                clu_aux = zeros(size(clu,1),2 + size(app.data_in.spikes,1)) -1; %when update classes from clu, not selected elements go to cluster 0
                clu_aux(:,ipermut+2) = clu(:,(1:length(ipermut))+2);
                clu_aux(:,1:2) = clu(:,1:2);
                clu = clu_aux;
                clear clu_aux
            elseif ~isempty(clu)
                naux = size(clu,2)-2;
                clu_aux = zeros(size(clu,1),2 + size(app.data_in.spikes,1)) -1; %when update classes from clu, not selected elements go to cluster 0
                clu_aux(:,(1:naux)+2) = clu(:,(1:naux)+2);
                clu_aux(:,1:2) = clu(:,1:2);
                clu = clu_aux;
                clear clu_aux
            end
            inspk = cluster_res.inspk;
            
            data = {[]};
            data{1} = app.settings.par;
            data{2} = app.data_in.spikes;
            data{3} = app.data_in.index;
            data{4} = clu;
            data{5} = tree;
            data{7} = inspk;
            if exist('ipermut','var') && ~isempty(ipermut)
                data{12} = ipermut;
            end
            data{13} = forced;
            data{14} = forced;
            data{15} = rejected;
            data{16} = rejected;
            data{53} = false(33,1);
            data{54} = false(33,1);

            app.edt_min_clus.Value = app.settings.par.min_clus;
            app.user_data = data;
            
            current_temp = cluster_res.gui_status.current_temp;
            if isempty(current_temp) || (current_temp == -1 )
                current_temp=1;
            end
            saved_gui_status = cluster_res.gui_status.original_classes;
            auto_sort_info = [];
            if isfield(cluster_res.gui_status,'auto_sort_info')
                auto_sort_info = cluster_res.gui_status.auto_sort_info;
            end
            
            app.autosort_nfo = auto_sort_info;

            clustering_results = zeros(length(classes),4);
            clustering_results(:,1) = repmat(current_temp,length(classes),1);
            for i=1:max(classes)
              clustering_results(classes==i,3)  = Temp(i);
            end
        
            clustering_results(:,2) = classes'; % GUI classes
            clustering_results(:,4) = saved_gui_status;
            app.settings.undo = 1;

            clustering_results(:,5) = repmat(app.settings.par.min_clus,length(classes),1); % minimum number of clusters
            data{6} = classes(:)';
            if exist('current_temp','var')
                data{8} = current_temp;
            else
                data{8} = temp(1);
            end
            data{10} = clustering_results;
            data{11} = clustering_results;
            app.settings.force = 0;
            app.settings.merge = 0;
            
            app.settings.minclus = app.settings.par.min_clus;

            app.user_data = data;

            clear clustering_results classes rejected spikes

            plot_spikes_ad(app)
            data = app.user_data;
            app.user_data = data;

            if isfield(app,'btn_force') && (nnz(forced)>0)
	            set(app.sbtn_force,'Value',1)
                set(app.sbtn_force,'String','FORCED')
                %set(handles.change_temperature_button,'enable','off');
            elseif isfield(app,'btn_force')
                set(app.sbtn_force,'Value',0)
                set(app.sbtn_force,'String','Force')
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, index, spikes, par, threshold)
            app.calling_app = caller;
            app.data_in.index = index;
            app.data_in.spikes = spikes;
            app.data_in.par = par;
            app.data_in.threshold = threshold;
            disableDefaultInteractivity(app.ax_temp)
            disableDefaultInteractivity(app.ax_clusters)
            disableDefaultInteractivity(app.ax_clu0)
            disableDefaultInteractivity(app.ax_clus_a)
            disableDefaultInteractivity(app.ax_clus_b)
            disableDefaultInteractivity(app.ax_clus_c)
            disableDefaultInteractivity(app.ax_clus_d)
            disableDefaultInteractivity(app.ax_clu0_isi)
            disableDefaultInteractivity(app.ax_clus_a_isi)
            disableDefaultInteractivity(app.ax_clus_b_isi)
            disableDefaultInteractivity(app.ax_clus_c_isi)
            disableDefaultInteractivity(app.ax_clus_d_isi)
            cluster_funct(app, app.data_in.par)
            
        end

        % Button pushed function: btn_temp
        function btn_tempButtonPushed(app, event)
            if exist('impoint','class')
                %point = impoint(app.ax_temp);
                point = drawpoint(app.ax_temp); %% neue versionj
                if isempty(point)
                    return
                end
                temp = point.Position(1);
                aux = point.Position(2);
%                 pos = point.getPosition();
% 	            temp = pos(1);
%                 aux = pos(2);
                point.delete()
            else
                [temp,aux,button] = ginput(1);
                if button == 3
                    return
                end
            end

            if temp <= app.settings.par.mintemp+0.5*app.settings.par.tempstep
                temp = 1;
            elseif temp>app.settings.par.maxtemp
                temp = floor((app.settings.par.maxtemp-app.settings.par.mintemp)/app.settings.par.tempstep);
            else
                temp = round((temp-app.settings.par.mintemp)/app.settings.par.tempstep); %temp from value to index
            end
            
            min_clus = round(aux);
            app.edt_min_clus.Value = min_clus;
            
            data = app.user_data;
            rejected = data{15};
            data{16} = rejected;
            data{54} = data{53};
            clu = data{4};
            classes = clu(temp, 3:end) + 1;
            classes(rejected) = 0;
            data{6} = classes(:)';
            data{8} = temp;
            
            app.settings.minclus = min_clus;
            app.user_data = data;
            app.settings.setclus = 0;
            app.settings.force = 0;
            app.settings.merge = 0;
            
            app.settings.undo = 0;
            app.settings.new_spc_classes = classes(:)';
            plot_spikes_ad(app);
            if isfield(app,'sbtn_force')
	            app.sbtn_force.Value = false;
                app.sbtn_force.Text = 'Force';
            end
        end

        % Value changed function: edt_min_clus
        function edt_min_clusValueChanged(app, event)
            data = app.user_data;
            min_clus = app.edt_min_clus.Value;
            clu = data{4};
            temp = data{8};
            classes = clu(temp,3:end)+1;
            data{6} = classes(:)';
            data{16} = data{15};
            clustering_results = data{10};
            clustering_results(:,5) = min_clus;
            app.user_data = data;
            app.settings.setclus = 0;
            app.settings.force = 0;
            app.settings.merge = 0;
            app.settings.undo = 0;
            
            app.settings.minclus = min_clus;
            plot_spikes_ad(app);
            
        end

        % Button pushed function: btn_confirm
        function btn_confirmButtonPushed(app, event)
            UIFigureCloseRequest(app, [])
            
        end

        % Value changed function: sbtn_force
        function sbtn_forceValueChanged(app, event)
            
            data = app.user_data;
            par = data{1};
            classes = data{6};
            forced = data{13};
            data{14} = forced;     %save forced in forced_bk
            rejected = data{15};
            data{16} = data{15}; %update bk of rejected spikes
            data{54} = data{53};
            button_state = app.sbtn_force.Value;
            if button_state
                spikes = data{2};
                inspk = data{7};
                data{16} = rejected;
                for i = 1 : 33
                    if app.settings.cluster(i).fix
                        fix_class = data{19+i};
                        classes(fix_class) = -1;
                    end
                end
        
                to_force = (~rejected)' & (classes==0);
                switch par.force_feature
                    case 'spk'
                        f_in  = spikes(classes>0,:);
                        f_out = spikes(to_force,:);
                    case 'wav'
                        if isempty(inspk)
                            [inspk] = wave_features(spikes,par);        % Extract spike features.
                            data{7} = inspk;
                        end
                        f_in  = inspk(classes>0,:);
                        f_out = inspk(to_force,:);
                end
        
                class_in = classes(classes>0);
                class_out = force_membership_wc(f_in, class_in, f_out, par);
                forced = forced(:) | to_force(:);
                classes(to_force) = class_out;
                data{13} = forced;
        
                clustering_results = data{10};
                app.settings.minclus = clustering_results(1,5);
                app.settings.force = 1;
                app.settings.setclus = 1;
                app.sbtn_force.Text = 'Forced';
                %set(handles.change_temperature_button,'enable','off');
            else
        
                new_forced = zeros(size(forced));
                % Fixed clusters are not considered for forcing
                for i=1:33
                    app.settings.cluster(i).fix = false;
                end
                classes(forced(:) & (~new_forced(:)) & (~rejected(:))) = 0;  %the elements that before were forced but it isn't force any more, pass to class 0
                data{13} = new_forced;
                app.settings.force = 0;
                app.settings.setclus = 1;
                app.settings.unforce = true;
                app.sbtn_force.Text = 'Force';
                %set(handles.change_temperature_button,'enable','on');
            end
            data{6} = classes(:)';
        
            app.settings.merge = 0;
            app.settings.undo = 0;
            app.user_data = data;
            plot_spikes_ad(app);
            
        end

        % Button pushed function: btn_clus0_man, btn_man_clus_a, 
        % ...and 4 other components
        function btn_clus0_manButtonPushed(app, event)
            %event.Source.Tag => name of the source
            event.Source.Enable = "off";
            
            switch event.Source.Tag
                case 'ax_clusters'
                    cl = -1;
                case 'ax_clu0'
                    cl = 0;
                case 'ax_clus_a'
                    cl = 1;
                case 'ax_clus_b'
                    cl = 2;
                case 'ax_clus_c'
                    cl = 3;
                case 'ax_clus_d'
                    cl = 4;
            end
            data = app.user_data;
               
            spikes = data{2};
            classes = data{6};
            forced = data{13};
    
            if cl == -1
                current_ax = ap.ax_clusters;
                valids = ~data{15}(:); %First, I don't select the rejected
            else
                eval(['current_ax = app.' event.Source.Tag ';']);
                valids = ~data{15}(:) & (classes(:)==cl); %First, I don't select the rejected
            end
            
            rect_ob = 	drawrectangle(current_ax);
            rect = rect_ob.Position;
            rect_ob.delete(), clear rect_ob
    
            if rect(1) > size(spikes,2) || (rect(1) + rect(3))<1 %if the rect is totally outside the axis
                event.Source.Enable = "on";
                return;
            end
            yborders = ylim(current_ax);
            if rect(2)<yborders(1)
                ymin = -inf;
            else
                ymin = rect(2);
            end
            if rect(2) + rect(4)>yborders(2)
                ymax = inf;
            else
                ymax = rect(2) + rect(4);
            end
            xind = max(1, ceil(rect(1)));
            xend = min(size(spikes,2),floor(rect(1) + rect(3)));
            if(rect(3)<2)
                if rect(3)==0
                    event.Source.Enable = "on";
                    return;
                end
                sp_selected = (max(spikes(valids,xind:xend),[],2)>ymin) &  (min(spikes(valids,xind:xend),[],2)<ymax);
                valids(valids==1) = sp_selected;
            else
                xD = xend-xind;
                yD = ymin - ymax;
                if xD==0 || yD == 0
                    event.Source.Enable = "on";
                    set(hObject,'value',0);
                    return;
                end
                [Mh, Mpos] = max(spikes(valids,xind:xend)');
                [mh ,mpos] = min(spikes(valids,xind:xend)');
                if ceil(rect(1)) < 1 %if rect is out the axis, extreme in border count like inside the rectangle
                    xiborder=0;
                else
                    xiborder=1;
                end
                if floor(rect(1) + rect(3)) > size(spikes,2) %if rect is out the axis, extreme in border count like inside the rectangle
                    xeborder = xD+2;
                else
                    xeborder = xD;
                end
                sp_selected = (Mh >= ymin & Mh <= ymax) & (Mpos > xiborder & Mpos < xeborder);
                sp_selected = sp_selected |((mh >= ymin & mh <= ymax) & (mpos > xiborder & mpos < xeborder));
                valids(valids==1) = sp_selected;
            end
            if nnz(valids)==0
                event.Source.Enable = "on";
                return;
            end
    
    
            clus_n = max(classes) + 1;
            data{14} = forced;
    
            forced(valids) = 0;
            classes(valids)= clus_n;
            app.settings.new_manual = valids;
            app.settings.setclus = 1;
            app.settings.force = 0;
            app.settings.merge = 0;
    
            app.settings.undo = 0;
            data{6} = classes(:)';
            data{13} = forced;
            data{16} = data{15}; %update bk of rejected spikes
            data{54} = data{53};

            app.user_data = data;
            event.Source.Enable = "on";
            plot_spikes_ad(app);
        end

        % Button pushed function: btn_projection
        function btn_projectionButtonPushed(app, event)
            data = app.user_data;           %
            par = data{1};                  %
            Plot_all_features_ad(app)  % <= need adaption ???
                                       %
        end

        % Value changed function: sbtn_fix_clus_a, sbtn_fix_clus_b, 
        % ...and 2 other components
        function sbtn_fix_clus_aValueChanged(app, event)

            btn = str2num(event.Source.Tag);
            clstr = app.settings.cluster_display(btn);
            data = app.user_data;
            classes = data{6};
            fix_class = find(classes== clstr);
            if isempty(fix_class)
                return
            end
            tmp = 'abcd';
            if eval(['app.sbtn_fix_clus_' tmp(btn) '.Value'])
                data{19+clstr} = fix_class;  %20 for class 1, etc
            else
                data{19+clstr} = [];
            end
            app.user_data = data;
            
        end

        % Value changed function: edt_max_clu_0, edt_max_clu_a, 
        % ...and 3 other components
        function edt_max_clu_dValueChanged(app, event)
            btn = str2num(event.Source.Tag);
            
            data = app.user_data;
            par = data{1};
            
            value = event.Value;
            if btn >= 0
                cn = app.settings.cluster_display(btn);
                tmp = ['app.settings.cluster(' num2str(cn) ').'];
            else
                cn = btn;
                tmp = 'app.settings.cluster_0.';
            end

            if ~isempty(value) && value > 0 && value>=eval([tmp 'bin_step'])
                eval([tmp 'nbins = value;']);
                data{1} = par;
                app.user_data = data;

                spk_times = data{3};
                classes = data{6};
                par = data{1};
                                
                if classes == 0
                    rejected = data{15};
                    times = diff(spk_times(classes(:)==cn & ~rejected(:)));
                    clear rejected 
                else
                    times = diff(spk_times(classes==cn));
                end
                % Calculates # ISIs < 3ms  
                multi_isi = nnz(times < 3); 
                % Builds and plots the histogram
                
                if cn == 0
                    bin_step = app.settings.cluster_0.bin_step;
                    nbins = app.settings.cluster_0.nbins;
                else
                    bin_step = app.settings.cluster(cn).bin_step;
                    nbins = app.settings.cluster(cn).nbins;
                end
            
                [N,X]=hist(times,0:bin_step:nbins);
            
                ttl = [num2str(multi_isi) ' in < 3ms'];
                if cn == 0
                    xlim (app.ax_clu0_isi,'manual')
                    bar(app.ax_clu0_isi,X(1:end-1),N(1:end-1))
                    xlim(app.ax_clu0_isi,[0 nbins]);
                    title(app.ax_clu0_isi,ttl)
                else
                    app.settings.cluster(cn).N = N;
                    app.settings.cluster(cn).X = X;
                    app.settings.cluster(cn).ttl_isi = ttl;
                end
                update_cluster_plots(app)
                
            else
                if cn == 0 
                    app.edt_max_clu_0.Value = app.settings.cluster_0.nbins;
                else
                    tmp = 'abcd';
                    eval(['app.edt_max_clu_' tmp(btn) '.Value = app.settings.cluster(' num2str(cn) ').nbins;']);
                end
            end
            
        end

        % Value changed function: edt_step_clu_0, edt_step_clu_a, 
        % ...and 3 other components
        function edt_step_clu_0ValueChanged(app, event)
            btn = str2num(event.Source.Tag);
            
            data = app.user_data;
            par = data{1};
            
            value = event.Value;
            if btn >= 0
                cn = app.settings.cluster_display(btn);
                tmp = ['app.settings.cluster(' num2str(cn) ').'];
            else
                cn = btn;
                tmp = 'app.settings.cluster_0.';
            end
            
            if ~isempty(value) && value > 0 && value<=eval([tmp 'nbins'])
                eval([tmp 'bin_step = value;']);
                data{1} = par;

                app.user_data = data;

                spk_times = data{3};
                classes = data{6};
                par = data{1};
                                
                if classes == 0
                    rejected = data{15};
                    times = diff(spk_times(classes(:)==cn & ~rejected(:)));
                    clear rejected 
                else
                    times = diff(spk_times(classes==cn));
                end
                % Calculates # ISIs < 3ms  
                multi_isi = nnz(times < 3); 
                % Builds and plots the histogram
                
                if cn == 0
                    bin_step = app.settings.cluster_0.bin_step;
                    nbins = app.settings.cluster_0.nbins;
                else
                    bin_step = app.settings.cluster(cn).bin_step;
                    nbins = app.settings.cluster(cn).nbins;
                end
            
                [N,X]=hist(times,0:bin_step:nbins);
            
                ttl = [num2str(multi_isi) ' in < 3ms'];
                if cn == 0
                    xlim (app.ax_clu0_isi,'manual')
                    bar(app.ax_clu0_isi,X(1:end-1),N(1:end-1))
                    xlim(app.ax_clu0_isi,[0 nbins]);
                    title(app.ax_clu0_isi,ttl)
                else
                    app.settings.cluster(cn).N = N;
                    app.settings.cluster(cn).X = X;
                    app.settings.cluster(cn).ttl_isi = ttl;
                end
                update_cluster_plots(app)
            else
                
                if cn == 0 
                    app.edt_step_clu_0.Value = app.settings.cluster_0.bin_step;
                else
                    tmp = 'abcd';
                    eval(['app.edt_step_clu_' tmp(btn) '.Value = app.settings.cluster(' num2str(cn) ').bin_step;']);
                end
            end
        end

        % Value changed function: sbtn_accept_clu_a, sbtn_accept_clu_b, 
        % ...and 2 other components
        function sbtn_accept_clu_aValueChanged(app, event)
            btn = str2num(event.Source.Tag);
            tmp = 'abcd';
            
            if ~value 
                eval(['app.sbtn_accept_clu_' tmp(btn) '.Value = true'])
            end
            
            cn = app.settings.cluster_display(btn);
            app.settings.cluster(cn).reject = false;
            eval(['app.sbtn_reject_clu_' tmp(btn) '.Value = false' ])
                    
        end

        % Value changed function: sbtn_reject_clu_a, sbtn_reject_clu_b, 
        % ...and 2 other components
        function sbtn_reject_clu_aValueChanged(app, event)
            value = event.Value;
            btn = str2num(event.Source.Tag);
            tmp = 'abcd';
            cn = app.settings.cluster_display(btn);


            eval(['app.sbtn_reject_clu_' tmp(btn) '.Value = true'])
            eval(['app.sbtn_accept_clu_' tmp(btn) '.Value = false'])
            
            data = app.user_data;
            classes = data{6};
            
%             app.settings.cluster(cn).reject = true;
            data{54} = data{53};
            data{53}(cn) = true;

            forced = data{13};
            data{14} = forced;
            forced(classes==cn) = 0;
            data{13} = forced;
            
            classes(classes==cn) = 0;
            data{6} = classes;
            
            clustering_results = data{10};
            data{11} = clustering_results; % Save backup
            
            app.user_data = data;
            
            eval(['app.sbtn_reject_clu_' tmp(btn) '.Value = false'])
            
            %linkaxes(eval(['handles.spikes' int2str(cn)]),'off');
            % cla(eval(['handles.spikes' int2str(cn)]),'reset');
            delete(allchild(eval(['app.ax_clus_' tmp(btn)])))
            
            eval(['cla(app.ax_clus_' tmp(btn) '_isi,''reset'');']);
            eval(['app.sbtn_accept_clu_' tmp(btn) '.Value = true'])
        end

        % Button pushed function: btn_undo
        function btn_undoButtonPushed(app, event)
            app.settings.force = 0;
            app.settings.merge = 0;
            app.settings.undo = 1;
            app.settings.setclus = 1;
            
            data = app.user_data;
            par = data{1};
            clustering_results_bk = data{11};
            forced_bk =  data{14};
            
            data{13} = forced_bk;
            
            data{15} = data{16}; %use bk of rejected spikes
            data{53} = data{54};

            data{6} = clustering_results_bk(:,2); % old gui classes
            data{10} = clustering_results_bk;
            app.settings.minclus = clustering_results_bk(1,5);
            data{8} = clustering_results_bk(1,1); % old gui temperatures
            app.user_data = data;
            plot_spikes_ad(app) % plot_spikes updates USER_DATA{11}
            
            app.edt_min_clus.Value = app.settings.minclus;

            if any(forced_bk)
                app.sbtn_force.Value = true;
                app.sbtn_force.Text = 'FORCED';
            else
                app.sbtn_force.Value = false;
                app.sbtn_force.Text = 'Force';
            end
            
        end

        % Button pushed function: btn_merge
        function btn_mergeButtonPushed(app, event)
            app.settings.force = 0;
            app.settings.merge = 1;
            app.settings.undo = 0;
            app.settings.setclus = 1;
            
            data = app.user_data; 
            clustering_results = data{10};
            app.settings.minclus = clustering_results(1,5);
            data{16} = data{15}; %use bk of rejected spikes
            data{54} = data{53};
            app.user_data = data;
            plot_spikes_ad(app)
        end

        % Button pushed function: btn_fix_all
        function btn_fix_allButtonPushed(app, event)
            data = app.user_data;
            par = data{1};
            classes = data{6};
            cls = unique(classes);
            cls = cls(cls>0);
        
            
            for ci = 1:length(cls)
                c = cls(ci);
                status(ci) = app.settings.cluster(c).fix;
                
            end
            if all(status==1)
                for ci = 1:length(cls)
                    c = cls(ci);
                    fix_class = find(classes==c);
                    data{19+c} = [];
                    app.settings.cluster(c).fix = false;
                end
            else
                for ci = 1:length(cls)
                    c = cls(ci);
                    fix_class = find(classes==c);
                    data{19+c} = fix_class;
                    app.settings.cluster(c).fix = false;
                end
            end
            data{1} = par;
            app.user_data = data;

        end

        % Button pushed function: SetparametersButton
        function SetparametersButtonPushed(app, event)
            [par_in, confirm] = edit_parameter(app.settings.par);
            app.settings.par = update_parameters(app.settings.par, par_in, 'relevant');
            if confirm
                cluster_funct(app, app.settings.par)
            end
        end

        % Button pushed function: btn_prev
        function btn_prevButtonPushed(app, event)
            if app.settings.cluster_display(1) > 1
                app.settings.cluster_display = app.settings.cluster_display-1;
                update_cluster_plots(app)
            end
        end

        % Button pushed function: btn_next
        function btn_nextButtonPushed(app, event)
            if app.settings.cluster_display(4) < app.settings.cluster_0.n_clus
                app.settings.cluster_display = app.settings.cluster_display+1;
                update_cluster_plots(app)
            end
        end

        % Value changed function: dd_shape
        function dd_shapeValueChanged(app, event)
            switch app.dd_shape.Value 
                case app.dd_shape.Items{1}
                    data = app.user_data;
                    cluster_results = data{10};
                    app.settings.setclus = 1;
                    app.settings.force = 0;
                    app.settings.merge = 0;
                    
                    app.settings.undo = 0;
                    app.settings.minclus = cluster_results(1,5);
                    plot_spikes_ad(app);
                case app.dd_shape.Items{2}
                    data = app.user_data;
                    cluster_results = data{10};
                    app.settings.setclus = 1;
                    app.settings.force = 0;
                    app.settings.merge = 0;
                    
                    app.settings.undo = 0;
                    app.settings.minclus = cluster_results(1,5);
                    plot_spikes_ad(app);
            end
            
            
        end

        % Value changed function: dd_plot
        function dd_plotValueChanged(app, event)
             switch app.dd_plot.Value 
                case app.dd_plot.Items{1}
                    data = app.user_data;
                    cluster_results = data{10};
                    app.settings.setclus = 1;
                    app.settings.force = 0;
                    app.settings.merge = 0;
                    app.settings.undo = 0;
                    app.settings.minclus = cluster_results(1,5);
                    plot_spikes_ad(app);
                case app.dd_plot.Items{2}
                    data = app.user_data;
                    cluster_results = data{10};
                    app.settings.setclus = 1;
                    app.settings.force = 0;
                    app.settings.merge = 0;
                    app.settings.undo = 0;
                    app.settings.minclus = cluster_results(1,5);
                    plot_spikes_ad(app);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            data = app.user_data;
            classes = data{6};
            clustering_results = data{10};
            rejected = data{15};
            
            data{16} =  rejected;     %update bk of rejected spikes
            data{15} = false(size(rejected));
            app.user_data = data;
            app.settings.setclus = 1;
            app.settings.force = 0;
            app.settings.merge = 0;
            
            app.settings.undo = 0;
            plot_spikes_ad(app)
            
            data = app.user_data;
            spikes = data{2};
            used_par = data{1};
            index = data{3};
            classes = data{6};
            gui_classes_data = data{10};
            
            % Classes should be consecutive numbers
            classes_names = nonzeros(sort(unique(classes)));
            for i= 1:length(classes_names)
               c = classes_names(i);
               if c~= i
                   classes(classes == c) = i;
               end
            end
            
            %Saves clusters
            cluster_class = zeros(size(spikes,1),2);
            cluster_class(:,1) = classes(:);
            cluster_class(:,2) = data{3}';
             
            par = struct;
            par = update_parameters(par,used_par,'relevant');
            par = update_parameters(par,used_par,'batch_plot');
            par.sorting_date = datestr(now);
            
            gui_status = struct();
            gui_status.current_temp =  gui_classes_data(1,1);
            gui_status.original_classes = gui_classes_data(1:end,4);
            gui_status.auto_sort_info = app.autosort_nfo;
            
            Temp = zeros(length(classes_names),1);
            for i = 1:length(classes_names)
                Temp(i) = gui_classes_data(find(classes==i,1,'first'),3);
            end
            forced = data{13};

            %% save output

            cluster_res = struct('rejected',rejected,'spikes',spikes,'cluster_class',cluster_class,'par',par,'gui_status',gui_status,'forced', forced, 'Temp',Temp);
            if ~isempty(data{7})
                cluster_res.inspk = data{7};
            end
            
            if ~isempty(data{12})
                cluster_res.ipermut = data{12};
            end


            %% the end
            import_wc_cluster(app.calling_app,cluster_res)
            delete(app)
                       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ax_temp = uiaxes(app.UIFigure);
            app.ax_clusters = uiaxes(app.UIFigure);
            app.ax_clu0 = uiaxes(app.UIFigure);
            app.ax_clu0_isi = uiaxes(app.UIFigure);
            app.ax_clus_c = uiaxes(app.UIFigure);
            app.ax_clus_b_isi = uiaxes(app.UIFigure);
            app.ax_clus_a = uiaxes(app.UIFigure);
            app.ax_clus_a_isi = uiaxes(app.UIFigure);
            app.ax_clus_c_isi = uiaxes(app.UIFigure);
            app.ax_clus_d = uiaxes(app.UIFigure);
            app.ax_clus_d_isi = uiaxes(app.UIFigure);
            app.ax_clus_b = uiaxes(app.UIFigure);
            app.plotDropDownLabel = uilabel(app.UIFigure);
            app.plotDropDownLabel.Text = 'plot';
            app.dd_plot = uidropdown(app.UIFigure);
            app.dd_plot.Items = {'all', 'average'};
            app.dd_plot.ValueChangedFcn = createCallbackFcn(app, @dd_plotValueChanged, true);
            app.dd_plot.Value = 'all';
            app.btn_undo = uibutton(app.UIFigure, 'push');
            app.btn_undo.ButtonPushedFcn = createCallbackFcn(app, @btn_undoButtonPushed, true);
            app.btn_undo.Text = 'undo';
            app.btn_confirm = uibutton(app.UIFigure, 'push');
            app.btn_confirm.ButtonPushedFcn = createCallbackFcn(app, @btn_confirmButtonPushed, true);
            app.btn_confirm.Text = 'confirm';
            app.btn_prev = uibutton(app.UIFigure, 'push');
            app.btn_prev.ButtonPushedFcn = createCallbackFcn(app, @btn_prevButtonPushed, true);
            app.btn_prev.Text = '<';
            app.btn_next = uibutton(app.UIFigure, 'push');
            app.btn_next.ButtonPushedFcn = createCallbackFcn(app, @btn_nextButtonPushed, true);
            app.btn_next.Text = '>';
            app.btn_temp = uibutton(app.UIFigure, 'push');
            app.btn_temp.ButtonPushedFcn = createCallbackFcn(app, @btn_tempButtonPushed, true);
            app.btn_temp.Text = 'Change temperature';
            app.minclustersizeEditFieldLabel = uilabel(app.UIFigure);
            app.minclustersizeEditFieldLabel.Text = 'min .cluster size';
            app.edt_min_clus = uieditfield(app.UIFigure, 'numeric');
            app.edt_min_clus.ValueChangedFcn = createCallbackFcn(app, @edt_min_clusValueChanged, true);
            app.btn_projection = uibutton(app.UIFigure, 'push');
            app.btn_projection.ButtonPushedFcn = createCallbackFcn(app, @btn_projectionButtonPushed, true);
            app.btn_projection.Text = 'Plot all projections';
            app.btn_manual = uibutton(app.UIFigure, 'push');
            app.btn_manual.ButtonPushedFcn = createCallbackFcn(app, @btn_clus0_manButtonPushed, true);
            app.btn_manual.Tag = 'ax_clusters';
            app.btn_manual.Text = 'Manual';
            app.btn_fix_all = uibutton(app.UIFigure, 'push');
            app.btn_fix_all.ButtonPushedFcn = createCallbackFcn(app, @btn_fix_allButtonPushed, true);
            app.btn_fix_all.Text = 'Fix All';
            app.btn_clus0_man = uibutton(app.UIFigure, 'push');
            app.btn_clus0_man.ButtonPushedFcn = createCallbackFcn(app, @btn_clus0_manButtonPushed, true);
            app.btn_clus0_man.Tag = 'ax_clu0';
            app.btn_clus0_man.Text = 'Manual';
            app.btn_merge = uibutton(app.UIFigure, 'push');
            app.btn_merge.ButtonPushedFcn = createCallbackFcn(app, @btn_mergeButtonPushed, true);
            app.btn_merge.Text = 'Merge';
            app.maxEditFieldLabel = uilabel(app.UIFigure);
            app.maxEditFieldLabel.Text = 'max.';
            app.edt_max_clu_0 = uieditfield(app.UIFigure, 'numeric');
            app.edt_max_clu_0.ValueChangedFcn = createCallbackFcn(app, @edt_max_clu_dValueChanged, true);
            app.edt_max_clu_0.Tag = '0';
            app.stepEditFieldLabel = uilabel(app.UIFigure);
            app.stepEditFieldLabel.Text = 'step';
            app.edt_step_clu_0 = uieditfield(app.UIFigure, 'numeric');
            app.edt_step_clu_0.ValueChangedFcn = createCallbackFcn(app, @edt_step_clu_0ValueChanged, true);
            app.edt_step_clu_0.Tag = '0';
            app.SpikeDropDownLabel = uilabel(app.UIFigure);
            app.SpikeDropDownLabel.Text = 'Spike';
            app.dd_shape = uidropdown(app.UIFigure);
            app.dd_shape.Items = {'shapes', 'features'};
            app.dd_shape.ValueChangedFcn = createCallbackFcn(app, @dd_shapeValueChanged, true);
            app.dd_shape.Value = 'shapes';
            app.btn_man_clus_a = uibutton(app.UIFigure, 'push');
            app.btn_man_clus_a.ButtonPushedFcn = createCallbackFcn(app, @btn_clus0_manButtonPushed, true);
            app.btn_man_clus_a.Tag = 'ax_clus_a';
            app.btn_man_clus_a.Text = 'Manual';
            app.btn_man_clus_b = uibutton(app.UIFigure, 'push');
            app.btn_man_clus_b.ButtonPushedFcn = createCallbackFcn(app, @btn_clus0_manButtonPushed, true);
            app.btn_man_clus_b.Tag = 'ax_clus_b';
            app.btn_man_clus_b.Text = 'Manual';
            app.btn_man_clus_c = uibutton(app.UIFigure, 'push');
            app.btn_man_clus_c.ButtonPushedFcn = createCallbackFcn(app, @btn_clus0_manButtonPushed, true);
            app.btn_man_clus_c.Tag = 'ax_clus_c';
            app.btn_man_clus_c.Text = 'Manual';
            app.btn_man_clus_d = uibutton(app.UIFigure, 'push');
            app.btn_man_clus_d.ButtonPushedFcn = createCallbackFcn(app, @btn_clus0_manButtonPushed, true);
            app.btn_man_clus_d.Tag = 'ax_clus_d';
            app.btn_man_clus_d.Text = 'Manual';
            app.maxEditField_2Label = uilabel(app.UIFigure);
            app.maxEditField_2Label.Text = 'max.';
            app.edt_max_clu_a = uieditfield(app.UIFigure, 'numeric');
            app.edt_max_clu_a.ValueChangedFcn = createCallbackFcn(app, @edt_max_clu_dValueChanged, true);
            app.edt_max_clu_a.Tag = '1';
            app.stepEditField_2Label = uilabel(app.UIFigure);
            app.stepEditField_2Label.Text = 'step';
            app.edt_step_clu_a = uieditfield(app.UIFigure, 'numeric');
            app.edt_step_clu_a.ValueChangedFcn = createCallbackFcn(app, @edt_step_clu_0ValueChanged, true);
            app.edt_step_clu_a.Tag = '1';
            app.maxEditField_3Label = uilabel(app.UIFigure);
            app.maxEditField_3Label.Text = 'max.';
            app.edt_max_clu_b = uieditfield(app.UIFigure, 'numeric');
            app.edt_max_clu_b.ValueChangedFcn = createCallbackFcn(app, @edt_max_clu_dValueChanged, true);
            app.edt_max_clu_b.Tag = '2';
            app.stepEditField_3Label = uilabel(app.UIFigure);
            app.stepEditField_3Label.Text = 'step';
            app.edt_step_clu_b = uieditfield(app.UIFigure, 'numeric');
            app.edt_step_clu_b.ValueChangedFcn = createCallbackFcn(app, @edt_step_clu_0ValueChanged, true);
            app.edt_step_clu_b.Tag = '2';
            app.maxEditField_4Label = uilabel(app.UIFigure);
            app.maxEditField_4Label.Text = 'max.';
            app.edt_max_clu_c = uieditfield(app.UIFigure, 'numeric');
            app.edt_max_clu_c.ValueChangedFcn = createCallbackFcn(app, @edt_max_clu_dValueChanged, true);
            app.edt_max_clu_c.Tag = '3';
            app.stepEditField_4Label = uilabel(app.UIFigure);
            app.stepEditField_4Label.Text = 'step';
            app.edt_step_clu_c = uieditfield(app.UIFigure, 'numeric');
            app.edt_step_clu_c.ValueChangedFcn = createCallbackFcn(app, @edt_step_clu_0ValueChanged, true);
            app.edt_step_clu_c.Tag = '3';
            app.maxEditField_5Label = uilabel(app.UIFigure);
            app.maxEditField_5Label.Text = 'max.';
            app.edt_max_clu_d = uieditfield(app.UIFigure, 'numeric');
            app.edt_max_clu_d.ValueChangedFcn = createCallbackFcn(app, @edt_max_clu_dValueChanged, true);
            app.edt_max_clu_d.Tag = '4';
            app.stepEditField_5Label = uilabel(app.UIFigure);
            app.stepEditField_5Label.Text = 'step';
            app.edt_step_clu_d = uieditfield(app.UIFigure, 'numeric');
            app.edt_step_clu_d.ValueChangedFcn = createCallbackFcn(app, @edt_step_clu_0ValueChanged, true);
            app.edt_step_clu_d.Tag = '4';
            app.lbl_clu_a = uilabel(app.UIFigure);
            app.lbl_clu_b = uilabel(app.UIFigure);
            app.lbl_clu_b.Text = 'Label2';
            app.lbl_clu0 = uilabel(app.UIFigure);
            app.lbl_clu0.Text = 'Label3';
            app.lbl_clu_c = uilabel(app.UIFigure);
            app.lbl_clu_c.Text = 'Label4';
            app.lbl_clu_d = uilabel(app.UIFigure);
            app.lbl_clu_d.Text = 'Label5';
            app.lbl_clus0_isi = uilabel(app.UIFigure);
            app.lbl_clus0_isi.Text = 'Label6';
            app.lbl_clu_a_isi = uilabel(app.UIFigure);
            app.lbl_clu_a_isi.Text = 'Label7';
            app.lbl_clu_b_isi = uilabel(app.UIFigure);
            app.lbl_clu_b_isi.Text = 'Label8';
            app.lbl_clu_c_isi = uilabel(app.UIFigure);
            app.lbl_clu_c_isi.Text = 'Label9';
            app.lbl_clu_d_isi = uilabel(app.UIFigure);
            app.lbl_clu_d_isi.Text = 'Label10';
            app.sbtn_fix_clus_a = uibutton(app.UIFigure, 'state');
            app.sbtn_fix_clus_a.ValueChangedFcn = createCallbackFcn(app, @sbtn_fix_clus_aValueChanged, true);
            app.sbtn_fix_clus_a.Tag = '1';
            app.sbtn_fix_clus_a.Text = 'Fix';
            app.sbtn_fix_clus_b = uibutton(app.UIFigure, 'state');
            app.sbtn_fix_clus_b.ValueChangedFcn = createCallbackFcn(app, @sbtn_fix_clus_aValueChanged, true);
            app.sbtn_fix_clus_b.Tag = '2';
            app.sbtn_fix_clus_b.Text = 'Fix';
            app.sbtn_fix_clus_c = uibutton(app.UIFigure, 'state');
            app.sbtn_fix_clus_c.ValueChangedFcn = createCallbackFcn(app, @sbtn_fix_clus_aValueChanged, true);
            app.sbtn_fix_clus_c.Tag = '3';
            app.sbtn_fix_clus_c.Text = 'Fix';
            app.sbtn_fix_clus_d = uibutton(app.UIFigure, 'state');
            app.sbtn_fix_clus_d.ValueChangedFcn = createCallbackFcn(app, @sbtn_fix_clus_aValueChanged, true);
            app.sbtn_fix_clus_d.Tag = '4';
            app.sbtn_fix_clus_d.Text = 'Fix';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Text = 'Button2';
            app.SetparametersButton = uibutton(app.UIFigure, 'push');
            app.SetparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetparametersButtonPushed, true);
            app.SetparametersButton.Text = 'Set parameters';
            app.BasedonLabel = uilabel(app.UIFigure);
            app.BasedonLabel.Text = 'Based on';
            app.Wave_Clus303Label = uilabel(app.UIFigure);
            app.Wave_Clus303Label.Text = 'Wave_Clus 3.0.3';
            app.sbtn_force = uibutton(app.UIFigure, 'state');
            app.sbtn_force.ValueChangedFcn = createCallbackFcn(app, @sbtn_forceValueChanged, true);
            app.sbtn_force.Text = 'Force';
            app.sbtn_reject_clu_a = uibutton(app.UIFigure, 'state');
            app.sbtn_reject_clu_a.ValueChangedFcn = createCallbackFcn(app, @sbtn_reject_clu_aValueChanged, true);
            app.sbtn_reject_clu_a.Tag = '1';
            app.sbtn_reject_clu_a.Text = 'Reject';
            app.sbtn_accept_clu_a = uibutton(app.UIFigure, 'state');
            app.sbtn_accept_clu_a.ValueChangedFcn = createCallbackFcn(app, @sbtn_accept_clu_aValueChanged, true);
            app.sbtn_accept_clu_a.Tag = '1';
            app.sbtn_accept_clu_a.Visible = 'off';
            app.sbtn_accept_clu_a.Text = 'Accept';
            app.sbtn_reject_clu_b = uibutton(app.UIFigure, 'state');
            app.sbtn_reject_clu_b.ValueChangedFcn = createCallbackFcn(app, @sbtn_reject_clu_aValueChanged, true);
            app.sbtn_reject_clu_b.Tag = '2';
            app.sbtn_reject_clu_b.Text = 'Reject';
            app.sbtn_accept_clu_b = uibutton(app.UIFigure, 'state');
            app.sbtn_accept_clu_b.ValueChangedFcn = createCallbackFcn(app, @sbtn_accept_clu_aValueChanged, true);
            app.sbtn_accept_clu_b.Tag = '2';
            app.sbtn_accept_clu_b.Visible = 'off';
            app.sbtn_accept_clu_b.Text = 'Accept';
            app.sbtn_reject_clu_c = uibutton(app.UIFigure, 'state');
            app.sbtn_reject_clu_c.ValueChangedFcn = createCallbackFcn(app, @sbtn_reject_clu_aValueChanged, true);
            app.sbtn_reject_clu_c.Tag = '3';
            app.sbtn_reject_clu_c.Text = 'Reject';
            app.sbtn_accept_clu_c = uibutton(app.UIFigure, 'state');
            app.sbtn_accept_clu_c.ValueChangedFcn = createCallbackFcn(app, @sbtn_accept_clu_aValueChanged, true);
            app.sbtn_accept_clu_c.Tag = '3';
            app.sbtn_accept_clu_c.Visible = 'off';
            app.sbtn_accept_clu_c.Text = 'Accept';
            app.sbtn_reject_clu_d = uibutton(app.UIFigure, 'state');
            app.sbtn_reject_clu_d.ValueChangedFcn = createCallbackFcn(app, @sbtn_reject_clu_aValueChanged, true);
            app.sbtn_reject_clu_d.Tag = '4';
            app.sbtn_reject_clu_d.Text = 'Reject';
            app.sbtn_accept_clu_d = uibutton(app.UIFigure, 'state');
            app.sbtn_accept_clu_d.ValueChangedFcn = createCallbackFcn(app, @sbtn_accept_clu_aValueChanged, true);
            app.sbtn_accept_clu_d.Tag = '4';
            app.sbtn_accept_clu_d.Visible = 'off';
            app.sbtn_accept_clu_d.Text = 'Accept';
            app.lbl_disp_clu = uilabel(app.UIFigure);
            app.lbl_disp_clu.Text = 'Label11';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cluster_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end