classdef det_params < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ConfirmcloseButton           matlab.ui.control.Button
        edt_fmin_sort                matlab.ui.control.NumericEditField
        BandfilterdetHzLabel_2       matlab.ui.control.Label
        edt_ref_period               matlab.ui.control.NumericEditField
        refractoryperiodmsEditFieldLabel  matlab.ui.control.Label
        edt_fmax_sort                matlab.ui.control.NumericEditField
        edt_fmax_det                 matlab.ui.control.NumericEditField
        edt_sort_order               matlab.ui.control.NumericEditField
        FilterordersortingEditField_2Label  matlab.ui.control.Label
        edt_fmin_det                 matlab.ui.control.NumericEditField
        BandfilterdetHzLabel         matlab.ui.control.Label
        edt_det_order                matlab.ui.control.NumericEditField
        FilterorderdetLabel          matlab.ui.control.Label
        tLabel                       matlab.ui.control.Label
        popup_int                    matlab.ui.control.DropDown
        IntervalDropDownLabel        matlab.ui.control.Label
        edt_out_thresh               matlab.ui.control.NumericEditField
        OutlierthreshxstdLabel       matlab.ui.control.Label
        edt_det_thresh               matlab.ui.control.NumericEditField
        DetectionthreshxstdLabel     matlab.ui.control.Label
        SpikeorientationButtonGroup  matlab.ui.container.ButtonGroup
        btn_up                       matlab.ui.control.ToggleButton
        btn_down                     matlab.ui.control.ToggleButton
        btn_both                     matlab.ui.control.ToggleButton
        ax_filt                      matlab.ui.control.UIAxes
        ax_raw                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        calling_app % Description
        data
        data_filt
        par
        thr
        thrmax
        ints
    end
    
    methods (Access = public)
        
        function filtered = filt_signal(~,x,order,fmin,fmax,sr)
            [b,a] = ellip(order,0.1,40,[fmin fmax]*2/sr);
            filtered = filtfilt(b, a, x);
        end

        function update_axis(app)
            plot(app.ax_raw, app.data(:,1), app.data(:,2))
            plot(app.ax_filt, app.data(:,1), app.data_filt)
            app.thr = app.par.stdmin * median(abs(app.data_filt))/0.6745;
            app.thrmax = app.par.stdmax * median(abs(filt_signal(app,app.data(:,2), app.edt_sort_order.Value, app.edt_fmin_sort.Value, app.edt_fmax_sort.Value, app.par.sr)))/0.6745;
            if (app.btn_up.Value)
                line(app.ax_filt,[app.data(1,1) app.data(end,1)],[app.thr, app.thr], 'Color','r', 'LineStyle','--')
                line(app.ax_filt,[app.data(1,1) app.data(end,1)],[app.thrmax, app.thrmax], 'Color','r', 'LineStyle',':')
            end
            if (app.btn_down.Value)
                line(app.ax_filt,[app.data(1,1) app.data(end,1)],[-app.thr, -app.thr], 'Color','r', 'LineStyle','--')
                line(app.ax_filt,[app.data(1,1) app.data(end,1)],[-app.thrmax, -app.thrmax], 'Color','r', 'LineStyle',':')
            end
            if (app.btn_both.Value)
                line(app.ax_filt,[app.data(1,1) app.data(end,1)],[app.thr, app.thr], 'Color','r', 'LineStyle','--')
                line(app.ax_filt,[app.data(1,1) app.data(end,1)],[app.thrmax, app.thrmax], 'Color','r', 'LineStyle',':')
                line(app.ax_filt,[app.data(1,1) app.data(end,1)],[-app.thr, -app.thr], 'Color','r', 'LineStyle','--')
                line(app.ax_filt,[app.data(1,1) app.data(end,1)],[-app.thrmax, -app.thrmax], 'Color','r', 'LineStyle',':')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.calling_app = caller;
            app.edt_fmin_sort.Value = app.calling_app.settings.par.sort_fmin;
            app.edt_fmax_sort.Value = app.calling_app.settings.par.sort_fmax;
            app.edt_fmin_det.Value = app.calling_app.settings.par.detect_fmin;
            app.edt_fmax_det.Value = app.calling_app.settings.par.detect_fmax;
            app.edt_ref_period.Value = app.calling_app.settings.par.ref_ms;
            app.edt_sort_order.Value = app.calling_app.settings.par.sort_order;
            app.edt_det_order.Value = app.calling_app.settings.par.detect_order;
            app.edt_det_thresh.Value = app.calling_app.settings.par.stdmin;
            app.edt_out_thresh.Value = app.calling_app.settings.par.stdmax;
            app.par = app.calling_app.settings.par;
            switch app.calling_app.settings.par.detection
                case 'neg'
                    app.btn_up.Value = false;
                    app.btn_down.Value = true;
                    app.btn_both.Value = false;
                case 'pos'
                    app.btn_up.Value = true;
                    app.btn_down.Value = false;
                    app.btn_both.Value = false;
                case 'both'
                    app.btn_up.Value = false;
                    app.btn_down.Value = false;
                    app.btn_both.Value = true;
            end
            [y,ts,~,~] = current_signal(app.calling_app, app.calling_app.settings.channel_idx.msna);
            app.data = (ts(1):ts(1):ts(2))';
            app.data(:,2) = y;
            app.data_filt = filt_signal(app,y, app.edt_det_order.Value, app.edt_fmin_det.Value, app.edt_fmax_det.Value, app.par.sr);
            itms= {'full'};
            app.ints = [app.data(1,1) app.data(end,1)];
            for i = 1: length(caller.burst_ints)
                itms{i+1} = caller.burst_ints(i).name;
                app.ints(i+1,:) = [caller.burst_ints(i).borders(1,1),caller.burst_ints(i).borders(end,end)];
            end
            app.popup_int.Items = itms;
            app.edt_fmax_det.Limits = [app.edt_fmin_det.Value, 0.5/mean(diff(app.data(:,1)))-0.001];
            app.edt_fmin_det.Limits = [0.001, app.edt_fmax_det.Value-0.001];
            app.edt_fmax_sort.Limits = [app.edt_fmin_sort.Value, 0.5/mean(diff(app.data(:,1)))-0.001];
            app.edt_fmin_sort.Limits = [0.001, app.edt_fmax_sort.Value-0.001];
            update_axis(app)
        end

        % Selection changed function: SpikeorientationButtonGroup
        function SpikeorientationButtonGroupSelectionChanged(app, event)
            update_axis(app)
            if app.btn_up.Value
                app.par.detection = 'pos';
            end
            if app.btn_down.Value
                app.par.detection = 'neg';
            end
            if app.btn_both.Value
                app.par.detection = 'both';
            end
            
        end

        % Value changed function: edt_det_thresh
        function edt_det_threshValueChanged(app, event)
            app.par.stdmin = app.edt_det_thresh.Value;
            update_axis(app)
        end

        % Value changed function: edt_fmin_det
        function edt_fmin_detValueChanged(app, event)
            app.par.detect_fmin = app.edt_fmin_det.Value;
            app.data_filt = filt_signal(app,app.data(:,2), app.edt_det_order.Value, app.edt_fmin_det.Value, app.edt_fmax_det.Value, app.par.sr);
            update_axis(app)
        end

        % Value changed function: edt_fmax_det
        function edt_fmax_detValueChanged(app, event)
            app.par.detect_fmax = app.edt_fmin_det.Value;
            app.data_filt = filt_signal(app,app.data(:,2), app.edt_det_order.Value, app.edt_fmin_det.Value, app.edt_fmax_det.Value, app.par.sr);
            update_axis(app)
        end

        % Value changed function: edt_out_thresh
        function edt_out_threshValueChanged(app, event)
            app.par.stdmax = app.edt_out_thresh.Value;
            update_axis(app)
        end

        % Value changed function: edt_det_order
        function edt_det_orderValueChanged(app, event)
            app.par.detect_order = app.edt_det_order.Value;
            app.data_filt = filt_signal(app,app.data(:,2), app.edt_det_order.Value, app.edt_fmin_det.Value, app.edt_fmax_det.Value, app.par.sr);
            update_axis(app)
        end

        % Value changed function: edt_fmin_sort
        function edt_fmin_sortValueChanged(app, event)
            app.par.sort_fmin = app.edt_fmin_sort.Value;
            update_axis(app)
        end

        % Value changed function: edt_fmax_sort
        function edt_fmax_sortValueChanged(app, event)
            app.par.sort_fmax = app.edt_fmin_sort.Value;   
            update_axis(app)
        end

        % Value changed function: edt_sort_order
        function edt_sort_orderValueChanged(app, event)
            app.par.sort_order = app.edt_sort_order.Value;
            update_axis(app)
        end

        % Value changed function: popup_int
        function popup_intValueChanged(app, event)
            idx = find (strcmp(app.popup_int.Items,app.popup_int.Value)); % idx = find (contains(app.popup_int.Items,app.popup_int.Value));
            xlim(app.ax_raw,app.ints(idx,:))
            xlim(app.ax_filt,app.ints(idx,:))
            
        end

        % Button pushed function: ConfirmcloseButton
        function ConfirmcloseButtonPushed(app, event)
            import_det_params(app.calling_app,app.par)
            delete(app)
        end

        % Value changed function: edt_ref_period
        function edt_ref_periodValueChanged(app, event)
            app.par.ref_ms = app.edt_ref_period.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_raw = uiaxes(app.UIFigure);
            title(app.ax_raw, 'Raw Signal')
            app.ax_filt = uiaxes(app.UIFigure);
            title(app.ax_filt, 'Filtered Signal')
            app.SpikeorientationButtonGroup = uibuttongroup(app.UIFigure);
            app.SpikeorientationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpikeorientationButtonGroupSelectionChanged, true);
            app.SpikeorientationButtonGroup.Title = 'Spike orientation';
            app.btn_both = uitogglebutton(app.SpikeorientationButtonGroup);
            app.btn_both.Text = 'both';
            app.btn_both.Value = true;
            app.btn_down = uitogglebutton(app.SpikeorientationButtonGroup);
            app.btn_down.Text = 'negative';
            app.btn_up = uitogglebutton(app.SpikeorientationButtonGroup);
            app.btn_up.Text = 'positive';
            app.DetectionthreshxstdLabel = uilabel(app.UIFigure);
            app.DetectionthreshxstdLabel.Text = 'Detection thresh.[x std]';
            app.edt_det_thresh = uieditfield(app.UIFigure, 'numeric');
            app.edt_det_thresh.ValueChangedFcn = createCallbackFcn(app, @edt_det_threshValueChanged, true);
            app.edt_det_thresh.Value = 3.5;
            app.OutlierthreshxstdLabel = uilabel(app.UIFigure);
            app.OutlierthreshxstdLabel.Text = 'Outlier thresh. [x std]';
            app.edt_out_thresh = uieditfield(app.UIFigure, 'numeric');
            app.edt_out_thresh.ValueChangedFcn = createCallbackFcn(app, @edt_out_threshValueChanged, true);
            app.edt_out_thresh.Value = 10;
            app.IntervalDropDownLabel = uilabel(app.UIFigure);
            app.IntervalDropDownLabel.Text = 'Interval';
            app.popup_int = uidropdown(app.UIFigure);
            app.popup_int.ValueChangedFcn = createCallbackFcn(app, @popup_intValueChanged, true);
            app.tLabel = uilabel(app.UIFigure);
            app.tLabel.Text = '[t]';
            app.FilterorderdetLabel = uilabel(app.UIFigure);
            app.FilterorderdetLabel.Text = 'Filter order (det.)';
            app.edt_det_order = uieditfield(app.UIFigure, 'numeric');
            app.edt_det_order.ValueChangedFcn = createCallbackFcn(app, @edt_det_orderValueChanged, true);
            app.edt_det_order.Value = 4;
            app.BandfilterdetHzLabel = uilabel(app.UIFigure);
            app.BandfilterdetHzLabel.Text = 'Band filter (det.) [Hz]';
            app.edt_fmin_det = uieditfield(app.UIFigure, 'numeric');
            app.edt_fmin_det.Limits = [0 Inf];
            app.edt_fmin_det.ValueChangedFcn = createCallbackFcn(app, @edt_fmin_detValueChanged, true);
            app.edt_fmin_det.Value = 300;
            app.FilterordersortingEditField_2Label = uilabel(app.UIFigure);
            app.FilterordersortingEditField_2Label.Text = 'Filter order (sorting)';
            app.edt_sort_order = uieditfield(app.UIFigure, 'numeric');
            app.edt_sort_order.ValueChangedFcn = createCallbackFcn(app, @edt_sort_orderValueChanged, true);
            app.edt_sort_order.Value = 2;
            app.edt_fmax_det = uieditfield(app.UIFigure, 'numeric');
            app.edt_fmax_det.ValueChangedFcn = createCallbackFcn(app, @edt_fmax_detValueChanged, true);
            app.edt_fmax_det.Value = 3000;
            app.edt_fmax_sort = uieditfield(app.UIFigure, 'numeric');
            app.edt_fmax_sort.ValueChangedFcn = createCallbackFcn(app, @edt_fmax_sortValueChanged, true);
            app.edt_fmax_sort.Value = 3000;
            app.refractoryperiodmsEditFieldLabel = uilabel(app.UIFigure);
            app.refractoryperiodmsEditFieldLabel.Text = 'refractory  period [ms]';
            app.edt_ref_period = uieditfield(app.UIFigure, 'numeric');
            app.edt_ref_period.ValueChangedFcn = createCallbackFcn(app, @edt_ref_periodValueChanged, true);
            app.edt_ref_period.Value = 10;
            app.BandfilterdetHzLabel_2 = uilabel(app.UIFigure);
            app.BandfilterdetHzLabel_2.Text = 'Band filter (sort.) [Hz]';
            app.edt_fmin_sort = uieditfield(app.UIFigure, 'numeric');
            app.edt_fmin_sort.Limits = [0 Inf];
            app.edt_fmin_sort.ValueChangedFcn = createCallbackFcn(app, @edt_fmin_sortValueChanged, true);
            app.edt_fmin_sort.Value = 300;
            app.ConfirmcloseButton = uibutton(app.UIFigure, 'push');
            app.ConfirmcloseButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmcloseButtonPushed, true);
            app.ConfirmcloseButton.Text = 'Confirm & close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = det_params(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end