classdef fileloader_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        lbl_warning                    matlab.ui.control.Label
        SignalChannelAffiliationLabel  matlab.ui.control.Label
        popup_co2                      matlab.ui.control.DropDown
        CO2DropDownLabel               matlab.ui.control.Label
        popup_resp_flow                matlab.ui.control.DropDown
        SpirometryflowDropDownLabel    matlab.ui.control.Label
        popup_resp                     matlab.ui.control.DropDown
        RespitationbeltDropDownLabel   matlab.ui.control.Label
        popup_bp                       matlab.ui.control.DropDown
        BloodpressureDropDownLabel     matlab.ui.control.Label
        popup_ecg                      matlab.ui.control.DropDown
        ECGDropDownLabel               matlab.ui.control.Label
        popup_mng                      matlab.ui.control.DropDown
        MNGDropDownLabel               matlab.ui.control.Label
        SelectChannelsLabel            matlab.ui.control.Label
        btn_close                      matlab.ui.control.Button
        lstbx_out                      matlab.ui.control.ListBox
        lstbx_in                       matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        calling_app% Description
    end
    
    properties (Access = public)
        F % Description
        channel_asso
        del_file = false
        file_path
        saved_session = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
 
            
            app.calling_app = caller;
            app.file_path =  app.calling_app.settings.file_path;
            [~,~,file_extension] = fileparts(app.file_path);
            if strcmp(file_extension,'.ssf')
                tmp = load(app.file_path ,"-mat");
                app.saved_session = tmp.savestruct;
                tmp = find(app.saved_session.settings.datafile == '.',1,'last');
                
                if exist(app.saved_session.settings.datafile, 'file')
                    file_extension = app.saved_session.settings.datafile(tmp:end); 
                    app.file_path = app.saved_session.settings.datafile;
                else
                    [file, path] = uigetfile({'*.mat';'*.h5';'*.adicht';'*.*'},'Datafile not found please select datafile');
                    tmp = find(file == '.',1,'last');
                    file_extension = file(tmp:end);
                    app.file_path = [path file];
                    app.saved_session.settings.datafile = app.file_path ;
                end
            end

            if strcmp(file_extension,'.mat')
                sdk = adi.mat_file_sdk;
                vars = who('-file', app.file_path);
                if ~ismember('file_meta', vars) 
                    app.file_path = convert_mat_file(app.file_path);
                    app.del_file = true;
                end
            elseif strcmp(file_extension,'.h5')
                sdk = adi.h5_file_sdk;
            else
                sdk = adi.sdk;
            end
            

            file_h = sdk.openFile(app.file_path);
          
            in = adi.file_read_options;
            app.F = adi.file(app.file_path,file_h, sdk, in);
            
            channel_names = cell(app.F.n_channels,1);
            for i = 1: app.F.n_channels

                channel_names{i} = [num2str(i) ' ' app.F.channel_specs(1, i).name];
            end

            channel_names(2:length(channel_names)+1) = channel_names;
            channel_names{1} = 'Available Channels';
            app.lstbx_in.Items = channel_names;
            app.channel_asso = zeros(size(channel_names)); 
            app.channel_asso(contains(channel_names,{'MSNA RAW','MNG','msna raw','mng','Microneurography','microneurograpy'})) = 1;
            app.channel_asso(contains(channel_names,{'ECG','ecg','Cardiac','cardiac','Electrocardiaogramm','electrocardiaogramm'})) = 2;
            app.channel_asso(contains(channel_names,{'BP','bp','Finger Pressure','finger pressure'})) = 3;
            app.channel_asso(contains(channel_names,{'RESP BELT','resp belt'})) = 4;
            app.channel_asso(contains(channel_names,{'CO2','co2'})) = 5;
            app.channel_asso(contains(channel_names,{'Spirometry flow','SPIROMETRY flow', 'spirometry flow'})) = 6;
            dropdown_items = channel_names;
            dropdown_items{1} = 'none';
            app.popup_mng.Items = dropdown_items;
            app.popup_mng.Value = app.popup_mng.Items{1};
            app.popup_ecg.Items = dropdown_items;
            app.popup_ecg.Value = app.popup_ecg.Items{1};
            app.popup_bp.Items = dropdown_items;
            app.popup_bp.Value = app.popup_bp.Items{1};
            app.popup_resp.Items = dropdown_items;
            app.popup_resp.Value = app.popup_resp.Items{1};
            app.popup_co2.Items = dropdown_items;
            app.popup_co2.Value = app.popup_co2.Items{1};
            app.popup_resp_flow.Items = dropdown_items;
            app.popup_resp_flow.Value = app.popup_resp_flow.Items{1};

            if ~isempty(app.saved_session)
                tmp = app.lstbx_out.Items;
                for i = 1 : length(app.saved_session.settings.channels)
                    for j = 2:length(app.lstbx_in.Items)
                        
                        if ~isempty(strfind(app.lstbx_in.Items{1,j}, app.saved_session.settings.channels{1,i}{1,1}))
                            tmp{end+1} = app.lstbx_in.Items{1,j};

                            switch app.channel_asso(j)
                                case 1
                                    app.popup_mng.Value = app.lstbx_in.Items{1,j};
                                case 2
                                    app.popup_ecg.Value = app.lstbx_in.Items{1,j};
                                case 3
                                    app.popup_bp.Value = app.lstbx_in.Items{1,j};
                                case 4
                                    app.popup_resp.Value = app.lstbx_in.Items{1,j};
                                case 5
                                    app.popup_co2.Value = app.lstbx_in.Items{1,j};
                                case 6
                                    app.popup_resp_flow .Value= app.lstbx_in.Items{1,j};
                            end

                            break
                        end
                    end
                end
                app.lstbx_out.Items = tmp;
                tmp{1} = 'DO NOT CHANGE THE CHANNELS:';
                tmp{end+1} = '';
                tmp{end+1} = 'other channels might be added';
                app.lbl_warning.Text = tmp;
            end
        end

        % Value changed function: lstbx_in
        function lstbx_inValueChanged(app, event)
            if ~strcmp(app.lstbx_in.Value, 'Available Channels')    
                idx = find(strcmp(app.lstbx_in.Value, app.lstbx_in.Items));
                switch app.channel_asso(idx)
                    case 1
                        popup = app.popup_mng;
                        edit_pop = true;
                    case 2
                        popup = app.popup_ecg;
                        edit_pop = true;
                    case 3
                        popup = app.popup_bp;
                        edit_pop = true;
                    case 4
                        popup = app.popup_resp;
                        edit_pop = true;
                    case 5
                        popup = app.popup_co2;
                        edit_pop = true;
                    case 6
                        popup = app.popup_resp_flow;
                        edit_pop = true;
                    case 0
                        edit_pop = false;

                end
                tmp = app.lstbx_out.Items;
                if find(strcmp(app.lstbx_out.Items, app.lstbx_in.Value))
                    tmp(strcmp(app.lstbx_out.Items, app.lstbx_in.Value)) = [];
                    if edit_pop
                        popup.Value = popup.Items{1};
                    end
                else
                    tmp{end+1} = app.lstbx_in.Value;
                    if edit_pop
                        popup.Value = popup.Items{idx};
                    end
                end
                app.lstbx_out.Items = tmp;
                app.lstbx_in.Value = app.lstbx_in.Items{1};
            end
            
        end

        % Value changed function: lstbx_out
        function lstbx_outValueChanged(app, event)
            if ~strcmp(app.lstbx_out.Value, 'Selected Channels')    
                idx = find(strcmp(app.lstbx_out.Value, app.lstbx_in.Items));
                tmp = app.lstbx_out.Items;
                tmp(strcmp(app.lstbx_out.Items, app.lstbx_out.Value)) = [];
                app.lstbx_out.Items = tmp;
                app.lstbx_out.Value = app.lstbx_out.Items{1};
                switch app.channel_asso(idx)
                    case 1
                        app.popup_mng.Value = app.popup_mng.Items{1};
                    case 2
                        app.popup_ecg.Value = app.popup_ecg.Items{1};
                    case 3
                        app.popup_bp.Value = app.popup_bp.Items{1};
                    case 4
                        app.popup_resp.Value = app.popup_resp.Items{1};
                    case 5
                        app.popup_co2.Value = app.popup_co2.Items{1};
                    case 6
                        app.popup_resp_flow.Value = app.popup_resp_flow.Items{1};
                end
            end
            
        end

        % Button pushed function: btn_close
        function btn_closeButtonPushed(app, event)
            if strcmp(app.popup_mng.Value, 'none')
                warndlg('please select an MNG channel affiliation')
            else
                chs = app.lstbx_out.Items(2:end);
                sel_channels = nan(1,length(chs));
                for i = 1 : length(chs)
                    sel_channels(i) = find(strcmp(app.lstbx_in.Items(2:end),chs{i}));
                end
                data = struct([]);
                
                for i = 1: app.F.n_records
                    data(i).n_ticks = app.F.records(1,i).n_ticks;
                    data(i).comments = app.F.records(1,i).comments;
                    data(i).tick_dt = app.F.records(1,i).tick_dt;
                    data(i).tick_fs = app.F.records(1,i).tick_fs;
                    data(i).duration = app.F.records(1,i).duration;
                    data(i).rec_start = app.F.records(1,i).record_start;
                    data(i).data_start = app.F.records(1,i).data_start;
                    data(i).data_start_str = app.F.records(1,i).data_start_str;
                    
                    for j = 1 : length(sel_channels)
                        data(i).channel(j).name = app.F.channel_names(sel_channels(j));
                        data(i).channel(j).unit = app.F.channel_specs(1,sel_channels(j)).units(1,i);
                        data(i).channel(j).n_samples = app.F.channel_specs(1,sel_channels(j)).n_samples(1,i);
                        data(i).channel(j).dt = app.F.channel_specs(1,sel_channels(j)).dt(1,i);
                        data(i).channel(j).fs = app.F.channel_specs(1,sel_channels(j)).fs(1,i);
                        data(i).channel(j).data_starts = app.F.channel_specs(1,sel_channels(j)).data_starts(1,i);
                        data(i).channel(j).rec_starts = app.F.channel_specs(1,sel_channels(j)).record_starts(1,i);
                        data(i).channel(j).ds = app.F.channel_specs(1,sel_channels(j)).downsample_amount(1,i);
                        chan_tmp = app.F.getChannelByName(data(i).channel(j).name,'partial_match',false); 
                        data(i).channel(j).data = chan_tmp.getData(i);
                
                    end
                
                end
                
                output = struct([]);
                
                
                for i = 1:length(data(1).channel)
                    output(i).name = data(1).channel(i).name;
                    output(i).unit = data(1).channel(i).unit;
                    output(i).tic_multipl = data(1).tick_fs / data(1).channel(i).fs;
                    output(i).data = [];
                    output(i).derived = false;
                %     tmp = 0;
                    for j = 1 : length(data)
                        output(i).data = [output(i).data;data(j).channel(i).data];
                    end
                    output(i).ts = [data(1).channel(i).dt,length(output(i).data)*data(1).channel(i).dt]; 
                end
                tmp = zeros(length(data(1).channel),1);
                for i = 1 : length(output)
                    tmp(i) = data(1).channel(i).dt;
                end
                min_dt = min(tmp);
                comments = struct([]);
                int_start_tic = 0;
                for i = 1: length(data)
                    if ~isempty(data(i).comments)
                        for j = 1 : length(data(i).comments)
                            comments(end+1).id = data(i).comments(1,j).id;
                            comments(end).string = data(i).comments(1,j).str;
                            comments(end).tic_pos = data(i).comments(1,j).tick_position + int_start_tic;
                            comments(end).ts = comments(end).tic_pos *min_dt;
                        end
                    end
                    int_start_tic = int_start_tic + data(i).n_ticks;
                end
                channel_idx = struct('msna',nan, 'ecg', nan, 'bldp', nan, 'resp', nan, 'co2', nan ,'res_flow',nan);
                tmp_idx = find(strcmp(app.lstbx_out.Items, app.popup_mng.Value));
                if ~isempty(tmp_idx)
                    channel_idx.msna = tmp_idx-1;
                end
                tmp_idx = find(strcmp(app.lstbx_out.Items, app.popup_ecg.Value));
                if ~isempty(tmp_idx)
                    channel_idx.ecg = tmp_idx-1;
                end
                tmp_idx = find(strcmp(app.lstbx_out.Items, app.popup_resp.Value));
                if ~isempty(tmp_idx)
                    channel_idx.resp = tmp_idx-1;
                end
                tmp_idx = find(strcmp(app.lstbx_out.Items, app.popup_bp.Value));
                if ~isempty(tmp_idx)
                    channel_idx.bldp = tmp_idx-1;
                end
                tmp_idx = find(strcmp(app.lstbx_out.Items, app.popup_co2.Value));
                if ~isempty(tmp_idx)
                    channel_idx.co2 = tmp_idx-1;
                end
                tmp_idx = find(strcmp(app.lstbx_out.Items, app.popup_resp_flow.Value));
                if ~isempty(tmp_idx)
                    channel_idx.res_flow = tmp_idx-1;
                end
                import_data(app.calling_app,output,comments, channel_idx, app.saved_session)
                if app.del_file
                    delete(app.file_path)
                end
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.lstbx_in = uilistbox(app.UIFigure);
            app.lstbx_in.Items = {'Available Channels'};
            app.lstbx_in.ValueChangedFcn = createCallbackFcn(app, @lstbx_inValueChanged, true);
            app.lstbx_in.Value = 'Available Channels';
            app.lstbx_out = uilistbox(app.UIFigure);
            app.lstbx_out.Items = {'Selected Channels'};
            app.lstbx_out.ValueChangedFcn = createCallbackFcn(app, @lstbx_outValueChanged, true);
            app.lstbx_out.Value = 'Selected Channels';
            app.btn_close = uibutton(app.UIFigure, 'push');
            app.btn_close.ButtonPushedFcn = createCallbackFcn(app, @btn_closeButtonPushed, true);
            app.btn_close.Text = 'Confirm & close';
            app.SelectChannelsLabel = uilabel(app.UIFigure);
            app.SelectChannelsLabel.Text = 'Select Channels';
            app.MNGDropDownLabel = uilabel(app.UIFigure);
            app.MNGDropDownLabel.Text = 'MNG';
            app.popup_mng = uidropdown(app.UIFigure);
            app.ECGDropDownLabel = uilabel(app.UIFigure);
            app.ECGDropDownLabel.Text = 'ECG';
            app.popup_ecg = uidropdown(app.UIFigure);
            app.BloodpressureDropDownLabel = uilabel(app.UIFigure);
            app.BloodpressureDropDownLabel.Text = 'Blood pressure';
            app.popup_bp = uidropdown(app.UIFigure);
            app.RespitationbeltDropDownLabel = uilabel(app.UIFigure);
            app.RespitationbeltDropDownLabel.Text = 'Respitation belt';
            app.popup_resp = uidropdown(app.UIFigure);
            app.SpirometryflowDropDownLabel = uilabel(app.UIFigure);
            app.SpirometryflowDropDownLabel.Text = 'Spirometry flow';
            app.popup_resp_flow = uidropdown(app.UIFigure);
            app.CO2DropDownLabel = uilabel(app.UIFigure);
            app.CO2DropDownLabel.Text = 'CO2';
            app.popup_co2 = uidropdown(app.UIFigure);
            app.SignalChannelAffiliationLabel = uilabel(app.UIFigure);
            app.SignalChannelAffiliationLabel.Text = 'Signal - Channel Affiliation';
            app.lbl_warning = uilabel(app.UIFigure);
            app.lbl_warning.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fileloader_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end