classdef hr_fix2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        edt_threshold            matlab.ui.control.NumericEditField
        thresholdEditFieldLabel  matlab.ui.control.Label
        chbx_bp                  matlab.ui.control.CheckBox
        edt_window               matlab.ui.control.NumericEditField
        FrameSizeEditFieldLabel  matlab.ui.control.Label
        btn_detect               matlab.ui.control.Button
        chbx_inverse_ecg         matlab.ui.control.CheckBox
        btn_finish               matlab.ui.control.Button
        btn_next                 matlab.ui.control.Button
        btn_prev                 matlab.ui.control.Button
        sldr_pos                 matlab.ui.control.Slider
        ax_edit                  matlab.ui.control.UIAxes
        ax_inst_hr               matlab.ui.control.UIAxes
        ax_ov_ecg                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        hb_res = struct('idx', [], 's',[]) % Description
        settings = struct('ecg', [],...
                          'ts',nan,...
                          'ecg_lim', [nan, nan],...
                          'hb_idx', [],...
                          'line',[],...
                          'listener', [],...
                          'dcm_obj', [],...
                          'ptr', [],...
                          'line_1',[], ...
                          'line_2', [], ...
                          'line_3', [], ...
                          'line_4', [])% Description
        ptr% Description
        ecg
        bp % Description
    end
    
    properties (Access = private)
        callingApp % main app % Description
    end
    
    methods (Access = private)
        
        function update_axes(app,src,evnt)
            if app.chbx_inverse_ecg.Value
                inv = -1;
            else
                inv = 1;
            end

            app.settings.line_1.XData = [app.ecg(app.ptr.start_idx,2) app.ecg(app.ptr.start_idx,2)];
            app.settings.line_2.XData = [app.ecg(app.ptr.stop_idx,2) app.ecg(app.ptr.stop_idx,2)];
            app.settings.line_3.XData = [app.ecg(app.ptr.start_idx,2) app.ecg(app.ptr.start_idx,2)];
            app.settings.line_4.XData = [app.ecg(app.ptr.stop_idx,2) app.ecg(app.ptr.stop_idx,2)];
            hold(app.ptr.ax_2, 'off')

            plot(app.ptr.ax_2, app.ecg(app.ptr.start_idx:app.ptr.stop_idx,2), app.ecg(app.ptr.start_idx:app.ptr.stop_idx,1)*inv, 'HitTest','off')
            hold(app.ptr.ax_2, 'on')
            plot(app.ptr.ax_2, app.ecg(app.ptr.dspl_idx,2), app.ecg(app.ptr.dspl_idx,1)*inv, '*r','HitTest','off')
            hold(app.ptr.ax_2, 'off')
            xlim(app.ptr.ax_2, [app.ecg(app.ptr.start_idx,2) app.ecg(app.ptr.stop_idx,2)])
            set(app.sldr_pos, 'Value',double(app.ptr.start_idx))
            %app.sldr_pos.Value = app.ptr.start_idx;
        end

        function update_hr_axes(app)
            
            x = app.ecg(app.hb_res.idx(1:end-1),2);
            rr  = diff(app.hb_res.t_events);
            v = (ones(size(rr))*60)./rr;
            xq = app.ecg(:,2);
            vq1 = interp1(x,v,xq);
            xq(isnan(vq1)) = [];
            vq1(isnan(vq1)) = [];
            app.ax_inst_hr.Children(3).XData = xq;
            app.ax_inst_hr.Children(3).YData = vq1;
%             plot (app.ax_inst_hr,xq,vq1, 'HitTest','off')
            mn = min(vq1);
            mx = max(vq1);
            app.settings.hr_lims = [mn-0.01*diff([mn,mx]),mx+0.01*diff([mn,mx])];
            app.settings.line_3.XData = [app.ecg(app.ptr.start_idx,2) app.ecg(app.ptr.start_idx,2)];
            app.settings.line_3.YData = app.settings.hr_lims;
            app.settings.line_4.XData = [app.ecg(app.ptr.stop_idx,2) app.ecg(app.ptr.stop_idx,2)];
            app.settings.line_4.YData = app.settings.hr_lims;
            

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            datacursormode(app.UIFigure,'off')
            app.callingApp = caller;
            idx = app.callingApp.settings.channel_idx.ecg;
            ecg_r = app.callingApp.data(idx).data;
            ecg_use = ecg_r(app.callingApp.settings.interval(1,1)/app.callingApp.data(idx).ts(1):app.callingApp.settings.interval(1,2)/app.callingApp.data(idx).ts(1),:);
            app.ecg(:,1) = lowpass(ecg_use(:,1),40,1/app.callingApp.data(idx).ts(1));
            app.ecg(:,2) = (0:size(ecg_use,1)-1)*app.callingApp.data(idx).ts(1);
            app.settings.ts = app.callingApp.data(idx).ts(1);
            if ~isnan(caller.settings.channel_idx.bldp)
                [tmp_bp,ts,~, ~] = current_signal(caller,caller.settings.channel_idx.bldp);
                tmp_bp(:,2) = (ts(1):ts(1):ts(2))';
                app.bp = interp1(tmp_bp(:,2),tmp_bp(:,1),app.ecg(:,2));
            else
                app.bp = nan;
                app.chbx_bp.Enable = "off";
            end
            plot(app.ax_ov_ecg, downsample(app.ecg(:,2),10),downsample(app.ecg(:,1),10))
            plot(app.ax_inst_hr, downsample(app.ecg(:,2),10),nan(size(downsample(app.ecg(:,2),10))))
            title(app.ax_ov_ecg,'ECG overview')
            xlabel(app.ax_ov_ecg,'')
            xlim (app.ax_ov_ecg, [app.ecg(1,2) app.ecg(end,2)])
            xlim (app.ax_inst_hr, [app.ecg(1,2) app.ecg(end,2)])
            %% add bp processing
                       
            %%
            app.ptr = pointer(app);
            app.settings.line_1 = line(app.ax_ov_ecg, [app.ecg(app.ptr.start_idx, 2) app.ecg(app.ptr.start_idx, 2)], ylim(app.ax_ov_ecg), 'Color','r');
            app.settings.line_2 = line(app.ax_ov_ecg, [app.ecg(app.ptr.stop_idx, 2) app.ecg(app.ptr.stop_idx, 2)], ylim(app.ax_ov_ecg), 'Color','r');
            app.settings.line_3 = line(app.ax_inst_hr, [app.ecg(app.ptr.start_idx, 2) app.ecg(app.ptr.start_idx, 2)], ylim(app.ax_ov_ecg), 'Color','r');
            app.settings.line_4 = line(app.ax_inst_hr, [app.ecg(app.ptr.stop_idx, 2) app.ecg(app.ptr.stop_idx, 2)], ylim(app.ax_ov_ecg), 'Color','r');
            app.settings.listener = addlistener(app.ptr, 'new_frame', @(src, evnt)update_axes(app,src,evnt));
                                    
            if  ~isempty(app.callingApp.hb_res)
                app.hb_res = app.callingApp.hb_res;
            end
            if (size(app.ecg,1)-app.ptr.dur) == 1
                app.sldr_pos.Enable = "off";
            else
                app.sldr_pos.Limits = [1, size(app.ecg,1)-app.ptr.dur];
                app.sldr_pos.Value = 1;
            end
            app.ptr.get_frame(1,app)
            if ~isempty(app.hb_res.idx)
                update_hr_axes(app)
            end

        end

        % Value changed function: sldr_pos
        function sldr_posValueChanged(app, event)
            app.ptr.get_frame(app.sldr_pos.Value,app)
            
        end

        % Button down function: ax_edit
        function ax_editButtonDown(app, event)
            ts = event.IntersectionPoint(1);
            y = event.IntersectionPoint(2);
            idx = find(app.hb_res.t_events >= ts -0.050 & app.hb_res.t_events <= ts +0.050);
            fs = 1/app.settings.ts;

            if app.chbx_inverse_ecg.Value
                inv = -1;
            else
                inv = 1;
            end

            if isempty(idx)
                idx = find(app.ecg(:,2)>= ts,1,"first");
                peakint = [round(0.06/app.settings.ts), round(0.03/app.settings.ts)];
                tmp = idx-peakint(2); %tmp = add(i)-20;
                [pks,locs] = findpeaks(app.ecg(tmp:tmp+peakint(1),1)*inv);
                locs = locs-1+tmp;
                tmp = [];
                tmp(:,1) = pks-y;
                tmp(:,2) = (app.ecg(locs,2)-ts)*5;
                tmp= tmp.*tmp;
                dis = sqrt(tmp(:,1)+tmp(:,2));
                [~,tmp_idx] = min(dis);
                idx = locs(tmp_idx);
                ts = app.ecg(idx,2);
                app.hb_res.idx = unique([app.hb_res.idx;idx]);
                app.hb_res.t_events = unique([app.hb_res.t_events;ts]);
            else
                if length(idx) ==1
                    app.hb_res.idx(idx) = [];
                    app.hb_res.t_events(idx) = [];
                else
                    tmp = [];
                    tmp(:,1) = app.hb_res.t_events(idx)-ts;
                    tmp(:,2) = app.ecg(app.hb_res.idx(idx))-y;
                    tmp= tmp.*tmp;
                    dis = sqrt(tmp(:,1)+tmp(:,2));
                    [~,tmp_idx] = min(dis);
                    
                    app.hb_res.idx(idx(tmp_idx)) = [];
                    app.hb_res.t_events(idx(tmp_idx)) = [];

                end
            end
            update_hr_axes(app)
            app.ptr.get_frame(app.ptr.start_idx,app)
        end

        % Button pushed function: btn_prev
        function btn_prevButtonPushed(app, event)
            app.ptr.prev_frame(app)
        end

        % Value changed function: edt_window
        function edt_windowValueChanged(app, event)
            app.ptr.dur = app.edt_window.Value * 1/(mean(diff(app.ecg(:,2))));
            app.sldr_pos.Limits = [1 size(app.ecg,1)-app.ptr.dur];
            app.ptr.get_frame(app.ptr.start_idx,app)
            
        end

        % Button pushed function: btn_next
        function btn_nextButtonPushed(app, event)
            app.ptr.next_frame(app)
        end

        % Value changed function: chbx_inverse_ecg
        function chbx_inverse_ecgValueChanged(app, event)
            app.ptr.get_frame(app.ptr.start_idx,app)
        end

        % Button pushed function: btn_detect
        function btn_detectButtonPushed(app, event)
        	app.hb_res = hb_det_2(app.ecg, app.edt_threshold.Value,app.chbx_inverse_ecg.Value, app.callingApp.bp_res , app.chbx_bp.Value);
            app.ptr.get_frame(app.ptr.start_idx,app)
            
            update_hr_axes(app)
        end

        % Button pushed function: btn_finish
        function btn_finishButtonPushed(app, event)
            app.hb_res.dt_instantaneous = diff(app.hb_res.t_events);
            app.hb_res.rate_instantaneous = app.hb_res.dt_instantaneous.^(-1) * 60;
            app.hb_res.rate_mean = mean(app.hb_res.rate_instantaneous);
            app.hb_res.dtDifferences = diff(app.hb_res.dt_instantaneous);
            app.hb_res.ts = [app.ecg(1,2),mean(diff(app.ecg(:,2))), app.ecg(end,2)];
            import_hb_datafunc(app.callingApp,app.hb_res)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_ov_ecg = uiaxes(app.UIFigure);
            app.ax_ov_ecg.XTick = [];
            app.ax_ov_ecg.YTick = [];
            app.ax_inst_hr = uiaxes(app.UIFigure);
            title(app.ax_inst_hr, 'instantaneous heart rate')
            app.ax_inst_hr.XTick = [];
            app.ax_inst_hr.YTick = [];
            app.ax_edit = uiaxes(app.UIFigure);
            app.ax_edit.YTick = [];
            app.ax_edit.ButtonDownFcn = createCallbackFcn(app, @ax_editButtonDown, true);
            app.sldr_pos = uislider(app.UIFigure);
            app.sldr_pos.MajorTicks = [];
            app.sldr_pos.ValueChangedFcn = createCallbackFcn(app, @sldr_posValueChanged, true);
            app.sldr_pos.MinorTicks = [];
            app.btn_prev = uibutton(app.UIFigure, 'push');
            app.btn_prev.ButtonPushedFcn = createCallbackFcn(app, @btn_prevButtonPushed, true);
            app.btn_prev.Text = 'Prev.  Frame';
            app.btn_next = uibutton(app.UIFigure, 'push');
            app.btn_next.ButtonPushedFcn = createCallbackFcn(app, @btn_nextButtonPushed, true);
            app.btn_next.Text = 'Next Frame';
            app.btn_finish = uibutton(app.UIFigure, 'push');
            app.btn_finish.ButtonPushedFcn = createCallbackFcn(app, @btn_finishButtonPushed, true);
            app.btn_finish.Text = 'Save and Return';
            app.chbx_inverse_ecg = uicheckbox(app.UIFigure);
            app.chbx_inverse_ecg.ValueChangedFcn = createCallbackFcn(app, @chbx_inverse_ecgValueChanged, true);
            app.chbx_inverse_ecg.Text = 'inverse ECG';
            app.btn_detect = uibutton(app.UIFigure, 'push');
            app.btn_detect.ButtonPushedFcn = createCallbackFcn(app, @btn_detectButtonPushed, true);
            app.btn_detect.Text = 'Detect';
            app.FrameSizeEditFieldLabel = uilabel(app.UIFigure);
            app.FrameSizeEditFieldLabel.Text = 'Frame Size';
            app.edt_window = uieditfield(app.UIFigure, 'numeric');
            app.edt_window.Limits = [1 Inf];
            app.edt_window.ValueChangedFcn = createCallbackFcn(app, @edt_windowValueChanged, true);
            app.edt_window.Value = 60;
            app.chbx_bp = uicheckbox(app.UIFigure);
            app.chbx_bp.Text = 'BP aided ';
            app.thresholdEditFieldLabel = uilabel(app.UIFigure);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.edt_threshold = uieditfield(app.UIFigure, 'numeric');
            app.edt_threshold.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hr_fix2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end