classdef int_selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        tbl_com                      matlab.ui.control.Table
        btn_new                      matlab.ui.control.Button
        loadtemplButton              matlab.ui.control.Button
        popup_int_template           matlab.ui.control.DropDown
        IntervaltemplLabel           matlab.ui.control.Label
        edt_dtip                     matlab.ui.control.NumericEditField
        placedatatipEditFieldLabel   matlab.ui.control.Label
        popup_sel_ch3                matlab.ui.control.DropDown
        selectch3Label               matlab.ui.control.Label
        popup_sel_ch2                matlab.ui.control.DropDown
        selectch2Label               matlab.ui.control.Label
        popup_sel_ch1                matlab.ui.control.DropDown
        selectch1Label               matlab.ui.control.Label
        btn_return                   matlab.ui.control.Button
        btn_rem_int                  matlab.ui.control.Button
        popup_disp_int               matlab.ui.control.DropDown
        showintervalDropDownLabel    matlab.ui.control.Label
        btn_save_int                 matlab.ui.control.Button
        popup_der_from               matlab.ui.control.DropDown
        withinintervalDropDownLabel  matlab.ui.control.Label
        edt_int_name                 matlab.ui.control.EditField
        intervalnameEditFieldLabel   matlab.ui.control.Label
        ax_ch3                       matlab.ui.control.UIAxes
        ax_ch2                       matlab.ui.control.UIAxes
        ax_ch1                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        calling_app % Description
        templ_app
    end
    
    properties (Access = public)
        ch_names % Description
        ch_limits %= nan(length(app.calling_app.data),2);
        comments
        lis_ax1   % listener
        lis_ax2   % for linking
        lis_ax3   % axes
        dcm_obj
        intervalls = struct('name', [],...
                            'type', [],...
                            'parent', [],...
                            'borders',[]);
        basic_ints = {'none'};
        all_ints = {'none'}
        dtip_target = struct('target', [], 'ts', [])
        lims
    end
    
    methods (Access = private)
        
        function adapt_lms(app,xl)
            xlim(app.ax_ch1, xl)
            xlim(app.ax_ch2, xl)
            xlim(app.ax_ch3, xl)
        end
        
        function update_ax(app,ax,ch_idx)
            cla(ax)
            [tmp_y,ts,name, unit] = current_signal(app.calling_app, ch_idx);
            tmp_x = (ts(1):ts(1):ts(2))';
%             tmp_y = downsample(app.calling_app.data(ch_idx).data(app.ch_limits(ch_idx,1):...
%                                app.ch_limits (ch_idx,2)),...
%                                100/app.calling_app.data(ch_idx).tic_multipl);
%             tmp_x = 0: app.calling_app.data(ch_idx).ts(1) * 100 / app.calling_app.data(ch_idx).tic_multipl :...
%                     (length(tmp_y)-1) * app.calling_app.data(ch_idx).ts(1)* 100 / app.calling_app.data(ch_idx).tic_multipl;
            if strcmp(ax.Tag, 'dtip_target')
                app.dtip_target.target = plot(ax,tmp_x,tmp_y);
                app.dtip_target.ts = mean(diff(tmp_x));
            else
                plot (ax,tmp_x,tmp_y)
            end
            tmp = [min(tmp_y), max(tmp_y)];
            yl = [tmp(1)-0.05*diff(tmp), tmp(2)+0.02*diff(tmp)];

            title(ax, name) % title(ax, app.calling_app.data(ch_idx).name)
            ylabel(ax, unit) % ylabel(ax, app.calling_app.data(ch_idx).unit)
            com_str = {[]};
            ltrs = 'ABCDEFGHIJKLMNOPQRSTUVWXYZabcdefghijklmnopqrstuvwxyz';

            cntr = 1;

            for j = 1: length (app.comments)
                if cntr <= 52
                    suf = '';
                else
                    suf = num2str(ceil(cntr/52)-1);
                end
                line(ax, [app.comments(j).ts, app.comments(j).ts], yl, 'Color', 'r')
                com_str{end+1,1} = [ltrs(cntr-(52*(ceil(cntr/52)-1))) suf ': ' app.comments(j).string];
                text(ax,app.comments(j).ts+0.5, yl(1) +abs(diff(yl)/10), [ltrs(cntr-(52*(ceil(cntr/52)-1))) suf], "Color",'r')
                cntr = cntr+1;
                
            end

            app.tbl_com.Data = com_str;
            xlim(ax,[tmp_x(1) tmp_x(end)])
            if ~strcmp(app.popup_disp_int.Value , 'none')
                int_idx = find(strcmp(app.popup_disp_int.Value,app.all_ints))-1;

                for i = 1: size(app.intervalls(int_idx).borders,1)
                    line(ax, app.intervalls(int_idx).borders (i,:), [yl(1) yl(1)], 'Color','g','LineWidth',4)
                    line(ax, app.intervalls(int_idx).borders (i,:), [yl(2) yl(2)], 'Color','g','LineWidth',4)
                end 
            end
            ylim(ax,yl)

        end
    end
    
    methods (Access = public)
        
        function import_templates(app,int_templates)
            tmp = {'select name template'};
            tmp(2:length(int_templates)+1) = int_templates;
            app.popup_int_template.Items = tmp;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.calling_app = caller;
            [~,  idx] = max(vertcat(caller.data.tic_multipl));
            [~,ts,~, ~] = current_signal(caller, idx);
            app.lims = [ts(1), ts(2)-ts(1)];
            if isempty(app.calling_app.burst_ints)
                app.intervalls(1) =[];

            else
                app.intervalls = app.calling_app.burst_ints;
                app.all_ints = {'none'};
                app.basic_ints = {'none'};
                for i = 1: length(app.intervalls)
                    if app.intervalls(i).type == 1
                        app.basic_ints{end+1} = app.intervalls(i).name;
                    end
                  app.all_ints{end+1} = app.intervalls(i).name;
                end
            end
            app.popup_der_from.Items = app.basic_ints;
            app.popup_der_from.Value = app.basic_ints{1};
            app.popup_disp_int.Items = app.all_ints;
            app.popup_disp_int.Value = app.all_ints{1};
            app.lis_ax1 = addlistener(app.ax_ch1 ,'XLim', 'PostSet', @(~,evnt)adapt_lms(app,evnt.AffectedObject.XLim));
            app.lis_ax2 = addlistener(app.ax_ch2 ,'XLim', 'PostSet', @(~,evnt)adapt_lms(app,evnt.AffectedObject.XLim));
            app.lis_ax3 = addlistener(app.ax_ch3 ,'XLim', 'PostSet', @(~,evnt)adapt_lms(app,evnt.AffectedObject.XLim));
            for i = 1 : length(app.calling_app.data)
                if app.calling_app.data(i).derived
                    app.ch_limits (i,:) = [1, length(app.calling_app.data(i).data)];
                else
                    app.ch_limits(i,:) = [(app.calling_app.settings.interval(1,1) / app.calling_app.data(i).ts(1))+1, ...
                                           app.calling_app.settings.interval(1,2) / app.calling_app.data(i).ts(1)];
                end
            end
            try
                tmp_idx = find(vertcat(app.calling_app.comments.ts) >= app.calling_app.settings.interval(1,1) & ...
                               vertcat(app.calling_app.comments.ts) <= app.calling_app.settings.interval(1,2));
                app.comments = app.calling_app.comments(tmp_idx);
                for i = 1: length(app.comments)
                    app.comments(i).ts = app.comments(i).ts - app.calling_app.settings.interval(1,1);
                end
            catch 
                app.comments = [];
            end
            
            for i = 1:3
                switch i 
                    case 1 
                        ax = app.ax_ch1;
                        case 2 
                        ax = app.ax_ch2;
                        case 3 
                        ax = app.ax_ch3;
                end
                if length(app.calling_app.data)  >= i
                    update_ax(app,ax,i)
                else
                    ax.Visible ="off";
                end 
            end
            app.ch_names = vertcat(app.calling_app.data.name);

            app.popup_sel_ch1.Items = app.ch_names;
            app.popup_sel_ch1.Value = app.ch_names{1};
            if length(app.ch_names)>1
                app.popup_sel_ch2.Items = app.ch_names;
                app.popup_sel_ch2.Value = app.ch_names{2};
            end
            if length(app.ch_names)>2
                app.popup_sel_ch3.Items = app.ch_names;
                app.popup_sel_ch3.Value = app.ch_names{3};
            end
            datacursormode (app.UIFigure, 'on');
            app.dcm_obj = datacursormode(app.UIFigure);
            
        end

        % Value changed function: popup_sel_ch1
        function popup_sel_ch1ValueChanged(app, event)
            idx = find (strcmp(app.popup_sel_ch1.Value,app.ch_names));
            update_ax(app,app.ax_ch1,idx)
            
        end

        % Value changed function: popup_sel_ch2
        function popup_sel_ch2ValueChanged(app, event)
            idx = find (strcmp(app.popup_sel_ch2.Value,app.ch_names));
            update_ax(app,app.ax_ch2,idx)
            
        end

        % Value changed function: popup_sel_ch3
        function popup_sel_ch3ValueChanged(app, event)
            idx = find (strcmp(app.popup_sel_ch3.Value,app.ch_names));
            update_ax(app,app.ax_ch3,idx)
        end

        % Button pushed function: btn_save_int
        function btn_save_intButtonPushed(app, event)
            name = app.edt_int_name.Value;
            if ~isempty(name)
                if ~contains(app.popup_disp_int.Items, name)
                    if strcmp(app.popup_der_from.Value, 'none')
                        type = 1;
                    else
                        type = 2;
                    end
                    parent = app.popup_der_from.Value;
                    info_struct = getCursorInfo(app.dcm_obj);
                                
                    x =nan(length(info_struct),1);
                    for i =1: size(info_struct,2)
                        x(i) = info_struct(i).Position(1);
                    end
                    save_flag = false;
                    x = round(sort(unique(x)),1);
                    if x(1) < app.lims(1)
                        x(1) = app.lims(1);
                    end
                    if x(end) > app.lims(2)
                        x(end) = app.lims(2);
                    end
                    if type == 1
                        if length(x) ==2
                            borders = [x(1),x(2)];
                            save_flag = true;
                        else
                            warndlg('select 2 unique x-values or select a parrent interval')
                        end
                    else
                        if rem(length(x), 2) == 0
                            for i = 1 :length(x)/2
                                borders(i,:) = [x((i-1)*2+1),x((i-1)*2+2)];
                                save_flag = true;
                            end
                        else
                            warndlg('select even number of unique x-values ')
                        end
                    end
                    
                    if save_flag
                        app.intervalls(end+1).name = name;
                        app.intervalls(end).type = type;
                        app.intervalls(end).borders = borders;
                        app.intervalls(end).parent = parent;
                        if type == 1
                            tmp_displ = name;
                            app.all_ints{end+1} = tmp_displ;
                            app.basic_ints{end+1} = name;
                            app.popup_der_from.Items = app.basic_ints;
                            app.popup_der_from.Value = 'none'; 
                        else
                            tmp_displ = [parent ' ' name];
                            app.all_ints{end+1} = tmp_displ;
                            if contains(name,'§$>') && contains(name,'<$§')
                                tmp_name = [name(1:2) '1' name(3:end-2) '1' name(end-1:end)];
                            else
                                tmp_name = name;
                            end
                            app.intervalls(end+1).name = ['avrg. 1st halves ' tmp_name];
                            app.intervalls(end).parent = parent;
                            app.intervalls(end).type = 3;
                            app.intervalls(end).borders(:,1) = borders(:,1); 
                            tmp = borders(:,1) +diff(borders,1,2)/2;
                            app.intervalls(end).borders(:,2) = tmp;
                            app.all_ints{end+1} = [parent ' ' name ' avrg. 1st halves'];
                            
                            if contains(name,'§$>') && contains(name,'<$§')
                                tmp_name = [name(1:2) '2' name(3:end-2) '2' name(end-1:end)];
                            else
                                tmp_name = name;
                            end
                            app.intervalls(end+1).name = ['avrg. 2nd halves ' tmp_name];
                            app.intervalls(end).parent = parent;
                            app.intervalls(end).type = 3;
                            app.intervalls(end).borders(:,1) = tmp; 
                            app.intervalls(end).borders(:,2) = borders(:,2) ;
                            app.all_ints{end+1} = [parent ' ' name ' avrg. 2nd halves'];
                        end
        
                        app.popup_disp_int.Items = app.all_ints;
                        app.popup_disp_int.Value = tmp_displ;
                        
                        idx = find (strcmp(app.popup_sel_ch1.Value,app.ch_names));
                        update_ax(app,app.ax_ch1,idx)
                        if length(app.ch_names) > 1
                            idx = find (strcmp(app.popup_sel_ch2.Value,app.ch_names));
                            update_ax(app,app.ax_ch2,idx)
                        end
                        if length(app.ch_names) > 2
                            idx = find (strcmp(app.popup_sel_ch3.Value,app.ch_names));
                            update_ax(app,app.ax_ch3,idx)
                        end
        
                        app.edt_int_name.Value = '';
                        app.popup_der_from.Value = app.popup_der_from.Items{1};
               
                    end
                else
                    warndlg('please choose unique interval name')
                end
            else
                warndlg('please enter an interval name')
            end

        end

        % Value changed function: popup_disp_int
        function popup_disp_intValueChanged(app, event)
            idx = find (strcmp(app.popup_sel_ch1.Value,app.ch_names));
            update_ax(app,app.ax_ch1,idx)
            if length(app.ch_names) > 1
                idx = find (strcmp(app.popup_sel_ch2.Value,app.ch_names));
                update_ax(app,app.ax_ch2,idx)
            end
            if length(app.ch_names) > 2
                idx = find (strcmp(app.popup_sel_ch3.Value,app.ch_names));
                update_ax(app,app.ax_ch3,idx)
            end            
        end

        % Button pushed function: btn_rem_int
        function btn_rem_intButtonPushed(app, event)
            if ~strcmp(app.popup_disp_int.Value,'none')
                idx = find(strcmp(app.popup_disp_int.Value, app.popup_disp_int.Items))-1;
                if app.intervalls(idx).type == 1
                    rem_idx = find(strcmp(app.intervalls(idx).name, app.basic_ints));
                    app.basic_ints(rem_idx) = [];
                    app.popup_der_from.Items = app.basic_ints;
                end
                app.all_ints(idx+1) = [];
                app.intervalls(idx) = [];
                app.popup_disp_int.Items = app.all_ints;
                app.popup_disp_int.Value = app.popup_disp_int.Items{1};
                idx = find (strcmp(app.popup_sel_ch1.Value,app.ch_names));
                update_ax(app,app.ax_ch1,idx)
                if length(app.ch_names) > 1
                    idx = find (strcmp(app.popup_sel_ch2.Value,app.ch_names));
                    update_ax(app,app.ax_ch2,idx)
                end
                if length(app.ch_names) > 2
                    idx = find (strcmp(app.popup_sel_ch3.Value,app.ch_names));
                    update_ax(app,app.ax_ch3,idx)
                end
            end
        end

        % Button pushed function: btn_return
        function btn_returnButtonPushed(app, event)
            import_ints(app.calling_app,app.intervalls)
            printcell = {[]};
            printcell{1,1} = 'name';
            printcell{1,2} = 'begin rel.';
            printcell{1,3} = 'end rel.';
            printcell{1,4} = 'begin abs.';
            printcell{1,5} = 'end abs.';
            line_idx = 2;
            for i = 1 : length(app.intervalls)
                printcell{line_idx,1} = app.intervalls(i).name;

                for j = 1:size(app.intervalls(i).borders,1)
                    printcell{line_idx,2} = app.intervalls(i).borders(j,1);
                    printcell{line_idx,3} = app.intervalls(i).borders(j,2);
                    printcell{line_idx,4} = app.intervalls(i).borders(j,1)+app.calling_app.settings.interval(1,1);
                    printcell{line_idx,5} = app.intervalls(i).borders(j,2)+app.calling_app.settings.interval(1,1);
                    line_idx = line_idx+1;
                end
            end

            tmp(1) = find(app.calling_app.settings.file_path == '\',1,'last');
            tmp(2) = find(app.calling_app.settings.file_path == '.',1,'last');
            file = app.calling_app.settings.file_path(tmp(1)+1:tmp(2)-1);
            file(file == '.') = '-';
            path = app.calling_app.settings.output_dir;

            writecell(printcell,[path '\' file '_INT_' num2str(app.calling_app.settings.interval(1,1)) '-' num2str(app.calling_app.settings.interval(1,2)) '_sub_intervals.xls' ],'FileType','spreadsheet','Range','A1','WriteMode','replacefile');
            delete(app)
        end

        % Value changed function: edt_dtip
        function edt_dtipValueChanged2(app, event)
            idx = int32(app.edt_dtip.Value./app.dtip_target.ts)+1;
            datatip(app.dtip_target.target,'DataIndex', idx)
            app.edt_dtip.Value = 0;
        end

        % Button pushed function: btn_new
        function btn_newButtonPushed(app, event)

            app.templ_app = template_app(app);
        end

        % Button pushed function: loadtemplButton
        function loadtemplButtonPushed(app, event)

            [file, path] = uigetfile('templ_name.mat','please select template_name.mat file');
            try

                templates = load([path file]);
                tmp = {'select name template'};
                tmp(2:length(templates.int_templates)+1) = templates.int_templates;
                app.popup_int_template.Items = tmp;
            catch

                warndlg('file does not contain name templates')
            end
        end

        % Value changed function: popup_int_template
        function popup_int_templateValueChanged(app, event)
            idx = find(strcmp(app.popup_int_template.Items,app.popup_int_template.Value));
            if idx ~= 1
                app.edt_int_name.Value = app.popup_int_template.Value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_ch1 = uiaxes(app.UIFigure);
            app.ax_ch2 = uiaxes(app.UIFigure);
            app.ax_ch2.Tag = 'dtip_target';
            app.ax_ch3 = uiaxes(app.UIFigure);
            app.intervalnameEditFieldLabel = uilabel(app.UIFigure);
            app.intervalnameEditFieldLabel.Text = 'interval name ';
            app.edt_int_name = uieditfield(app.UIFigure, 'text');
            app.withinintervalDropDownLabel = uilabel(app.UIFigure);
            app.withinintervalDropDownLabel.Text = 'within  interval';
            app.popup_der_from = uidropdown(app.UIFigure);
            app.btn_save_int = uibutton(app.UIFigure, 'push');
            app.btn_save_int.ButtonPushedFcn = createCallbackFcn(app, @btn_save_intButtonPushed, true);
            app.btn_save_int.Text = 'save interval';
            app.showintervalDropDownLabel = uilabel(app.UIFigure);
            app.showintervalDropDownLabel.Text = 'show interval ';
            app.popup_disp_int = uidropdown(app.UIFigure);
            app.popup_disp_int.ValueChangedFcn = createCallbackFcn(app, @popup_disp_intValueChanged, true);
            app.btn_rem_int = uibutton(app.UIFigure, 'push');
            app.btn_rem_int.ButtonPushedFcn = createCallbackFcn(app, @btn_rem_intButtonPushed, true);
            app.btn_rem_int.Text = 'remove';
            app.btn_return = uibutton(app.UIFigure, 'push');
            app.btn_return.ButtonPushedFcn = createCallbackFcn(app, @btn_returnButtonPushed, true);
            app.btn_return.Text = 'save & return';
            app.selectch1Label = uilabel(app.UIFigure);
            app.selectch1Label.Text = 'Select CH1';
            app.popup_sel_ch1 = uidropdown(app.UIFigure);
            app.popup_sel_ch1.ValueChangedFcn = createCallbackFcn(app, @popup_sel_ch1ValueChanged, true);
            app.selectch2Label = uilabel(app.UIFigure);
            app.selectch2Label.Text = 'Select Ch2';
            app.popup_sel_ch2 = uidropdown(app.UIFigure);
            app.popup_sel_ch2.ValueChangedFcn = createCallbackFcn(app, @popup_sel_ch2ValueChanged, true);
            app.selectch3Label = uilabel(app.UIFigure);
            app.selectch3Label.Text = 'Select Ch3';
            app.popup_sel_ch3 = uidropdown(app.UIFigure);
            app.popup_sel_ch3.ValueChangedFcn = createCallbackFcn(app, @popup_sel_ch3ValueChanged, true);
            app.placedatatipEditFieldLabel = uilabel(app.UIFigure);
            app.placedatatipEditFieldLabel.Text = 'place datatip';
            app.edt_dtip = uieditfield(app.UIFigure, 'numeric');
            app.edt_dtip.Limits = [0 Inf];
            app.edt_dtip.ValueChangedFcn = createCallbackFcn(app, @edt_dtipValueChanged2, true);
            app.IntervaltemplLabel = uilabel(app.UIFigure);
            app.IntervaltemplLabel.Text = 'Interval templ.';
            app.popup_int_template = uidropdown(app.UIFigure);
            app.popup_int_template.Items = {'no template'};
            app.popup_int_template.ValueChangedFcn = createCallbackFcn(app, @popup_int_templateValueChanged, true);
            app.popup_int_template.Value = 'no template';
            app.loadtemplButton = uibutton(app.UIFigure, 'push');
            app.loadtemplButton.ButtonPushedFcn = createCallbackFcn(app, @loadtemplButtonPushed, true);
            app.loadtemplButton.Text = 'load templ';
            app.btn_new = uibutton(app.UIFigure, 'push');
            app.btn_new.ButtonPushedFcn = createCallbackFcn(app, @btn_newButtonPushed, true);
            app.btn_new.Text = 'new templ';
            app.tbl_com = uitable(app.UIFigure);
            app.tbl_com.ColumnName = {'Comments'};
            app.tbl_com.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = int_selection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end