classdef spike_selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        popup_int                      matlab.ui.control.DropDown
        DropDownLabel                  matlab.ui.control.Label
        MNGfullLabel                   matlab.ui.control.Label
        MNGoverviewLabel               matlab.ui.control.Label
        MNGDetailLabel                 matlab.ui.control.Label
        btn_return                     matlab.ui.control.Button
        btn_next                       matlab.ui.control.Button
        btn_save                       matlab.ui.control.Button
        btn_prev                       matlab.ui.control.Button
        edt_framesize_det              matlab.ui.control.NumericEditField
        FramesizedetailEditFieldLabel  matlab.ui.control.Label
        edt_framesize_ov               matlab.ui.control.NumericEditField
        FramesizeoverviewEditFieldLabel  matlab.ui.control.Label
        popup_unit                     matlab.ui.control.DropDown
        UnitDropDownLabel              matlab.ui.control.Label
        sldr_overview                  matlab.ui.control.Slider
        sldr_full                      matlab.ui.control.Slider
        ax_detail1                     matlab.ui.control.UIAxes
        ax_overview                    matlab.ui.control.UIAxes
        ax_full                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        calling_app % Description
    end
    
    properties (Access = public)
        lines
        plots
        data
        data_s
        ts
        spike_res
        cols
        listener1
        listener2
        listener3
        dcm_obj
        ptr
        ints
        thresholds
        limits
    end
    
    methods (Access = private)
        
        function update_axis(app,src,update_ov, update_full)
            
            if update_full
                app.plots.full.XData = app.data(src.start_idx_full:src.stop_idx_full,2);
                app.plots.full.YData = app.data(src.start_idx_full:src.stop_idx_full,1);
                xlim(app.ax_full,app.data([src.start_idx_full,src.stop_idx_full],2))
                
            end
            
            if update_ov
%                 plot (app.ax_overview,app.data(src.start_idx_ov:src.stop_idx_ov,2),app.data(src.start_idx_ov:src.stop_idx_ov,1),'UserData',nan) %% plot (app.ax_overview,app.data(src.start_idx_ov:src.stop_idx_ov,2),app.data(src.start_idx_ov:src.stop_idx_ov,1))
                app.plots.ov.XData = app.data(src.start_idx_ov:src.stop_idx_ov,2);
                app.plots.ov.YData = app.data(src.start_idx_ov:src.stop_idx_ov,1);
%                 app.lines.strt_ov = line(app.ax_overview,[app.data(src.start_idx_det,2), app.data(src.start_idx_det,2)], ylim(app.ax_full),'Color','r');
%                 app.lines.stp_ov = line(app.ax_overview,[app.data(src.stop_idx_det,2), app.data(src.stop_idx_det,2)], ylim(app.ax_full),'Color','r');
                app.lines.strt_full.XData = [app.data(src.start_idx_ov,2),app.data(src.start_idx_ov,2)];
                app.lines.stp_full.XData = [app.data(src.stop_idx_ov,2),app.data(src.stop_idx_ov,2)];
                app.sldr_full.Value = double(src.start_idx_ov); 


                xlim(app.ax_overview,[app.data(src.start_idx_ov,2),app.data(src.stop_idx_ov,2)])
            end
            
            app.lines.strt_ov.XData = [app.data(src.start_idx_det,2),app.data(src.start_idx_det,2)];
            app.lines.stp_ov.XData = [app.data(src.stop_idx_det,2),app.data(src.stop_idx_det,2)];
            
            clus = find(strcmp(app.popup_unit.Items,app.popup_unit.Value));
            if clus == 1
                plot_clus = 1:length(app.popup_unit.Items);
            else
                plot_clus = [1,clus];
            end
            cla(app.ax_detail1)
            plot(app.ax_detail1,app.data(src.start_idx_det:src.stop_idx_det,2),app.thresholds(src.start_idx_det:src.stop_idx_det,1),'m-.') 
            hold (app.ax_detail1,'on')
            plot(app.ax_detail1,app.data(src.start_idx_det:src.stop_idx_det,2),app.thresholds(src.start_idx_det:src.stop_idx_det,2),'m:') 
            plot(app.ax_detail1,app.data(src.start_idx_det:src.stop_idx_det,2),app.data(src.start_idx_det:src.stop_idx_det,1),'b')
            
            spike_ts = [];
            for i = plot_clus
                plot(app.ax_detail1,app.data(src.plot_idx{i},2),app.data(src.plot_idx{i},1), 'LineStyle','none','MarkerSize',8,'Marker','o','MarkerEdgeColor',[1,1,1] - app.cols(i,:),'MarkerFaceColor',app.cols(i,:),'UserData',i)
                if i>1
                    spike_ts = [spike_ts;app.data(src.plot_idx{i},2)];
                end
            end
            
            %%plot spikes pos as one object
            spks = nan(length(spike_ts)*3,2);
            tmp_idx = 1:3:length(spike_ts)*3;
            spks(tmp_idx,1)= spike_ts;
            spks(tmp_idx+1,1)= spike_ts;
            yl = [app.limits(1)+diff(app.limits)*0.02, app.limits(1)+diff(app.limits)*0.07];
            spks(tmp_idx,2)= yl(1);
            spks(tmp_idx+1,2)= yl(2);
            plot (app.ax_detail1,spks(:,1),spks(:,2),'LineWidth',1.5,'Color','r')

            %% plot ov spikes
            spike_ts =  app.data(src.ov_idx,2);
            spks = nan(length(spike_ts)*3,2);
            tmp_idx = 1:3:length(spike_ts)*3;
            spks(tmp_idx,1)= spike_ts;
            spks(tmp_idx+1,1)= spike_ts;
            tmp = ylim (app.ax_overview);
            yl = [tmp(1)+diff(tmp)*0.02, tmp(1)+diff(tmp)*0.07];
            spks(tmp_idx,2)= yl(1);
            spks(tmp_idx+1,2)= yl(2);
%                 plot (app.ax_detail1,spks(:,1),spks(:,2),'LineWidth',1.5,'Color','r')
            app.plots.ov_spks.XData = spks(:,1);
            app.plots.ov_spks.YData = spks(:,2);
            %%


            xlim(app.ax_detail1, [app.data(src.start_idx_det+1,2),app.data(src.stop_idx_det+1,2)]) %%%%%%% +1
            ylim(app.ax_detail1, app.limits)
            app.sldr_overview.Value = double(src.start_idx_det);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.calling_app = caller;
            [app.data,app.ts,~,~] = current_signal(app.calling_app, app.calling_app.settings.channel_idx.msna);
            app.data(:,2) = (app.ts(1):app.ts(1):app.ts(2))';
            [b,a] = ellip(caller.settings.par.detect_order,0.1,40,[caller.settings.par.detect_fmin caller.settings.par.detect_fmax]*2/caller.settings.par.sr);
            app.data(:,1) = filtfilt(b, a, app.data(:,1)); 
            
            [b,a] = ellip(caller.settings.par.sort_order,0.1,40,[caller.settings.par.sort_fmin caller.settings.par.sort_fmax]*2/caller.settings.par.sr);
            app.data_s(:,1) = filtfilt(b, a, app.data(:,1)); 
            
%             plot(app.ax_full,app.data(:,2),app.data(:,1))
            %%
            seglen = caller.settings.par.segments_length*caller.settings.par.sr*60;
            num_seg = ceil (size(app.data,1)/(seglen)); %% process 5 min segments
            dur_seg = floor(size(app.data,1)/num_seg);
            idx = nan(num_seg,2);
            idx(1,1) = 1; 
            for i = 1: num_seg
                idx(i,2) = idx(i,1)+dur_seg-1;
                if i ~= num_seg
                    idx(i+1,1) = idx(i,1)+ dur_seg;
                end
            end
            app.thresholds = nan(size(app.data));
            for i = 1: size(idx,1)
                tmp = app.data(idx(i,1):idx(i,2),1);
                app.thresholds(idx(i,1):idx(i,2),1) = caller.settings.par.stdmin * median(abs(tmp))/0.6745;
                app.thresholds(idx(i,1):idx(i,2),2) = caller.settings.par.stdmax * median(abs(tmp))/0.6745;
            end

            %%
            app.spike_res = app.calling_app.spike_res;
            app.spike_res.n_clus = length(unique(app.spike_res.cluster))+1;
            app.spike_res.newspikes = false;
            app.cols = distinguishable_colors(app.spike_res.n_clus,[1 1 1; 1 0 0; 0 0 1; 0.80 0.95 0.90]);%distinguishable_colors(app.spike_res.n_clus,[0, 0.4470, 0.7410;1, 1, 1; 0, 0, 0]);
            app.cols = [.7,.7,.7; app.cols];
            app.ints = app.calling_app.burst_ints;
            int_itm = {'full'};
            for i = 1: length(app.ints)
                int_itm{i+1} = app.ints(i).name;
            end
            app.popup_int.Items = int_itm;
            app.popup_int.Value = app.popup_int.Items{1};
            app.limits = [-max(app.thresholds(:,2))*1.1, max(app.thresholds(:,2))*1.1];
            app.ptr = spike_pointer(app,[1,size(app.data,1)]);
            app.plots.full = plot (app.ax_full, app.data(app.ptr.start_idx_full:app.ptr.stop_idx_full,2), app.data(app.ptr.start_idx_full:app.ptr.stop_idx_full,1),'UserData',nan);
            app.lines.strt_full = line(app.ax_full,[app.data(app.ptr.start_idx_ov,2), app.data(app.ptr.start_idx_ov,2)], ylim(app.ax_full),'Color','r');
            app.lines.stp_full = line(app.ax_full,[app.data(app.ptr.stop_idx_ov,2), app.data(app.ptr.stop_idx_ov,2)], ylim(app.ax_full),'Color','r'); 
            xlim (app.ax_full,app.data([app.ptr.start_idx_full,app.ptr.stop_idx_full],2))
            app.plots.ov = plot (app.ax_overview, app.data(app.ptr.start_idx_ov:app.ptr.stop_idx_ov,2), app.data(app.ptr.start_idx_ov:app.ptr.stop_idx_ov,1),'UserData',nan);
            app.lines.strt_ov = line(app.ax_overview,[app.data(app.ptr.start_idx_det,2), app.data(app.ptr.start_idx_det,2)], ylim(app.ax_full),'Color','r');
            app.lines.stp_ov = line(app.ax_overview,[app.data(app.ptr.stop_idx_det,2), app.data(app.ptr.stop_idx_det,2)], ylim(app.ax_full),'Color','r'); 
            hold (app.ax_overview,'on')
            app.plots.ov_spks = plot (app.ax_overview, nan, nan, 'Color','r');
            hold (app.ax_overview,'off')
            xlim (app.ax_overview,app.data([app.ptr.start_idx_ov,app.ptr.stop_idx_ov],2))
            clus_itm = {'all'};
            for i = 1:app.spike_res.n_clus
                clus_itm{i+1} = num2str(i);
            end
            app.popup_unit.Items = clus_itm;


            app.listener1 = addlistener(app.ptr, 'new_frame_ov', @(src, evnt)update_axis(app,src,true,false));
            app.listener2 = addlistener(app.ptr, 'new_frame', @(src, evnt)update_axis(app,src,false,false));
            app.listener3 = addlistener(app.ptr, 'new_frame_full', @(src, evnt)update_axis(app,src,true,true));
         
            datacursormode (app.UIFigure, 'on');
            app.dcm_obj = datacursormode(app.UIFigure);
                        
            if app.ptr.stop_idx_full-app.ptr.start_idx_full<=app.ptr.dur_ov
                app.ptr.dur_ov = app.ptr.stop_idx_full-app.ptr.start_idx_full;
                app.sldr_full.Enable = 'off';
                app.edt_framesize_ov.Value = app.ptr.dur_ov*mean(diff(app.data(:,2)));
            else
                app.sldr_full.Limits = [double(1),double(app.ptr.stop_idx_full-app.ptr.start_idx_full-app.ptr.dur_ov)];
                app.sldr_full.Value = 1;
            end

            if (app.ptr.stop_idx_ov -app.ptr.start_idx_ov +1) <= app.ptr.dur_det
                app.ptr.dur_det = app.ptr.stop_idx_ov -app.ptr.start_idx_ov;
                app.sldr_overview.Enable = 'off';
                app.edt_framesize_det.Value = app.ptr.dur_det*mean(diff(app.data(:,2)));
            else
                app.sldr_overview.Limits = [double(app.ptr.start_idx_ov),double(app.ptr.stop_idx_ov-app.ptr.dur_det)];
                app.sldr_overview.Value = app.ptr.start_idx_ov;
            end

            app.ptr.get_frame_ov(1,app)

        end

        % Value changed function: sldr_full
        function sldr_fullValueChanged(app, event)
            app.ptr.get_frame_ov(int64(app.sldr_full.Value),app)            
        end

        % Value changed function: sldr_overview
        function sldr_overviewValueChanged(app, event)
            app.ptr.get_frame(int64(app.sldr_overview.Value),app)
            
        end

        % Value changed function: popup_unit
        function popup_unitValueChanged(app, event)
            app.ptr.get_frame(app.ptr.start_idx_det,app)
            
        end

        % Value changed function: edt_framesize_ov
        function edt_framesize_ovValueChanged(app, event)
            dur = app.edt_framesize_ov.Value;
            if dur >= app.ptr.stop_idx_full - app.ptr.start_idx_full+1
                warndlg(['framesize too long, must be smaller than ' num2str(app.pointer.length*mean(diff(app.data(:,2))))])
            else
                if dur/mean(diff(app.data(:,2))) <= app.ptr.dur_det
                    warndlg(['framesize too short, must be bigger than ' num2str(app.pointer.dur_det*mean(diff(app.data(:,2))))])
                else
                    app.ptr.set_ov_dur(dur,app)

                end
            end            
        end

        % Value changed function: edt_framesize_det
        function edt_framesize_detValueChanged(app, event)
            dur = app.edt_framesize_det.Value;
            if dur/mean(diff(app.data(:,2))) >= app.ptr.dur_ov
                warndlg(['framesize too long, must be smaller than ' num2str(app.pointer.dur_ov*mean(diff(app.data(:,2))))])
            else
                app.ptr.set_det_dur(dur,app)
            end      
            
        end

        % Button pushed function: btn_prev
        function btn_prevButtonPushed(app, event)
            app.ptr.prev_frame(app)
        end

        % Button pushed function: btn_save
        function btn_saveButtonPushed(app, event)
            info_struct = getCursorInfo(app.dcm_obj);
            
            for i =1: size(info_struct,2)
                %check userdata == nan 
                if isempty(info_struct(i).Target.UserData) 
                    app.spike_res.newspikes = true;
                    
                    new_spike_idx = find(app.spike_res.spike_ts > info_struct.Position(1)*1000,1,'first');%%
                    app.spike_res.det_res.spk_pos(1,new_spike_idx+1:end+1) = app.spike_res.det_res.spk_pos(1,new_spike_idx:end);
                    app.spike_res.det_res.spikes(new_spike_idx+1:end+1,:) = app.spike_res.det_res.spikes(new_spike_idx:end,:);
                    app.spike_res.det_res.index(1,new_spike_idx+1:end+1) = app.spike_res.det_res.index(1,new_spike_idx:end);
                    
                    app.spike_res.spike_idx(1,new_spike_idx+1:end+1) = app.spike_res.spike_idx(1,new_spike_idx:end);
                    app.spike_res.spike_ts(1,new_spike_idx+1:end+1) = app.spike_res.spike_ts(1,new_spike_idx:end);
                    app.spike_res.spikes(:,new_spike_idx+1:end+1) = app.spike_res.spikes(:,new_spike_idx:end);
                    app.spike_res.cluster(1,new_spike_idx+1:end+1) = app.spike_res.cluster(1,new_spike_idx:end);
                    app.spike_res.extremes(new_spike_idx+1:end+1,:) = app.spike_res.extremes(new_spike_idx:end,:);
                    app.spike_res.use_spikes(new_spike_idx+1:end+1,:) = app.spike_res.use_spikes(new_spike_idx:end,:);
                    %%fix sorting below

                    %%
                    tmp_idx_sel = info_struct(i).DataIndex;
                    if info_struct(i).Position(2)>= 0
                        [~,tmp_cor] = max(info_struct(i).Target.YData(tmp_idx_sel-7:tmp_idx_sel+7));
                        app.spike_res.det_res.spk_pos(1,new_spike_idx) = true;
                        orientation = -1;
                    else
                        [~,tmp_cor] = min(info_struct(i).Target.YData(tmp_idx_sel-7:tmp_idx_sel+7));
                        app.spike_res.det_res.spk_pos(1,new_spike_idx) = false;
                        orientation = 1;
                    end
                    tmp_cor = tmp_cor-8;
                    tmp_idx = tmp_idx_sel+tmp_cor;
                    app.spike_res.spike_ts(1,new_spike_idx) = info_struct(i).Target.XData(tmp_idx)*1000; % info_struct(i).Target.XData(tmp_idx)
                    app.spike_res.spike_idx(1,new_spike_idx) = find(app.data(:,2) ==info_struct(i).Target.XData(tmp_idx)); %==app.spike_res.spike_ts(1,end));
                    app.spike_res.cluster(1,new_spike_idx) = size(app.cols,1)-1;
                    app.spike_res.use_spikes(new_spike_idx,:) = true(1,size(app.spike_res.use_spikes,2));
                    app.spike_res.spikes(:,new_spike_idx) = app.data_s(app.spike_res.spike_idx(1,new_spike_idx)-app.calling_app.settings.par.w_pre:app.spike_res.spike_idx(1,new_spike_idx)+app.calling_app.settings.par.w_post);
                    %%
%                     rmv = [];
                    [minPks,minLocs]=findpeaks(app.spike_res.spikes(:,new_spike_idx)*orientation);%find all local minima
%                     if numel(minPks)<2
%                         rmv=[rmv i];
%                         continue
%                     end
                    [~,I]=sort(minPks, 'descend');
                    I=I(1:2); 
                    I=sort(I,'ascend'); 
                    minLocs2=minLocs(I);%get greatest 2
                    minima2=app.spike_res.spikes(minLocs2,new_spike_idx);
                    dip1(i)=minima2(1);
                    dip2(i)=minima2(2);
                    %%
                    app.spike_res.extremes(new_spike_idx,:) = [dip1 ,app.data_s(app.spike_res.spike_idx(1,new_spike_idx)),dip2];
                    app.spike_res.det_res.index(1,new_spike_idx) = double(app.spike_res.spike_idx(1,end))/(1/app.ts(1))*1000;
                    tmp_spk = app.data_s(app.spike_res.spike_idx(1,new_spike_idx)-app.calling_app.settings.par.w_pre-1:app.spike_res.spike_idx(1,new_spike_idx)+app.calling_app.settings.par.w_post+2);
                    switch app.calling_app.settings.par.interpolation 
                        case 'n'
                            app.spike_res.det_res.spikes(new_spike_idx,:) = tmp_spk(3:end-2);
                        case 'y'
                            app.spike_res.det_res.spikes(new_spike_idx,:) = int_spikes(tmp_spk',app.calling_app.settings.par);
                    end
                    %app.spike_res.det_res.spikes(end+1,:) = tmp_spk(3:end-2);
                        %% spikes(i,:)=xf(index(i)-w_pre-1:index(i)+w_post+2);
                    clear tmp_idx tmp_cor tmp_idx_sel
                else
                    tmp_idx = app.ptr.plot_idx{info_struct(i).Target.UserData}(info_struct(i).DataIndex);
                    app.spike_res.use_spikes(app.spike_res.spike_idx == tmp_idx,1) = ~app.spike_res.use_spikes(app.spike_res.spike_idx == tmp_idx,1);
                end
            end
%             [~,sort_idx] = sort(app.spike_res.spike_idx);
%             app.spike_res.spike_ts = app.spike_res.spike_ts(sort_idx);
%             app.spike_res.spike_idx = app.spike_res.spike_idx(sort_idx);
%             app.spike_res.cluster = app.spike_res.cluster(sort_idx);
%             app.spike_res.spikes = app.spike_res.spikes(:,sort_idx);
%             app.spike_res.extremes = app.spike_res.extremes(sort_idx,:);
%             app.spike_res.use_spikes = app.spike_res.use_spikes(sort_idx,:);
%             app.spike_res.det_res.spk_pos = app.spike_res.det_res.spk_pos(sort_idx);
%             app.spike_res.det_res.index = app.spike_res.det_res.index(sort_idx);
%             app.spike_res.det_res.spikes = app.spike_res.det_res.spikes(sort_idx,:);
            app.ptr.get_frame(app.ptr.start_idx_det,app)
        end

        % Button pushed function: btn_next
        function btn_nextButtonPushed(app, event)
            app.ptr.next_frame(app)
        end

        % Button pushed function: btn_return
        function btn_returnButtonPushed(app, event)
%             UIFigureCloseRequest(app, [])
            import_spike_selection(app.calling_app,app.spike_res)
            delete(app)
        end

        % Value changed function: popup_int
        function popup_intValueChanged(app, event)
            idx = find(strcmp(app.popup_int.Items,app.popup_int.Value));
            if idx == 1
                full_idx = [1,size(app.data,1)];
            else
                full_idx = [find(app.data(:,2) >= app.ints(idx-1).borders(1),1) find(app.data(:,2) >= app.ints(idx-1).borders(2),1)];

            end

            app.ptr.set_full_idx(full_idx,app)
            
        end

        % Value changing function: sldr_full
        function sldr_fullValueChanging(app, event)
            
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.limits = app.limits *1.1;
            app.ptr.get_frame(app.ptr.start_idx_det,app)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.limits = app.limits /1.1;
            app.ptr.get_frame(app.ptr.start_idx_det,app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_full = uiaxes(app.UIFigure);
            app.ax_full.YTick = [];
            app.ax_full.YTickLabel = '';
            app.ax_overview = uiaxes(app.UIFigure);
            app.ax_overview.YTick = [];
            app.ax_overview.YTickLabel = '';
            app.ax_detail1 = uiaxes(app.UIFigure);
            app.ax_detail1.YTick = [];
            app.sldr_full = uislider(app.UIFigure);
            app.sldr_full.MajorTicks = [];
            app.sldr_full.ValueChangedFcn = createCallbackFcn(app, @sldr_fullValueChanged, true);
            app.sldr_full.ValueChangingFcn = createCallbackFcn(app, @sldr_fullValueChanging, true);
            app.sldr_full.MinorTicks = [];
            app.sldr_overview = uislider(app.UIFigure);
            app.sldr_overview.MajorTicks = [];
            app.sldr_overview.ValueChangedFcn = createCallbackFcn(app, @sldr_overviewValueChanged, true);
            app.sldr_overview.MinorTicks = [];
            app.UnitDropDownLabel = uilabel(app.UIFigure);
            app.UnitDropDownLabel.Text = 'Unit';
            app.popup_unit = uidropdown(app.UIFigure);
            app.popup_unit.ValueChangedFcn = createCallbackFcn(app, @popup_unitValueChanged, true);
            app.FramesizeoverviewEditFieldLabel = uilabel(app.UIFigure);
            app.FramesizeoverviewEditFieldLabel.Text = 'Framesize overview';
            app.edt_framesize_ov = uieditfield(app.UIFigure, 'numeric');
            app.edt_framesize_ov.ValueDisplayFormat = '%.3f';
            app.edt_framesize_ov.ValueChangedFcn = createCallbackFcn(app, @edt_framesize_ovValueChanged, true);
            app.edt_framesize_ov.Value = 50;
            app.FramesizedetailEditFieldLabel = uilabel(app.UIFigure);
            app.FramesizedetailEditFieldLabel.Text = 'Framesize detail';
            app.edt_framesize_det = uieditfield(app.UIFigure, 'numeric');
            app.edt_framesize_det.ValueDisplayFormat = '%.3f';
            app.edt_framesize_det.ValueChangedFcn = createCallbackFcn(app, @edt_framesize_detValueChanged, true);
            app.edt_framesize_det.Value = 0.25;
            app.btn_prev = uibutton(app.UIFigure, 'push');
            app.btn_prev.ButtonPushedFcn = createCallbackFcn(app, @btn_prevButtonPushed, true);
            app.btn_prev.Text = 'prev. frame';
            app.btn_save = uibutton(app.UIFigure, 'push');
            app.btn_save.ButtonPushedFcn = createCallbackFcn(app, @btn_saveButtonPushed, true);
            app.btn_save.Text = 'save changes';
            app.btn_next = uibutton(app.UIFigure, 'push');
            app.btn_next.ButtonPushedFcn = createCallbackFcn(app, @btn_nextButtonPushed, true);
            app.btn_next.Text = 'next frame';
            app.btn_return = uibutton(app.UIFigure, 'push');
            app.btn_return.ButtonPushedFcn = createCallbackFcn(app, @btn_returnButtonPushed, true);
            app.btn_return.Text = 'save and return';
            app.MNGDetailLabel = uilabel(app.UIFigure);
            app.MNGDetailLabel.Text = 'MNG Detail';
            app.MNGoverviewLabel = uilabel(app.UIFigure);
            app.MNGoverviewLabel.Text = 'MNG overview';
            app.MNGfullLabel = uilabel(app.UIFigure);
            app.MNGfullLabel.Text = 'MNG full';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.popup_int = uidropdown(app.UIFigure);
            app.popup_int.ValueChangedFcn = createCallbackFcn(app, @popup_intValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spike_selection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end