classdef stat_selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        lstbx_signals                   matlab.ui.control.ListBox
        AvailablesignalsLabel           matlab.ui.control.Label
        btn_add                         matlab.ui.control.Button
        btn_return                      matlab.ui.control.Button
        btn_remove                      matlab.ui.control.Button
        SelectstatisticgroupsLabel      matlab.ui.control.Label
        popup_posthoc                   matlab.ui.control.DropDown
        PosthoctestDropDownLabel        matlab.ui.control.Label
        popup_test                      matlab.ui.control.DropDown
        StatisticaltestDropDownLabel    matlab.ui.control.Label
        tbl_groups                      matlab.ui.control.Table
        lstbx_intervals                 matlab.ui.control.ListBox
        AvailableintervalsListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        calling_app % Description
        stat_group = struct('intervals',[],'test',[],'posthoc',[], 'paired',[])
        sel_ints
        sel_sig
        n_groups = 0;
        stat_test = {{''},{'two sample t test','Wilcoxon rank sum test'}, {'anova'}}
        posthoc = {{''},{'none', 'tukey-kramer', 'hsd', 'dunn-sidak', 'bonferroni', 'scheffe', 'dunnett','lsd'}};
    end
    
    methods (Access = public)
        
        function update_table(app)
            tbl_cell = [];
            for i = 1:length(app.stat_group)
                int_str = [];
                for j = 1 : length(app.stat_group(i).intervals)
                    if j ==1
                        int_str = app.lstbx_intervals.Items{app.stat_group(i).intervals(j)};
                    else
                        int_str = [int_str ', ' app.lstbx_intervals.Items{app.stat_group(i).intervals(j)}];
                    end
                end
                sig_str = [];
                for j=1 : length(app.stat_group(i).signals)
                    if j == 1
                        sig_str = num2str(app.stat_group(i).signals(j));
                    else
                        sig_str = [sig_str ', ' num2str(app.stat_group(i).signals(j))];
                    end
                end
                tbl_cell{i,1} = int_str;
                tbl_cell{i,2} = app.stat_group(i).test;
                tbl_cell{i,3} = app.stat_group(i).posthoc;
                tbl_cell{i,4} = sig_str;
            end
            app.tbl_groups.Data = tbl_cell;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.calling_app = caller;
            lst_str_ints = {};
            for i =1 : length (app.calling_app.burst_ints)
                lst_str_ints{i} = app.calling_app.burst_ints(i).name;
            end
            app.lstbx_intervals.Items = lst_str_ints;
            lst_str_sig = {};
            for i = 1: length(app.calling_app.data)
                if iscell(app.calling_app.data(i).name)
                    lst_str_sig{i} = [num2str(i) ' ' app.calling_app.data(i).name{1,1}];
                else
                    lst_str_sig{i} = [num2str(i) ' ' app.calling_app.data(i).name];
                end
            end
            app.lstbx_signals.Items = lst_str_sig;

        end

        % Value changed function: lstbx_intervals
        function lstbx_intervalsValueChanged(app, event)
            value = app.lstbx_intervals.Value;
            app.sel_ints = nan(size(value));
            for i = 1 : length(value)
                app.sel_ints(i) = find(strcmp(value{i},app.lstbx_intervals.Items));
            end
            switch length(app.sel_ints)
                case 0
                    app.popup_test.Items = app.stat_test{1};
                    app.popup_test.Value = app.popup_test.Items{1};
                    app.popup_test.Enable = 'off';
                    app.popup_posthoc.Items = app.posthoc{1};
                    app.popup_posthoc.Value = app.popup_posthoc.Items{1};
                    app.popup_posthoc.Enable = 'off';
                case 1
                    app.popup_test.Items = app.stat_test{1};
                    app.popup_test.Value = app.popup_test.Items{1};
                    app.popup_test.Enable = 'off';
                    app.popup_posthoc.Items = app.posthoc{1};
                    app.popup_posthoc.Value = app.popup_posthoc.Items{1};
                    app.popup_posthoc.Enable = 'off';

                case 2
                    app.popup_test.Items = app.stat_test{2};
                    app.popup_test.Value = app.popup_test.Items{1};
                    app.popup_test.Enable = 'on';
                    app.popup_posthoc.Items = app.posthoc{1};
                    app.popup_posthoc.Value = app.popup_posthoc.Items{1};
                    app.popup_posthoc.Enable = 'off';
                otherwise
                    app.popup_test.Items = app.stat_test{3};
                    app.popup_test.Value = app.popup_test.Items{1};
                    app.popup_test.Enable = 'on';
                    app.popup_posthoc.Items = app.posthoc{2};
                    app.popup_posthoc.Value = app.popup_posthoc.Items{1};
                    app.popup_posthoc.Enable = 'on';
            end
            
        end

        % Button pushed function: btn_return
        function btn_returnPushed(app, event)
            try
                if isempty(app.stat_group(1).signals)
                    no_stats = true;
                else
                    no_stats = false;
                end
            catch
                no_stats = true;
            end
            import_stats_groups(app.calling_app, app.stat_group, no_stats)
            
            delete(app)
        end

        % Button pushed function: btn_add
        function btn_addPushed(app, event)
            
            if length(app.sel_ints) < 2
%                 warndlg('select at least 2 intervals')
                app.stat_group(app.n_groups+1).intervals = [];
                app.stat_group(app.n_groups+1).test = [];
                app.stat_group(app.n_groups+1).posthoc = [];
                app.stat_group(end).paired = false;
                app.stat_group(app.n_groups+1).signals = sort(app.sel_sig);
                app.n_groups = app.n_groups+1;
                update_table(app)
            else
                app.stat_group(app.n_groups+1).intervals = sort(app.sel_ints);
                app.stat_group(app.n_groups+1).test = app.popup_test.Value;
                app.stat_group(app.n_groups+1).posthoc = app.popup_posthoc.Value;
                if length(app.sel_ints) == 2
                    app.stat_group(end).paired = true;
                else
                    app.stat_group(end).paired = false;
                end
                app.stat_group(app.n_groups+1).signals = sort(app.sel_sig);
                app.n_groups = app.n_groups+1;
                update_table(app)
            end

        end

        % Button pushed function: btn_remove
        function btn_removePushed(app, event)
            if app.n_groups > 0
                app.stat_group(app.tbl_groups.Selection) = [];
                update_table(app)
                app.n_groups = app.n_groups -1;
            end
        end

        % Value changed function: lstbx_signals
        function lstbx_signalsValueChanged(app, event)
            value = app.lstbx_signals.Value;

            app.sel_sig = nan(size(value));
            for i = 1 : length(value)
                app.sel_sig(i) = find(strcmp(value{i},app.lstbx_signals.Items));
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AvailableintervalsListBoxLabel = uilabel(app.UIFigure);
            app.AvailableintervalsListBoxLabel.Text = 'Available  intervals';
            app.lstbx_intervals = uilistbox(app.UIFigure);
            app.lstbx_intervals.Multiselect = 'on';
            app.lstbx_intervals.ValueChangedFcn = createCallbackFcn(app, @lstbx_intervalsValueChanged, true);
            app.lstbx_intervals.Value = {'Item 1'};
            app.tbl_groups = uitable(app.UIFigure);
            app.tbl_groups.ColumnName = {'Intervals'; 'Test'; 'Post hoc'; 'Signals'};
            app.tbl_groups.RowName = {};
            app.tbl_groups.SelectionType = 'row';
            app.StatisticaltestDropDownLabel = uilabel(app.UIFigure);
            app.StatisticaltestDropDownLabel.Text = 'Statistical test';
            app.popup_test = uidropdown(app.UIFigure);
            app.PosthoctestDropDownLabel = uilabel(app.UIFigure);
            app.PosthoctestDropDownLabel.Text = 'Post hoc test';
            app.popup_posthoc = uidropdown(app.UIFigure);
            app.SelectstatisticgroupsLabel = uilabel(app.UIFigure);
            app.SelectstatisticgroupsLabel.Text = 'Select statistic groups';
            app.btn_remove = uibutton(app.UIFigure, 'push');
            app.btn_remove.ButtonPushedFcn = createCallbackFcn(app, @btn_removePushed, true);
            app.btn_remove.Text = 'remove selrected group';
            app.btn_return = uibutton(app.UIFigure, 'push');
            app.btn_return.ButtonPushedFcn = createCallbackFcn(app, @btn_returnPushed, true);
            app.btn_return.Text = 'confirm + start statistics';
            app.btn_add = uibutton(app.UIFigure, 'push');
            app.btn_add.ButtonPushedFcn = createCallbackFcn(app, @btn_addPushed, true);
            app.btn_add.Text = 'add group';
            app.AvailablesignalsLabel = uilabel(app.UIFigure);
            app.AvailablesignalsLabel.Text = 'Available  signals';
            app.lstbx_signals = uilistbox(app.UIFigure);
            app.lstbx_signals.Multiselect = 'on';
            app.lstbx_signals.ValueChangedFcn = createCallbackFcn(app, @lstbx_signalsValueChanged, true);
            app.lstbx_signals.Value = {'Item 1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stat_selection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end